classdef MotorEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShogunSimMotoreditorUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        NewMotormapMenu               matlab.ui.container.Menu
        OpenmotormapMenu              matlab.ui.container.Menu
        SaveMotormapMenu              matlab.ui.container.Menu
        SaveMotormapasMenu            matlab.ui.container.Menu
        QuitMenu                      matlab.ui.container.Menu
        SpecialMenu                   matlab.ui.container.Menu
        AddspeedlineMenu              matlab.ui.container.Menu
        AddtorquepowerlineMenu        matlab.ui.container.Menu
        DeletespeedlineMenu           matlab.ui.container.Menu
        DeletepowerlineMenu           matlab.ui.container.Menu
        ExporttoCSVfileMenu           matlab.ui.container.Menu
        ImportfromCSVMenu             matlab.ui.container.Menu
        EfficiencyEditFieldLabel      matlab.ui.control.Label
        XYaxesDropDown                matlab.ui.control.DropDown
        XYaxesLabel                   matlab.ui.control.Label
        ZaxesDropDown                 matlab.ui.control.DropDown
        ZaxesLabel                    matlab.ui.control.Label
        SpeedpointLabel               matlab.ui.control.Label
        PowerfactorEditFieldLabel     matlab.ui.control.Label
        SlipEditFieldLabel            matlab.ui.control.Label
        UphVEditFieldLabel            matlab.ui.control.Label
        IphAEditFieldLabel            matlab.ui.control.Label
        TorquepointLabel              matlab.ui.control.Label
        MoveactivepointLabel          matlab.ui.control.Label
        PowerfactorEditField          matlab.ui.control.NumericEditField
        SlipEditField                 matlab.ui.control.NumericEditField
        UphVEditField                 matlab.ui.control.NumericEditField
        IphAEditField                 matlab.ui.control.NumericEditField
        LosskWEditField               matlab.ui.control.NumericEditField
        LosskWEditFieldLabel          matlab.ui.control.Label
        EfficiencyEditField           matlab.ui.control.NumericEditField
        SpeedrpmEditField             matlab.ui.control.NumericEditField
        SpeedrpmEditFieldLabel        matlab.ui.control.Label
        PowerkWEditField              matlab.ui.control.NumericEditField
        PowerkWEditFieldLabel         matlab.ui.control.Label
        TorqueNmEditField             matlab.ui.control.NumericEditField
        tEndBtn                       matlab.ui.control.Button
        tUpBtn                        matlab.ui.control.Button
        tZeroBtn                      matlab.ui.control.Button
        tDownBtn                      matlab.ui.control.Button
        tStartBtn                     matlab.ui.control.Button
        vEndBtn                       matlab.ui.control.Button
        vUpBtn                        matlab.ui.control.Button
        vBaseBtn                      matlab.ui.control.Button
        vDownBtn                      matlab.ui.control.Button
        vStartBtn                     matlab.ui.control.Button
        TorqueNmEditFieldLabel        matlab.ui.control.Label
        TorqueCutFig                  matlab.ui.control.UIAxes
        SpeedCutFig                   matlab.ui.control.UIAxes
        OverviewFig                   matlab.ui.control.UIAxes
    end

    properties (Constant)
        app_version = '0.10' % version of the app
        dirs = {'vStart', 'vDown', 'vUp', 'vBase', 'vEnd', 'tStart', 'tDown', 'tZero', 'tUp', 'tEnd'} %
    end
    
    properties (Access = private)
        motor % Current motor for editing
        selectedPoint % index motor speed and torque
        currentFile % opened file
        working_directory % Description
        settings_file % name with full path to the settings file
        caller % Description
        graphRotation %
    end

    methods (Static)
        function launch(caller, app, selFile)
            if exist(selFile, 'file')
                data = load(selFile);
                if isfield(data, 'motor')
                    app.motor = data.motor;
                    app.currentFile = selFile;
                    UpdateFigure(app);
                else
                end
                app.caller = caller;
            else
                close(app.ShogunSimMotoreditorUIFigure);
            end
        end

    end

    methods (Access = public)
    end

    methods (Access = private)
        
        function UpdateFigure(app)
            if ~isempty(app.motor)
                switch app.ZaxesDropDown.ValueIndex
                    case 1
                        zdataMT = app.motor.TotLossMT/1000;
                        zdataMP = app.motor.TotLossMP/1000;
                        zdataGT = app.motor.TotLossGT/1000;
                        zdataGP = app.motor.TotLossGP/1000;
                    case 2
                        zdataMT = app.motor.IPhaseMT;
                        zdataMP = app.motor.IPhaseMP;
                        zdataGT = app.motor.IPhaseGT;
                        zdataGP = app.motor.IPhaseGP;
                    case 3
                        zdataMT = app.motor.UPhaseMT;
                        zdataMP = app.motor.UPhaseMP;
                        zdataGT = app.motor.UPhaseGT;
                        zdataGP = app.motor.UPhaseGP;
                    case 4
                        zdataMT = app.motor.SlipMT;
                        zdataMP = app.motor.SlipMP;
                        zdataGT = app.motor.SlipGT;
                        zdataGP = app.motor.SlipGP;
                    case 5
                        zdataMT = app.motor.PFactMT;
                        zdataMP = app.motor.PFactMP;
                        zdataGT = app.motor.PFactGT;
                        zdataGP = app.motor.PFactGP;
                end
                if app.XYaxesDropDown.ValueIndex == 1
                    ydataMT = app.motor.TorqueMT;
                    ydataMP = app.motor.PowerMP./(app.motor.SpeedMP/60*2*pi);
                    ydataGT = app.motor.TorqueGT;
                    ydataGP = app.motor.PowerGP./(app.motor.SpeedGP/60*2*pi);
                else
                    ydataMT = app.motor.TorqueMT.*(app.motor.SpeedMT/60*2*pi);
                    ydataMP = app.motor.PowerMP;
                    ydataGT = app.motor.TorqueGT.*(app.motor.SpeedGT/60*2*pi);
                    ydataGP = app.motor.PowerGP;
                end
                app.graphRotation = app.OverviewFig.View;

                hold(app.OverviewFig,'off')
                surf(app.OverviewFig, app.motor.SpeedMT, ydataMT, zdataMT);
                hold(app.OverviewFig,'on')
                grid(app.OverviewFig,'on')
                surf(app.OverviewFig, app.motor.SpeedGT, ydataGT, zdataGT);
                surf(app.OverviewFig, app.motor.SpeedMP, ydataMP, zdataMP);
                surf(app.OverviewFig, app.motor.SpeedGP, ydataGP, zdataGP);
                app.OverviewFig.View = app.graphRotation;

                % plot marker
                if app.selectedPoint(2) >= 0 % positive torque
                    if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                        q = 1;
                        x = app.selectedPoint(1);
                        y = app.selectedPoint(2);
                        plot3(app.OverviewFig,app.motor.SpeedMT(x, y), ydataMT(x, y), zdataMT(x, y) + 1, 'Or','MarkerSize',3,'LineWidth',3);
                    else % above base speed
                        q = 2;
                        x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                        y = app.selectedPoint(2);
                        plot3(app.OverviewFig, app.motor.SpeedMP(x, y), ydataMP(x, y), zdataMP(x, y) + 1, 'Or','MarkerSize',3,'LineWidth',3);
                    end
                else
                    if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                        q = 3;
                        x = app.selectedPoint(1);
                        y = -app.selectedPoint(2);
                        plot3(app.OverviewFig,app.motor.SpeedGT(x, y), ydataGT(x, y), zdataGT(x, y)+1, 'Or','MarkerSize',3,'LineWidth',3);
                    else % above base speed
                        q = 4;
                        x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                        y = -app.selectedPoint(2);
                        plot3(app.OverviewFig, app.motor.SpeedGP(x, y), ydataGP(x, y), zdataGP(x, y)+1, 'Or','MarkerSize',3,'LineWidth',3);
                    end
                end

                % Plot cuts
                % Losses vs. Speed
                hold(app.SpeedCutFig, "off")
                hold(app.TorqueCutFig, "off")
                switch q
                    case 1
                        plot(app.SpeedCutFig, app.motor.SpeedMT(:, y), zdataMT(:, y))
                        hold(app.SpeedCutFig, "on")
                        plot(app.SpeedCutFig, app.motor.SpeedMT(x, y), zdataMT(x, y), 'Or','MarkerSize',3,'LineWidth',3);
                        plot(app.TorqueCutFig, ydataMT(x, :), zdataMT(x, :))
                        hold(app.TorqueCutFig, "on")
                        plot(app.TorqueCutFig, ydataMT(x, y), zdataMT(x, y), 'Or','MarkerSize',3,'LineWidth',3);
                    case 2
                        plot(app.SpeedCutFig, app.motor.SpeedMP(:, y), zdataMP(:, y))
                        hold(app.SpeedCutFig, "on")
                        plot(app.SpeedCutFig, app.motor.SpeedMP(x, y), zdataMP(x, y), 'Or','MarkerSize',3,'LineWidth',3);
                        plot(app.TorqueCutFig, ydataMP(x, :), zdataMP(x, :))
                        hold(app.TorqueCutFig, "on")
                        plot(app.TorqueCutFig, ydataMP(x, y), zdataMP(x, y), 'Or','MarkerSize',3,'LineWidth',3);
                    case 3
                        plot(app.SpeedCutFig, app.motor.SpeedGT(:, y), zdataGT(:, y))
                        hold(app.SpeedCutFig, "on")
                        plot(app.SpeedCutFig, app.motor.SpeedGT(x, y), zdataGT(x, y), 'Or','MarkerSize',3,'LineWidth',3);
                        plot(app.TorqueCutFig, ydataGT(x, :), zdataGT(x, :))
                        hold(app.TorqueCutFig, "on")
                        plot(app.TorqueCutFig, ydataGT(x, y), zdataGT(x, y), 'Or','MarkerSize',3,'LineWidth',3);
                    case 4
                        plot(app.SpeedCutFig, app.motor.SpeedGP(:, y), zdataGP(:, y))
                        hold(app.SpeedCutFig, "on")
                        plot(app.SpeedCutFig, app.motor.SpeedGP(x, y), zdataGP(x, y), 'Or','MarkerSize',3,'LineWidth',3);
                        plot(app.TorqueCutFig, ydataGP(x, :), zdataGP(x, :))
                        hold(app.TorqueCutFig, "on")
                        plot(app.TorqueCutFig, ydataGP(x, y), zdataGP(x, y), 'Or','MarkerSize',3,'LineWidth',3);
                end
                grid(app.SpeedCutFig, 'on');
                grid(app.TorqueCutFig, 'on');
            end
        end
        
        function change_power_line(app, pow)
            if app.selectedPoint(2) >= 0 % positive torque
                if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = app.selectedPoint(2);
                    v = app.motor.SpeedMT(x, y);
                    tq = pow/(v*2*pi/60);
                    for i1 = 1:size(app.motor.TorqueMT,1)
                        v = app.motor.SpeedMT(i1, y);
                        pow = tq * v*2*pi/60;
                        uph = app.motor.UPhaseMT(i1, y);
                        loss = app.motor.TotLossMT(i1, y);
                        pf = app.motor.PFactMT(i1, y);
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.motor.TorqueMT(i1, y) = tq;
                        app.motor.IPhaseMT(i1, y) = iph;
                    end
                else % above base speed
                    y = app.selectedPoint(2);
                    for i1 = 1:size(app.motor.PowerMP,1)
                        uph = app.motor.UPhaseMP(i1, y);
                        loss = app.motor.TotLossMP(i1, y);
                        pf = app.motor.PFactMP(i1, y);
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.motor.PowerMP(i1, y) = pow;
                        app.motor.IPhaseMP(i1, y) = iph;
                    end
                end
            else
                if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = -app.selectedPoint(2);
                    v = app.motor.SpeedGT(x, y);
                    tq = pow/(v*2*pi/60);
                    for i1 = 1:size(app.motor.TorqueGT,1)
                        v = app.motor.SpeedGT(i1, y);
                        pow = tq * v*2*pi/60;
                        uph = app.motor.UPhaseGT(i1, y);
                        loss = app.motor.TotLossGT(i1, y);
                        pf = app.motor.PFactGT(i1, y);
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.motor.TorqueGT(i1, y) = tq;
                        app.motor.IPhaseGT(i1, y) = iph;
                    end
                else % above base speed
                    y = -app.selectedPoint(2);
                    for i1 = 1:size(app.motor.PowerGP,1)
                        uph = app.motor.UPhaseGP(i1, y);
                        loss = app.motor.TotLossGP(i1, y);
                        pf = app.motor.PFactGP(i1, y);
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.motor.PowerGP(i1, y) = pow;
                        app.motor.IPhaseGP(i1, y) = iph;
                    end

                end
            end

        end % change_power_line

        function change_speed_line(app, v)
            if app.selectedPoint(2) >= 0 % positive torque
                if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = app.selectedPoint(2);
                    v0 = app.motor.SpeedMT(x, y);
                    
                    for i1 = 1:size(app.motor.SpeedMT,2)
                        tq = app.motor.TorqueMT(x, i1);
                        pow = tq * v*2*pi/60;
                        uph0 = app.motor.UPhaseMT(x, i1);
                        % Scale up phase voltage
                        uph = uph0 * v/v0;
                        loss = app.motor.TotLossMT(x, i1);
                        pf = app.motor.PFactMT(x, i1);
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.motor.SpeedMT(x, i1) = v;
                        app.motor.UPhaseMT(x, i1) = uph;
                        app.motor.IPhaseMT(x, i1) = iph;
                    end

                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                    for i1 = 1:size(app.motor.SpeedMP,2)
                        app.motor.SpeedMP(x, i1) = v;
                    end
                end
            else
                if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = -app.selectedPoint(2);
                    v0 = app.motor.SpeedGT(x, y);
                    for i1 = 1:size(app.motor.SpeedGT,2)
                        tq = app.motor.TorqueGT(x, i1);
                        pow = tq * v*2*pi/60;
                        uph0 = app.motor.UPhaseGT(x, i1);
                        % Scale up phase voltage
                        uph = uph0 * v/v0;
                        loss = app.motor.TotLossGT(x, i1);
                        pf = app.motor.PFactGT(x, i1);
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.motor.SpeedGT(x, i1) = v;
                        app.motor.UPhaseGT(x, i1) = uph;
                        app.motor.IPhaseGT(x, i1) = iph;
                    end

                else % above base speed
                    y = -app.selectedPoint(2);
                    for i1 = 1:size(app.motor.SpeedGP,2)
                        app.motor.SpeedGP(i1, y) = v;
                    end

                end
            end
        end % change_power_line
        
        function change_loss_point(app, loss)
            if app.selectedPoint(2) >= 0 % positive torque
                if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = app.selectedPoint(2);
                    app.motor.TotLossMT(x,y) = loss;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                    y = app.selectedPoint(2);
                    app.motor.TotLossMP(x,y) = loss;
                end
            else
                if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = -app.selectedPoint(2);
                    app.motor.TotLossGT(x,y) = loss;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                    y = -app.selectedPoint(2);
                    app.motor.TotLossGP(x,y) = loss;
                end
            end            
        end % change_loss_point
        
        function change_iph_point(app, iph)
            if app.selectedPoint(2) >= 0 % positive torque
                if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = app.selectedPoint(2);
                    app.motor.IPhaseMT(x,y) = iph;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                    y = app.selectedPoint(2);
                    app.motor.IPhaseMP(x,y) = iph;
                end
            else
                if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = -app.selectedPoint(2);
                    app.motor.IPhaseGT(x,y) = iph;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                    y = -app.selectedPoint(2);
                    app.motor.IPhaseGP(x,y) = iph;
                end
            end            
        end % change_iph_point
        
        function change_uph_point(app, uph)
            if app.selectedPoint(2) >= 0 % positive torque
                if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = app.selectedPoint(2);
                    app.motor.UPhaseMT(x,y) = uph;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                    y = app.selectedPoint(2);
                    app.motor.UPhaseMP(x,y) = uph;
                end
            else
                if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = -app.selectedPoint(2);
                    app.motor.UPhaseGT(x,y) = uph;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                    y = -app.selectedPoint(2);
                    app.motor.UPhaseGP(x,y) = uph;
                end
            end
        end % change_pf_point

        function change_slip_point(app, slip)
            if app.selectedPoint(2) >= 0 % positive torque
                if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = app.selectedPoint(2);
                    app.motor.SlipMT(x,y) = slip;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                    y = app.selectedPoint(2);
                    app.motor.SlipMP(x,y) = slip;
                end
            else
                if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = -app.selectedPoint(2);
                    app.motor.SlipGT(x,y) = slip;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                    y = -app.selectedPoint(2);
                    app.motor.SlipGP(x,y) = slip;
                end
            end
        end % change_pf_point

        function change_pf_point(app, pf)
            if app.selectedPoint(2) >= 0 % positive torque
                if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = app.selectedPoint(2);
                    app.motor.PFactMT(x,y) = pf;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                    y = app.selectedPoint(2);
                    app.motor.PFactMP(x,y) = pf;
                end
            else
                if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                    x = app.selectedPoint(1);
                    y = -app.selectedPoint(2);
                    app.motor.PFactGT(x,y) = pf;
                else % above base speed
                    x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                    y = -app.selectedPoint(2);
                    app.motor.PFactGP(x,y) = pf;
                end
            end
        end % change_pf_point

        
        function [spd, tq, loss, iph, uph, slip, pf] = parseLine(~, ln)
            b = [find(ln == ',') length(ln)+1];
            if size(b, 2) == 9
                spd = str2double(ln(b(1)+1:b(2)-1));
                tq = str2double(ln(b(3)+1:b(4)-1));
                loss = str2double(ln(b(6)+1:b(7)-1));
                iph = str2double(ln(b(4)+1:b(5)-1));
                uph = str2double(ln(b(5)+1:b(6)-1));
                slip = str2double(ln(b(7)+1:b(8)-1));
                pf = str2double(ln(b(8)+1:b(9)-1));
            else
                spd = 0;
                tq = 0;
                loss = 0;
                iph = 0;
                uph = 0;
                slip = 0;
                pf = 0;
            end
        end
        
        function newSel = limitpoint(app)
            newSel = app.selectedPoint;
            if newSel(2) > 0 % motoring
                if newSel(1) <= size(app.motor.SpeedMT,1) % const pos effort
                    newSel(1) = max([newSel(1), 1]);
                    newSel(2) = min([newSel(2), size(app.motor.SpeedMT,2)]);
                else % const pos pow area
                    newSel(1) = min([newSel(1), size(app.motor.SpeedMT,1) + size(app.motor.SpeedMP,1)]);
                    newSel(2) = min([newSel(2), size(app.motor.SpeedMP,2)]);
                end
            else % braking
                if newSel(1) <= size(app.motor.SpeedGT,1) % const pos effort
                    newSel(1) = max([newSel(1), 1]);
                    newSel(2) = max([newSel(2), -size(app.motor.SpeedGT,2)]);
                else % const neg pow area
                    newSel(1) = min([newSel(1), size(app.motor.SpeedGT,1) + size(app.motor.SpeedGP,1)]);
                    newSel(2) = max([newSel(2), -size(app.motor.SpeedGP,2)]);
                end
            end
        end
        
        function DirIfExist(~, dirs)
            for i1 = 1:size(dirs, 2)
                if exist(dirs{i1}, 'dir')
                    cd(dirs{i1});
                else
                    return
                end
            end
        end

        function DoMoveSelection(app, dir)
            if ~isempty(app.motor)
                nSpdPointsMot = size(app.motor.SpeedMT, 1) + size(app.motor.SpeedMP, 1);
                nSpdPointsGen = size(app.motor.SpeedGT, 1) + size(app.motor.SpeedGP, 1);
                switch dir
                    case 'vStart'
                        app.selectedPoint(1) = 1;
                    case 'vDown'
                        app.selectedPoint(1) = max([app.selectedPoint(1) - 1 , 1]);
                    case 'vUp'
                        if app.selectedPoint(2) >= 0 % positive torque
                            app.selectedPoint(1) = min([app.selectedPoint(1) + 1, nSpdPointsMot]);
                        else
                            app.selectedPoint(1) = min([app.selectedPoint(1) + 1, nSpdPointsGen]);
                        end
                    case 'vBase'
                        if app.selectedPoint(2) >= 0 % positive torque
                            app.selectedPoint(1) = size(app.motor.SpeedMT, 1);
                        else
                            app.selectedPoint(1) = size(app.motor.SpeedGT, 1);
                        end
                    case 'vEnd'
                        if app.selectedPoint(2) >= 0 % positive torque
                            app.selectedPoint(1) = nSpdPointsMot;
                        else
                            app.selectedPoint(1) = nSpdPointsGen;
                        end
                    case 'tStart'
                        if app.selectedPoint(1) >= size(app.motor.SpeedGT, 1) % above vbase
                            app.selectedPoint(2) = -size(app.motor.SpeedGP, 2);
                        else
                            app.selectedPoint(2) = -size(app.motor.SpeedGT, 2);
                        end
                    case 'tDown'
                        app.selectedPoint(2) = app.selectedPoint(2) - 1;
                        if app.selectedPoint(2) == 0
                            app.selectedPoint(2) = -1;
                        end
                        if app.selectedPoint(1) >= size(app.motor.SpeedGT, 1) % above vbase
                            app.selectedPoint(2) = max([app.selectedPoint(2), -size(app.motor.SpeedGP,1)]);
                        else
                            app.selectedPoint(2) = max([app.selectedPoint(2), -size(app.motor.SpeedGT,1)]);
                        end
                    case 'tZero'
                        app.selectedPoint(2) = 1;
                    case 'tUp'
                        app.selectedPoint(2) = app.selectedPoint(2) + 1;
                        if app.selectedPoint(2) == 0
                            app.selectedPoint(2) = 1;
                        end
                        if app.selectedPoint(2) > 0 % motoring
                            if app.selectedPoint(1) >= size(app.motor.SpeedMT, 1) % above vbase
                                app.selectedPoint(2) = min([app.selectedPoint(2), size(app.motor.SpeedMP,1)]);
                            else
                                app.selectedPoint(2) = min([app.selectedPoint(2), size(app.motor.SpeedMT,1)]);
                            end
                        else
                            if app.selectedPoint(1) >= size(app.motor.SpeedGT, 1) % above vbase
                                app.selectedPoint(2) = min([app.selectedPoint(2), size(app.motor.SpeedMP,1)]);
                            else
                                app.selectedPoint(2) = min([app.selectedPoint(2), size(app.motor.SpeedMT,1)]);
                            end
                        end
                    case 'tEnd'
                        if app.selectedPoint(1) >= size(app.motor.SpeedMT, 1) % above vbase
                            app.selectedPoint(2) = size(app.motor.SpeedMP, 2);
                        else
                            app.selectedPoint(2) = size(app.motor.SpeedMT, 2);
                        end
                    otherwise
                end
                app.selectedPoint = limitpoint(app);
                if app.selectedPoint(2) >= 0 % positive torque
                    if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                        x = app.selectedPoint(1);
                        y = app.selectedPoint(2);
                        tq = app.motor.TorqueMT(x, y);
                        spd = app.motor.SpeedMT(x, y);
                        pow = spd * app.motor.TorqueMT(x, y)*2*pi/60; % W
                        loss = app.motor.TotLossMT(x, y);
                        iph = app.motor.IPhaseMT(x, y);
                        uph = app.motor.UPhaseMT(x, y);
                        slip = app.motor.SlipMT(x, y);
                        pf = app.motor.PFactMT(x, y);
                    else % above base speed
                        x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                        y = app.selectedPoint(2);
                        spd = app.motor.SpeedMP(x, y);
                        pow = app.motor.PowerMP(x, y); % kW
                        tq = pow / (spd * 2 * pi / 60);
                        loss = app.motor.TotLossMP(x, y);
                        iph = app.motor.IPhaseMP(x, y);
                        uph = app.motor.UPhaseMP(x, y);
                        slip = app.motor.SlipMP(x, y);
                        pf = app.motor.PFactMP(x, y);
                    end
                else
                    if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                        x = app.selectedPoint(1);
                        y = -app.selectedPoint(2);
                        tq = app.motor.TorqueGT(x, y);
                        spd = app.motor.SpeedGT(x, y);
                        pow = spd * app.motor.TorqueGT(x, y)*2*pi/60; % kW
                        loss = app.motor.TotLossGT(x, y);
                        iph = app.motor.IPhaseGT(x, y);
                        uph = app.motor.UPhaseGT(x, y);
                        slip = app.motor.SlipGT(x, y);
                        pf = app.motor.PFactGT(x, y);
                    else % above base speed
                        x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                        y = -app.selectedPoint(2);
                        spd = app.motor.SpeedGP(x, y);
                        pow = app.motor.PowerGP(x, y); % kW
                        tq = pow / (spd * 2 * pi / 60);
                        loss = app.motor.TotLossGP(x, y);
                        iph = app.motor.IPhaseGP(x, y);
                        uph = app.motor.UPhaseGP(x, y);
                        slip = app.motor.SlipGP(x, y);
                        pf = app.motor.PFactGP(x, y);
                    end
                end
                app.TorqueNmEditField.Value = tq;
                app.PowerkWEditField.Value = pow / 1000;
                app.SpeedrpmEditField.Value = spd;
                app.LosskWEditField.Value = loss / 1000;
                app.IphAEditField.Value = iph;
                app.UphVEditField.Value = uph;
                app.SlipEditField.Value = slip;
                app.PowerfactorEditField.Value = pf;
                UpdateFigure(app);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ShogunSimMotoreditorUIFigure.Visible = "off";
            cd(fileparts(mfilename('fullpath')));
            addpath('code');
            app.caller = [];
            app.motor = [];
            app.currentFile = [];
            app.graphRotation = [];
            app.selectedPoint = [1, 1];
            cd(getenv('APPDATA'));
            if ~exist('ShogunSim_Settings', 'dir')
                mkdir('ShogunSim_Settings');
            end
            cd('ShogunSim_Settings')
            if ~exist('Shogun_Settings.mat', 'file')
                Shogun_Settings.workingdirectory = [];
                save('Shogun_Settings.mat', 'Shogun_Settings'); %#ok<NODEF>
            end
            app.settings_file = fullfile(pwd, 'Shogun_Settings.mat');
            load(app.settings_file, 'Shogun_Settings');
            if isfield(Shogun_Settings, 'MotorEditorPosition')
                r = MoveIntoScreen(Shogun_Settings.MotorEditorPosition);
                app.ShogunSimMotoreditorUIFigure.Position = r;
            end
            app.working_directory = Shogun_Settings.workingdirectory;
            app.ShogunSimMotoreditorUIFigure.Visible = "on";
        end

        % Close request function: ShogunSimMotoreditorUIFigure
        function ShogunSimMotoreditorUIFigureCloseRequest(app, event)
            if exist(app.settings_file, 'file')
                load(app.settings_file, 'Shogun_Settings');
                Shogun_Settings.MotorEditorPosition = app.ShogunSimMotoreditorUIFigure.Position;
                save(app.settings_file, 'Shogun_Settings');
            end
            delete(app)            
        end

        % Menu selected function: NewMotormapMenu
        function NewMotormapMenuSelected(app, event)
            centrePoint = [app.ShogunSimMotoreditorUIFigure.Position(1) + app.ShogunSimMotoreditorUIFigure.Position(3)/2, app.ShogunSimMotoreditorUIFigure.Position(2) + app.ShogunSimMotoreditorUIFigure.Position(4)/2];
            [dlg, answ] = new_motor_dlg.ask(centrePoint);
            if ~isempty(answ)
                app.motor = answ;
            end
            delete(dlg)
            UpdateFigure(app)
            figure(app.ShogunSimMotoreditorUIFigure);
        end

        % Menu selected function: OpenmotormapMenu
        function OpenmotormapMenuSelected(app, event)
            DirIfExist(app, {app.working_directory, 'Vehicles', 'MotorMaps'})
            [f, p] = uigetfile('*.mat', 'Select motor map to be opened');
            cd(fileparts(mfilename('fullpath')));
            if f
                selFile = fullfile(p,f);
                data = load(selFile);
                if isfield(data, 'motor')
                    app.motor = data.motor;
                    app.currentFile = selFile;
                    UpdateFigure(app);
                else
                end
            end
            figure(app.ShogunSimMotoreditorUIFigure);
        end

        % Menu selected function: SaveMotormapMenu
        function SaveMotormapMenuSelected(app, event)
            if ~isempty(app.motor) 
                if exist(app.currentFile, 'file')
                    motor = app.motor; %#ok<ADPROPLC>
                    save(app.currentFile, 'motor');
                else
                    SaveMotormapasMenuSelected(app, event);
                end
            end
        end

        % Menu selected function: SaveMotormapasMenu
        function SaveMotormapasMenuSelected(app, event)
            if ~isempty(app.motor)
                cd(app.working_directory);
                [f, p] = uiputfile('*.mat', 'Save current motor data as...');
                cd(fileparts(mfilename('fullpath')));
                if f
                    app.currentFile = fullfile(p,f);
                    motor = app.motor; %#ok<ADPROPLC>
                    save(app.currentFile, 'motor');
                end
            end
            figure(app.ShogunSimMotoreditorUIFigure);
        end

        % Button pushed function: tDownBtn, tEndBtn, tStartBtn, tUpBtn, 
        % ...and 6 other components
        function MoveSelectionButton(app, event)
            DoMoveSelection(app, event.Source.Tag)
        end

        % Value changed function: IphAEditField, LosskWEditField, 
        % ...and 6 other components
        function TorqueNmEditFieldValueChanged(app, event)
            if ~isempty(app.motor)
                tq = app.TorqueNmEditField.Value;
                pow = app.PowerkWEditField.Value * 1000; % Watt
                spd = app.SpeedrpmEditField.Value;
                loss = app.LosskWEditField.Value * 1000; % Watt
                iph = app.IphAEditField.Value;
                uph = app.UphVEditField.Value;
                slip = app.SlipEditField.Value;
                pf = app.PowerfactorEditField.Value;

                switch event.Source.Tag
                    case 'Tq'
% if a torque, power or speed point is changed, the whole "line" shall be changed
                        pow = tq * spd * 2 * pi / 60;
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.IphAEditField.Value = iph;
                        app.PowerkWEditField.Value = pow / 1000;
                        change_power_line(app, pow);
                    case 'Pow'
                        tq = pow / (spd * 2 * pi / 60000);
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.IphAEditField.Value = iph;
                        app.TorqueNmEditField.Value = tq;
                        change_power_line(app, pow);
                    case 'Spd'
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.IphAEditField.Value = iph;
                        change_speed_line(app,spd);
                    case 'Loss'
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        app.IphAEditField.Value = iph;
                        change_loss_point(app, loss)
                        change_iph_point(app, iph)
                    case 'Iph'
                        pf = (pow + loss) / iph / uph / sqrt(3);
                        change_iph_point(app, iph);
                        change_pf_point(app, pf);
                    case 'Uph'
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        change_iph_point(app, iph)
                    case 'Slip'
                        change_slip_point(app, slip)
                    case 'PF'
                        iph = (pow + loss) / pf / uph / sqrt(3);
                        change_iph_point(app, iph);
                        change_pf_point(app, pf);
                end
                UpdateFigure(app);
            end
        end

        % Value changed function: XYaxesDropDown, ZaxesDropDown
        function AxesDropDownValueChanged(app, event)
            switch app.ZaxesDropDown.ValueIndex
                case 1
                    str = 'Losses, ';
                    zlabel(app.OverviewFig,'Losses [kW]')
                    ylabel(app.SpeedCutFig,'Losses [kW]')
                    ylabel(app.TorqueCutFig,'Losses [kW]')
                case 2
                    str = 'IPhase, ';
                    zlabel(app.OverviewFig,'Iph [A]')
                    ylabel(app.SpeedCutFig,'Iph [A]')
                    ylabel(app.TorqueCutFig,'Iph [A]')
                case 3
                    str = 'UPhase, ';
                    zlabel(app.OverviewFig,'Uph [V]')
                    ylabel(app.SpeedCutFig,'Uph [V]')
                    ylabel(app.TorqueCutFig,'Uph [V]')
                case 4
                    str = 'Slip, ';
                    zlabel(app.OverviewFig,'Slip')
                    ylabel(app.SpeedCutFig,'Slip')
                    ylabel(app.TorqueCutFig,'Slip')
                case 5
                    str = 'Power factor, ';
                    zlabel(app.OverviewFig,'PF')
                    ylabel(app.SpeedCutFig,'PF')
                    ylabel(app.TorqueCutFig,'PF')
            end
            str2 = [str(1:end-1) ' vs.Speed'];
            if app.XYaxesDropDown.ValueIndex == 1
                str = [str 'Torque vs. Speed'];
                str3 = [str(1:end-1) ' vs.Torque'];
                ylabel(app.OverviewFig,'Torque [Nm]')
                xlabel(app.TorqueCutFig,'Torque [Nm]')
            else
                str = [str 'Power vs. Speed'];
                str3 = [str(1:end-1) ' vs.Power'];
                xlabel(app.TorqueCutFig,'Power [kW]')
            end
            title(app.OverviewFig,str)
            title(app.SpeedCutFig,str2)
            title(app.TorqueCutFig,str3)
            UpdateFigure(app);
        end

        % Menu selected function: AddspeedlineMenu
        function AddspeedlineMenuSelected(app, event)
            if ~isempty(app.motor)
                if app.selectedPoint(2) >= 0 % positive torque
                    if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                        x = app.selectedPoint(1);
                        app.motor.SpeedMT = app.motor.SpeedMT([1:x x:end],:);
                        app.motor.TorqueMT = app.motor.TorqueMT([1:x x:end],:);
                        app.motor.TotLossMT = app.motor.TotLossMT([1:x x:end],:);
                        app.motor.IPhaseMT = app.motor.IPhaseMT([1:x x:end],:);
                        app.motor.UPhaseMT = app.motor.UPhaseMT([1:x x:end],:);
                        app.motor.SlipMT = app.motor.SlipMT([1:x x:end],:);
                        app.motor.PFactMT = app.motor.PFactMT([1:x x:end],:);
                    else % above base speed
                        x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                        app.motor.SpeedMP = app.motor.SpeedMP([1:x x:end],:);
                        app.motor.PowerMP = app.motor.PowerMP([1:x x:end],:);
                        app.motor.TotLossMP = app.motor.TotLossMP([1:x x:end],:);
                        app.motor.IPhaseMP = app.motor.IPhaseMP([1:x x:end],:);
                        app.motor.UPhaseMP = app.motor.UPhaseMP([1:x x:end],:);
                        app.motor.SlipMP = app.motor.SlipMP([1:x x:end],:);
                        app.motor.PFactMP = app.motor.PFactMP([1:x x:end],:);
                    end
                else
                    if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                        x = app.selectedPoint(1);
                        app.motor.SpeedGT = app.motor.SpeedGT([1:x x:end],:);
                        app.motor.TorqueGT = app.motor.TorqueGT([1:x x:end],:);
                        app.motor.TotLossGT = app.motor.TotLossGT([1:x x:end],:);
                        app.motor.IPhaseGT = app.motor.IPhaseGT([1:x x:end],:);
                        app.motor.UPhaseGT = app.motor.UPhaseGT([1:x x:end],:);
                        app.motor.SlipGT = app.motor.SlipGT([1:x x:end],:);
                        app.motor.PFactGT = app.motor.PFactGT([1:x x:end],:);
                    else % above base speed
                        x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                        app.motor.SpeedGP = app.motor.SpeedGP([1:x x:end],:);
                        app.motor.PowerGP = app.motor.PowerGP([1:x x:end],:);
                        app.motor.TotLossGP = app.motor.TotLossGP([1:x x:end],:);
                        app.motor.IPhaseGP = app.motor.IPhaseGP([1:x x:end],:);
                        app.motor.UPhaseGP = app.motor.UPhaseGP([1:x x:end],:);
                        app.motor.SlipGP = app.motor.SlipGP([1:x x:end],:);
                        app.motor.PFactGP = app.motor.PFactGP([1:x x:end],:);
                    end
                end
                UpdateFigure(app);
            end
        end

        % Menu selected function: DeletespeedlineMenu
        function DeletespeedlineMenuSelected(app, event)
            if ~isempty(app.motor)
                if app.selectedPoint(2) >= 0 % positive torque
                    if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                        x = app.selectedPoint(1);
                        app.motor.SpeedMT = app.motor.SpeedMT([1:x-1 x+1:end],:);
                        app.motor.TorqueMT = app.motor.TorqueMT([1:x-1 x+1:end],:);
                        app.motor.TotLossMT = app.motor.TotLossMT([1:x-1 x+1:end],:);
                        app.motor.IPhaseMT = app.motor.IPhaseMT([1:x-1 x+1:end],:);
                        app.motor.UPhaseMT = app.motor.UPhaseMT([1:x-1 x+1:end],:);
                        app.motor.SlipMT = app.motor.SlipMT([1:x-1 x+1:end],:);
                        app.motor.PFactMT = app.motor.PFactMT([1:x-1 x+1:end],:);
                    else % above base speed
                        x = app.selectedPoint(1) - size(app.motor.SpeedMT,1);
                        app.motor.SpeedMP = app.motor.SpeedMP([1:x-1 x+1:end],:);
                        app.motor.PowerMP = app.motor.PowerMP([1:x-1 x+1:end],:);
                        app.motor.TotLossMP = app.motor.TotLossMP([1:x-1 x+1:end],:);
                        app.motor.IPhaseMP = app.motor.IPhaseMP([1:x-1 x+1:end],:);
                        app.motor.UPhaseMP = app.motor.UPhaseMP([1:x-1 x+1:end],:);
                        app.motor.SlipMP = app.motor.SlipMP([1:x-1 x+1:end],:);
                        app.motor.PFactMP = app.motor.PFactMP([1:x-1 x+1:end],:);
                    end
                else
                    if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                        x = app.selectedPoint(1);
                        app.motor.SpeedGT = app.motor.SpeedGT([1:x-1 x+1:end],:);
                        app.motor.TorqueGT = app.motor.TorqueGT([1:x-1 x+1:end],:);
                        app.motor.TotLossGT = app.motor.TotLossGT([1:x-1 x+1:end],:);
                        app.motor.IPhaseGT = app.motor.IPhaseGT([1:x-1 x+1:end],:);
                        app.motor.UPhaseGT = app.motor.UPhaseGT([1:x-1 x+1:end],:);
                        app.motor.SlipGT = app.motor.SlipGT([1:x-1 x+1:end],:);
                        app.motor.PFactGT = app.motor.PFactGT([1:x-1 x+1:end],:);
                    else % above base speed
                        x = app.selectedPoint(1) - size(app.motor.SpeedGT,1);
                        app.motor.SpeedGP = app.motor.SpeedGP([1:x-1 x+1:end],:);
                        app.motor.PowerGP = app.motor.PowerGP([1:x-1 x+1:end],:);
                        app.motor.TotLossGP = app.motor.TotLossGP([1:x-1 x+1:end],:);
                        app.motor.IPhaseGP = app.motor.IPhaseGP([1:x-1 x+1:end],:);
                        app.motor.UPhaseGP = app.motor.UPhaseGP([1:x-1 x+1:end],:);
                        app.motor.SlipGP = app.motor.SlipGP([1:x-1 x+1:end],:);
                        app.motor.PFactGP = app.motor.PFactGP([1:x-1 x+1:end],:);
                    end
                end
                UpdateFigure(app);
            end
        end

        % Menu selected function: AddtorquepowerlineMenu
        function AddtorquepowerlineMenuSelected(app, event)
            if ~isempty(app.motor)
                y = app.selectedPoint(2);
                if app.selectedPoint(2) >= 0 % positive torque
                    if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                        app.motor.SpeedMT = app.motor.SpeedMT(:,[1:y y:end]);
                        app.motor.TorqueMT = app.motor.TorqueMT(:,[1:y y:end]);
                        app.motor.TotLossMT = app.motor.TotLossMT(:,[1:y y:end]);
                        app.motor.IPhaseMT = app.motor.IPhaseMT(:,[1:y y:end]);
                        app.motor.UPhaseMT = app.motor.UPhaseMT(:,[1:y y:end]);
                        app.motor.SlipMT = app.motor.SlipMT(:,[1:y y:end]);
                        app.motor.PFactMT = app.motor.PFactMT(:,[1:y y:end]);
                    else % above base speed
                        app.motor.SpeedMP = app.motor.SpeedMP(:,[1:y y:end]);
                        app.motor.TorqueMP = app.motor.TorqueMP(:,[1:y y:end]);
                        app.motor.TotLossMP = app.motor.TotLossMP(:,[1:y y:end]);
                        app.motor.IPhaseMP = app.motor.IPhaseMP(:,[1:y y:end]);
                        app.motor.UPhaseMP = app.motor.UPhaseMP(:,[1:y y:end]);
                        app.motor.SlipMP = app.motor.SlipMP(:,[1:y y:end]);
                        app.motor.PFactMP = app.motor.PFactMP(:,[1:y y:end]);
                    end
                else
                    if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                        app.motor.SpeedGT = app.motor.SpeedGT(:,[1:y y:end]);
                        app.motor.TorqueGT = app.motor.TorqueGT(:,[1:y y:end]);
                        app.motor.TotLossGT = app.motor.TotLossGT(:,[1:y y:end]);
                        app.motor.IPhaseGT = app.motor.IPhaseGT(:,[1:y y:end]);
                        app.motor.UPhaseGT = app.motor.UPhaseGT(:,[1:y y:end]);
                        app.motor.SlipGT = app.motor.SlipGT(:,[1:y y:end]);
                        app.motor.PFactGT = app.motor.PFactGT(:,[1:y y:end]);
                    else % above base speed
                        app.motor.SpeedGP = app.motor.SpeedGP(:,[1:y y:end]);
                        app.motor.TorqueGP = app.motor.TorqueGP(:,[1:y y:end]);
                        app.motor.TotLossGP = app.motor.TotLossGP(:,[1:y y:end]);
                        app.motor.IPhaseGP = app.motor.IPhaseGP(:,[1:y y:end]);
                        app.motor.UPhaseGP = app.motor.UPhaseGP(:,[1:y y:end]);
                        app.motor.SlipGP = app.motor.SlipGP(:,[1:y y:end]);
                        app.motor.PFactGP = app.motor.PFactGP(:,[1:y y:end]);
                    end
                end
                UpdateFigure(app);            
            end
        end

        % Menu selected function: DeletepowerlineMenu
        function DeletepowerlineMenuSelected(app, event)
            if ~isempty(app.motor)
                y = app.selectedPoint(2);
                if app.selectedPoint(2) >= 0 % positive torque
                    if app.selectedPoint(1) <= size(app.motor.SpeedMT,1) % below base speed
                        app.motor.SpeedMT = app.motor.SpeedMT(:,[1:y-1 y+1:end]);
                        app.motor.TorqueMT = app.motor.TorqueMT(:,[1:y-1 y+1:end]);
                        app.motor.TotLossMT = app.motor.TotLossMT(:,[1:y-1 y+1:end]);
                        app.motor.IPhaseMT = app.motor.IPhaseMT(:,[1:y-1 y+1:end]);
                        app.motor.UPhaseMT = app.motor.UPhaseMT(:,[1:y-1 y+1:end]);
                        app.motor.SlipMT = app.motor.SlipMT(:,[1:y-1 y+1:end]);
                        app.motor.PFactMT = app.motor.PFactMT(:,[1:y-1 y+1:end]);
                    else % above base speed
                        app.motor.SpeedMP = app.motor.SpeedMP(:,[1:y-1 y+1:end]);
                        app.motor.PowerMP = app.motor.PowerMP(:,[1:y-1 y+1:end]);
                        app.motor.TotLossMP = app.motor.TotLossMP(:,[1:y-1 y+1:end]);
                        app.motor.IPhaseMP = app.motor.IPhaseMP(:,[1:y-1 y+1:end]);
                        app.motor.UPhaseMP = app.motor.UPhaseMP(:,[1:y-1 y+1:end]);
                        app.motor.SlipMP = app.motor.SlipMP(:,[1:y-1 y+1:end]);
                        app.motor.PFactMP = app.motor.PFactMP(:,[1:y-1 y+1:end]);
                    end
                else
                    if app.selectedPoint(1) <= size(app.motor.SpeedGT,1) % below base speed
                        app.motor.SpeedGT = app.motor.SpeedGT(:,[1:y-1 y+1:end]);
                        app.motor.TorqueGT = app.motor.TorqueGT(:,[1:y-1 y+1:end]);
                        app.motor.TotLossGT = app.motor.TotLossGT(:,[1:y-1 y+1:end]);
                        app.motor.IPhaseGT = app.motor.IPhaseGT(:,[1:y-1 y+1:end]);
                        app.motor.UPhaseGT = app.motor.UPhaseGT(:,[1:y-1 y+1:end]);
                        app.motor.SlipGT = app.motor.SlipGT(:,[1:y-1 y+1:end]);
                        app.motor.PFactGT = app.motor.PFactGT(:,[1:y-1 y+1:end]);
                    else % above base speed
                        app.motor.SpeedGP = app.motor.SpeedGP(:,[1:y-1 y+1:end]);
                        app.motor.PowerGP = app.motor.PowerGP(:,[1:y-1 y+1:end]);
                        app.motor.TotLossGP = app.motor.TotLossGP(:,[1:y-1 y+1:end]);
                        app.motor.IPhaseGP = app.motor.IPhaseGP(:,[1:y-1 y+1:end]);
                        app.motor.UPhaseGP = app.motor.UPhaseGP(:,[1:y-1 y+1:end]);
                        app.motor.SlipGP = app.motor.SlipGP(:,[1:y-1 y+1:end]);
                        app.motor.PFactGP = app.motor.PFactGP(:,[1:y-1 y+1:end]);
                    end
                end
                UpdateFigure(app);
            end
        end

        % Menu selected function: ExporttoCSVfileMenu
        function ExporttoCSVfileMenuSelected(app, event)
            if ~isempty(app.motor)
                cd(app.working_directory);
                [f,p] = uiputfile('*.CSV', 'Export current motor as..');
                cd(fileparts(mfilename('fullpath')));
                if f
                    fid = fopen(fullfile(p,f),"w");
                    fprintf(fid, 'Number of poles:,%3.0f\n',app.motor.nPoles);
                    fprintf(fid, 'Base speed motoring [rpm]:,%3.2f\n', app.motor.rpmBaseMotor);
                    fprintf(fid, 'Base speed generator [rpm]:,%3.2f\n', app.motor.rpmBaseGenerator);
                    fprintf(fid, 'Max speed [rpm]:,%3.2f\n', app.motor.VMax);
                    fprintf(fid, 'FCV in motoring [V]:,%5.2f\n', app.motor.UDC_motor);
                    fprintf(fid, 'FCV in generator [V]:,%5.2f\n', app.motor.UDC_generator);
                    fprintf(fid,'Motor data:\n');
                    fprintf(fid, 'Relative Torque [%%],Motor speed [rpm],Stator frequency [Hz],Torque [Nm],Fund. Current RMS [A],Fund. Voltage RMS [V],Tot. Losses [kW],Slip [-],Power factor for fund [-]\n');
                    for tq = size(app.motor.SpeedMT,2):-1:1
                        for spd = 1:size(app.motor.SpeedMT,1)
                            fprintf(fid, '%5.2f,', app.motor.TorqueMT(spd, tq)*100/app.motor.TorqueMT(spd, end));
                            fprintf(fid, '%5.2f,', app.motor.SpeedMT(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.SpeedMT(spd, tq) * app.motor.nPoles/60);
                            fprintf(fid, '%5.2f,', app.motor.TorqueMT(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.IPhaseMT(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.UPhaseMT(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.TotLossMT(spd, tq) / 1000);
                            fprintf(fid, '%5.2f,', app.motor.SlipMT(spd, tq));
                            fprintf(fid, '%5.2f\n', app.motor.PFactMT(spd, tq));
                        end
                    end
                    for tq = size(app.motor.SpeedMP,2):-1:1
                        for spd = 1:size(app.motor.SpeedMP,1)
                            fprintf(fid, '%5.2f,', app.motor.PowerMP(spd, tq)*100/app.motor.PowerMP(spd, end));
                            fprintf(fid, '%5.2f,', app.motor.SpeedMP(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.SpeedMP(spd, tq) * app.motor.nPoles/60);
                            fprintf(fid, '%5.2f,', app.motor.PowerMP(spd, tq) / (app.motor.SpeedMP(spd, tq) * 2 * pi / 60));
                            fprintf(fid, '%5.2f,', app.motor.IPhaseMP(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.UPhaseMP(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.TotLossMP(spd, tq) / 1000);
                            fprintf(fid, '%5.2f,', app.motor.SlipMP(spd, tq));
                            fprintf(fid, '%5.2f\n', app.motor.PFactMP(spd, tq));
                        end
                    end
                    for tq = size(app.motor.SpeedGT,2):-1:1
                        for spd = 1:size(app.motor.SpeedGT,1)
                            fprintf(fid, '%5.2f,', app.motor.TorqueGT(spd, tq)*100/app.motor.TorqueGT(spd, end));
                            fprintf(fid, '%5.2f,', app.motor.SpeedGT(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.SpeedGT(spd, tq) * app.motor.nPoles/60);
                            fprintf(fid, '%5.2f,', app.motor.TorqueGT(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.IPhaseGT(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.UPhaseGT(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.TotLossGT(spd, tq) / 1000);
                            fprintf(fid, '%5.2f,', app.motor.SlipGT(spd, tq));
                            fprintf(fid, '%5.2f\n', app.motor.PFactGT(spd, tq));
                        end
                    end
                    for tq = size(app.motor.SpeedGP,2):-1:1
                        for spd = 1:size(app.motor.SpeedGP,1)
                            fprintf(fid, '%5.2f,', app.motor.PowerGP(spd, tq)*100/app.motor.PowerGP(spd, end));
                            fprintf(fid, '%5.2f,', app.motor.SpeedGP(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.SpeedGP(spd, tq) * app.motor.nPoles/60);
                            fprintf(fid, '%5.2f,', app.motor.PowerGP(spd, tq) / (app.motor.SpeedGP(spd, tq) * 2 * pi / 60));
                            fprintf(fid, '%5.2f,', app.motor.IPhaseGP(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.UPhaseGP(spd, tq));
                            fprintf(fid, '%5.2f,', app.motor.TotLossGP(spd, tq) / 1000);
                            fprintf(fid, '%5.2f,', app.motor.SlipGP(spd, tq));
                            fprintf(fid, '%5.2f\n', app.motor.PFactGP(spd, tq));
                        end
                    end

                    fclose(fid);
                    % system(['notepad.exe "' fullfile(p,f) '"']);
                    answ = questdlg('Open the file with excel?', 'Open file quest', 'Yes', 'No', 'No');
                    if strcmp(answ, 'Yes')
                        system(['start excel.exe "' fullfile(p,f) '"']);
                    end
                    % winopen(fullfile(p,f))
                end
            end
        end

        % Menu selected function: ImportfromCSVMenu
        function ImportfromCSVMenuSelected(app, event)
            [f,p]=uigetfile('*.CSV','Open file to import');
            if f
                tMotor.nPoles = [];
                tMotor.rpmBaseMotor = [];
                tMotor.rpmBaseGenerator = [];
                tMotor.VMax = [];
                tMotor.UDC_motor = [];
                tMotor.UDC_generator = [];

                fid = fopen(fullfile(p,f),'r');
                
                while ~ feof(fid)
                    ln = fgetl(fid);
                    b = find(ln == ',');
                    if contains(ln, 'Motor data:')
                        ln = fgetl(fid); %#ok<NASGU>
                        break
                    end
                    if isempty(b)
                        continue
                    else
                        b = b(1);
                    end
                    if contains(ln, 'Number of poles')
                        tMotor.nPoles = str2double(ln(b+1:end));
                    end
                    if contains(ln, 'Base speed motoring')
                        tMotor.rpmBaseMotor = str2double(ln(b+1:end));
                    end
                    if contains(ln, 'Base speed generator')
                        tMotor.rpmBaseGenerator = str2double(ln(b+1:end));
                    end
                    if contains(ln, 'Max speed')
                        tMotor.VMax = str2double(ln(b+1:end));
                    end
                    if contains(ln, 'DC-link voltage in motoring')
                        tMotor.UDC_motor = str2double(ln(b+1:end));
                    end
                    if contains(ln, 'DC-link voltage in generator')
                        tMotor.UDC_generator = str2double(ln(b+1:end));
                    end
                end
                statstart = ftell(fid);
                lastLevel = -1;
                n = 0;
                m = 0;
                idx = 1;
                spdSize = [0 0 0 0];
                powSize = [0 0 0 0];
                while ~ feof(fid)
                    ln = fgetl(fid);
                    b = find(ln == ',', 1, 'first')-1;
                    lev = str2double(ln(1:b));
                    if lev < lastLevel
                        lastLevel = lev;
                        m = m + 1;
                        spdSize(idx) = n;
                        n = 0;
                    elseif lev > lastLevel % shift to new power level
                        if lastLevel >= 0
                            powSize(idx) = m + 1;
                            m = 0;
                            n = 0;
                            idx = idx + 1;
                        end
                        lastLevel = lev;
                    end
                    n = n + 1;
                end
                powSize(idx) = m + 1;
                tMotor.SpeedMT(spdSize(1), powSize(1)) = 0;
                tMotor.TorqueMT(spdSize(1), powSize(1)) = 0;
                tMotor.TotLossMT(spdSize(1), powSize(1)) = 0;
                tMotor.IPhaseMT(spdSize(1), powSize(1)) = 0;
                tMotor.UPhaseMT(spdSize(1), powSize(1)) = 0;
                tMotor.SlipMT(spdSize(1), powSize(1)) = 0;
                tMotor.PFactMT(spdSize(1), powSize(1)) = 0;
                tMotor.SpeedGT(spdSize(3), powSize(3)) = 0;
                tMotor.TorqueGT(spdSize(3), powSize(3)) = 0;
                tMotor.TotLossGT(spdSize(3), powSize(3)) = 0;
                tMotor.IPhaseGT(spdSize(3), powSize(3)) = 0;
                tMotor.UPhaseGT(spdSize(3), powSize(3)) = 0;
                tMotor.SlipGT(spdSize(3), powSize(3)) = 0;
                tMotor.PFactGT(spdSize(3), powSize(3)) = 0;
                tMotor.SpeedMP(spdSize(2), powSize(2)) = 0;
                tMotor.PowerMP(spdSize(2), powSize(2)) = 0;
                tMotor.TotLossMP(spdSize(2), powSize(2)) = 0;
                tMotor.IPhaseMP(spdSize(2), powSize(2)) = 0;
                tMotor.UPhaseMP(spdSize(2), powSize(2)) = 0;
                tMotor.SlipMP(spdSize(2), powSize(2)) = 0;
                tMotor.PFactMP(spdSize(2), powSize(2)) = 0;
                tMotor.SpeedGP(spdSize(4), powSize(4)) = 0;
                tMotor.PowerGP(spdSize(4), powSize(4)) = 0;
                tMotor.TotLossGP(spdSize(4), powSize(4)) = 0;
                tMotor.IPhaseGP(spdSize(4), powSize(4)) = 0;
                tMotor.UPhaseGP(spdSize(4), powSize(4)) = 0;
                tMotor.SlipGP(spdSize(4), powSize(4)) = 0;
                tMotor.PFactGP(spdSize(4), powSize(4)) = 0;
                fseek(fid,statstart,'bof');
                for p = powSize(1):-1:1
                    for v = 1:spdSize(1)
                        ln = fgetl(fid);
                        [spd, tq, loss, iph, uph, slip, pf] = parseLine(app, ln);
                        tMotor.SpeedMT(v, p) = spd;
                        tMotor.TorqueMT(v, p) = tq;
                        tMotor.TotLossMT(v, p) = loss * 1000;
                        tMotor.IPhaseMT(v, p) = iph;
                        tMotor.UPhaseMT(v, p) = uph;
                        tMotor.SlipMT(v, p) = slip;
                        tMotor.PFactMT(v, p) = pf;
                    end
                end
                for p = powSize(2):-1:1
                    for v = 1:spdSize(2)
                        ln = fgetl(fid);
                        [spd, tq, loss, iph, uph, slip, pf] = parseLine(app, ln);
                        tMotor.SpeedMP(v, p) = spd;
                        tMotor.PowerMP(v, p) = tq * spd * 2 * pi / 60;
                        tMotor.TotLossMP(v, p) = loss * 1000;
                        tMotor.IPhaseMP(v, p) = iph;
                        tMotor.UPhaseMP(v, p) = uph;
                        tMotor.SlipMP(v, p) = slip;
                        tMotor.PFactMP(v, p) = pf;
                    end
                end
                for p = powSize(3):-1:1
                    for v = 1:spdSize(3)
                        ln = fgetl(fid);
                        [spd, tq, loss, iph, uph, slip, pf] = parseLine(app, ln);
                        tMotor.SpeedGT(v, p) = spd;
                        tMotor.TorqueGT(v, p) = tq;
                        tMotor.TotLossGT(v, p) = loss * 1000;
                        tMotor.IPhaseGT(v, p) = iph;
                        tMotor.UPhaseGT(v, p) = uph;
                        tMotor.SlipGT(v, p) = slip;
                        tMotor.PFactGT(v, p) = pf;
                    end
                end
                for p = powSize(4):-1:1
                    for v = 1:spdSize(4)
                        ln = fgetl(fid);
                        [spd, tq, loss, iph, uph, slip, pf] = parseLine(app, ln);
                        tMotor.SpeedGP(v, p) = spd;
                        tMotor.PowerGP(v, p) = tq * spd * 2 * pi / 60;
                        tMotor.TotLossGP(v, p) = loss * 1000;
                        tMotor.IPhaseGP(v, p) = iph;
                        tMotor.UPhaseGP(v, p) = uph;
                        tMotor.SlipGP(v, p) = slip;
                        tMotor.PFactGP(v, p) = pf;
                    end
                end
                fclose(fid);
                app.motor = tMotor;
                UpdateFigure(app);
            end
        end

        % Button down function: OverviewFig
        function OverviewFigButtonDown(app, event)
            rotate3d(app.OverviewFig,'on');
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            close(app.ShogunSimMotoreditorUIFigure);
        end

        % Window key press function: ShogunSimMotoreditorUIFigure
        function ShogunSimMotoreditorUIFigureWindowKeyPress(app, event)
            b = find(matches({'home', 'leftarrow', 'rightarrow', 'insert', 'end', 'pagedown','downarrow', 'delete', 'uparrow', 'pageup'}, event.Key), 1, 'first');
            if ~isempty(b) && b <= 10
                key = app.dirs{b};
                DoMoveSelection(app, key);
            else
                key = '';
            end
            % dirs = {'vStart', 'vDown', 'vUp', 'vBase', 'vEnd', 'tStart', 'tDown', 'tZero', 'tUp', 'tEnd'};
            % fprintf([event.Key, '=>', key, newline])
            % DoMoveSelection(app, event.Source.key)
        end

        % Callback function: not associated with a component
        function ShogunSimMotoreditorUIFigureKeyPress(app, event)
            key = event.Key;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShogunSimMotoreditorUIFigure = uifigure('Visible', 'off');
            app.ShogunSimMotoreditorUIFigure.Name = 'ShogunSim - Motor editor';
            app.ShogunSimMotoreditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShogunSimMotoreditorUIFigureCloseRequest, true);
            app.ShogunSimMotoreditorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ShogunSimMotoreditorUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.ShogunSimMotoreditorUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMotormapMenu = uimenu(app.FileMenu);
            app.NewMotormapMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMotormapMenuSelected, true);
            app.NewMotormapMenu.Accelerator = 'N';
            app.NewMotormapMenu.Text = 'New Motor map...';
            app.OpenmotormapMenu = uimenu(app.FileMenu);
            app.OpenmotormapMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenmotormapMenuSelected, true);
            app.OpenmotormapMenu.Accelerator = 'O';
            app.OpenmotormapMenu.Text = 'Open motor map';
            app.SaveMotormapMenu = uimenu(app.FileMenu);
            app.SaveMotormapMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMotormapMenuSelected, true);
            app.SaveMotormapMenu.Accelerator = 'S';
            app.SaveMotormapMenu.Text = 'Save Motor map';
            app.SaveMotormapasMenu = uimenu(app.FileMenu);
            app.SaveMotormapasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMotormapasMenuSelected, true);
            app.SaveMotormapasMenu.Text = 'Save Motor map as...';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Accelerator = 'Q';
            app.QuitMenu.Text = 'Quit';
            app.SpecialMenu = uimenu(app.ShogunSimMotoreditorUIFigure);
            app.SpecialMenu.Text = 'Special';
            app.AddspeedlineMenu = uimenu(app.SpecialMenu);
            app.AddspeedlineMenu.MenuSelectedFcn = createCallbackFcn(app, @AddspeedlineMenuSelected, true);
            app.AddspeedlineMenu.Text = 'Add speed line';
            app.AddtorquepowerlineMenu = uimenu(app.SpecialMenu);
            app.AddtorquepowerlineMenu.MenuSelectedFcn = createCallbackFcn(app, @AddtorquepowerlineMenuSelected, true);
            app.AddtorquepowerlineMenu.Text = 'Add torque/power line';
            app.DeletespeedlineMenu = uimenu(app.SpecialMenu);
            app.DeletespeedlineMenu.MenuSelectedFcn = createCallbackFcn(app, @DeletespeedlineMenuSelected, true);
            app.DeletespeedlineMenu.Text = 'Delete speed line';
            app.DeletepowerlineMenu = uimenu(app.SpecialMenu);
            app.DeletepowerlineMenu.MenuSelectedFcn = createCallbackFcn(app, @DeletepowerlineMenuSelected, true);
            app.DeletepowerlineMenu.Text = 'Delete power line';
            app.ExporttoCSVfileMenu = uimenu(app.SpecialMenu);
            app.ExporttoCSVfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoCSVfileMenuSelected, true);
            app.ExporttoCSVfileMenu.Text = 'Export to CSV file';
            app.ImportfromCSVMenu = uimenu(app.SpecialMenu);
            app.ImportfromCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfromCSVMenuSelected, true);
            app.ImportfromCSVMenu.Text = 'Import from CSV';
            app.OverviewFig = uiaxes(app.ShogunSimMotoreditorUIFigure);
            title(app.OverviewFig, 'Torque vs. Speed')
            xlabel(app.OverviewFig, 'speed [rpm]')
            ylabel(app.OverviewFig, 'Torque [Nm]')
            zlabel(app.OverviewFig, 'loss [kW]')
            app.OverviewFig.ButtonDownFcn = createCallbackFcn(app, @OverviewFigButtonDown, true);
            app.SpeedCutFig = uiaxes(app.ShogunSimMotoreditorUIFigure);
            title(app.SpeedCutFig, 'Losses vs. Speed')
            xlabel(app.SpeedCutFig, 'speed [rpm]')
            ylabel(app.SpeedCutFig, 'Losses [kW]')
            zlabel(app.SpeedCutFig, 'Z')
            app.TorqueCutFig = uiaxes(app.ShogunSimMotoreditorUIFigure);
            title(app.TorqueCutFig, 'Losses vs. Torque')
            xlabel(app.TorqueCutFig, 'Torque [Nm]')
            ylabel(app.TorqueCutFig, 'Losses [kW]')
            zlabel(app.TorqueCutFig, 'Z')
            app.TorqueNmEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.TorqueNmEditFieldLabel.Text = 'Torque [Nm]:';
            app.vStartBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.vStartBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.vStartBtn.Tag = 'vStart';
            app.vStartBtn.Text = '<<';
            app.vDownBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.vDownBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.vDownBtn.Tag = 'vDown';
            app.vDownBtn.Text = '<';
            app.vBaseBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.vBaseBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.vBaseBtn.Tag = 'vBase';
            app.vBaseBtn.Text = 'Base';
            app.vUpBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.vUpBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.vUpBtn.Tag = 'vUp';
            app.vUpBtn.Text = '>';
            app.vEndBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.vEndBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.vEndBtn.Tag = 'vEnd';
            app.vEndBtn.Text = '>>';
            app.tStartBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.tStartBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.tStartBtn.Tag = 'tStart';
            app.tStartBtn.Text = '<<';
            app.tDownBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.tDownBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.tDownBtn.Tag = 'tDown';
            app.tDownBtn.Text = '<';
            app.tZeroBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.tZeroBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.tZeroBtn.Tag = 'tZero';
            app.tZeroBtn.Text = 'Zero';
            app.tUpBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.tUpBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.tUpBtn.Tag = 'tUp';
            app.tUpBtn.Text = '>';
            app.tEndBtn = uibutton(app.ShogunSimMotoreditorUIFigure, 'push');
            app.tEndBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveSelectionButton, true);
            app.tEndBtn.Tag = 'tEnd';
            app.tEndBtn.Text = '>>';
            app.TorqueNmEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.TorqueNmEditField.ValueDisplayFormat = '%5.4g';
            app.TorqueNmEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueNmEditFieldValueChanged, true);
            app.TorqueNmEditField.Tag = 'Tq';
            app.PowerkWEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.PowerkWEditFieldLabel.Text = 'Power [kW]:';
            app.PowerkWEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.PowerkWEditField.ValueDisplayFormat = '%5.4g';
            app.PowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueNmEditFieldValueChanged, true);
            app.PowerkWEditField.Tag = 'Pow';
            app.SpeedrpmEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.SpeedrpmEditFieldLabel.Text = 'Speed [rpm]:';
            app.SpeedrpmEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.SpeedrpmEditField.Limits = [0 Inf];
            app.SpeedrpmEditField.ValueDisplayFormat = '%5.4g';
            app.SpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueNmEditFieldValueChanged, true);
            app.SpeedrpmEditField.Tag = 'Spd';
            app.EfficiencyEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.EfficiencyEditField.ValueDisplayFormat = '%5.4g';
            app.EfficiencyEditField.Tag = 'Loss';
            app.LosskWEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.LosskWEditFieldLabel.Text = 'Loss [kW]:';
            app.LosskWEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.LosskWEditField.ValueDisplayFormat = '%5.4g';
            app.LosskWEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueNmEditFieldValueChanged, true);
            app.LosskWEditField.Tag = 'Loss';
            app.IphAEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.IphAEditField.ValueDisplayFormat = '%5.4g';
            app.IphAEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueNmEditFieldValueChanged, true);
            app.IphAEditField.Tag = 'Iph';
            app.UphVEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.UphVEditField.ValueDisplayFormat = '%5.4g';
            app.UphVEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueNmEditFieldValueChanged, true);
            app.UphVEditField.Tag = 'Uph';
            app.SlipEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.SlipEditField.ValueDisplayFormat = '%5.4g';
            app.SlipEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueNmEditFieldValueChanged, true);
            app.SlipEditField.Tag = 'Slip';
            app.PowerfactorEditField = uieditfield(app.ShogunSimMotoreditorUIFigure, 'numeric');
            app.PowerfactorEditField.ValueDisplayFormat = '%5.4g';
            app.PowerfactorEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueNmEditFieldValueChanged, true);
            app.PowerfactorEditField.Tag = 'PF';
            app.MoveactivepointLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.MoveactivepointLabel.Text = 'Move active point:';
            app.TorquepointLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.TorquepointLabel.Text = 'Torque point:';
            app.IphAEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.IphAEditFieldLabel.Text = 'Iph [A]:';
            app.UphVEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.UphVEditFieldLabel.Text = 'Uph [V]:';
            app.SlipEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.SlipEditFieldLabel.Text = 'Slip:';
            app.PowerfactorEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.PowerfactorEditFieldLabel.Text = 'Power factor:';
            app.SpeedpointLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.SpeedpointLabel.Text = 'Speed point:';
            app.ZaxesLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.ZaxesLabel.Text = 'Z-axes:';
            app.ZaxesDropDown = uidropdown(app.ShogunSimMotoreditorUIFigure);
            app.ZaxesDropDown.Items = {'Losses', 'IPhase', 'UPhase', 'Slip', 'Power factor'};
            app.ZaxesDropDown.ValueChangedFcn = createCallbackFcn(app, @AxesDropDownValueChanged, true);
            app.ZaxesDropDown.Value = 'Losses';
            app.XYaxesLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.XYaxesLabel.Text = 'XY-axes:';
            app.XYaxesDropDown = uidropdown(app.ShogunSimMotoreditorUIFigure);
            app.XYaxesDropDown.Items = {'Torque vs. Speed', 'Power vs. Speed'};
            app.XYaxesDropDown.ValueChangedFcn = createCallbackFcn(app, @AxesDropDownValueChanged, true);
            app.XYaxesDropDown.Value = 'Torque vs. Speed';
            app.EfficiencyEditFieldLabel = uilabel(app.ShogunSimMotoreditorUIFigure);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency [%]:';
            % Show the figure after all components are created
            app.ShogunSimMotoreditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotorEdit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShogunSimMotoreditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShogunSimMotoreditorUIFigure)
        end
    end
end