classdef RouteEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RouteEditorUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        SetProjectDirectoryMenu         matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        DataMenu                        matlab.ui.container.Menu
        ImportdatafromExcelMenu         matlab.ui.container.Menu
        ExportdatatoExcelMenu           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        ShogunSimRouteEditorLabel       matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        PurposeEditField                matlab.ui.control.EditField
        PurposeEditFieldLabel           matlab.ui.control.Label
        RoutenameEditField              matlab.ui.control.EditField
        RoutenameEditFieldLabel         matlab.ui.control.Label
        GeneraldataTab                  matlab.ui.container.Tab
        StartinglocationEditFieldLabel  matlab.ui.control.Label
        kmLabel_2                       matlab.ui.control.Label
        kmLabel                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SpdLabel6                       matlab.ui.control.Label
        SpdLabel5                       matlab.ui.control.Label
        SpdLabel4                       matlab.ui.control.Label
        StopcoastbelowspeedEditFieldLabel  matlab.ui.control.Label
        StartcoastabovespeedEditFieldLabel  matlab.ui.control.Label
        SpdLabel3                       matlab.ui.control.Label
        SpdLabel2                       matlab.ui.control.Label
        InitialbrakingspeedEditField_2Label  matlab.ui.control.Label
        SpdLabel1                       matlab.ui.control.Label
        StopcoastatpositionEditFieldLabel  matlab.ui.control.Label
        StartcoastatpositionEditFieldLabel  matlab.ui.control.Label
        MasterControllerlimitEditFieldLabel  matlab.ui.control.Label
        SpeedrampdownV2EditFieldLabel   matlab.ui.control.Label
        SpeedrampdownV1EditFieldLabel   matlab.ui.control.Label
        MargintomaximalspeedEditField_2Label  matlab.ui.control.Label
        MaximalspeedEditFieldLabel      matlab.ui.control.Label
        ATOoperationLabel               matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        MiscdataLabel                   matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        AtmosphericdataLabel            matlab.ui.control.Label
        degdirectionofnoseatstartpositionLabel  matlab.ui.control.Label
        LatitudeLabel                   matlab.ui.control.Label
        LonitudeLabel                   matlab.ui.control.Label
        NameofcityvillageorsimilarLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        GeographiclocationLabel         matlab.ui.control.Label
        LinevoltageandcurrentlimitsLabel  matlab.ui.control.Label
        VoltLabel2                      matlab.ui.control.Label
        VoltLabel1                      matlab.ui.control.Label
        ATOStopCoastPosEditField        matlab.ui.control.NumericEditField
        ATOStartCoastPosEditField       matlab.ui.control.NumericEditField
        ATOTBCLimitEditField            matlab.ui.control.NumericEditField
        ATOVRampdownV2EditField         matlab.ui.control.NumericEditField
        ATOVRampdownV1EditField         matlab.ui.control.NumericEditField
        ATOVCoastStopEditField          matlab.ui.control.NumericEditField
        ATOVCoastStartEditField         matlab.ui.control.NumericEditField
        ATOBkrSpdEditField              matlab.ui.control.NumericEditField
        ATOVMgnEditField                matlab.ui.control.NumericEditField
        ATOSpdUnitDropDown              matlab.ui.control.DropDown
        ATOVMaxEditField                matlab.ui.control.NumericEditField
        TrackguageEditField             matlab.ui.control.NumericEditField
        TrackguageEditFieldLabel        matlab.ui.control.Label
        HeadwindEditField               matlab.ui.control.NumericEditField
        HeadwindEditFieldLabel          matlab.ui.control.Label
        RelativehumidityEditField       matlab.ui.control.NumericEditField
        RelativehumidityEditFieldLabel  matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        HeadingEditField                matlab.ui.control.NumericEditField
        StartpositionheadingEditFieldLabel  matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        StartpositionlatitudeEditFieldLabel  matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        StartpositionlongitudeEditFieldLabel  matlab.ui.control.Label
        StartinglocationEditField       matlab.ui.control.EditField
        ILimRegRelEditField             matlab.ui.control.NumericEditField
        RegenerationlimitrelativeEditFieldLabel  matlab.ui.control.Label
        ILimRegAbsEditField             matlab.ui.control.NumericEditField
        RegenerationlimitabsoluteEditFieldLabel  matlab.ui.control.Label
        ULRegenEditField                matlab.ui.control.NumericEditField
        LinevoltageatregenerationEditFieldLabel  matlab.ui.control.Label
        ULMotoringEditField             matlab.ui.control.NumericEditField
        LinevoltageatmotoringEditFieldLabel  matlab.ui.control.Label
        LFreqEditField                  matlab.ui.control.NumericEditField
        NominalLinefrequencyEditFieldLabel  matlab.ui.control.Label
        VoltUnitDropDown                matlab.ui.control.DropDown
        ULNomEditField                  matlab.ui.control.NumericEditField
        NominalLinevoltageEditFieldLabel  matlab.ui.control.Label
        StationsTab                     matlab.ui.container.Tab
        ExportStationBtn                matlab.ui.control.Button
        ImportStationBtn                matlab.ui.control.Button
        DelStationBtn                   matlab.ui.control.Button
        AddStationBtn                   matlab.ui.control.Button
        StationTable                    matlab.ui.control.Table
        ParametersTab                   matlab.ui.container.Tab
        ExportParaBtn                   matlab.ui.control.Button
        ImportParaBtn                   matlab.ui.control.Button
        DelParaRowBtn                   matlab.ui.control.Button
        AddParaRowBtn                   matlab.ui.control.Button
        DelParaBtn                      matlab.ui.control.Button
        AddParaBtn                      matlab.ui.control.Button
        ParameterTable                  matlab.ui.control.Table
        SpeedlimitsTab                  matlab.ui.container.Tab
        ExportSpeedBtn                  matlab.ui.control.Button
        ImportSpeedBtn                  matlab.ui.control.Button
        DelSpeedBtn                     matlab.ui.control.Button
        AddSpeedBtn                     matlab.ui.control.Button
        SpeedTable                      matlab.ui.control.Table
        SpeedAxes                       matlab.ui.control.UIAxes
        TopologyTab                     matlab.ui.container.Tab
        ExportSlopeBtn                  matlab.ui.control.Button
        ImportSlopeBtn                  matlab.ui.control.Button
        DelSlopeBtn                     matlab.ui.control.Button
        AddSlopeBtn                     matlab.ui.control.Button
        GradientTable                   matlab.ui.control.Table
        GradientAxes                    matlab.ui.control.UIAxes
        CurvatureTab                    matlab.ui.container.Tab
        ExportCurvesBtn                 matlab.ui.control.Button
        ImportCurvesBtn                 matlab.ui.control.Button
        DelCurveBtn                     matlab.ui.control.Button
        AddCurveBtn                     matlab.ui.control.Button
        CurveTable                      matlab.ui.control.Table
        CurveAxis                       matlab.ui.control.UIAxes
        TunnelsTab                      matlab.ui.container.Tab
        ExportTunnelBtn                 matlab.ui.control.Button
        ImportTunnelBtn                 matlab.ui.control.Button
        DelTunnelBtn                    matlab.ui.control.Button
        AddTunnelBtn                    matlab.ui.control.Button
        TunnelTable                     matlab.ui.control.Table
        TunnelAxis                      matlab.ui.control.UIAxes
        GeographicTab                   matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
    end

    % ShogunSim - Route Editor
    %
    %  This is a part of the ShogunSim where the route data can be altered
    %
    % Software modifications:
    %   Ver         Date        Who     Description
    %   0.1.0.0     20251216    JR      First tracked version
    %
    %
    properties (Constant)
        app_version = '0.10' % version of the app
        
    end

    properties (Access = private)
        settings_file % full path and file name of the settings file
        working_directory % working directory
        localRoute % Description
        RouteLength % pos start to end of total track
        CurrentFile % saved file name including full path.
        Dirty % Means that user made changes that has not been saved
        isReadOnly % set if the current file is in read only mode
        caller % if called from cockpit app
    end % properties (Access = private)

    methods (Static)

        function launch(caller, app, selFile)
            if exist(selFile, 'file')
                app.caller = caller;
                app.CurrentFile = selFile;
                [~, attr] = fileattrib(selFile);
                load(app.CurrentFile, 'route_data');
                data2layout(app, route_data, false);
                CleanDirty(app);
                app.isReadOnly = ~attr.UserWrite;
            end
        end
    end % methods (Static)

    methods (Access = private)

        function results = assignif(~,val1,val2)
            if isempty(val1)
                results = val2;
            else
                results = val1;
            end
        end % assignif
        
        function DefaultRouteData(app)
            % ========== StartTab ===================
            app.RoutenameEditField.Value = '';
            app.PurposeEditField.Value = '';
            app.NotesTextArea.Value = '';
            % ========== GeneraldataTab =============
            % line supply
            app.ULNomEditField.Value = [];
            app.VoltUnitDropDown.ValueIndex = 1;
            app.LFreqEditField.Value = [];
            app.ULMotoringEditField.Value = [];
            app.ULRegenEditField.Value = [];
            app.ILimRegRelEditField.Value = [];
            app.ILimRegAbsEditField.Value = [];
            % Geographical
            app.StartinglocationEditField.Value = '';
            app.LongitudeEditField.Value = [];
            app.LatitudeEditField.Value = [];
            app.HeadingEditField.Value = [];
            % atmospheric data
            app.TemperatureEditField.Value = [];
            app.RelativehumidityEditField.Value = [];
            app.HeadwindEditField.Value = [];
            % misc
            app.TrackguageEditField.Value = [];
            % ATO parameters
            app.ATOSpdUnitDropDown.ValueIndex = 1;
            app.ATOVMaxEditField.Value = [];
            app.ATOVMgnEditField.Value = [];
            app.ATOBkrSpdEditField.Value = [];
            app.ATOVCoastStartEditField.Value = [];
            app.ATOVCoastStopEditField.Value = [];
            app.ATOVRampdownV1EditField.Value = [];
            app.ATOVRampdownV2EditField.Value = [];
            app.ATOTBCLimitEditField.Value = [];
            app.ATOStartCoastPosEditField.Value = [];
            app.ATOStopCoastPosEditField.Value = [];

            % Prepara Station table
            data = {'0.000', 'Departure station', '0.0', '0.5', '12:00:00.0';...
                '10.000', 'End station', '0.0', '0.5', '12:10:00.0'};
            app.StationTable.Data = data;
            rightAlignStyle = uistyle('HorizontalAlignment','right');
            addStyle(app.StationTable, rightAlignStyle, 'column', 1);
            addStyle(app.StationTable, rightAlignStyle, 'column', 3);
            addStyle(app.StationTable, rightAlignStyle, 'column', 4);
            app.AddStationBtn.Enable = "on";
            app.DelStationBtn.Enable = "off";
            app.RouteLength = [0, 10];

            % Prepare Parameter table
            addStyle(app.ParameterTable, rightAlignStyle, 'column', 1);
            app.ParameterTable.Data = [];
            app.AddParaBtn.Enable = "on";
            app.AddParaRowBtn.Enable = "on";
            app.DelParaRowBtn.Enable = "off";
            app.DelParaBtn.Enable = "off";

            % Prepare Speed limit table
            addStyle(app.SpeedTable, rightAlignStyle, 'column', 1);
            addStyle(app.SpeedTable, rightAlignStyle, 'column', 2);
            app.SpeedTable.Data = {'0.000', '100.0', ''};
            app.AddSpeedBtn.Enable = "on";
            app.DelSpeedBtn.Enable = "off";

            % Prepare Gradient table
            addStyle(app.GradientTable, rightAlignStyle, 'column', 1);
            addStyle(app.GradientTable, rightAlignStyle, 'column', 2);
            addStyle(app.GradientTable, rightAlignStyle, 'column', 3);
            app.GradientTable.Data = {'0.000', '0.0', '0.0', ''};
            app.AddSlopeBtn.Enable = "on";
            app.DelSlopeBtn.Enable = "off";

            % Prepare Curve table
            addStyle(app.CurveTable, rightAlignStyle, 'column', 1);
            addStyle(app.CurveTable, rightAlignStyle, 'column', 2);
            addStyle(app.CurveTable, rightAlignStyle, 'column', 3);
            addStyle(app.CurveTable, rightAlignStyle, 'column', 4);
            app.CurveTable.Data = {'0.000', '==', '', '', ''};
            app.AddCurveBtn.Enable = "on";
            app.DelCurveBtn.Enable = "off";

            % Prepare Tunnel table
            addStyle(app.TunnelTable, rightAlignStyle, 'column', 1);
            addStyle(app.TunnelTable, rightAlignStyle, 'column', 2);
            addStyle(app.TunnelTable, rightAlignStyle, 'column', 3);
            app.TunnelTable.Data = [];
            app.AddTunnelBtn.Enable = "on";
            app.DelTunnelBtn.Enable = "off";

            CalculateSpeed(app);
            CalculateSlope(app);
            CalculateCurves(app);
        end % DefaultRouteData
        
        function tableData = convert2TableData(~, fileData, formatStr)
            tableData = cell(size(fileData));
            for row = 1:size(fileData,1)
                for col = 1:size(fileData,2)
                    if isnumeric(fileData{row, col})
                        if isempty(formatStr{col})
                            tableData{row, col} = num2str(fileData{row, col});
                        else
                            tableData{row, col} = num2str(fileData{row, col}, formatStr{col});
                        end
                    elseif ischar(fileData{row, col})
                        tableData{row, col} = fileData{row, col};
                    else
                        fprintf('what?')
                    end
                end
            end

        end % convert2tabledata

        function fileData = convert2FileData(~, tableData)
            fileData = cell(size(tableData));
            for row = 1:size(tableData,1)
                for col = 1:size(tableData,2)
                    val = str2double(tableData{row, col});
                    if isnan(val)
                        fileData{row, col} = tableData{row, col};
                    else
                        fileData{row, col} = val;
                    end
                end
            end
        end % convert2tabledata

        function data2layout(app, route, general_only)
            % ========== StartTab ===================
            app.RoutenameEditField.Value = route.name ;
            app.PurposeEditField.Value = route.purpose;
            app.NotesTextArea.Value = route.Notes;
            % ========== GeneraldataTab =============
            % line supply
            app.ULNomEditField.Value = route.USupply;
            app.VoltUnitDropDown.ValueIndex = route.VoltageSel;
            VoltUnitDropDownValueChanged(app, []);
            app.LFreqEditField.Value = route.LineFreq;
            if route.VoltageSel >= 3
                app.ULMotoringEditField.Value = route.UL_Motoring / 1000;
                app.ULRegenEditField.Value = route.UL_Regen / 1000;
            else
                app.ULMotoringEditField.Value = route.UL_Motoring;
                app.ULRegenEditField.Value = route.UL_Regen;
            end
            app.ILimRegAbsEditField.Value = route.Regen_current;
            app.ILimRegRelEditField.Value = route.Regen_percent;
            % Geographical
            app.StartinglocationEditField.Value = route.regionName;
            app.LongitudeEditField.Value = route.start_lon;
            app.LatitudeEditField.Value = route.start_lat;
            app.HeadingEditField.Value = route.Start_direction * 360 / (2 * pi);
            % atmospheric data
            app.TemperatureEditField.Value = route.ambTemp;
            app.RelativehumidityEditField.Value = route.relHumidity;
            app.HeadwindEditField.Value = route.headWind;
            % misc
            app.TrackguageEditField.Value = route.Track_Guage;
            % ATO parameters
            app.ATOSpdUnitDropDown.ValueIndex = route.ATOSpeedUnitSel;
            ATOSpdUnitDropDownValueChanged(app, []);
            spd_scale = 1;
            switch app.ATOSpdUnitDropDown.ValueIndex
                case 1
                    spd_scale = 3.6;
                    app.SpeedTable.ColumnName{2} = 'Speed [km/h]';
                case 2
                    spd_scale = 2.2369;
                    app.SpeedTable.ColumnName{2} = 'Speed [MPH]';
                case 3
                    app.SpeedTable.ColumnName{2} = 'Speed [m/s]';
            end
            app.ATOVMaxEditField.Value = route.ATO_SpdMax * spd_scale;
            app.ATOVMgnEditField.Value = route.ATO_SpdMgn * spd_scale;
            app.ATOBkrSpdEditField.Value = route.ATO_InitBrkSpd * spd_scale;
            app.ATOVCoastStartEditField.Value = route.ATO_vCoastStart * spd_scale;
            app.ATOVCoastStopEditField.Value = route.ATO_vCoastEnd * spd_scale;
            app.ATOVRampdownV1EditField.Value = route.ATO_V1 * spd_scale;
            app.ATOVRampdownV2EditField.Value = route.ATO_V2 * spd_scale;
            app.ATOTBCLimitEditField.Value = route.ATO_TBC * 100;
            app.ATOStartCoastPosEditField.Value = route.ATO_dCoastStart;
            app.ATOStopCoastPosEditField.Value = route.ATO_dCoastEnd;
            % route.ATO_cGrad; % future expansion
            if ~general_only
                % station data
                app.StationTable.Data = convert2TableData(app, route.stations, {'%.3f', '', '%.1f', '%.2f', ''});
                s = [route.stations{:, 1}];
                app.RouteLength = [min(s), max(s)];
                
                % Parameter data
                app.ParameterTable.ColumnName = route.parameterNames;
                app.ParameterTable.ColumnWidth = [70, num2cell(ones(1, size(route.parameterNames, 1) - 1) * 120)];
                app.ParameterTable.ColumnEditable = logical(ones(1, size(route.parameterNames, 1) - 1) * true);
                app.ParameterTable.Data = route.parameters;
                ParameterTableSelectionChanged(app, []);

                % Speed table
                temp = route.speedLimits;
                for i1 = 1:size(temp,1)
                    temp{i1, 2} = temp{i1, 2} * spd_scale;
                end
                app.SpeedTable.Data = convert2TableData(app, temp, {'%.3f', '%.1f', ''});
                
                app.GradientTable.Data = convert2TableData(app, route.gradients, {'%.3f', '%.1f', '%.1f', ''});

                app.CurveTable.Data = convert2TableData(app, route.curves, {'%.3f', '%.1f', '%.1f', ''});
                app.TunnelTable.Data = convert2TableData(app, route.tunnels, {'%.3f', '%.1f', '%.2f', ''});
                
                StationTableSelectionChanged(app, []);
                SpeedTableSelectionChanged(app, []);
                GradientTableSelectionChanged(app, []);
                CurveTableSelectionChanged(app, []);
                TunnelTableSelectionChanged(app, []);
                CalculateSpeed(app);
                CalculateSlope(app);
                CalculateCurves(app);
            end
        end % data2layout

        function route = layout2data(app, general_only)
            % ========== StartTab ===================
            route.name = app.RoutenameEditField.Value;
            route.purpose = app.PurposeEditField.Value;
            route.Notes = app.NotesTextArea.Value;
            % ========== GeneraldataTab =============
            % line supply
            route.USupply = app.ULNomEditField.Value;
            route.VoltageSel = app.VoltUnitDropDown.ValueIndex;

            route.LineFreq = app.LFreqEditField.Value;
            if route.VoltageSel >= 3
                route.UL_Motoring = app.ULMotoringEditField.Value * 1000;
                route.UL_Regen = app.ULRegenEditField.Value * 1000;
            else
                route.UL_Motoring = app.ULMotoringEditField.Value;
                route.UL_Regen = app.ULRegenEditField.Value;
            end
            route.Regen_percent = app.ILimRegRelEditField.Value;
            route.Regen_current = app.ILimRegAbsEditField.Value;

            % Geographical
            route.regionName = app.StartinglocationEditField.Value;
            route.start_lon = app.LongitudeEditField.Value;
            route.start_lat = app.LatitudeEditField.Value;
            route.Start_direction = app.HeadingEditField.Value * 2 * pi / 360;
            % atmospheric data
            route.ambTemp = app.TemperatureEditField.Value;
            route.relHumidity = app.RelativehumidityEditField.Value;
            route.headWind = app.HeadwindEditField.Value;
            % misc
            route.Track_Guage = app.TrackguageEditField.Value;
            % ATO parameters
            route.ATOSpeedUnitSel = app.ATOSpdUnitDropDown.ValueIndex;
            spd_scale = 1;
            switch app.ATOSpdUnitDropDown.ValueIndex
                case 1
                    spd_scale = 3.6;
                case 2
                    spd_scale = 2.2369;
            end
            route.ATO_SpdMax = app.ATOVMaxEditField.Value / spd_scale;
            route.ATO_SpdMgn = app.ATOVMgnEditField.Value / spd_scale;
            route.ATO_InitBrkSpd = app.ATOBkrSpdEditField.Value / spd_scale;
            route.ATO_vCoastStart = app.ATOVCoastStartEditField.Value / spd_scale;
            route.ATO_vCoastEnd = app.ATOVCoastStopEditField.Value / spd_scale;
            route.ATO_V1 = app.ATOVRampdownV1EditField.Value / spd_scale;
            route.ATO_V2 = app.ATOVRampdownV2EditField.Value / spd_scale;
            route.ATO_TBC = app.ATOTBCLimitEditField.Value / 100;
            route.ATO_dCoastStart = app.ATOStartCoastPosEditField.Value;
            route.ATO_dCoastEnd = app.ATOStopCoastPosEditField.Value;
            route.ATO_cGrad = 0; % future expansion
            if ~general_only
                route.stations = convert2FileData(app, app.StationTable.Data);
                route.parameters = convert2FileData(app, app.ParameterTable.Data);
                route.parameterNames = convert2FileData(app, app.ParameterTable.ColumnName);
                temp = convert2FileData(app, app.SpeedTable.Data);
                for i1 = 1:size(temp, 1)
                    temp{i1, 2} = temp{i1, 2} / spd_scale;
                end
                route.speedLimits = temp;
                route.gradients = convert2FileData(app, app.GradientTable.Data);
                route.curves = convert2FileData(app, app.CurveTable.Data);
                route.tunnels = convert2FileData(app, app.TunnelTable.Data);
            end
        end % layout2data
        
        function newName = findUniqueStationName(app, base)
            newName = base;
            data = app.StationTable.Data;
            b = find(matches(data(:, 2), base), 1, 'first');
            if ~isempty(b)
                idx = 1;
                testName = [base num2str(idx)];
                while find(matches(data(:, 2), testName)) > 0
                    idx = idx + 1;
                    testName = [base num2str(idx)];
                end
                newName = testName;
            end
        end
        
        function CalculateSpeed(app)
            data = app.SpeedTable.Data;
            SpeedCurve(size(data, 1), 2) = 0;
            for i1 = 1:size(data, 1)
                Pos = str2double(data{i1, 1});
                spd  = str2double(data{i1, 2});
                SpeedCurve(i1, :) = [Pos, spd];
            end
            if Pos < app.RouteLength(2)
                Pos = app.RouteLength(2);
                SpeedCurve(end + 1, :) = [Pos, spd];
            end
            stairs(app.SpeedAxes, SpeedCurve(:,1), SpeedCurve(:,2))
            grid(app.SpeedAxes, "on");
            d = max([max(SpeedCurve(:, 2)) * 1.05, 1]);
            ylim(app.SpeedAxes, [0, d]);
            xlim(app.SpeedAxes, app.RouteLength);
        end % CalculateSpeed
        
        function CalculateSlope(app)
            data = app.GradientTable.Data;
            TopologyCurve(size(data,1), 2) = 0;
            GradientCurve(size(data,1), 2) = 0;
            TopologyCurve(1, :) = [str2double(data{1, 1}), str2double(data{1, 3})];
            GradientCurve(1, :) = [str2double(data{1, 1}), str2double(data{1, 2})];
            lastPos = str2double(data{1, 1});
            gradient = str2double(data{1, 2});
            lastLevel = str2double(data{1, 3});
            Pos = 0;
            for i1 = 2:size(data,1)
                Pos = str2double(data{i1, 1});
                dist = Pos - lastPos;
                level = lastLevel + dist * gradient;
                data{i1, 3} = num2str(level, '%.1f');
                TopologyCurve(i1, :) = [Pos, level];
                gradient = str2double(data{i1, 2});
                GradientCurve(i1, :) = [Pos, gradient];
                lastPos = Pos;
                lastLevel = level;
            end
            if Pos < app.RouteLength(2)
                Pos = app.RouteLength(2);
                dist = Pos - lastPos;
                level = lastLevel + dist * gradient;
                TopologyCurve(end + 1, :) = [Pos, level];
                GradientCurve(end + 1, :) = [Pos, gradient];
            end
            app.GradientTable.Data = data;

            % create tunnel line
            tunnels = app.TunnelTable.Data;
            % cell2mat(tunnels)
            TunnelCurve = TopologyCurve;
            for i1 = 1:size(tunnels, 1)
                % Tunnel start
                Pos = str2double(tunnels{i1, 1});
                b = find(Pos >= TunnelCurve(:, 1), 1, 'last');
                level = TunnelCurve(b, 2) + (TunnelCurve(b + 1, 2) - TunnelCurve(b, 2)) * (Pos - TunnelCurve(b, 1)) / (TunnelCurve(b + 1, 1) - TunnelCurve(b, 1));
                TunnelCurve = [TunnelCurve(1:b, :); Pos, level; TunnelCurve(b + 1:end, :)];

                % split line and delete non-tunnel points
                c = find(isnan(TunnelCurve(1:b - 1, 2)), 1, 'last');
                if isempty(c)
                    TunnelCurve = TunnelCurve(b + 1:end, :);
                else
                    TunnelCurve = [TunnelCurve(1:c, :);TunnelCurve(b + 1:end, :)];
                end

                % Tunnel end
                Pos = Pos + str2double(tunnels{i1, 2});
                b = find(Pos >= TunnelCurve(:, 1), 1, 'last');
                level = TunnelCurve(b, 2) + (TunnelCurve(b + 1, 2) - TunnelCurve(b, 2)) * (Pos - TunnelCurve(b, 1)) / (TunnelCurve(b + 1, 1) - TunnelCurve(b, 1));
                TunnelCurve = [TunnelCurve(1:b, :); Pos, level; Pos, nan; Pos, level; TunnelCurve(b + 1:end, :)];
            end
            c = find(isnan(TunnelCurve(:, 2)), 1, 'last');
            if ~isempty(c)
                TunnelCurve = TunnelCurve(1:c - 1, :);
            else
                TunnelCurve = [];
            end

            % hold(app.GradientAxes, "off");
            yyaxis(app.GradientAxes, "left")
            plot(app.GradientAxes, TopologyCurve(:, 1), TopologyCurve(:, 2));
            grid(app.GradientAxes, "on");
            % hold(app.GradientAxes, "on");
            % if ~isempty(TunnelCurve)
            %     plot(app.GradientAxes, TunnelCurve(:, 1), TunnelCurve(:, 2), '-k', 'LineWidth', 3);
            % end
            d = max([(max(TopologyCurve(:, 2)) - min(TopologyCurve(:, 2))) * 0.1, 1]);
            ylim(app.GradientAxes, [min(TopologyCurve(:, 2)) - d, max(TopologyCurve(:, 2)) + d])
            yyaxis(app.GradientAxes, "right")
            hold(app.GradientAxes, "off");
            stairs(app.GradientAxes, GradientCurve(:, 1), GradientCurve(:, 2));
            ylabel(app.GradientAxes, ['Gradient [' char(8240) ']'])
            xlim(app.GradientAxes, app.RouteLength);
            d = max([(max(GradientCurve(:, 2)) - min(GradientCurve(:, 2))) * 0.1, 1]);
            ylim(app.GradientAxes, [min(GradientCurve(:, 2)) - d, max(GradientCurve(:, 2)) + d])

            hold(app.TunnelAxis, "off")
            plot(app.TunnelAxis, TopologyCurve(:, 1), TopologyCurve(:, 2));
            grid(app.TunnelAxis, "on");
            hold(app.TunnelAxis, "on");
            if ~isempty(TunnelCurve)
                plot(app.TunnelAxis, TunnelCurve(:, 1), TunnelCurve(:, 2), '-k', 'LineWidth', 3);
            end
            d = max([(max(TopologyCurve(:, 2)) - min(TopologyCurve(:, 2))) * 0.1, 1]);
            ylim(app.TunnelAxis, [min(TopologyCurve(:, 2)) - d, max(TopologyCurve(:, 2)) + d])

        end % CalculateSlope
        
        function CalculateCurves(app)
            data = app.CurveTable.Data;
            plotdata(size(data,1), 2) = 0;
            dir = assignif(app, app.HeadingEditField.Value, 0);
            dirplot(1000,2) = 0;
            idx = 1;
            for i1 = 1:size(data,1)
                plotdata(i1, 1) = str2double(data{i1, 1});
                if strcmp(data{i1, 2}, '==')
                    plotdata(i1, 2) = 0;
                    dirplot(idx, :) = [plotdata(i1, 1), dir];
                    idx = idx + 1;
                elseif strcmp(data{i1, 2}, 'T')
                    plotdata(i1, 2) = plotdata(i1 - 1, 2);
                    if i1 < size(data, 1)
                        dSteps = (str2double(data{i1 + 1, 1}) - plotdata(i1, 1)) / 10;
                        if strcmp(data{i1 + 1, 2}, '==')
                            targetCurve = 0;
                        else
                            targetCurve = 1000 / str2double(data{i1 + 1, 2});
                        end
                        for i2 = 1:10
                            curvature = targetCurve * i2 / 10 + (10 - i2) * plotdata(i1, 2) / 10;
                            dir = dir + dSteps * curvature;
                            dirplot(idx, :) = [plotdata(i1, 1) + i2 * dSteps, dir];
                            idx = idx + 1;
                        end
                    end
                else
                    % recalculate to curvature, rad/km
                    radius = str2double(data{i1, 2});
                    curvature = 1000 / radius;
                    plotdata(i1, 2) = curvature;
                    dirplot(idx, :) = [plotdata(i1, 1), dir];
                    idx = idx + 1;
                    if i1 < size(data, 1)
                        dist = str2double(data{i1 + 1, 1}) - plotdata(i1, 1);
                        dir = dir + curvature * dist;
                    end
                    gauge = app.TrackguageEditField.Value;
                    if isempty(gauge)
                        data{i1, 4} = 'no gauge';
                    else
                        cant = str2double(data{i1, 3});
                        optSpeed = sqrt(cant * 9.81 / (gauge * abs(radius))) * abs(radius) * 3.6;
                        data{i1, 4} = num2str(optSpeed, '%.1f km/h');
                    end
                end
            end
            app.CurveTable.Data = data;

            remDist = app.RouteLength(2) - plotdata(end, 1);
            if remDist > 0
                dist = plotdata(end, 1);
                if plotdata(end, 2) == 0
                    dirplot(idx, 1) = app.RouteLength(2);
                    dirplot(idx, 2) = dirplot(idx - 1, 2);
                    idx = idx + 1;
                else
                    nSteps = ceil(remDist / 0.01);
                    for i1 = 1:nSteps
                        dir = dir + plotdata(end, 2) * remDist / nSteps;
                        dist = dist + remDist / nSteps;
                        dirplot(idx, :) = [dist, dir];
                        idx = idx + 1;
                    end
                end
                plotdata(end + 1, 1) = app.RouteLength(2);
                plotdata(end, 2) = plotdata(end - 1, 2);
            end
            dirplot = dirplot(1:idx - 1, :);

            yyaxis(app.CurveAxis, "left")
            plot(app.CurveAxis, plotdata(:, 1), plotdata(:, 2))
            grid(app.CurveAxis, "on");
            yl = max([min(plotdata(:, 2)) * -1.1, max(plotdata(:, 2)) * 1.1, 0.5]);
            ylim(app.CurveAxis, [-yl, yl]);
            % ylim(app.CurveAxis, [min([min(plotdata(:, 2)) * 1.1, -0.5]), max([max(plotdata(:, 2)) * 1.1, 0.5])]);
            yyaxis(app.CurveAxis, "right")
            dirplot(:, 2) = (mod(dirplot(:, 2) + pi, 2 * pi) - pi) * 360 / (2 * pi);
            plot(app.CurveAxis, dirplot(:, 1), dirplot(:, 2))
            yl = max([min(dirplot(:, 2)) * -1.1, max(dirplot(:, 2)) * 1.1, 25]);
            ylim(app.CurveAxis, [-yl, yl]);
            % ylim(app.CurveAxis, [min([min(dirplot(:, 2)) * 1.1, -25]), max([max(dirplot(:, 2)) * 1.1, 25])]);
            ylabel(app.CurveAxis, ['Direction change [' char(176) ']'])
            xlim(app.CurveAxis, app.RouteLength);
        end
        
        function ImportGeneral(app, file)
            importData = readcell(file, 'Sheet', 'General Data','Range','A2');
            for i1 = 1:size(importData,1)
                data = importData{i1, 2};
                if ismissing(data)
                    val = [];
                elseif ischar(data) && all(max(data == ['0':'9', '-', '.']')) % numeric value
                    val = str2double(data);
                else
                    val = data;
                end
                route.(importData{i1, 1}) = val;
            end
            data2layout(app, route, true);
            % app.NotesTextArea.Value = data
        end % ImportGeneral

        function ImportStationData(app, file)
            importData = readcell(file, 'Sheet', 'Stations','Range','A2');
            data = repmat({''}, size(importData));
            lastDist = -inf;
            lastDuration = seconds(0);
            for row = 1:size(importData, 1)
                for col = 1:5
                    if ischar(importData{row, col})
                        val = str2double(importData{row, col});
                    else
                        val = importData{row, col};
                    end
                    switch col
                        case 1 % dist
                            if isnan(val) || val < lastDist
                                dlg = warndlg(['Not a valid distance' newline 'Row: ' num2str(row)]);
                                uiwait(dlg);
                                return
                            end
                            lastDist = val;
                            data{row, col} = num2str(val, '%.3f');
                        case 2 % station name
                            data{row, col} = importData{row, col};
                        case 3 % dwell time
                            if ismissing(val) || isnan(val) || val < 0
                                val = [];
                            end
                            data{row, col} = num2str(val, '%.1f');
                        case 4 % stop pos
                            if ismissing(val)
                                val = [];
                            elseif isnan(val) || val < 0 || val > 1
                                dlg = warndlg(['Not a valid stopping point' newline 'Row: ' num2str(row)]);
                                uiwait(dlg);
                                return
                            end
                            data{row, col} = num2str(val, '%.1f');
                        case 5 % Departure time
                            if ismissing(importData{row, col})
                                data{row, col} = [];
                                continue
                            end
                            if ischar(importData{row, col})
                                try
                                    newDur = duration(importData{row, col});
                                catch
                                    newDur = -inf;
                                end
                            else % assume time was tipped into excel cell
                                newDur = days(importData{row, col});
                            end
                            if newDur < lastDuration
                                dlg = warndlg(['Not a valid departure time' newline 'Row: ' num2str(row)]);
                                uiwait(dlg);
                                return
                            end
                            data{row, col} = char(datetime("today") + newDur, 'HH:mm:ss.S');
                        otherwise
                    end
                end
            end
            app.StationTable.Data = data;
            GeneralChange(app, []);
        end % ImportStationData
        
        function ImportParameter(app, file)
            importData = readcell(file, 'Sheet', 'Parameters','Range','A1');
            app.ParameterTable.ColumnName = importData(1, :);
            app.ParameterTable.ColumnEditable = true(1, size(importData, 2));
            app.ParameterTable.ColumnWidth = num2cell([70, 120 * ones(1, size(importData, 2))]);
            app.ParameterTable.Data = importData(2:end, :);
            GeneralChange(app, []);
        end % ImportParameter
        
        function ImportSpeeds(app, file)
            importData = readcell(file, 'Sheet', 'Speeds','Range','A1');
            for i1 = 2:size(importData, 1)
                for i2 = 1:2
                    if ~ischar(importData{i1, i2})
                        importData{i1, i2} = num2str(importData{i1, i2}, '%.3f');
                    end
                end
                if ismissing(importData{i1, 3})
                    importData{i1, 3} = '';
                end
            end
            app.SpeedTable.Data = importData(2:end, :);
            s = str2double(app.StationTable.Data(:, 1));
            app.RouteLength = [min(s), max(s)];
            CalculateSpeed(app);
            GeneralChange(app, []);
        end % ImportSpeeds
        
        function ImportGradients(app, file)
            importData = readcell(file, 'Sheet', 'Gradients','Range','A1');
            for i1 = 2:size(importData, 1)
                if ~ischar(importData{i1, 1})
                    importData{i1, 1} = num2str(importData{i1, 1}, '%.3f');
                end
                if ~ischar(importData{i1, 2})
                    importData{i1, 2} = num2str(importData{i1, 2}, '%.1f');
                end
                if ~ischar(importData{i1, 3})
                    importData{i1, 3} = num2str(importData{i1, 3}, '%.2f');
                end
                if ismissing(importData{i1, 4})
                    importData{i1, 4} = '';
                end
            end
            app.GradientTable.Data = importData(2:end, :);
            CalculateSlope(app)
            GeneralChange(app, []);
        end % ImportGradients

        
        function ImportCurves(app, file)
            importData = readcell(file, 'Sheet', 'Curves','Range','A1');
            for i1 = 2:size(importData, 1)
                if ~ischar(importData{i1, 1})
                    importData{i1, 1} = num2str(importData{i1, 1}, '%.3f');
                end
                if ~ischar(importData{i1, 2})
                    importData{i1, 2} = num2str(importData{i1, 2}, '%.1f');
                end
                for i2 = 3:5
                    if ismissing(importData{i1, i2})
                        importData{i1, i2} = '';
                    end
                end
            end
            app.CurveTable.Data = importData(2:end, :);
            CalculateCurves(app);
            GeneralChange(app, []);
        end % ImportCurves

        
        function ImportTunnels(app, file)
            importData = readcell(file, 'Sheet', 'Tunnels','Range','A1');
            for i1 = 2:size(importData, 1)
                if ~ischar(importData{i1, 1})
                    importData{i1, 1} = num2str(importData{i1, 1}, '%.3f');
                end
                if ~ischar(importData{i1, 2})
                    importData{i1, 2} = num2str(importData{i1, 2}, '%.3f');
                end
                if ~ischar(importData{i1, 3})
                    importData{i1, 3} = num2str(importData{i1, 3}, '%.2f');
                end
                if ismissing(importData{i1, 4})
                    importData{i1, 4} = '';
                end
            end
            app.TunnelTable.Data = importData(2:end, :);
            CalculateSlope(app);
            GeneralChange(app, []);
        end % ImportTunnels

        
        function ExportGeneralData(app, file)
            route = layout2data(app, true);
            fNames = fieldnames(route);
            fNames{end, 2} = '';
            fNames = [{'Variable', 'Value'}; fNames];
            for i1 = 2:size(fNames)
                if isempty(route.(fNames{i1}))
                    fNames{i1, 2} = '';
                else
                    switch class(route.(fNames{i1}))
                        case 'double'
                            fNames{i1, 2} = num2str(route.(fNames{i1}));
                        case 'char'
                            fNames{i1, 2} = route.(fNames{i1});
                        case 'cell'
                            celldata = route.(fNames{i1});
                            ch = celldata{1};
                            for i2 = 2:size(celldata,1)
                                ch = [ch, newline, celldata{i2}];
                            end
                            fNames{i1, 2} = ch;
                        otherwise
                            fNames{i1, 2} = '??';
                    end
                end
            end
            % winopen(file)
            % sheetnames(file)
            if exist(file, 'file')
                sheets = sheetnames(file);
                if any(matches(sheets, 'General Data'))
                    try
                        temp = readcell(file, 'Sheet', 'General Data', 'Range', 'A1');
                        writecell(cell(size(temp)), file, 'Sheet', 'General Data', 'Range', 'A1');
                    catch ME


                        return
                    end
                end
            end
            writecell(fNames, file, 'Sheet', 'General Data', 'Range', 'A1');
        end % ExportGeneralData

        
        function CleanDirty(app)
            if exist(app.CurrentFile, 'file')
                [~, f] = fileparts(app.CurrentFile);
                [~, attr] = fileattrib(app.CurrentFile);
                if attr.UserWrite
                    name = ['Shogun Route Editor - ', f];
                else
                    name = ['Shogun Route Editor - ', f, ' [Read-only]'];
                end
            else
                name = 'Shogun Route Editor';
            end
            app.RouteEditorUIFigure.Name = name;
            app.Dirty = false;
        end % CleanDirty

        
        % function pos = MoveIntoScreen(~, pos)
        %     % Get monitor positions: each row = [left bottom width height]
        %     monitors = get(0, 'MonitorPositions');
        %     % add title bar height
        %     titlebarheight = 40;
        %     pos(3) = pos(3) + titlebarheight;
        %     % Choose the monitor where most of the figure currently lies
        %     overlapArea = zeros(size(monitors,1),1);
        %     for i = 1:size(monitors, 1)
        %         mon = monitors(i, :);
        %         % Compute overlap rectangle
        %         overlapWidth  = max(0, min(pos(1) + pos(3), mon(1) + mon(3)) - max(pos(1), mon(1)));
        %         overlapHeight = max(0, min(pos(2) + pos(4), mon(2) + mon(4)) - max(pos(2), mon(2)));
        %         overlapArea(i) = overlapWidth * overlapHeight;
        %     end
        %     [~, bestMonIdx] = max(overlapArea);
        %     mon = monitors(bestMonIdx,:);
        % 
        %     % Clamp figure coordinates to stay inside chosen monitor
        %     newLeft   = max(mon(1), min(pos(1), mon(1)+mon(3)-pos(3)));
        %     newBottom = max(mon(2), min(pos(2), mon(2)+mon(4)-pos(4)));
        % 
        %     % Apply new position
        %     pos = [newLeft newBottom pos(3) pos(4)];
        %     pos(3) = pos(3) - titlebarheight;
        % end % MoveIntoScreen

        end % methods (Access = private)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RouteEditorUIFigure.Visible = "off";
            app.caller = [];
            cd(getenv('APPDATA'));
            if ~exist('ShogunSim_Settings','dir')
                mkdir('ShogunSim_Settings');
                cd('ShogunSim_Settings')
            else
                cd('ShogunSim_Settings')
            end
            if ~exist('Shogun_Settings.mat','file')
                Shogun_Settings.workingdirectory = [];
                Shogun_Settings.RouteEditorPosition = app.RouteEditorUIFigure.Position;
                save('Shogun_Settings.mat','Shogun_Settings');
            end

            load('Shogun_Settings.mat','Shogun_Settings');
            app.settings_file = fullfile(pwd, 'Shogun_Settings.mat');
            app.working_directory = Shogun_Settings.workingdirectory;
            if isfield(Shogun_Settings, 'RouteEditorPosition')
                r = MoveIntoScreen(Shogun_Settings.RouteEditorPosition);
                app.RouteEditorUIFigure.Position = r;
            end
            app.CurrentFile = [];
            
            DefaultRouteData(app);
            app.isReadOnly = false;
            CleanDirty(app);
            app.RouteEditorUIFigure.Visible = "on";
        end

        % Callback function
        function RouteEditorCloseRequest(app, event)
            if app.Dirty
                answ = questdlg('Save changes before closing?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SaveasMenuSelected(app,event)
                end
            end
            if exist(app.settings_file, 'file')
                load(app.settings_file, 'Shogun_Settings');
                Shogun_Settings.RouteEditorPosition = app.RouteEditorUIFigure.Position;
                save(app.settings_file, 'Shogun_Settings');
            end
            delete(app)
        end

        % Button pushed function: AddParaBtn
        function AddParaBtnPushed(app, event)
            parName = inputdlg('Enter name for new parameter:', 'New parameter',[1 60], {''});
            if ~isempty(parName)
                % drawnow nocallbacks;
                app.ParameterTable.Visible = 'off';
                if ~isempty(app.ParameterTable.Data)
                    newcol = num2cell(zeros(size(app.ParameterTable.Data,1),1));
                    app.ParameterTable.Data = [app.ParameterTable.Data, newcol];
                end
                app.ParameterTable.ColumnName = [app.ParameterTable.ColumnName; parName];
                app.ParameterTable.ColumnWidth = [app.ParameterTable.ColumnWidth, {120}];
                app.ParameterTable.ColumnEditable = [app.ParameterTable.ColumnEditable, true];
                % drawnow;
                app.ParameterTable.Visible = 'on';
                app.AddParaRowBtn.Enable = "on";
                GeneralChange(app, event);
            end
        end

        % Button pushed function: DelParaBtn
        function DelParaBtnPushed(app, event)
            if ~isempty(app.ParameterTable.Selection)
                col = app.ParameterTable.Selection(2);
                app.ParameterTable.Data = [app.ParameterTable.Data(:,1:col-1), app.ParameterTable.Data(:,col+1:end)];
                app.ParameterTable.ColumnName = [app.ParameterTable.ColumnName(1:col-1,1);app.ParameterTable.ColumnName(col+1:end,1)];
                GeneralChange(app, event);
            end
        end

        % Button pushed function: AddParaRowBtn
        function AddParaRowBtnPushed(app, event)
            sel = app.ParameterTable.Selection;
            data = app.ParameterTable.Data;
            if isempty(sel)
                sel = size(data);
            end
            row = sel(1);
            if row < size(data, 1) % insert between
                dist = round((str2double(data(row , 1)) + str2double(data(row + 1, 1))) * 10) / 20;
            elseif row > 0 % insert at end
                dist = round(str2double(data(end, 1))*10 + 10) / 10;
            else 
                dist = 0;
            end
            newrow{1,1} = num2str(dist, '%.3f');
            for i1 = 2:size(app.ParameterTable.ColumnName,1)
                newrow{1,i1} = 0;
            end
            data = [app.ParameterTable.Data;newrow];
            app.ParameterTable.Data = data;
            GeneralChange(app, event);
        end

        % Button pushed function: DelParaRowBtn
        function DelParaRowBtnPushed(app, event)
            if ~isempty(app.ParameterTable.Selection)
                row = app.ParameterTable.Selection(1);
                app.ParameterTable.Data = [app.ParameterTable.Data(1:row-1,:);app.ParameterTable.Data(row+1:end,:)];
                GeneralChange(app, event);
            end
        end

        % Button pushed function: AddStationBtn
        function AddStationBtnPushed(app, event)
            if ~app.isReadOnly
                data = app.StationTable.Data;
                sel = app.StationTable.Selection;
                if isempty(sel)
                    newdep = char(datetime(data{end, 5}) + minutes(5), 'HH:mm:ss.S');
                    newName = findUniqueStationName(app, 'New end station');
                    ln = {num2str(round(str2double(data{end, 1}) + 5), '%.3f'), newName, '60.0', '0.5', newdep};
                    data = [data; ln];
                else
                    row = sel(1);
                    if row < size(data, 1)
                        newdep = char((duration(data{row, 5}) + duration(data{row + 1, 5}))/2, 'HH:mm:ss.S');
                        newName = findUniqueStationName(app, 'New intermediate station');
                        newkm = num2str((str2double(data{row, 1}) + str2double(data{row + 1, 1})/2),'%.3f');

                        ln = {newkm, newName, '60.0', '0.5', newdep};
                        data = [data(1:row, :); ln; data(row + 1:end, :)];
                    else
                        newdep = char(datetime(data{end, 5}) + minutes(5), 'HH:mm:ss.S');
                        newName = findUniqueStationName(app, 'New end station');
                        ln = {num2str(round(str2double(data{end, 1}) + 5), '%.3f'), newName, '60.0', '0.5', newdep};
                        data = [data; ln];
                    end
                end
                app.StationTable.Data = data;
                StationTableSelectionChanged(app, event)
                s = str2double(app.StationTable.Data(:, 1));
                app.RouteLength = [min(s), max(s)];
                GeneralChange(app, event);
            end
        end

        % Button pushed function: DelStationBtn
        function DelStationBtnPushed(app, event)
            sel = app.StationTable.Selection;
            if ~isempty(sel)
                data = app.StationTable.Data;
                row = sel(1);
                data = [data(1:row - 1, :); data(row + 1:end, :)];
                app.StationTable.Data = data;
                s = str2double(app.StationTable.Data(:, 1));
                app.RouteLength = [min(s), max(s)];
                GeneralChange(app, event);
            end
        end

        % Button pushed function: AddSpeedBtn
        function AddSpeedBtnPushed(app, event)
            sel = app.SpeedTable.Selection;
            data = app.SpeedTable.Data;
            if isempty(sel)
                sel = [size(data, 1), 1];
            end
            row = sel(1);
            if row < size(data, 1)
                dist = round((str2double(data(row , 1)) + str2double(data(row + 1, 1))) * 10) / 20;
            else
                dist = round(str2double(data(end, 1))*10 + 10) / 10;
            end
            nLine = {num2str(dist, '%.3f'), '100.0', ''};
            data = [data(1:row, :); nLine; data(row + 1:end, :)];
            app.SpeedTable.Data = data;
            CalculateSpeed(app);
            GeneralChange(app, event);
        end

        % Button pushed function: DelSpeedBtn
        function DelSpeedBtnPushed(app, event)
            sel = app.SpeedTable.Selection;
            if ~isempty(sel)
                row = sel(2);
                data = app.SpeedTable.Data;
                data = [data(1:row - 1, :); data(row + 1:end, :)];
                app.SpeedTable.Data = data;
                CalculateSpeed(app);
                GeneralChange(app, event);
            end
        end

        % Button pushed function: AddSlopeBtn
        function AddSlopeBtnPushed(app, event)
            sel = app.GradientTable.Selection;
            data = app.GradientTable.Data;
            if isempty(sel)
                sel = [size(data, 1), 1];
            end
            row = sel(1);
            if row < size(data, 1)
                dist = round((str2double(data(row , 1)) + str2double(data(row + 1, 1))) * 10) / 20;
            else
                dist = round(str2double(data(end, 1))*10 + 10) / 10;
            end
            nLine = {num2str(dist, '%.3f'), '0.0', '0.0', ''};
            data = [data(1:row, :); nLine; data(row + 1:end, :)];
            app.GradientTable.Data = data;
            CalculateSlope(app);
            GeneralChange(app, event);
        end

        % Button pushed function: DelSlopeBtn
        function DelSlopeBtnPushed(app, event)
            sel = app.GradientTable.Selection;
            if ~isempty(sel)
                row = sel(2);
                data = app.GradientTable.Data;
                data = [data(1:row - 1, :); data(row + 1:end, :)];
                app.GradientTable.Data = data;
                CalculateSlope(app);
                GeneralChange(app, event);
            end
        end

        % Button pushed function: AddCurveBtn
        function AddCurveBtnPushed(app, event)
            sel = app.CurveTable.Selection;
            data = app.CurveTable.Data;
            if isempty(sel)
                sel = [size(data, 1), 1];
            end
            row = sel(1);
            if row < size(data, 1)
                if strcmp(data{row, 2}, 'T')
                    row = row - 1;
                end
                dist = round((str2double(data(row , 1)) + str2double(data(row + 1, 1))) * 10) / 20;
            else
                dist = round(str2double(data(row, 1))*10 + 10) / 10;
            end
            if strcmp(data{row, 2}, '==')
                nLine = {num2str(dist - 0.1, '%.3f'), 'T', '', '', '';...
                    num2str(dist, '%.3f'), '1000', '100', 'n/c', ''};
            else
                nLine = {num2str(dist - 0.1, '%.3f'), 'T', '', '', '';...
                num2str(dist, '%.3f'), '==', '', '', ''};
            end
            data = [data(1:row, :); nLine; data(row + 1:end, :)];
            app.CurveTable.Data = data;
            CalculateCurves(app);
            GeneralChange(app, event);
        end

        % Button pushed function: DelCurveBtn
        function DelCurveBtnPushed(app, event)
            data = app.CurveTable.Data;
            sel = app.CurveTable.Selection;
            if ~isempty(sel)
                row = sel(1);
                data = [data(1:row - 2, :); data(row + 1:end, :)];
                app.CurveTable.Data = data;
                CalculateCurves(app);
                GeneralChange(app, event);
            end
        end

        % Button pushed function: AddTunnelBtn
        function AddTunnelBtnButtonPushed(app, event)
            sel = app.TunnelTable.Selection;
            data = app.TunnelTable.Data;
            if isempty(sel) % no selection, treat as last row was selected
                sel = [size(data, 1), 1];
            end
            row = sel(1);
            if row < size(data, 1)
                dist = round((str2double(data(row , 1)) + str2double(data(row + 1, 1))) * 10) / 20;
            elseif isempty(data)
                app.TunnelTable.Data = {'1.000', '0.500', '1.00', 'New tunnel'};
                CalculateSpeed(app);
                return
            else
                dist = round(str2double(data(row, 1))*10 + 10) / 10;
            end
            nLine = {num2str(dist, '%.3f'), '0.500', '1.00', 'New tunnel'};
            data = [data(1:row, :); nLine; data(row + 1:end, :)];
            app.TunnelTable.Data = data;
            CalculateSpeed(app);
            GeneralChange(app, event);
        end

        % Button pushed function: DelTunnelBtn
        function DelTunnelBtnButtonPushed(app, event)
            data = app.TunnelTable.Data;
            sel = app.TunnelTable.Selection;
            if ~isempty(sel)
                row = sel(1);
                data = [data(1:row - 1, :); data(row + 1:end, :)];
                app.TunnelTable.Data = data;
                CalculateSpeed(app);
                GeneralChange(app, event);
            end
        end

        % Cell edit callback: StationTable
        function StationTableCellEdit(app, event)
            if app.isReadOnly
                app.StationTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                row = event.Indices(1);
                col = event.Indices(2);
                newData = event.NewData;
                data = app.StationTable.Data;
                switch col
                    case 1 % distance
                        dist = str2double(newData);
                        newData = num2str(dist,'%.3f');
                        data{row, 1} = newData;
                        if isnan(newData)
                            app.StationTable.Data{row, 1} = event.PreviousData;
                            return
                        end
                        b = find(matches(data(:, 1), newData), 1, 'first');
                        if size(b, 2) > 1
                            app.StationTable.Data{row, 1} = event.PreviousData;
                            return
                        end
                        line_data = data(row, :);
                        data = [data(1:row - 1, :); data(row + 1:end, :)];
                        newRow = 1;
                        while size(data,1) >= newRow && dist > str2double(data{newRow,1})
                            newRow = newRow + 1;
                        end
                        if newRow ~= row
                            if newRow > 1
                                if newRow <= size(data, 1)
                                    newdep = char((duration(data{newRow - 1, 5}) + duration(data{newRow, 5}))/2, 'HH:mm:ss.S');
                                else
                                    newdep = char(datetime(data{end, 5}) + minutes(5), 'HH:mm:ss.S');
                                end
                            else
                                newdep = '00:00:00.0';
                            end
                            line_data{1, 5} = char(newdep);
                            data = [data(1:newRow - 1, :); line_data; data(newRow:end, :)];
                            app.StationTable.Data = data;
                        else
                            app.StationTable.Data{row, 1} = newData;
                        end
                        s = str2double(app.StationTable.Data(:, 1));
                        app.RouteLength = [min(s), max(s)];
                        CalculateSpeed(app);
                    case 2 % station name
                        % check that name is unique
                        b = find(matches(data(:, 2), data{row, 2}));
                        if size(b, 2) > 1
                            app.StationTable.Data{row, 2} = event.PreviousData;
                            return
                        end
                    case 3 % dwell time
                        newDW = str2double(newData);
                        newData = num2str(newDW,'%.1f');
                        if isnan(newDW) || newDW < 0
                            if row > 1 && row < size(data,1)
                                app.StationTable.Data{row, 3} = '';
                            else
                                app.StationTable.Data{row, 3} = event.PreviousData;
                            end
                        else
                            app.StationTable.Data{row, 3} = newData;
                        end
                    case 4 % stop pos
                        newPos = str2double(newData);
                        newData = num2str(newPos,'%.1f');
                        if isnan(newPos)
                            app.StationTable.Data{row, 4} = [];
                            % elseif newPos < 0 || newPos > 1
                            %     app.StationTable.Data{row, 4} = event.PreviousData;
                        else
                            app.StationTable.Data{row, 4} = newData;
                        end
                    case 5 % departure time
                        if isempty(newData)
                            return
                        end
                        b = find(newData == ':');
                        if size(b, 2) == 1 % only one ':'
                            newData = [newData ':00'];
                        end
                        try
                            newData = char(datetime(newData), 'HH:mm:ss.S');
                        catch
                            app.StationTable.Data{row, 5} = event.PreviousData;
                            return
                        end
                        app.StationTable.Data{row, 5} = newData;
                end
                GeneralChange(app, event);
            end
        end

        % Cell edit callback: SpeedTable
        function SpeedTableCellEdit(app, event)
            if app.isReadOnly
                app.SpeedTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                row = event.Indices(1);
                col = event.Indices(2);
                newData = str2double(event.NewData);
                data = app.SpeedTable.Data;
                switch col
                    case 1 % Distance
                        if isnan(newData)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(newData, '%.3f');
                        end
                    case 2 % speed level
                        if isnan(newData)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(newData, '%.1f');
                        end
                end
                app.SpeedTable.Data = data;
                CalculateSpeed(app);
                GeneralChange(app, event);
            end
        end

        % Cell edit callback: ParameterTable
        function ParameterTableCellEdit(app, event)
            if app.isReadOnly
                app.ParameterTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                row = event.Indices(1);
                col = event.Indices(2);
                newData = event.NewData;
                if col == 1 % From pos
                    data = app.ParameterTable.Data;
                    Pos = str2double(newData);
                    if isnan(Pos)
                        data{row, col} = event.PreviousData;
                    else
                        data{row, col} = num2str(Pos, '%.3f');
                    end
                    line_data = data(row,:);
                    data = [data(1:row - 1, :);data(row + 1:end, :)];
                    newRow = 1;
                    while size(data,1) >= newRow && Pos > str2double(data{newRow,1})
                        newRow = newRow + 1;
                    end
                    data = [data(1:newRow - 1, :); line_data; data(newRow:end, :)];
                    app.ParameterTable.Data = data;
                    ParameterTableSelectionChanged(app, event);
                end
                GeneralChange(app, event);
            end
        end

        % Cell edit callback: GradientTable
        function GradientTableCellEdit(app, event)
            if app.isReadOnly
                app.GradientTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                row = event.Indices(1);
                col = event.Indices(2);
                newData = event.NewData;
                data = app.GradientTable.Data;
                switch col
                    case 1 % dist
                        Pos = str2double(newData);
                        if isnan(Pos)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(Pos, '%.3f');

                            line_data = data(row,:);
                            data = [data(1:row - 1, :); data(row + 1:end, :)];

                            newRow = 1;
                            while size(data,1) >= newRow && Pos > str2double(data{newRow,1})
                                newRow = newRow + 1;
                            end
                            data = [data(1:newRow - 1, :); line_data; data(newRow:end, :)];
                        end
                    case 2 % slop in ‰
                        Pos = str2double(newData);
                        if isnan(Pos)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(Pos, '%.1f');
                        end
                    case 3 % level
                        level = str2double(newData);
                        if isnan(level)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(level, '%.1f');
                            if row > 2
                                dH = level - str2double(data{row - 1, 3});
                                Pos = str2double(data{row, 1}) - str2double(data{row - 1, 1});
                                grad = dH/Pos;
                                data{row - 1, 2} = num2str(grad, '%.1f');
                            end
                        end
                    otherwise
                end
                app.GradientTable.Data = data;
                CalculateSlope(app);
                GeneralChange(app, event);
            end
        end

        % Cell edit callback: CurveTable
        function CurveTableCellEdit(app, event)
            if app.isReadOnly
                app.CurveTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                row = event.Indices(1);
                col = event.Indices(2);
                newData = event.NewData;
                data = app.CurveTable.Data;
                switch col
                    case 1 % dist - cannot be sorted
                        val = str2double(newData);
                        lim = [0, inf];
                        if row < size(data, 1)
                            lim(2) = min([lim(2), str2double(data(row + 1, 1))]);
                        elseif row > 1
                            lim(1) = max([lim(1), str2double(data(row - 1, 1))]);
                        end
                        if isnan(val) || val <= lim(1) || val >= lim(2)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(val, '%.3f');
                        end
                    case 2 % radius
                        if strcmp(event.PreviousData, 'T')
                            data{row, col} = event.PreviousData;
                        elseif strcmp(newData, '==') || ~isnan(str2double(newData))
                            val = str2double(newData);
                            if val == 0
                                data{row, col} = '==';
                            end
                        else
                            data{row, col} = event.PreviousData;
                        end
                    case 3 % cant, must be >= 0
                        val = str2double(newData);

                        if isnan(val) || val < 0 || any(matches({'==', 'T'}, data{row, 2}))
                            data{row, col} = event.PreviousData;
                        end
                end
                app.CurveTable.Data = data;
                CalculateCurves(app);
                GeneralChange(app, event);
            end
        end

        % Cell edit callback: TunnelTable
        function TunnelTableCellEdit(app, event)
            if app.isReadOnly
                app.TunnelTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                row = event.Indices(1);
                col = event.Indices(2);
                newData = event.NewData;
                data = app.TunnelTable.Data;
                switch col
                    case 1 % distance
                        Pos = str2double(newData);
                        if isnan(Pos)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(Pos, '%.3f');

                            line_data = data(row,:);
                            data = [data(1:row - 1, :); data(row + 1:end, :)];

                            newRow = 1;
                            while size(data,1) >= newRow && Pos > str2double(data{newRow,1})
                                newRow = newRow + 1;
                            end
                            data = [data(1:newRow - 1, :); line_data; data(newRow:end, :)];
                        end
                    case 2 % Length of tunnel
                        val = str2double(newData);
                        if isnan(val)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(val, '%.3f');
                        end
                    case 3 % C-factor
                        val = str2double(newData);
                        if isnan(val)
                            data{row, col} = event.PreviousData;
                        else
                            data{row, col} = num2str(val, '%.3f');
                        end
                end
                app.TunnelTable.Data = data;
                CalculateSlope(app);
                GeneralChange(app, event);
            end
        end

        % Selection changed function: StationTable
        function StationTableSelectionChanged(app, event)
            selection = app.StationTable.Selection;
            if isempty(selection) || size(app.StationTable.Data,1) <= 2 || app.isReadOnly
                app.DelStationBtn.Enable = "off";
            else
                app.DelStationBtn.Enable = "on";
            end
            app.AddStationBtn.Enable = ~app.isReadOnly;
            app.ImportStationBtn.Enable = ~app.isReadOnly;
        end

        % Selection changed function: SpeedTable
        function SpeedTableSelectionChanged(app, event)
            selection = app.SpeedTable.Selection;
            if isempty(selection) || size(app.SpeedTable.Data,1) <= 2 || app.isReadOnly
                app.DelSpeedBtn.Enable = "off";
            else
                app.DelSpeedBtn.Enable = "on";
            end
            app.AddSpeedBtn.Enable = ~app.isReadOnly;
            app.ImportSpeedBtn.Enable = ~app.isReadOnly;
        end

        % Selection changed function: ParameterTable
        function ParameterTableSelectionChanged(app, event)
            sel = app.ParameterTable.Selection;
            if isempty(sel) || app.isReadOnly
                app.DelParaBtn.Enable = "off";
                app.DelParaRowBtn.Enable = "off";
            else
                app.DelParaBtn.Enable  = sel(2) > 1;
                app.DelParaRowBtn.Enable = "on";
            end
            app.AddParaRowBtn.Enable = (size(app.ParameterTable.ColumnName,1) > 1) && ~app.isReadOnly;
            app.ImportParaBtn.Enable = ~app.isReadOnly;
        end

        % Selection changed function: GradientTable
        function GradientTableSelectionChanged(app, event)
            sel = app.GradientTable.Selection;
            if isempty(sel) || size(app.GradientTable.Data,1) <= 1 || app.isReadOnly
                app.DelSlopeBtn.Enable = "off";
            else
                app.DelSlopeBtn.Enable = "on";
            end
            app.AddSlopeBtn.Enable = ~app.isReadOnly;
            app.ImportSlopeBtn.Enable = ~app.isReadOnly;
        end

        % Selection changed function: CurveTable
        function CurveTableSelectionChanged(app, event)
            sel = app.CurveTable.Selection;
            if isempty(sel) || size(app.CurveTable.Data,1) <= 1 || app.isReadOnly
                app.DelCurveBtn.Enable = "off";
            else
                row = sel(1);
                ch = app.CurveTable.Data{row, 2};
                app.DelCurveBtn.Enable = (strcmp(ch, '==') || ~isnan(str2double(ch))) && row > 1;
            end
            app.AddCurveBtn.Enable = ~app.isReadOnly;
            app.ImportCurvesBtn.Enable = ~app.isReadOnly;
        end

        % Selection changed function: TunnelTable
        function TunnelTableSelectionChanged(app, event)
            sel = app.TunnelTable.Selection;
            app.DelTunnelBtn.Enable = ~isempty(sel) && ~app.isReadOnly;
            app.AddTunnelBtn.Enable = ~app.isReadOnly;
            app.ImportTunnelBtn.Enable = ~app.isReadOnly;
        end

        % Value changed function: ATOSpdUnitDropDown
        function ATOSpdUnitDropDownValueChanged(app, event)
            if app.isReadOnly
                app.ATOSpdUnitDropDown.ValueIndex = event.PreviousValueIndex;
            else
                txt = app.ATOSpdUnitDropDown.Value;
                app.SpdLabel1.Text = txt;
                app.SpdLabel2.Text = txt;
                app.SpdLabel3.Text = txt;
                app.SpdLabel4.Text = txt;
                app.SpdLabel5.Text = txt;
                app.SpdLabel6.Text = txt;
                GeneralChange(app, []);
            end
        end

        % Value changed function: VoltUnitDropDown
        function VoltUnitDropDownValueChanged(app, event)
            if app.isReadOnly
                app.VoltUnitDropDown.ValueIndex = event.PreviousValueIndex;
            else
                value = app.VoltUnitDropDown.ValueIndex;
                app.VoltLabel1.Text = app.VoltUnitDropDown.Items(value);
                app.VoltLabel2.Text = app.VoltUnitDropDown.Items(value);
                GeneralChange(app, []);
            end
        end

        % Callback function
        function TrackguageEditFieldValueChanged(app, event)
            CalculateSlope(app);
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            DefaultRouteData(app);
            CleanDirty(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            cd(app.working_directory)
            if exist('Routes', 'dir')
                cd('Routes');
            end
            [f, p] = uigetfile('*.mat', 'Open route file...');
            cd(fileparts(mfilename('fullpath')));
            if f
                app.CurrentFile = fullfile(p, f);
                [~, attr] = fileattrib(app.CurrentFile);
                load(app.CurrentFile, 'route_data');
                data2layout(app, route_data, false);
                CleanDirty(app);
                app.isReadOnly = ~attr.UserWrite;
            end
            pause(1);
            figure(app.RouteEditorUIFigure);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if ~isempty(app.CurrentFile) && exist(app.CurrentFile, 'file')
                route_data = layout2data(app, false);
                save(app.CurrentFile, 'route_data');
                CleanDirty(app);
                if ~isempty(app.caller)
                    app.caller.RouteDataHasChanged(app.caller)
                end
            else
                app.CurrentFile = [];
                SaveasMenuSelected(app, event);
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            cd(app.working_directory)
            if exist('RouteData', 'dir')
                cd('RouteData');
            end
            [f, p] = uiputfile('*.mat', 'Save route as...');
            cd(fileparts(mfilename('fullpath')));
            if f
                route_data = layout2data(app, false);
                app.CurrentFile = fullfile(p, f);
                save(app.CurrentFile, 'route_data');
                CleanDirty(app);
                app.isReadOnly = 0;
                if ~isempty(app.caller)
                    app.caller.RouteDataHasChanged(app.caller)
                end
            end
            figure(app.RouteEditorUIFigure);
        end

        % Menu selected function: SetProjectDirectoryMenu
        function SetProjectDirectoryMenuSelected(app, event)
            close(app.RouteEditorUIFigure);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            delete(app)
        end

        % Value changed function: ATOBkrSpdEditField, 
        % ...and 24 other components
        function GeneralChange(app, event)
            if app.isReadOnly
                switch event.Source.Type
                    case {'uieditfield', 'uinumericeditfield', 'uitextarea'}
                        event.Source.Value = event.PreviousValue;
                    
                    otherwise
                        event.Source.Type
                end
            else
                if ~isempty(app.CurrentFile)
                    [~, f] = fileparts(app.CurrentFile);
                    app.RouteEditorUIFigure.Name = ['Shogun Route Editor - ' f '*'];
                else
                    app.RouteEditorUIFigure.Name = ['Shogun Route Editor', '*'];
                end
                app.Dirty = true;
            end
        end

        % Callback function: RouteEditorUIFigure, RouteEditorUIFigure
        function RouteEditorUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(event.Modifier, 'control')
                needFocus = 0;
                switch key
                    case 'pagedown'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab), size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);
                        needFocus = 1;
                    case 'pageup'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab) - 2, size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);
                        needFocus = 1;
                end
                if needFocus
                    for i1 = size(tabs(idx).Children,1):-1:1
                        switch tabs(idx).Children(i1).Type
                            case 'uinumericeditfield'
                                focus(tabs(idx).Children(i1))
                                break
                            case 'uieditfield'
                                focus(tabs(idx).Children(i1))
                                break
                            case 'uitable'
                                focus(tabs(idx).Children(i1))
                                break
                            case 'uibutton'
                                focus(tabs(idx).Children(i1))
                                break
                        end
                    end
                end
            end
        end

        % Value changed function: LatitudeEditField, LongitudeEditField
        function LongitudeEditFieldValueChanged(app, event)
            if app.isReadOnly
                event.Source.Value = event.PreviousValue;
            else
                value = app.LongitudeEditField.Value;
                if isempty(value)
                    app.LonitudeLabel.Text = 'deg DD';
                else
                    coord_deg = floor(value);
                    coord_min = floor(mod(value * 60, 60));
                    coord_sec = mod(value * 3600, 60);
                    if value > 0
                        ch = 'W';
                    else
                        ch = 'E';
                    end
                    app.LonitudeLabel.Text = ['deg DD, (DMS: ', num2str(coord_deg), char(176), num2str(coord_min), '''', num2str(coord_sec, '%05.2f'), '"', ch, ')'];
                end
                value = app.LatitudeEditField.Value;
                if isempty(value)
                    app.LatitudeLabel.Text = 'deg DD';
                else
                    coord_deg = floor(value);
                    coord_min = floor(mod(value * 60, 60));
                    coord_sec = mod(value * 3600, 60);
                    if value > 0
                        ch = 'N';
                    else
                        ch = 'S';
                    end
                    app.LatitudeLabel.Text = ['deg DD, (DMS: ', num2str(coord_deg), char(176), num2str(coord_min), '''', num2str(coord_sec, '%05.2f'), '"', ch, ')'];
                end
                GeneralChange(app, []);
            end
        end

        % Button pushed function: ExportStationBtn
        function ExportStationBtnPushed(app, event)
            cd(app.working_directory);
            [f, p] = uiputfile('*.XLSX', 'Export station data as..');
            cd(fileparts(mfilename('fullpath')));
            if f
                if exist(fullfile(p, f), 'file')
                    try
                        sheets = sheetnames(fullfile(p, f));
                    catch
                        dlg = warndlg('Cannot access the excel sheet');
                        uiwait(dlg);
                    end
                    if any(matches(sheets, 'Stations'))
                        temp = readtable(fullfile(p, f), 'Sheet','Stations', 'Range', 'A1');
                        writecell(cell(size(temp) + [1 0]), fullfile(p, f), 'Sheet','Stations', 'Range', 'A1');
                    end
                end
                writecell(app.StationTable.Data, fullfile(p, f), 'Sheet', 'Stations', 'Range', 'A2');
                writecell(app.StationTable.ColumnName', fullfile(p, f), 'Sheet', 'Stations', 'Range', 'A1');
                winopen(fullfile(p, f));
            end
        end

        % Button pushed function: ImportStationBtn
        function ImportStationBtnPushed(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            cd(app.working_directory);
            [f, p] = uigetfile('*.XLSX', 'Import station data from excel file');
            cd(fileparts(mfilename('fullpath')));
            if f
                sheets = sheetnames(fullfile(p, f));
                b = find(matches(sheets, 'Stations'), 1, 'first');
                if b
                    ImportStationData(app, fullfile(p, f));
                end
            end
        end

        % Button pushed function: ExportParaBtn
        function ExportParaBtnButtonPushed(app, event)
            cd(app.working_directory);
            [f, p] = uiputfile('*.XLSX', 'Export parametere data as..');
            cd(fileparts(mfilename('fullpath')));
            if f
                if exist(fullfile(p, f), 'file')
                    try
                        sheets = sheetnames(fullfile(p, f));
                    catch
                        dlg = warndlg('Cannot access the excel sheet');
                        uiwait(dlg);
                        return
                    end
                    if any(matches(sheets, 'Parameters'))
                        temp = readtable(fullfile(p, f), 'Sheet', 'Parameters', 'Range', 'A1');
                        writecell(cell(size(temp) + [1 0]), fullfile(p, f), 'Sheet', 'Parameters', 'Range', 'A1');
                    end
                end
                writecell(app.ParameterTable.Data, fullfile(p, f), 'Sheet', 'Parameters', 'Range', 'A2');
                writecell(app.ParameterTable.ColumnName', fullfile(p, f), 'Sheet', 'Parameters', 'Range', 'A1');
                winopen(fullfile(p, f));
            end
        end

        % Button pushed function: ImportParaBtn
        function ImportParaBtnButtonPushed(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            cd(app.working_directory);
            [f, p] = uigetfile('*.XLSX', 'Import Parameter data from excel file');
            cd(fileparts(mfilename('fullpath')));
            if f
                sheets = sheetnames(fullfile(p, f));
                b = find(matches(sheets, 'Parameters'), 1, 'first');
                if b
                    ImportParameter(app, fullfile(p, f));
                end
            end
        end

        % Button pushed function: ExportSpeedBtn
        function ExportSpeedBtnButtonPushed(app, event)
            cd(app.working_directory);
            [f, p] = uiputfile('*.XLSX', 'Export speed limits as..');
            cd(fileparts(mfilename('fullpath')));
            if f
                if exist(fullfile(p, f), 'file')
                    try
                        sheets = sheetnames(fullfile(p, f));
                    catch
                        dlg = warndlg('Cannot access the excel sheet');
                        uiwait(dlg);
                        return
                    end
                    if any(matches(sheets, 'Speeds'))
                        temp = readtable(fullfile(p, f), 'Sheet', 'Speeds', 'Range', 'A1');
                        writecell(cell(size(temp) + [1 0]), fullfile(p, f), 'Sheet', 'Speeds', 'Range', 'A1');
                    end
                end
                writecell(app.SpeedTable.Data, fullfile(p, f), 'Sheet', 'Speeds', 'Range', 'A2');
                writecell(app.SpeedTable.ColumnName', fullfile(p, f), 'Sheet', 'Speeds', 'Range', 'A1');
                winopen(fullfile(p, f));
            end
        end

        % Button pushed function: ImportSpeedBtn
        function ImportSpeedBtnButtonPushed(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            cd(app.working_directory);
            [f, p] = uigetfile('*.XLSX', 'Import speed limits from excel file');
            cd(fileparts(mfilename('fullpath')));
            if f
                sheets = sheetnames(fullfile(p, f));
                b = find(matches(sheets, 'Speeds'), 1, 'first');
                if b
                    ImportSpeeds(app, file)
                end
            end
        end

        % Button pushed function: ExportSlopeBtn
        function ExportSlopeBtnButtonPushed(app, event)
            cd(app.working_directory);
            [f, p] = uiputfile('*.XLSX', 'Export gradients as..');
            cd(fileparts(mfilename('fullpath')));
            if f
                if exist(fullfile(p, f), 'file')
                    try
                        sheets = sheetnames(fullfile(p, f));
                    catch
                        dlg = warndlg('Cannot access the excel sheet');
                        uiwait(dlg);
                        return
                    end
                    if any(matches(sheets, 'Gradients'))
                        temp = readcell(fullfile(p, f), 'Sheet', 'Gradients', 'Range', 'A1');
                        writecell(cell(size(temp)), fullfile(p, f), 'Sheet', 'Gradients', 'Range', 'A1');
                    end
                end
                writecell(app.GradientTable.Data, fullfile(p, f), 'Sheet', 'Gradients', 'Range', 'A2');
                writecell(app.GradientTable.ColumnName', fullfile(p, f), 'Sheet', 'Gradients', 'Range', 'A1');
                winopen(fullfile(p, f));
            end
        end

        % Button pushed function: ImportSlopeBtn
        function ImportSlopeBtnButtonPushed(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            cd(app.working_directory);
            [f, p] = uigetfile('*.XLSX', 'Import gradients from excel file');
            cd(fileparts(mfilename('fullpath')));
            if f
                sheets = sheetnames(fullfile(p, f));
                b = find(matches(sheets, 'Gradients'), 1, 'first');
                if b
                    ImportGradients(app, fullfile(p, f));
                end
            end
        end

        % Button pushed function: ExportCurvesBtn
        function ExportCurvesBtnButtonPushed(app, event)
            cd(app.working_directory);
            [f, p] = uiputfile('*.XLSX', 'Export curve data as..');
            cd(fileparts(mfilename('fullpath')));
            if f
                if exist(fullfile(p, f), 'file')
                    try
                        sheets = sheetnames(fullfile(p, f));
                    catch
                        dlg = warndlg('Cannot access the excel sheet');
                        uiwait(dlg);
                        return
                    end
                    if any(matches(sheets, 'Curves'))
                        temp = readtable(fullfile(p, f), 'Sheet', 'Curves', 'Range', 'A1');
                        writecell(cell(size(temp) + [1 0]), fullfile(p, f), 'Sheet', 'Curves', 'Range', 'A1');
                    end
                end
                writecell(app.CurveTable.Data, fullfile(p, f), 'Sheet', 'Curves', 'Range', 'A2');
                writecell(app.CurveTable.ColumnName', fullfile(p, f), 'Sheet', 'Curves', 'Range', 'A1');
                winopen(fullfile(p, f));
            end
        end

        % Button pushed function: ImportCurvesBtn
        function ImportCurvesBtnButtonPushed(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            cd(app.working_directory);
            [f, p] = uigetfile('*.XLSX', 'Import curve data from excel file');
            cd(fileparts(mfilename('fullpath')));
            if f
                sheets = sheetnames(fullfile(p, f));
                b = find(matches(sheets, 'Curves'), 1, 'first');
                if b
                    ImportCurves(app, fullfile(p, f));
                end
            end
        end

        % Button pushed function: ExportTunnelBtn
        function ExportTunnelBtnButtonPushed(app, event)
            cd(app.working_directory);
            [f, p] = uiputfile('*.XLSX', 'Export tunnels as..');
            cd(fileparts(mfilename('fullpath')));
            if f
                if exist(fullfile(p, f), 'file')
                    try
                        sheets = sheetnames(fullfile(p, f));
                    catch
                        dlg = warndlg('Cannot access the excel sheet');
                        uiwait(dlg);
                        return
                    end
                    if any(matches(sheets, 'Tunnels'))
                        temp = readcell(fullfile(p, f), 'Sheet', 'Tunnels', 'Range', 'A1');
                        writecell(cell(size(temp)), fullfile(p, f), 'Sheet', 'Tunnels', 'Range', 'A1');
                    end
                end
                writecell(app.TunnelTable.Data, fullfile(p, f), 'Sheet', 'Tunnels', 'Range', 'A2');
                writecell(app.TunnelTable.ColumnName', fullfile(p, f), 'Sheet', 'Tunnels', 'Range', 'A1');
                winopen(fullfile(p, f));
            end

        end

        % Button pushed function: ImportTunnelBtn
        function ImportTunnelBtnButtonPushed(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            cd(app.working_directory);
            [f, p] = uigetfile('*.XLSX', 'Import tunnel data from excel file');
            cd(fileparts(mfilename('fullpath')));
            if f
                sheets = sheetnames(fullfile(p, f));
                b = find(matches(sheets, 'Tunnels'), 1, 'first');
                if b
                    ImportTunnels(app, fullfile(p, f));
                end
            end
        end

        % Menu selected function: ImportdatafromExcelMenu
        function ImportdatafromExcelMenuSelected(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ, 'Cancel')
                    figure(app.RouteEditorUIFigure);
                    return
                elseif strcmp(answ, 'Yes')
                    SavevehiclefileMenuSelected(app, event);
                end
            end
            cd(app.working_directory);
            [f, p] = uigetfile('*.XLSX', 'Import data from excel file');
            cd(fileparts(mfilename('fullpath')));
            if f
                sheets = sheetnames(fullfile(p, f));
                idx = 1;
                while idx <= size(sheets, 1) 
                    if any(matches({'General Data', 'Stations', 'Parameters', 'Speeds', 'Gradients', 'Curves', 'Tunnels'}, sheets(idx)))
                        idx = idx + 1;
                    else
                        sheets = [sheets(1:idx - 1); sheets(idx + 1:end)];
                    end
                end
                if isempty(sheets)
                    dlg = warndlg('The selected workbook has no valid tabs');
                    uiwait(dlg);
                    return
                else
                    sel = listdlg('PromptString', 'Select sheets to be imported', 'ListString', sheets);
                    for i1 = sel
                        switch char(sheets(i1))
                            case 'General Data'
                                ImportGeneral(app, fullfile(p, f));
                            case 'Stations'
                                ImportStationData(app, fullfile(p, f));
                            case 'Parameters'
                                ImportParameter(app, fullfile(p, f));
                            case 'Speeds'
                                ImportSpeeds(app, fullfile(p, f));
                            case 'Gradients'
                                ImportGradients(app, fullfile(p, f));
                            case 'Curves'
                                ImportCurves(app, fullfile(p, f));
                            case 'Tunnels'
                                ImportTunnels(app, fullfile(p, f));
                        end
                    end
                end
            end
        end

        % Menu selected function: ExportdatatoExcelMenu
        function ExportdatatoExcelMenuSelected(app, event)
            cd(app.working_directory)
            [f, p] = uiputfile('*.xlsx', 'Export data to excel file');
            cd(fileparts(mfilename('fullpath')));
            if f
                if exist(fullfile(p, f), 'file')
                    try
                        sheets = sheetnames(fullfile(p, f));
                    catch
                        dlg = warndlg('Cannot access the excel sheet');
                        uiwait(dlg);
                        return
                    end
                else
                    sheets = [];
                end
                export_sheets = {'General Data', 'Stations', 'Parameters', 'Speeds', 'Gradients', 'Curves', 'Tunnels'};
                sel = listdlg('PromptString', 'Select sheets to be imported', 'ListString', export_sheets);
                for i1 = sel
                    switch i1
                        case 1 % General data
                            ExportGeneralData(app, fullfile(p, f))
                        case 2 % Stations
                            if ~isempty(sheets) && any(matches(sheets, 'Stations'))
                                % delete previous data from the tab
                                temp = readcell(fullfile(p, f), 'Sheet','Stations', 'Range', 'A1');
                                writecell(cell(size(temp)), fullfile(p, f), 'Sheet','Stations', 'Range', 'A1');
                            end
                            writecell(app.StationTable.Data, fullfile(p, f), 'Sheet', 'Stations', 'Range', 'A2');
                            writecell(app.StationTable.ColumnName', fullfile(p, f), 'Sheet', 'Stations', 'Range', 'A1');
                        case 3 % Parameters
                            if ~isempty(sheets) && any(matches(sheets, 'Parameters'))
                                % delete previous data from the tab
                                temp = readtable(fullfile(p, f), 'Sheet', 'Parameters', 'Range', 'A1');
                                writecell(cell(size(temp)), fullfile(p, f), 'Sheet', 'Parameters', 'Range', 'A1');
                            end
                            if ~isempty(app.ParameterTable.Data)
                                writecell(app.ParameterTable.Data, fullfile(p, f), 'Sheet', 'Parameters', 'Range', 'A2');
                                writecell(app.ParameterTable.ColumnName', fullfile(p, f), 'Sheet', 'Parameters', 'Range', 'A1');
                            end
                        case 4 % Speeds
                            if ~isempty(sheets) && any(matches(sheets, 'Speeds'))
                                % delete previous data from the tab
                                temp = readtable(fullfile(p, f), 'Sheet', 'Speeds', 'Range', 'A1');
                                writecell(cell(size(temp)), fullfile(p, f), 'Sheet', 'Speeds', 'Range', 'A1');
                            end
                            writecell(app.SpeedTable.Data, fullfile(p, f), 'Sheet', 'Speeds', 'Range', 'A2');
                            writecell(app.SpeedTable.ColumnName', fullfile(p, f), 'Sheet', 'Speeds', 'Range', 'A1');
                        case 5 % Gradients
                            if ~isempty(sheets) && any(matches(sheets, 'Gradients'))
                                % delete previous data from the tab
                                temp = readtable(fullfile(p, f), 'Sheet', 'Gradients', 'Range', 'A1');
                                writecell(cell(size(temp)), fullfile(p, f), 'Sheet', 'Gradients', 'Range', 'A1');
                            end
                            writecell(app.GradientTable.Data, fullfile(p, f), 'Sheet', 'Gradients', 'Range', 'A2');
                            writecell(app.GradientTable.ColumnName', fullfile(p, f), 'Sheet', 'Gradients', 'Range', 'A1');
                        case 6 % Curves
                            if ~isempty(sheets) && any(matches(sheets, 'Curves'))
                                % delete previous data from the tab
                                temp = readtable(fullfile(p, f), 'Sheet', 'Curves', 'Range', 'A1');
                                writecell(cell(size(temp)), fullfile(p, f), 'Sheet', 'Curves', 'Range', 'A1');
                            end
                            writecell(app.CurveTable.Data, fullfile(p, f), 'Sheet', 'Curves', 'Range', 'A2');
                            writecell(app.CurveTable.ColumnName', fullfile(p, f), 'Sheet', 'Curves', 'Range', 'A1');
                        case 7 % Tunnels
                            if ~isempty(sheets) && any(matches(sheets, 'Curves'))
                                % delete previous data from the tab
                                temp = readcell(fullfile(p, f), 'Sheet', 'Tunnels', 'Range', 'A1');
                                writecell(cell(size(temp)), fullfile(p, f), 'Sheet', 'Tunnels', 'Range', 'A1');
                            end
                            writecell(app.TunnelTable.Data, fullfile(p, f), 'Sheet', 'Tunnels', 'Range', 'A2');
                            writecell(app.TunnelTable.ColumnName', fullfile(p, f), 'Sheet', 'Tunnels', 'Range', 'A1');

                    end
                end

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RouteEditorUIFigure = uifigure('Visible', 'off');
            app.RouteEditorUIFigure.Name = 'Route Editor';
            app.RouteEditorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @RouteEditorUIFigureKeyPress, true);
            app.RouteEditorUIFigure.KeyPressFcn = createCallbackFcn(app, @RouteEditorUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.RouteEditorUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.SetProjectDirectoryMenu = uimenu(app.FileMenu);
            app.SetProjectDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SetProjectDirectoryMenuSelected, true);
            app.SetProjectDirectoryMenu.Separator = 'on';
            app.SetProjectDirectoryMenu.Text = 'Set Project Directory...';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Accelerator = 'Q';
            app.QuitMenu.Text = 'Quit';
            app.DataMenu = uimenu(app.RouteEditorUIFigure);
            app.DataMenu.Text = 'Data';
            app.ImportdatafromExcelMenu = uimenu(app.DataMenu);
            app.ImportdatafromExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportdatafromExcelMenuSelected, true);
            app.ImportdatafromExcelMenu.Text = 'Import data from Excel...';
            app.ExportdatatoExcelMenu = uimenu(app.DataMenu);
            app.ExportdatatoExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportdatatoExcelMenuSelected, true);
            app.ExportdatatoExcelMenu.Text = 'Export data to Excel...';
            app.TabGroup = uitabgroup(app.RouteEditorUIFigure);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.RoutenameEditFieldLabel = uilabel(app.StartTab);
            app.RoutenameEditFieldLabel.Text = 'Route name:';
            app.RoutenameEditField = uieditfield(app.StartTab, 'text');
            app.RoutenameEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.RoutenameEditField.Tooltip = {'class'};
            app.PurposeEditFieldLabel = uilabel(app.StartTab);
            app.PurposeEditFieldLabel.Text = 'Purpose:';
            app.PurposeEditField = uieditfield(app.StartTab, 'text');
            app.PurposeEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.PurposeEditField.Tooltip = {'class'};
            app.NotesTextAreaLabel = uilabel(app.StartTab);
            app.NotesTextAreaLabel.Text = 'Notes:';
            app.NotesTextArea = uitextarea(app.StartTab);
            app.NotesTextArea.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.NotesTextArea.Tooltip = {'notes'};
            app.ShogunSimRouteEditorLabel = uilabel(app.StartTab);
            app.ShogunSimRouteEditorLabel.Text = 'ShogunSim - Route Editor';
            app.Image = uiimage(app.StartTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'res', 'RouteIllustration.png');
            app.GeneraldataTab = uitab(app.TabGroup);
            app.GeneraldataTab.Title = 'General data';
            app.NominalLinevoltageEditFieldLabel = uilabel(app.GeneraldataTab);
            app.NominalLinevoltageEditFieldLabel.Text = 'Nominal Line voltage:';
            app.ULNomEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ULNomEditField.Limits = [0 Inf];
            app.ULNomEditField.AllowEmpty = 'on';
            app.ULNomEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ULNomEditField.Value = [];
            app.VoltUnitDropDown = uidropdown(app.GeneraldataTab);
            app.VoltUnitDropDown.Items = {'VDC', 'VAC', 'kVDC', 'kVAC'};
            app.VoltUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltUnitDropDownValueChanged, true);
            app.VoltUnitDropDown.Value = 'VDC';
            app.NominalLinefrequencyEditFieldLabel = uilabel(app.GeneraldataTab);
            app.NominalLinefrequencyEditFieldLabel.Text = 'Nominal Line frequency:';
            app.LFreqEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.LFreqEditField.Limits = [0 Inf];
            app.LFreqEditField.AllowEmpty = 'on';
            app.LFreqEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.LFreqEditField.Value = [];
            app.LinevoltageatmotoringEditFieldLabel = uilabel(app.GeneraldataTab);
            app.LinevoltageatmotoringEditFieldLabel.Text = 'Line voltage at motoring:';
            app.ULMotoringEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ULMotoringEditField.Limits = [0 Inf];
            app.ULMotoringEditField.AllowEmpty = 'on';
            app.ULMotoringEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ULMotoringEditField.Value = [];
            app.LinevoltageatregenerationEditFieldLabel = uilabel(app.GeneraldataTab);
            app.LinevoltageatregenerationEditFieldLabel.Text = 'Line voltage at regeneration:';
            app.ULRegenEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ULRegenEditField.Limits = [0 Inf];
            app.ULRegenEditField.AllowEmpty = 'on';
            app.ULRegenEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ULRegenEditField.Value = [];
            app.RegenerationlimitabsoluteEditFieldLabel = uilabel(app.GeneraldataTab);
            app.RegenerationlimitabsoluteEditFieldLabel.Text = 'Regeneration limit, absolute:';
            app.ILimRegAbsEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ILimRegAbsEditField.Limits = [0 Inf];
            app.ILimRegAbsEditField.AllowEmpty = 'on';
            app.ILimRegAbsEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ILimRegAbsEditField.Value = [];
            app.RegenerationlimitrelativeEditFieldLabel = uilabel(app.GeneraldataTab);
            app.RegenerationlimitrelativeEditFieldLabel.Text = 'Regeneration limit, relative:';
            app.ILimRegRelEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ILimRegRelEditField.Limits = [0 Inf];
            app.ILimRegRelEditField.AllowEmpty = 'on';
            app.ILimRegRelEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ILimRegRelEditField.Value = [];
            app.StartinglocationEditField = uieditfield(app.GeneraldataTab, 'text');
            app.StartinglocationEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.StartpositionlongitudeEditFieldLabel = uilabel(app.GeneraldataTab);
            app.StartpositionlongitudeEditFieldLabel.Text = 'Start position, longitude:';
            app.LongitudeEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.LongitudeEditField.Limits = [-180 360];
            app.LongitudeEditField.ValueDisplayFormat = '%11.4f';
            app.LongitudeEditField.AllowEmpty = 'on';
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            app.LongitudeEditField.Value = [];
            app.StartpositionlatitudeEditFieldLabel = uilabel(app.GeneraldataTab);
            app.StartpositionlatitudeEditFieldLabel.Text = 'Start position, latitude:';
            app.LatitudeEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.LatitudeEditField.Limits = [-180 360];
            app.LatitudeEditField.ValueDisplayFormat = '%11.4f';
            app.LatitudeEditField.AllowEmpty = 'on';
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            app.LatitudeEditField.Value = [];
            app.StartpositionheadingEditFieldLabel = uilabel(app.GeneraldataTab);
            app.StartpositionheadingEditFieldLabel.Text = 'Start position, heading:';
            app.HeadingEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.HeadingEditField.Limits = [-180 360];
            app.HeadingEditField.AllowEmpty = 'on';
            app.HeadingEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.HeadingEditField.Value = [];
            app.TemperatureEditFieldLabel = uilabel(app.GeneraldataTab);
            app.TemperatureEditFieldLabel.Text = 'Temperature:';
            app.TemperatureEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.TemperatureEditField.Limits = [-273 Inf];
            app.TemperatureEditField.AllowEmpty = 'on';
            app.TemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.TemperatureEditField.Value = [];
            app.RelativehumidityEditFieldLabel = uilabel(app.GeneraldataTab);
            app.RelativehumidityEditFieldLabel.Text = 'Relative humidity:';
            app.RelativehumidityEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.RelativehumidityEditField.Limits = [0 100];
            app.RelativehumidityEditField.AllowEmpty = 'on';
            app.RelativehumidityEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.RelativehumidityEditField.Value = [];
            app.HeadwindEditFieldLabel = uilabel(app.GeneraldataTab);
            app.HeadwindEditFieldLabel.Text = 'Head wind:';
            app.HeadwindEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.HeadwindEditField.AllowEmpty = 'on';
            app.HeadwindEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.HeadwindEditField.Value = [];
            app.TrackguageEditFieldLabel = uilabel(app.GeneraldataTab);
            app.TrackguageEditFieldLabel.Text = 'Track guage:';
            app.TrackguageEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.TrackguageEditField.Limits = [1 Inf];
            app.TrackguageEditField.AllowEmpty = 'on';
            app.TrackguageEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.TrackguageEditField.Value = [];
            app.ATOVMaxEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOVMaxEditField.Limits = [0 Inf];
            app.ATOVMaxEditField.AllowEmpty = 'on';
            app.ATOVMaxEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOVMaxEditField.Value = [];
            app.ATOSpdUnitDropDown = uidropdown(app.GeneraldataTab);
            app.ATOSpdUnitDropDown.Items = {'km/h', 'mph', 'm/s'};
            app.ATOSpdUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @ATOSpdUnitDropDownValueChanged, true);
            app.ATOSpdUnitDropDown.Value = 'km/h';
            app.ATOVMgnEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOVMgnEditField.Limits = [0 Inf];
            app.ATOVMgnEditField.AllowEmpty = 'on';
            app.ATOVMgnEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOVMgnEditField.Value = [];
            app.ATOBkrSpdEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOBkrSpdEditField.Limits = [0 Inf];
            app.ATOBkrSpdEditField.AllowEmpty = 'on';
            app.ATOBkrSpdEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOBkrSpdEditField.Value = [];
            app.ATOVCoastStartEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOVCoastStartEditField.Limits = [0 Inf];
            app.ATOVCoastStartEditField.AllowEmpty = 'on';
            app.ATOVCoastStartEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOVCoastStartEditField.Value = [];
            app.ATOVCoastStopEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOVCoastStopEditField.Limits = [0 Inf];
            app.ATOVCoastStopEditField.AllowEmpty = 'on';
            app.ATOVCoastStopEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOVCoastStopEditField.Value = [];
            app.ATOVRampdownV1EditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOVRampdownV1EditField.Limits = [0 Inf];
            app.ATOVRampdownV1EditField.AllowEmpty = 'on';
            app.ATOVRampdownV1EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOVRampdownV1EditField.Value = [];
            app.ATOVRampdownV2EditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOVRampdownV2EditField.Limits = [0 Inf];
            app.ATOVRampdownV2EditField.AllowEmpty = 'on';
            app.ATOVRampdownV2EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOVRampdownV2EditField.Value = [];
            app.ATOTBCLimitEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOTBCLimitEditField.Limits = [0 100];
            app.ATOTBCLimitEditField.AllowEmpty = 'on';
            app.ATOTBCLimitEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOTBCLimitEditField.Value = [];
            app.ATOStartCoastPosEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOStartCoastPosEditField.AllowEmpty = 'on';
            app.ATOStartCoastPosEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOStartCoastPosEditField.Value = [];
            app.ATOStopCoastPosEditField = uieditfield(app.GeneraldataTab, 'numeric');
            app.ATOStopCoastPosEditField.AllowEmpty = 'on';
            app.ATOStopCoastPosEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralChange, true);
            app.ATOStopCoastPosEditField.Value = [];
            app.VoltLabel1 = uilabel(app.GeneraldataTab);
            app.VoltLabel1.Text = 'V';
            app.VoltLabel2 = uilabel(app.GeneraldataTab);
            app.VoltLabel2.Text = 'V';
            app.LinevoltageandcurrentlimitsLabel = uilabel(app.GeneraldataTab);
            app.LinevoltageandcurrentlimitsLabel.Text = 'Line voltage and current limits:';
            app.GeographiclocationLabel = uilabel(app.GeneraldataTab);
            app.GeographiclocationLabel.Text = 'Geographic location:';
            app.HzLabel = uilabel(app.GeneraldataTab);
            app.HzLabel.Text = 'Hz';
            app.ALabel = uilabel(app.GeneraldataTab);
            app.ALabel.Text = 'A';
            app.Label = uilabel(app.GeneraldataTab);
            app.Label.Text = '%';
            app.NameofcityvillageorsimilarLabel = uilabel(app.GeneraldataTab);
            app.NameofcityvillageorsimilarLabel.Text = '(Name of city, village or similar)';
            app.LonitudeLabel = uilabel(app.GeneraldataTab);
            app.LonitudeLabel.Text = 'deg DD';
            app.LatitudeLabel = uilabel(app.GeneraldataTab);
            app.LatitudeLabel.Text = 'deg DD';
            app.degdirectionofnoseatstartpositionLabel = uilabel(app.GeneraldataTab);
            app.degdirectionofnoseatstartpositionLabel.Text = 'deg - direction of nose at start position';
            app.AtmosphericdataLabel = uilabel(app.GeneraldataTab);
            app.AtmosphericdataLabel.Text = 'Atmospheric data:';
            app.CLabel = uilabel(app.GeneraldataTab);
            app.CLabel.Text = '°C';
            app.Label_2 = uilabel(app.GeneraldataTab);
            app.Label_2.Text = '%';
            app.msLabel = uilabel(app.GeneraldataTab);
            app.msLabel.Text = 'm/s';
            app.MiscdataLabel = uilabel(app.GeneraldataTab);
            app.MiscdataLabel.Text = 'Misc data:';
            app.mmLabel = uilabel(app.GeneraldataTab);
            app.mmLabel.Text = 'mm';
            app.ATOoperationLabel = uilabel(app.GeneraldataTab);
            app.ATOoperationLabel.Text = 'ATO operation:';
            app.MaximalspeedEditFieldLabel = uilabel(app.GeneraldataTab);
            app.MaximalspeedEditFieldLabel.Text = 'Maximal speed:';
            app.MargintomaximalspeedEditField_2Label = uilabel(app.GeneraldataTab);
            app.MargintomaximalspeedEditField_2Label.Text = 'Margin to maximal speed:';
            app.SpeedrampdownV1EditFieldLabel = uilabel(app.GeneraldataTab);
            app.SpeedrampdownV1EditFieldLabel.Text = 'Speed ramp down V1:';
            app.SpeedrampdownV2EditFieldLabel = uilabel(app.GeneraldataTab);
            app.SpeedrampdownV2EditFieldLabel.Text = 'Speed ramp down V2:';
            app.MasterControllerlimitEditFieldLabel = uilabel(app.GeneraldataTab);
            app.MasterControllerlimitEditFieldLabel.Text = 'Master Controller limit:';
            app.StartcoastatpositionEditFieldLabel = uilabel(app.GeneraldataTab);
            app.StartcoastatpositionEditFieldLabel.Text = 'Start coast at position:';
            app.StopcoastatpositionEditFieldLabel = uilabel(app.GeneraldataTab);
            app.StopcoastatpositionEditFieldLabel.Text = 'Stop coast at position:';
            app.SpdLabel1 = uilabel(app.GeneraldataTab);
            app.SpdLabel1.Text = 'km/h';
            app.InitialbrakingspeedEditField_2Label = uilabel(app.GeneraldataTab);
            app.InitialbrakingspeedEditField_2Label.Text = 'Initial braking speed:';
            app.SpdLabel2 = uilabel(app.GeneraldataTab);
            app.SpdLabel2.Text = 'km/h';
            app.SpdLabel3 = uilabel(app.GeneraldataTab);
            app.SpdLabel3.Text = 'km/h';
            app.StartcoastabovespeedEditFieldLabel = uilabel(app.GeneraldataTab);
            app.StartcoastabovespeedEditFieldLabel.Text = 'Start coast above speed:';
            app.StopcoastbelowspeedEditFieldLabel = uilabel(app.GeneraldataTab);
            app.StopcoastbelowspeedEditFieldLabel.Text = 'Stop coast below speed:';
            app.SpdLabel4 = uilabel(app.GeneraldataTab);
            app.SpdLabel4.Text = 'km/h';
            app.SpdLabel5 = uilabel(app.GeneraldataTab);
            app.SpdLabel5.Text = 'km/h';
            app.SpdLabel6 = uilabel(app.GeneraldataTab);
            app.SpdLabel6.Text = 'km/h';
            app.Label_3 = uilabel(app.GeneraldataTab);
            app.Label_3.Text = '%';
            app.kmLabel = uilabel(app.GeneraldataTab);
            app.kmLabel.Text = 'km';
            app.kmLabel_2 = uilabel(app.GeneraldataTab);
            app.kmLabel_2.Text = 'km';
            app.StartinglocationEditFieldLabel = uilabel(app.GeneraldataTab);
            app.StartinglocationEditFieldLabel.Text = 'Starting location:';
            app.StationsTab = uitab(app.TabGroup);
            app.StationsTab.Title = 'Stations';
            app.StationTable = uitable(app.StationsTab);
            app.StationTable.ColumnName = {'Dist [km]'; 'Name'; 'Dwell time'; 'Stop pos'; 'Departure time'};
            app.StationTable.RowName = {};
            app.StationTable.ColumnEditable = true;
            app.StationTable.CellEditCallback = createCallbackFcn(app, @StationTableCellEdit, true);
            app.StationTable.SelectionChangedFcn = createCallbackFcn(app, @StationTableSelectionChanged, true);
            app.StationTable.Multiselect = 'off';
            app.AddStationBtn = uibutton(app.StationsTab, 'push');
            app.AddStationBtn.ButtonPushedFcn = createCallbackFcn(app, @AddStationBtnPushed, true);
            app.AddStationBtn.Text = 'Add station';
            app.DelStationBtn = uibutton(app.StationsTab, 'push');
            app.DelStationBtn.ButtonPushedFcn = createCallbackFcn(app, @DelStationBtnPushed, true);
            app.DelStationBtn.Text = 'Delete station';
            app.ImportStationBtn = uibutton(app.StationsTab, 'push');
            app.ImportStationBtn.ButtonPushedFcn = createCallbackFcn(app, @ImportStationBtnPushed, true);
            app.ImportStationBtn.Text = 'Import data';
            app.ExportStationBtn = uibutton(app.StationsTab, 'push');
            app.ExportStationBtn.ButtonPushedFcn = createCallbackFcn(app, @ExportStationBtnPushed, true);
            app.ExportStationBtn.Text = 'Export data';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.ParameterTable = uitable(app.ParametersTab);
            app.ParameterTable.ColumnName = {'Dist [km]'};
            app.ParameterTable.RowName = {};
            app.ParameterTable.ColumnEditable = true;
            app.ParameterTable.CellEditCallback = createCallbackFcn(app, @ParameterTableCellEdit, true);
            app.ParameterTable.SelectionChangedFcn = createCallbackFcn(app, @ParameterTableSelectionChanged, true);
            app.ParameterTable.Multiselect = 'off';
            app.AddParaBtn = uibutton(app.ParametersTab, 'push');
            app.AddParaBtn.ButtonPushedFcn = createCallbackFcn(app, @AddParaBtnPushed, true);
            app.AddParaBtn.Text = 'Add parameter';
            app.DelParaBtn = uibutton(app.ParametersTab, 'push');
            app.DelParaBtn.ButtonPushedFcn = createCallbackFcn(app, @DelParaBtnPushed, true);
            app.DelParaBtn.Text = 'Delete parameter';
            app.AddParaRowBtn = uibutton(app.ParametersTab, 'push');
            app.AddParaRowBtn.ButtonPushedFcn = createCallbackFcn(app, @AddParaRowBtnPushed, true);
            app.AddParaRowBtn.Text = 'Add row';
            app.DelParaRowBtn = uibutton(app.ParametersTab, 'push');
            app.DelParaRowBtn.ButtonPushedFcn = createCallbackFcn(app, @DelParaRowBtnPushed, true);
            app.DelParaRowBtn.Text = 'Delete row';
            app.ImportParaBtn = uibutton(app.ParametersTab, 'push');
            app.ImportParaBtn.ButtonPushedFcn = createCallbackFcn(app, @ImportParaBtnButtonPushed, true);
            app.ImportParaBtn.Text = 'Import data';
            app.ExportParaBtn = uibutton(app.ParametersTab, 'push');
            app.ExportParaBtn.ButtonPushedFcn = createCallbackFcn(app, @ExportParaBtnButtonPushed, true);
            app.ExportParaBtn.Text = 'Export data';
            app.SpeedlimitsTab = uitab(app.TabGroup);
            app.SpeedlimitsTab.Title = 'Speed limits';
            app.SpeedAxes = uiaxes(app.SpeedlimitsTab);
            title(app.SpeedAxes, 'Speed limits [km/h]')
            xlabel(app.SpeedAxes, 'Distance [km]')
            ylabel(app.SpeedAxes, 'Speed limit [km/h]')
            zlabel(app.SpeedAxes, 'Z')
            app.SpeedTable = uitable(app.SpeedlimitsTab);
            app.SpeedTable.ColumnName = {'Dist [km]'; 'Speed [km/h]'; 'Comment'};
            app.SpeedTable.RowName = {};
            app.SpeedTable.ColumnEditable = true;
            app.SpeedTable.CellEditCallback = createCallbackFcn(app, @SpeedTableCellEdit, true);
            app.SpeedTable.SelectionChangedFcn = createCallbackFcn(app, @SpeedTableSelectionChanged, true);
            app.AddSpeedBtn = uibutton(app.SpeedlimitsTab, 'push');
            app.AddSpeedBtn.ButtonPushedFcn = createCallbackFcn(app, @AddSpeedBtnPushed, true);
            app.AddSpeedBtn.Text = 'Add row';
            app.DelSpeedBtn = uibutton(app.SpeedlimitsTab, 'push');
            app.DelSpeedBtn.ButtonPushedFcn = createCallbackFcn(app, @DelSpeedBtnPushed, true);
            app.DelSpeedBtn.Text = 'Delete row';
            app.ImportSpeedBtn = uibutton(app.SpeedlimitsTab, 'push');
            app.ImportSpeedBtn.ButtonPushedFcn = createCallbackFcn(app, @ImportSpeedBtnButtonPushed, true);
            app.ImportSpeedBtn.Text = 'Import data';
            app.ExportSpeedBtn = uibutton(app.SpeedlimitsTab, 'push');
            app.ExportSpeedBtn.ButtonPushedFcn = createCallbackFcn(app, @ExportSpeedBtnButtonPushed, true);
            app.ExportSpeedBtn.Text = 'Export data';
            app.TopologyTab = uitab(app.TabGroup);
            app.TopologyTab.Title = 'Topology';
            app.GradientAxes = uiaxes(app.TopologyTab);
            title(app.GradientAxes, 'Route Topology')
            xlabel(app.GradientAxes, 'Distance [km]')
            ylabel(app.GradientAxes, 'Level [m]')
            zlabel(app.GradientAxes, 'Z')
            app.GradientTable = uitable(app.TopologyTab);
            app.GradientTable.ColumnName = {'Dist [km]'; 'Slope [‰]'; 'Level [m]'; 'Comment'};
            app.GradientTable.RowName = {};
            app.GradientTable.ColumnEditable = true;
            app.GradientTable.CellEditCallback = createCallbackFcn(app, @GradientTableCellEdit, true);
            app.GradientTable.SelectionChangedFcn = createCallbackFcn(app, @GradientTableSelectionChanged, true);
            app.GradientTable.Multiselect = 'off';
            app.AddSlopeBtn = uibutton(app.TopologyTab, 'push');
            app.AddSlopeBtn.ButtonPushedFcn = createCallbackFcn(app, @AddSlopeBtnPushed, true);
            app.AddSlopeBtn.Text = 'Add row';
            app.DelSlopeBtn = uibutton(app.TopologyTab, 'push');
            app.DelSlopeBtn.ButtonPushedFcn = createCallbackFcn(app, @DelSlopeBtnPushed, true);
            app.DelSlopeBtn.Text = 'Delete row';
            app.ImportSlopeBtn = uibutton(app.TopologyTab, 'push');
            app.ImportSlopeBtn.ButtonPushedFcn = createCallbackFcn(app, @ImportSlopeBtnButtonPushed, true);
            app.ImportSlopeBtn.Text = 'Import data';
            app.ExportSlopeBtn = uibutton(app.TopologyTab, 'push');
            app.ExportSlopeBtn.ButtonPushedFcn = createCallbackFcn(app, @ExportSlopeBtnButtonPushed, true);
            app.ExportSlopeBtn.Text = 'Export data';
            app.CurvatureTab = uitab(app.TabGroup);
            app.CurvatureTab.Title = 'Curvature';
            app.CurveAxis = uiaxes(app.CurvatureTab);
            title(app.CurveAxis, 'Curve Gradients')
            xlabel(app.CurveAxis, 'Distance [km]')
            ylabel(app.CurveAxis, 'Curvature [rad/km]')
            zlabel(app.CurveAxis, 'Z')
            app.CurveTable = uitable(app.CurvatureTab);
            app.CurveTable.ColumnName = {'Dist [km]'; 'Radiu [m]'; 'Cant [mm]'; 'Opt Spd'; 'Curve name'};
            app.CurveTable.RowName = {};
            app.CurveTable.ColumnEditable = [true true true false true];
            app.CurveTable.CellEditCallback = createCallbackFcn(app, @CurveTableCellEdit, true);
            app.CurveTable.SelectionChangedFcn = createCallbackFcn(app, @CurveTableSelectionChanged, true);
            app.AddCurveBtn = uibutton(app.CurvatureTab, 'push');
            app.AddCurveBtn.ButtonPushedFcn = createCallbackFcn(app, @AddCurveBtnPushed, true);
            app.AddCurveBtn.Text = 'Add row';
            app.DelCurveBtn = uibutton(app.CurvatureTab, 'push');
            app.DelCurveBtn.ButtonPushedFcn = createCallbackFcn(app, @DelCurveBtnPushed, true);
            app.DelCurveBtn.Text = 'Delete row';
            app.ImportCurvesBtn = uibutton(app.CurvatureTab, 'push');
            app.ImportCurvesBtn.ButtonPushedFcn = createCallbackFcn(app, @ImportCurvesBtnButtonPushed, true);
            app.ImportCurvesBtn.Text = 'Import data';
            app.ExportCurvesBtn = uibutton(app.CurvatureTab, 'push');
            app.ExportCurvesBtn.ButtonPushedFcn = createCallbackFcn(app, @ExportCurvesBtnButtonPushed, true);
            app.ExportCurvesBtn.Text = 'Export data';
            app.TunnelsTab = uitab(app.TabGroup);
            app.TunnelsTab.Title = 'Tunnels';
            app.TunnelAxis = uiaxes(app.TunnelsTab);
            title(app.TunnelAxis, 'Tunnels')
            xlabel(app.TunnelAxis, 'Distance [km]')
            ylabel(app.TunnelAxis, 'Level [m]')
            zlabel(app.TunnelAxis, 'Z')
            app.TunnelTable = uitable(app.TunnelsTab);
            app.TunnelTable.ColumnName = {'Dist [km]'; 'Len [km]'; 'C factor'; 'Tunnel Name'};
            app.TunnelTable.RowName = {};
            app.TunnelTable.ColumnEditable = [true true true false];
            app.TunnelTable.CellEditCallback = createCallbackFcn(app, @TunnelTableCellEdit, true);
            app.TunnelTable.SelectionChangedFcn = createCallbackFcn(app, @TunnelTableSelectionChanged, true);
            app.AddTunnelBtn = uibutton(app.TunnelsTab, 'push');
            app.AddTunnelBtn.ButtonPushedFcn = createCallbackFcn(app, @AddTunnelBtnButtonPushed, true);
            app.AddTunnelBtn.Text = 'Add row';
            app.DelTunnelBtn = uibutton(app.TunnelsTab, 'push');
            app.DelTunnelBtn.ButtonPushedFcn = createCallbackFcn(app, @DelTunnelBtnButtonPushed, true);
            app.DelTunnelBtn.Text = 'Delete row';
            app.ImportTunnelBtn = uibutton(app.TunnelsTab, 'push');
            app.ImportTunnelBtn.ButtonPushedFcn = createCallbackFcn(app, @ImportTunnelBtnButtonPushed, true);
            app.ImportTunnelBtn.Text = 'Import data';
            app.ExportTunnelBtn = uibutton(app.TunnelsTab, 'push');
            app.ExportTunnelBtn.ButtonPushedFcn = createCallbackFcn(app, @ExportTunnelBtnButtonPushed, true);
            app.ExportTunnelBtn.Text = 'Export data';
            app.GeographicTab = uitab(app.TabGroup);
            app.GeographicTab.Title = 'Geographic';
            app.UIAxes = uiaxes(app.GeographicTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.RouteEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RouteEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RouteEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RouteEditorUIFigure)
        end
    end
end