classdef ShogunCockpit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShogunCockpitUIFigure           matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewSimulationsMenu              matlab.ui.container.Menu
        OpenSimulationsMenu             matlab.ui.container.Menu
        SaveSimulationsMenu             matlab.ui.container.Menu
        SaveSimulationsasMenu           matlab.ui.container.Menu
        SelectProjectfolderMenu         matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        ReportMenu                      matlab.ui.container.Menu
        CreateSimulationreportMenu      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SimulationsTab                  matlab.ui.container.Tab
        DeleteSimulationButton          matlab.ui.control.Button
        NewSimulationButton             matlab.ui.control.Button
        SkiptimetableCheckBox           matlab.ui.control.CheckBox
        CalculateButton                 matlab.ui.control.Button
        OptimisedCheckBox               matlab.ui.control.CheckBox
        SimulationTable                 matlab.ui.control.Table
        VehiclesTab                     matlab.ui.container.Tab
        UnlockVehicleFileButton         matlab.ui.control.Button
        VehicleEditorButton             matlab.ui.control.Button
        VehicleTable                    matlab.ui.control.Table
        RoutesTab                       matlab.ui.container.Tab
        UnlockRouteFileButton           matlab.ui.control.Button
        RouteEditorButton               matlab.ui.control.Button
        RouteTable                      matlab.ui.control.Table
        ResultsTab                      matlab.ui.container.Tab
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        YaxisrightDropDown              matlab.ui.control.DropDown
        YaxisrightDropDownLabel         matlab.ui.control.Label
        YaxisleftDropDown               matlab.ui.control.DropDown
        YaxisleftDropDownLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        SettingsTab                     matlab.ui.container.Tab
        UsedynamicthermalpowerCheckBox  matlab.ui.control.CheckBox
        ImporteddatarepresentsButtonGroup  matlab.ui.container.ButtonGroup
        AxleButton                      matlab.ui.control.RadioButton
        InverterButton                  matlab.ui.control.RadioButton
        HVSystemButton                  matlab.ui.control.RadioButton
        UnitButton                      matlab.ui.control.RadioButton
        ConsistButton                   matlab.ui.control.RadioButton
        PlotaveragevaluesFVICheckBox    matlab.ui.control.CheckBox
        UseRoutedataforlinesupplyCheckBox  matlab.ui.control.CheckBox
        PlotalternativevehiclesimulationasButtonGroup  matlab.ui.container.ButtonGroup
        AlternativeButton_2             matlab.ui.control.RadioButton
        BackgroundButton_2              matlab.ui.control.RadioButton
        ForegroundButton_2              matlab.ui.control.RadioButton
        OffButton_2                     matlab.ui.control.RadioButton
        PlotoriginalvehiclesimulationasButtonGroup  matlab.ui.container.ButtonGroup
        AlternativeButton               matlab.ui.control.RadioButton
        BackgroundButton                matlab.ui.control.RadioButton
        ForegroundButton                matlab.ui.control.RadioButton
        OffButton                       matlab.ui.control.RadioButton
        sLabel_3                        matlab.ui.control.Label
        ReferencepointindisplayofresultsButtonGroup  matlab.ui.container.ButtonGroup
        RearButton                      matlab.ui.control.RadioButton
        CentreButton                    matlab.ui.control.RadioButton
        FrontButton                     matlab.ui.control.RadioButton
        MinimumcoastingtimeEditField    matlab.ui.control.EditField
        MinimumcoastingtimeEditFieldLabel  matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        TimeoutinthecalculationEditField  matlab.ui.control.EditField
        TimeoutinthecalculationEditFieldLabel  matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        MaxoperationtimeEditField       matlab.ui.control.EditField
        MaxoperationtimeEditFieldLabel  matlab.ui.control.Label
        DeltaTinthecalculationEditField  matlab.ui.control.EditField
        DeltaTinthecalculationEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Constant)
        app_version = '0.10' % version of the app
        
    end

    properties (Access = private)
        settings_file % Description
        working_directory % Description
        sim_config % Description
        CurrentFile % saved file name including full path.
        Dirty % Means that user made changes that has not been saved
        yllabel = 'Speed' %
        yrlabel = '' %
        xxlabel = 'Distance' %
        Result % Description
        Vehicle % vehicle used in last calculation
    end
    
    methods (Static)
        function VehicleDataHasChanged(app)
            UpdateVehicleTable(app);
        end

        function RouteDataHasChanged(app)
            UpdateRouteTable(app);
        end
    end

    methods (Access = private)
        
        function data2layout(app, data)
            app.SimulationTable.Data = data;
        end % data2layout

        function data = layout2data(app)
            data = app.SimulationTable.Data;
        end % layout2data

        function UpdateVehicleTable(app)
            vehicles = {};
            if ~isempty(app)
                if exist(app.working_directory, 'dir')
                    cd(app.working_directory)
                    if exist('Vehicles', 'dir')
                        cd('Vehicles');
                        content = dir('*.mat');
                        for i1 = 1:size(content, 1)
                            if strcmp(who('-file', content(i1).name), 'vehicle_data')
                                [~, f] = fileparts(content(i1).name);
                                [~, attr] = fileattrib(content(i1).name);
                                load(content(i1).name, 'vehicle_data');
                                if ~attr.UserWrite
                                    f = [f, ' [L]']; %#ok<AGROW>
                                end
                                vehicles = [vehicles; {f, vehicle_data.class, vehicle_data.Purpose, ''}]; %#ok<AGROW>
                            end
                        end
                        app.VehicleTable.Data = vehicles;
                    end
                end
                cd(fileparts(mfilename('fullpath')));
                VehicleTableSelectionChanged(app, [])
            end
        end % UpdateVehicleTable

        function UpdateRouteTable(app)
            routes = {};
            if ~isempty(app)
                if exist(app.working_directory, 'dir')
                    cd(app.working_directory)
                    if exist('Routes', 'dir')
                        cd('Routes');
                        content = dir('*.mat');
                        for i1 = 1:size(content, 1)
                            if strcmp(who('-file', content(i1).name), 'route_data')
                                [~, f] = fileparts(content(i1).name);
                                [~, attr] = fileattrib(content(i1).name);
                                load(content(i1).name, 'route_data');
                                if ~attr.UserWrite
                                    f = [f, ' [L]']; %#ok<AGROW>
                                end
                                routes = [routes; {f, route_data.name, route_data.purpose, ''}]; %#ok<AGROW>
                            end
                        end
                        app.RouteTable.Data = routes;
                    end
                end
                cd(fileparts(mfilename('fullpath')))
                RouteTableSelectionChanged(app, []);
            end
        end % UpdateRouteTable

        function UpdateTables(app)
            data = app.SimulationTable.Data;
            i1 = 1;
            while i1 <= size(data, 1)
                f = strrep(data{i1, 2}, ' [L]', '');
                vcl_file = fullfile(app.working_directory, 'Vehicles', [f, '.mat']);
                if exist(vcl_file, 'file')
                    [~, attr] = fileattrib(vcl_file);
                    if ~attr.UserWrite
                        f = [f, ' [L]']; %#ok<AGROW>
                    end
                    data{i1, 2} = f;
                else % remove line and continue
                    data = [data(1:i1 - 1); data(i1 + 1:end)];
                    continue
                end
                f = strrep(data{i1, 3}, ' [L]', '');
                rte_file = fullfile(app.working_directory, 'Routes', [f, '.mat']);
                if exist(rte_file, 'file')
                    [~, attr] = fileattrib(rte_file);
                    if ~attr.UserWrite
                        f = [f, ' [L]']; %#ok<AGROW>
                    end
                    data{i1, 3} = f;
                else % remove line and continue
                    data = [data(1:i1 - 1); data(i1 + 1:end)];
                    continue
                end
                i1 = i1 + 1;
            end
            app.SimulationTable.Data = data;
            UpdateVehicleTable(app);
            UpdateRouteTable(app);
        end % UpdateTables

        function UpdateGraphs(app)
            if ~isempty(app.Result)
                data = [];
                for i1 = 1:size(app.Result, 2) - 1
                    data = [data; app.Result(i1).timedata]; %#ok<AGROW>
                end
                switch app.XaxisDropDown.ValueIndex
                    case 1 % Distance
                        xvalues = data(:, 2) / 1000;
                    case 2 % Time
                        xvalues = data(:, 1);
                    case 3 % Speed
                        xvalues = data(:, 3) * 3.6;
                    case 4 % Motor speed rpm
                        xvalues = data(:, 3) * 3.6;
                    case 5 % Motor speed Hz
                        xvalues = data(:, 3) * 3.6;
                end
                switch app.YaxisleftDropDown.ValueIndex
                    case 1 % Speed
                        yvalues = data(:, 3) * 3.6;
                    case 2 % Effort
                        yvalues(:, 1) = data(:, 4);
                        yvalues(:, 2) = data(:, 5);
                    case 3 % Line voltage
                        yvalues = data(:, 6);
                    case 4 % Line current
                        yvalues = data(:, 7);
                    case 5 % Line power
                        yvalues = data(:, 6) .* data(:, 7) / 1000;
                    case 6 % rail power
                        yvalues = data(:, 3) .* data(:, 4);
                    case 7 % Acceleration
                        yvalues = [0; diff(data(:, 3)) ./ diff(data(:, 1))];
                    case 8 % Distance
                        yvalues = data(:, 2) / 1000;
                    case 9 % BR temp
                        yvalues = data(:, 9);
                    case 10 % Thermal HV
                        if app.Vehicle.uFreq > 0 % AC system
                            yvalues(:, 1) = data(:, 15) / app.Vehicle.IPrimTrafoCont * 100;
                            yvalues(:, 2) = data(:, 16) / app.Vehicle.I2ndTrafoCont * 100;
                            yvalues(:, 3) = data(:, 19) / app.Vehicle.IphLCMCont * 100;
                        else
                            yvalues = data(:, 16) / app.Vehicle.IDCFilterCont * 100;
                        end
                    case 11 % Thermal traction
                        yvalues(:, 1) = data(:, 19) / app.Vehicle.IphMCMContHex * 100;
                        yvalues(:, 2) = data(:, 20) / app.Vehicle.IstatTMCont * 100;
                    case 12 % Performance reduction
                        yvalues(:, 1) = data(:, 21) * 100;
                        yvalues(:, 2) = data(:, 22) * 100;
                    case 13 % Energy
                        dt = diff(data(:, 1));
                        %Line power => energy
                        yvalues(:, 1) = [0; cumsum(data(2:end, 29) .* data(2:end, 6) / 3600000 .* dt)];
                        % Rail power => energy
                        yvalues(:, 2) = [0; cumsum(data(2:end, 27) .* data(2:end, 26) / 3600 .* dt)];
                        % Potential energy
                        yvalues(:, 3) = (app.Vehicle.totMass * data(:, 3).^2) / 7200;
                    case 14 % Efficiency
                        pRail = data(:, 26) .* data(:, 27);         % kW, average speed times average effort
                        pLine = data(:, 6) .* data(:, 29) / 1000;   % kW, line voltage times average line current
                        b = find((pRail - app.Vehicle.AuxPower) > 0.1 * max(pRail));
                        yvalues(:, 1) = (pRail(b) + app.Vehicle.AuxPower) ./ pLine(b) * 100;
                        b = find((pRail - app.Vehicle.AuxPower) < 0.1 * min(pRail));
                        yvalues(:, 2) = (pRail(b) + app.Vehicle.AuxPower) ./ pLine(b) * 100;
                    case 15 % Notch
                        yvalues = data(:, 31);
                end
                switch app.YaxisrightDropDown.ValueIndex
                    case 1 % None
                        yvalues_right = [];
                    case 2 % Speed
                        yvalues_right = data(:, 3) * 3.6;
                    case 3 % Effort
                        yvalues_right(:, 1) = data(:, 4);
                        yvalues_right(:, 2) = data(:, 5);
                    case 4 % Line voltage
                        yvalues_right = data(:, 6);
                    case 5 % Line current
                        yvalues_right = data(:, 7);
                    case 6 % Line power
                        yvalues_right = data(:, 6) .* data(:, 7) / 1000;
                    case 7 % rail power
                        yvalues_right = data(:, 3) .* data(:, 4);
                    case 8 % Acceleration
                        yvalues_right = [0; diff(data(:, 3)) ./ diff(data(:, 1))];
                    case 9 % Distance
                        yvalues_right = data(:, 2) / 1000;
                    case 10 % BR temp
                        yvalues_right = data(:, 9);
                    case 11 % Thermal HV
                        if app.Vehicle.uFreq > 0 % AC system
                            yvalues_right(:, 1) = data(:, 15) / app.Vehicle.IPrimTrafoCont * 100;
                            yvalues_right(:, 2) = data(:, 16) / app.Vehicle.I2ndTrafoCont * 100;
                            yvalues_right(:, 3) = data(:, 19) / app.Vehicle.IphLCMCont * 100;
                        else
                            yvalues_right = data(:, 16) / app.Vehicle.IDCFilterCont * 100;
                        end
                    case 12 % Thermal traction
                        yvalues_right(:, 1) = data(:, 19) / app.Vehicle.IphMCMContHex * 100;
                        yvalues_right(:, 2) = data(:, 20) / app.Vehicle.IstatTMCont * 100;
                    case 13 % Performance reduction
                        yvalues_right(:, 1) = data(:, 21) * 100;
                        yvalues_right(:, 2) = data(:, 22) * 100;
                    case 14 % Energy
                        dt = diff(data(:, 1));
                        %Line power => energy
                        yvalues_right(:, 1) = [0; cumsum(data(2:end, 29) .* data(2:end, 6) / 3600000 .* dt)];
                        % Rail power => energy
                        yvalues_right(:, 2) = [0; cumsum(data(2:end, 27) .* data(2:end, 26) / 3600 .* dt)];
                        % Potential energy
                        yvalues_right(:, 3) = (vehicle.totMass * data(:, 3).^2) / 7200;
                    case 15 % Efficiency
                        pRail = data(:, 26) .* data(:, 27);         % kW, average speed times average effort
                        pLine = data(:, 6) .* data(:, 29) / 1000;   % kW, line voltage times average line current
                        b = find((pRail - app.Vehicle.AuxPower) > 0.1 * max(pRail));
                        yvalues_right(:, 1) = (pRail(b) + app.Vehicle.AuxPower) ./ pLine(b) * 100;
                        b = find((pRail - app.Vehicle.AuxPower) < 0.1 * min(pRail));
                        yvalues_right(:, 2) = (pRail(b) + app.Vehicle.AuxPower) ./ pLine(b) * 100;
                    case 16 % Notch
                        yvalues_right = data(:, 31);
                end
                hold(app.UIAxes, 'off');
                plot(app.UIAxes, xvalues, yvalues);
                if ~isempty(yvalues_right)
                    hold(app.UIAxes, 'on');
                    yyaxis(app.UIAxes, 'right');
                    plot(app.UIAxes, xvalues, yvalues_right)
                    yyaxis(app.UIAxes, 'left');
                end
                grid(app.UIAxes, 'on');
            end
        end
        
        function pickVehicle(app, row)
            list = app.VehicleTable.Data(:, 1);
            sel = listdlg('PromptString', 'Select vehicle', 'ListString', list, 'SelectionMode', 'single');
            if ~isempty(sel)
                app.SimulationTable.Data{row, 2} = list{sel};
            end
        end % pickVehicle

        function pickRoute(app, row)
            list = app.RouteTable.Data(:, 1);
            sel = listdlg('PromptString', 'Select route', 'ListString', list, 'SelectionMode', 'single');
            if ~isempty(sel)
                app.SimulationTable.Data{row, 3} = list{sel};
            end
        end % pickRoute
        
        function NewSimulation(app)
            data = app.SimulationTable.Data;
            newRow = {};
            vehicles = app.VehicleTable.Data;
            routes = app.RouteTable.Data;
            for i1 = 1:size(vehicles, 1)
                for i2 = 1:size(routes, 1)
                    found = false;
                    for i3 = 1:size(data, 1)
                        if strcmp(data{i3, 2}, vehicles{i1, 1}) && strcmp(data{i3, 3}, routes{i2, 1})
                            found = true;
                            break
                        end
                    end
                    if ~found
                        newRow = {'ID', vehicles{i1, 1}, routes{i2, 1}, ''};
                        break
                    end
                end
                if ~isempty(newRow)
                    break
                end
            end
            app.SimulationTable.Data = [app.SimulationTable.Data; newRow];
        end % NewSimulation

        function DeleteCurrentRow(app)
            Sel = app.SimulationTable.Selection;
            if ~isempty(Sel)
                row = Sel(1);
                data = app.SimulationTable.Data;
                data = [data(1:row - 1, :); data(row + 1:end, :)];
                app.SimulationTable.Data = data;
            end
        end % DeleteCurrentRow
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ShogunCockpitUIFigure.Visible = "off";
            cd(getenv('APPDATA'));
            if ~exist('ShogunSim_Settings','dir')
                mkdir('ShogunSim_Settings');
            end
            cd('ShogunSim_Settings')
            if ~exist('Shogun_Settings.mat','file')
                Shogun_Settings.workingdirectory = [];
                Shogun_Settings.CockpitPosition = app.ShogunCockpitUIFigure.Position;
                save('Shogun_Settings.mat','Shogun_Settings');
            end
            load('Shogun_Settings.mat','Shogun_Settings');
            cd(fileparts(mfilename('fullpath')));
            addpath('code');
            app.settings_file = fullfile(pwd, 'Shogun_Settings.mat');
            if exist(Shogun_Settings.workingdirectory, 'dir')
                app.working_directory = Shogun_Settings.workingdirectory;
                UpdateTables(app);
            else
                app.working_directory = [];
            end
            if isfield(Shogun_Settings, 'CockpitPosition')
                r = MoveIntoScreen(Shogun_Settings.CockpitPosition);
                app.ShogunCockpitUIFigure.Position = r;
            end
            if isfield(Shogun_Settings, 'sim_config') && 0
                if ~isfield(Shogun_Settings.sim_config, 'delta_T')
                    Shogun_Settings.sim_config.delta_T = 0.1;
                end
                if ~isfield(Shogun_Settings.sim_config, 'maxT')
                    Shogun_Settings.sim_config.maxT = 3600;
                end
                
                app.sim_config = Shogun_Settings.sim_config;
            else
                app.sim_config.plotRef = 2;
                app.sim_config.delta_T = 0.1;
                app.sim_config.maxT = 3600;
                app.sim_config.time_out = inf;
            end
            app.CurrentFile = [];
            app.Dirty = 0;
            app.yllabel = 'Speed';
            app.yrlabel = '';
            app.xxlabel = 'Distance';
            app.ShogunCockpitUIFigure.Visible = "on";
        end

        % Close request function: ShogunCockpitUIFigure
        function ShogunCockpitUIFigureCloseRequest(app, event)
            if exist(app.settings_file, 'file')
                load(app.settings_file, 'Shogun_Settings');
                Shogun_Settings.sim_config = app.sim_config;
                Shogun_Settings.CockpitPosition = app.ShogunCockpitUIFigure.Position;
                save(app.settings_file, 'Shogun_Settings');
            end
            delete(app)
        end

        % Menu selected function: OpenSimulationsMenu
        function OpenSimulationsMenuSelected(app, event)
            if app.Dirty
                answ = questdlg('Save changes before open?', event.Source.Tag, 'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ, 'Cancel')
                    figure(app.ShogunCockpitUIFigure);
                    return
                elseif strcmp(answ, 'Yes')
                    SaveSimulationsMenuSelected(app, event)
                end
            end
            cd(app.working_directory)
            if exist('Simulations', 'dir')
                cd('Simulations');
            end
            [f, p] = uigetfile('*.mat', 'Open simulation file...');
            cd(fileparts(mfilename('fullpath')));
            if f
                app.CurrentFile = fullfile(p, f);
                [~, attr] = fileattrib(app.CurrentFile);
                load(app.CurrentFile, 'SimulationData');
                data2layout(app, SimulationData);
                CleanDirty(app);
                app.isReadOnly = ~attr.UserWrite;
            end
            pause(1);
            figure(app.ShogunCockpitUIFigure);
        end

        % Menu selected function: SaveSimulationsMenu
        function SaveSimulationsMenuSelected(app, event)
            if ~isempty(app.CurrentFile) && exist(app.CurrentFile, 'file')
                SimulationData = layout2data(app);
                save(app.CurrentFile, 'SimulationData');
                CleanDirty(app);
                if isvalid(app.caller)
                    app.caller.RouteDataHasChanged(app.caller)
                end
            else
                app.CurrentFile = [];
                SaveasMenuSelected(app, event);
            end

        end

        % Menu selected function: SaveSimulationsasMenu
        function SaveSimulationsasMenuSelected(app, event)
            cd(app.working_directory);
            if exist('Simulations', 'dir')
                cd('Simulations');
            end
            [f, p] = uigetfile('*.mat', 'Save current experiement as...');
            cd(fileparts(mfilename('fullpath')));
            if f
                app.CurrentFile = fullfile(p, f);
                SimulationData = layout2data(app);
                save(app.CurrentFile, 'SimulationData');
            end
            figure(app.ShogunCockpitUIFigure);
        end

        % Button pushed function: NewSimulationButton
        function NewSimulationButtonPushed(app, event)
            NewSimulation(app);
        end

        % Button pushed function: DeleteSimulationButton
        function DeleteSimulationButtonPushed(app, event)
            
            DeleteCurrentRow(app);
        end

        % Button pushed function: VehicleEditorButton
        function VehicleEditorButtonPushed(app, event)
            sel = app.VehicleTable.Selection;
            if ~isempty(sel)
                file = strrep(app.VehicleTable.Data{sel(1), 1}, ' [L]', ''); % file name
                file = fullfile(app.working_directory, 'Vehicles', [file, '.mat']);
            else
                file = [];
            end
            vehicleEditorApp = VehicleEditor; % start the app
            vehicleEditorApp.launch(app, vehicleEditorApp, file)
        end

        % Button pushed function: UnlockVehicleFileButton
        function UnlockVehicleFileButtonPushed(app, event)
            sel = app.VehicleTable.Selection;
            if ~isempty(sel)
                file = app.VehicleTable.Data{sel(1), 1}; % file name
                if length(file) > 5 && strcmp(file(end - 3:end), ' [L]')
                    f = file(1:end - 4);
                    file = fullfile(app.working_directory, 'Vehicles', [f, '.mat']);
                    fileattrib(file, '+w'); % set writeable
                    app.VehicleTable.Data{sel(1), 1} = f;
                end
            end
            UpdateTables(app);
        end

        % Button pushed function: RouteEditorButton
        function RouteEditorButtonPushed(app, event)
            sel = app.RouteTable.Selection;
            if ~isempty(sel)
                file = strrep(app.RouteTable.Data{sel(1), 1}, ' [L]', ''); % file name
                file = fullfile(app.working_directory, 'Routes', [file, '.mat']);
            else
                file = [];
            end
            RouteEditorApp = RouteEditor; % start the app
            RouteEditorApp.launch(app, RouteEditorApp, file)

        end

        % Button pushed function: UnlockRouteFileButton
        function UnlockRouteFileButtonPushed(app, event)
            sel = app.RouteTable.Selection;
            if ~isempty(sel)
                file = app.RouteTable.Data{sel(1), 1}; % file name
                if length(file) > 5 && strcmp(file(end - 3:end), ' [L]')
                    f = file(1:end - 4);
                    file = fullfile(app.working_directory, 'Routes', [f, '.mat']);
                    fileattrib(file, '+w'); % set writeable
                    app.RouteTable.Data{sel(1), 1} = f;
                end
            end
            UpdateTables(app);
        end

        % Menu selected function: SelectProjectfolderMenu
        function SelectProjectfolderMenuSelected(app, event)
            if exist(app.working_directory, 'dir')
                cd(app.working_directory)
            end
            p = uigetdir('Select new working directory');
            if p
                cd(p);
                if exist('Vehicles', 'dir') && exist('Routes', 'dir') && exist('Simulations', 'dir')
                    app.working_directory = p;
                    load(app.settings_file, 'Shogun_Settings');
                    Shogun_Settings.workingdirectory = app.working_directory;
                    Shogun_Settings.CockpitPosition = app.ShogunCockpitUIFigure.Position;
                    save(app.settings_file, 'Shogun_Settings');
                    UpdateTables(app);
                end
            end
        end

        % Cell edit callback: SimulationTable
        function SimulationTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            sel = app.SimulationTable.Selection;
            if ~isempty(sel)
                vcl = strrep(app.SimulationTable.Data{sel(1), 2}, ' [L]', '');
                vehicle = fullfile(app.working_directory, 'Vehicles', [vcl, '.mat']);
                rte = strrep(app.SimulationTable.Data{sel(1), 3}, ' [L]', '');
                route = fullfile(app.working_directory, 'Routes', [rte, '.mat']);
                if exist(vehicle, 'file') && exist(route, 'file')
                    data.vehicle_file = vehicle;
                    data.track_file = route;
                    result = ShSim_Calc(data, app.sim_config);
                    app.Result = result;
                    load(data.vehicle_file, 'vehicle_data')
                    vehicle = ShSim_calcVehicle(vehicle_data);
                    app.Vehicle = ShSim_CalcMassRes(vehicle);
                    UpdateGraphs(app);
                    UpdateTables(app);
                end
            end
        end

        % Window key press function: ShogunCockpitUIFigure
        function ShogunCockpitUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(event.Modifier, 'control')
                needFocus = 0;
                switch key
                    case 'pagedown'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab), size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);
                        needFocus = 1;
                    case 'pageup'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab) - 2, size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);
                        needFocus = 1;
                end
                if needFocus
                    for i1 = size(tabs(idx).Children,1):-1:1
                        if any(matches({'uinumericeditfield', 'uieditfield', 'uitable', 'uibutton'}, tabs(idx).Children(i1).Type))
                                focus(tabs(idx).Children(i1))
                                break
                        end
                    end
                end
            end            
        end

        % Value changed function: XaxisDropDown, YaxisleftDropDown, 
        % ...and 1 other component
        function AxisDropDownValueChanged(app, event)
            Sel = event.Source.ValueIndex;
            if event.Source == app.YaxisrightDropDown
                yyaxis(app.UIAxes, 'right');
                switch Sel
                    case 1 % None
                        cla(app.UIAxes, 'reset');
                    case 2 % Speed
                        app.yrlabel = 'Speed';
                        ylabel(app.UIAxes, 'Speed [km/h]');
                    case 3 % Effort
                        app.yrlabel = 'Effort';
                        ylabel(app.UIAxes, 'Effort [kN]');
                    case 4 % Line voltage
                        app.yrlabel = 'Line voltage';
                        ylabel(app.UIAxes, 'Line Voltage [V]');
                    case 5 % Line current
                        app.yrlabel = 'Line current';
                        ylabel(app.UIAxes, 'Line Current [A]');
                    case 6 % Line Power
                        app.yrlabel = 'Line power';
                        ylabel(app.UIAxes, 'Line Power [kW]');
                    case 7 % Rail Power
                        app.yrlabel = 'Rail power';
                        ylabel(app.UIAxes, 'Rail Power [kW]');
                    case 8 % Acceleration
                        app.yrlabel = 'Acceleration';
                        ylabel(app.UIAxes, 'Acceleration [m/s^{2}]');
                    case 9 % Distance
                        app.yrlabel = 'Distance';
                        ylabel(app.UIAxes, 'Distance [km]');
                    case 10 % BR temp
                        app.yrlabel = 'BR temperature';
                        ylabel(app.UIAxes, ['Temperaure [', char(176), 'C]']);
                    case 11 % Thermal HV System
                        app.yrlabel = 'Thermal HV';
                        ylabel(app.UIAxes, 'Thermal limit []');
                    case 12 % Thermal Traction System
                        app.yrlabel = 'Thermal Traction';
                        ylabel(app.UIAxes, 'Thermal limit []');
                    case 13 % Energy
                        app.yrlabel = 'Energy';
                        ylabel(app.UIAxes, 'Energy [kWh]');
                    case 14 % Efficiency
                        app.yrlabel = 'Efficiency';
                        ylabel(app.UIAxes, 'Efficiency [%]');
                    case 15 % Notch selection
                        app.yrlabel = 'Notch selection';
                        ylabel(app.UIAxes, 'Notch [-]');
                end
                yyaxis(app.UIAxes, 'left');
            end
            if event.Source == app.YaxisleftDropDown
                yyaxis(app.UIAxes, 'left');
                switch Sel
                    case 1 % Speed
                        app.yllabel = 'Speed';
                        ylabel(app.UIAxes, 'Speed [km/h]');
                    case 2 % Effort
                        app.yllabel = 'Effort';
                        ylabel(app.UIAxes, 'Effort [kN]');
                    case 3 % Line voltage
                        app.yllabel = 'Line voltage';
                        ylabel(app.UIAxes, 'Line Voltage [V]');
                    case 4 % Line current
                        app.yllabel = 'Line current';
                        ylabel(app.UIAxes, 'Line Current [A]');
                    case 5 % Line Power
                        app.yllabel = 'Line power';
                        ylabel(app.UIAxes, 'Line Power [kW]');
                    case 6 % Rail Power
                        app.yllabel = 'Rail power';
                        ylabel(app.UIAxes, 'Rail Power [kW]');
                    case 7 % Acceleration
                        app.yllabel = 'Acceleration';
                        ylabel(app.UIAxes, 'Acceleration [m/s^{2}]');
                    case 8 % Distance
                        app.yllabel = 'Distance';
                        ylabel(app.UIAxes, 'Distance [km]');
                    case 9 % BR temp
                        app.yllabel = 'BR temperature';
                        ylabel(app.UIAxes, ['Temperaure [', char(176), 'C]']);
                    case 10 % Thermal HV System
                        app.yllabel = 'Thermal HV';
                        ylabel(app.UIAxes, 'Thermal limit []');
                    case 11 % Thermal Traction System
                        app.yllabel = 'Thermal Traction';
                        ylabel(app.UIAxes, 'Thermal limit []');
                    case 12 % Energy
                        app.yllabel = 'Energy';
                        ylabel(app.UIAxes, 'Energy [kWh]');
                    case 13 % Efficiency
                        app.yllabel = 'Efficiency';
                        ylabel(app.UIAxes, 'Efficiency [%]');
                    case 14 % Notch selection
                        app.yllabel = 'Notch selection';
                        ylabel(app.UIAxes, 'Notch [-]');
                end
            end
            if event.Source == app.XaxisDropDown
                switch Sel
                    case 1 % Distance
                        app.xxlabel = 'Distance';
                        xlabel(app.UIAxes, 'Distance [km]')
                    case 2 % Time
                        app.xxlabel = 'Time';
                        xlabel(app.UIAxes, 'Time [s]')
                    case 3 % Speed
                        app.xxlabel = 'Speed';
                        xlabel(app.UIAxes, 'Speed [km/h]')
                    case 4 % Motor speed [rpm]
                        app.xxlabel = 'Motor speed';
                        xlabel(app.UIAxes, 'Motor speed [rpm]')
                    case 5 % Motor speed [Hz]
                        app.xxlabel = 'Motor speed';
                        xlabel(app.UIAxes, 'Motor speed [Hz]')
                end
            end
            if isempty(app.yrlabel)
                title(app.UIAxes, [app.yllabel, ' vs. ', app.xxlabel]);
            else
                title(app.UIAxes, [app.yllabel, ' / ', app.yrlabel, ' vs. ', app.xxlabel]);
            end
            UpdateGraphs(app);
        end

        % Selection changed function: VehicleTable
        function VehicleTableSelectionChanged(app, event)
            Sel = app.VehicleTable.Selection;
            if ~isempty(Sel)
                f = app.VehicleTable.Data{Sel(1), 1};
                if length(f) > 5 && strcmp(f(end - 3:end), ' [L]')
                    app.UnlockVehicleFileButton.Enable = 'on';
                else
                    app.UnlockVehicleFileButton.Enable = 'off';
                end
            else
                app.UnlockVehicleFileButton.Enable = 'off';
            end
        end

        % Selection changed function: RouteTable
        function RouteTableSelectionChanged(app, event)
            Sel = app.RouteTable.Selection;
            if ~isempty(Sel)
                f = app.RouteTable.Data{Sel(1), 1};
                if length(f) > 5 && strcmp(f(end - 3:end), ' [L]')
                    app.UnlockRouteFileButton.Enable = 'on';
                else
                    app.UnlockRouteFileButton.Enable = 'off';
                end
            else
                app.UnlockRouteFileButton.Enable = 'off';
            end
        end

        % Key press function: SimulationTable
        function SimulationTableKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return' % edit cell data
                    if ~isempty(app.SimulationTable.Selection)
                        row = app.SimulationTable.Selection(1);
                        col = app.SimulationTable.Selection(2);
                        switch col
                            case 2 % vehicle column
                                pickVehicle(app, row);
                            case 3 % route column
                                pickRoute(app, row);
                        end
                    end
                case 'insert' % insert a new line
                    NewSimulation(app);
                case 'delete' % remove the current line
                    DeleteCurrentRow(app);
            end
            figure(app.ShogunCockpitUIFigure);
        end

        % Button down function: SimulationTable
        function SimulationTableButtonDown(app, event)
            Sel = app.RouteTable.Selection;
        end

        % Cell selection callback: SimulationTable
        function SimulationTableCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Double-clicked callback: SimulationTable
        function SimulationTableDoubleClicked(app, event)
            if ~isempty(app.SimulationTable.Selection)
                row = app.SimulationTable.Selection(1);
                col = app.SimulationTable.Selection(2);
                switch col
                    case 2 % vehicle column
                        pickVehicle(app, row);
                    case 3 % route column
                        pickRoute(app, row);
                end
            end
            figure(app.ShogunCockpitUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShogunCockpitUIFigure = uifigure('Visible', 'off');
            app.ShogunCockpitUIFigure.Name = 'Shogun Cockpit';
            app.ShogunCockpitUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShogunCockpitUIFigureCloseRequest, true);
            app.ShogunCockpitUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ShogunCockpitUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.ShogunCockpitUIFigure);
            app.FileMenu.Text = 'File';
            app.NewSimulationsMenu = uimenu(app.FileMenu);
            app.NewSimulationsMenu.Text = 'New Simulations';
            app.OpenSimulationsMenu = uimenu(app.FileMenu);
            app.OpenSimulationsMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSimulationsMenuSelected, true);
            app.OpenSimulationsMenu.Accelerator = 'O';
            app.OpenSimulationsMenu.Text = 'Open Simulations...';
            app.SaveSimulationsMenu = uimenu(app.FileMenu);
            app.SaveSimulationsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSimulationsMenuSelected, true);
            app.SaveSimulationsMenu.Accelerator = 'S';
            app.SaveSimulationsMenu.Text = 'Save Simulations';
            app.SaveSimulationsasMenu = uimenu(app.FileMenu);
            app.SaveSimulationsasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSimulationsasMenuSelected, true);
            app.SaveSimulationsasMenu.Text = 'Save Simulations as...';
            app.SelectProjectfolderMenu = uimenu(app.FileMenu);
            app.SelectProjectfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectProjectfolderMenuSelected, true);
            app.SelectProjectfolderMenu.Text = 'Select Project folder...';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.ReportMenu = uimenu(app.ShogunCockpitUIFigure);
            app.ReportMenu.Text = 'Report';
            app.CreateSimulationreportMenu = uimenu(app.ReportMenu);
            app.CreateSimulationreportMenu.Text = 'Create Simulation report';
            app.TabGroup = uitabgroup(app.ShogunCockpitUIFigure);
            app.SimulationsTab = uitab(app.TabGroup);
            app.SimulationsTab.Title = 'Simulations';
            app.SimulationTable = uitable(app.SimulationsTab);
            app.SimulationTable.ColumnName = {'ID'; 'Vehicle'; 'Route'; 'Comment'};
            app.SimulationTable.RowName = {};
            app.SimulationTable.ColumnEditable = [false false false true];
            app.SimulationTable.CellEditCallback = createCallbackFcn(app, @SimulationTableCellEdit, true);
            app.SimulationTable.CellSelectionCallback = createCallbackFcn(app, @SimulationTableCellSelection, true);
            app.SimulationTable.DoubleClickedFcn = createCallbackFcn(app, @SimulationTableDoubleClicked, true);
            app.SimulationTable.ButtonDownFcn = createCallbackFcn(app, @SimulationTableButtonDown, true);
            app.SimulationTable.KeyPressFcn = createCallbackFcn(app, @SimulationTableKeyPress, true);
            app.OptimisedCheckBox = uicheckbox(app.SimulationsTab);
            app.OptimisedCheckBox.Text = 'Optimised';
            app.CalculateButton = uibutton(app.SimulationsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SkiptimetableCheckBox = uicheckbox(app.SimulationsTab);
            app.SkiptimetableCheckBox.Text = 'Skip time table';
            app.NewSimulationButton = uibutton(app.SimulationsTab, 'push');
            app.NewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NewSimulationButtonPushed, true);
            app.NewSimulationButton.Text = 'New Simulation';
            app.DeleteSimulationButton = uibutton(app.SimulationsTab, 'push');
            app.DeleteSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSimulationButtonPushed, true);
            app.DeleteSimulationButton.Text = 'Delete Simulation';
            app.VehiclesTab = uitab(app.TabGroup);
            app.VehiclesTab.Title = 'Vehicles';
            app.VehicleTable = uitable(app.VehiclesTab);
            app.VehicleTable.ColumnName = {'File'; 'Class'; 'Purpose'; 'Notes'};
            app.VehicleTable.RowName = {};
            app.VehicleTable.ColumnEditable = [false false false true];
            app.VehicleTable.SelectionChangedFcn = createCallbackFcn(app, @VehicleTableSelectionChanged, true);
            app.VehicleEditorButton = uibutton(app.VehiclesTab, 'push');
            app.VehicleEditorButton.ButtonPushedFcn = createCallbackFcn(app, @VehicleEditorButtonPushed, true);
            app.VehicleEditorButton.Text = 'Vehicle Editor';
            app.UnlockVehicleFileButton = uibutton(app.VehiclesTab, 'push');
            app.UnlockVehicleFileButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockVehicleFileButtonPushed, true);
            app.UnlockVehicleFileButton.Text = 'Unlock file';
            app.RoutesTab = uitab(app.TabGroup);
            app.RoutesTab.Title = 'Routes';
            app.RouteTable = uitable(app.RoutesTab);
            app.RouteTable.ColumnName = {'File'; 'Route'; 'Purpose'; 'Comment'};
            app.RouteTable.RowName = {};
            app.RouteTable.SelectionChangedFcn = createCallbackFcn(app, @RouteTableSelectionChanged, true);
            app.RouteEditorButton = uibutton(app.RoutesTab, 'push');
            app.RouteEditorButton.ButtonPushedFcn = createCallbackFcn(app, @RouteEditorButtonPushed, true);
            app.RouteEditorButton.Text = 'Route Editor';
            app.UnlockRouteFileButton = uibutton(app.RoutesTab, 'push');
            app.UnlockRouteFileButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockRouteFileButtonPushed, true);
            app.UnlockRouteFileButton.Text = 'Unlock file';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes = uiaxes(app.ResultsTab);
            title(app.UIAxes, 'Speed vs. Distance')
            xlabel(app.UIAxes, 'Distance [km]')
            ylabel(app.UIAxes, 'Speed [km/h]')
            zlabel(app.UIAxes, 'Z')
            app.YaxisleftDropDownLabel = uilabel(app.ResultsTab);
            app.YaxisleftDropDownLabel.Text = 'Y-axis left:';
            app.YaxisleftDropDown = uidropdown(app.ResultsTab);
            app.YaxisleftDropDown.Items = {'Speed', 'Effort', 'Line Voltage', 'Line Current', 'Line Power', 'Rail Power', 'Acceleration', 'Distance', 'BR temp', 'Thermal HV System', 'Thermal Traction', 'Performance reduction', 'Energy', 'Efficiency', 'Notch selection'};
            app.YaxisleftDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.YaxisleftDropDown.Value = 'Speed';
            app.YaxisrightDropDownLabel = uilabel(app.ResultsTab);
            app.YaxisrightDropDownLabel.Text = 'Y-axis right:';
            app.YaxisrightDropDown = uidropdown(app.ResultsTab);
            app.YaxisrightDropDown.Items = {'None', 'Speed', 'Effort', 'Line Voltage', 'Line Current', 'Line Power', 'Rail Power', 'Acceleration', 'Distance', 'BR temp', 'Thermal HV System', 'Thermal Traction', 'Performance reduction', 'Energy', 'Efficiency', 'Notch selection'};
            app.YaxisrightDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.YaxisrightDropDown.Value = 'None';
            app.XaxisDropDownLabel = uilabel(app.ResultsTab);
            app.XaxisDropDownLabel.Text = 'X-axis :';
            app.XaxisDropDown = uidropdown(app.ResultsTab);
            app.XaxisDropDown.Items = {'Distance', 'Time', 'Speed', 'Motor speed (rpm)', 'Motor speed (Hz)'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Distance';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DeltaTinthecalculationEditFieldLabel = uilabel(app.SettingsTab);
            app.DeltaTinthecalculationEditFieldLabel.Text = 'Delta T in the calculation:';
            app.DeltaTinthecalculationEditField = uieditfield(app.SettingsTab, 'text');
            app.DeltaTinthecalculationEditField.Value = '100';
            app.MaxoperationtimeEditFieldLabel = uilabel(app.SettingsTab);
            app.MaxoperationtimeEditFieldLabel.Text = 'Max operation time:';
            app.MaxoperationtimeEditField = uieditfield(app.SettingsTab, 'text');
            app.MaxoperationtimeEditField.Value = '3600';
            app.msLabel = uilabel(app.SettingsTab);
            app.msLabel.Text = 'ms';
            app.sLabel = uilabel(app.SettingsTab);
            app.sLabel.Text = 's';
            app.TimeoutinthecalculationEditFieldLabel = uilabel(app.SettingsTab);
            app.TimeoutinthecalculationEditFieldLabel.Text = 'Time-out in the calculation:';
            app.TimeoutinthecalculationEditField = uieditfield(app.SettingsTab, 'text');
            app.TimeoutinthecalculationEditField.Value = '3600';
            app.sLabel_2 = uilabel(app.SettingsTab);
            app.sLabel_2.Text = 's';
            app.MinimumcoastingtimeEditFieldLabel = uilabel(app.SettingsTab);
            app.MinimumcoastingtimeEditFieldLabel.Text = 'Minimum coasting time:';
            app.MinimumcoastingtimeEditField = uieditfield(app.SettingsTab, 'text');
            app.MinimumcoastingtimeEditField.Value = '10';
            app.ReferencepointindisplayofresultsButtonGroup = uibuttongroup(app.SettingsTab);
            app.ReferencepointindisplayofresultsButtonGroup.Title = 'Reference point in display of results:';
            app.FrontButton = uiradiobutton(app.ReferencepointindisplayofresultsButtonGroup);
            app.FrontButton.Text = 'Front';
            app.FrontButton.Value = true;
            app.CentreButton = uiradiobutton(app.ReferencepointindisplayofresultsButtonGroup);
            app.CentreButton.Text = 'Centre';
            app.RearButton = uiradiobutton(app.ReferencepointindisplayofresultsButtonGroup);
            app.RearButton.Text = 'Rear';
            app.sLabel_3 = uilabel(app.SettingsTab);
            app.sLabel_3.Text = 's';
            app.PlotoriginalvehiclesimulationasButtonGroup = uibuttongroup(app.SettingsTab);
            app.PlotoriginalvehiclesimulationasButtonGroup.Title = 'Plot original vehicle simulation as:';
            app.OffButton = uiradiobutton(app.PlotoriginalvehiclesimulationasButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.ForegroundButton = uiradiobutton(app.PlotoriginalvehiclesimulationasButtonGroup);
            app.ForegroundButton.Text = 'Foreground';
            app.BackgroundButton = uiradiobutton(app.PlotoriginalvehiclesimulationasButtonGroup);
            app.BackgroundButton.Text = 'Background';
            app.AlternativeButton = uiradiobutton(app.PlotoriginalvehiclesimulationasButtonGroup);
            app.AlternativeButton.Text = 'Alternative';
            app.PlotalternativevehiclesimulationasButtonGroup = uibuttongroup(app.SettingsTab);
            app.PlotalternativevehiclesimulationasButtonGroup.Title = 'Plot alternative vehicle simulation as:';
            app.OffButton_2 = uiradiobutton(app.PlotalternativevehiclesimulationasButtonGroup);
            app.OffButton_2.Text = 'Off';
            app.OffButton_2.Value = true;
            app.ForegroundButton_2 = uiradiobutton(app.PlotalternativevehiclesimulationasButtonGroup);
            app.ForegroundButton_2.Text = 'Foreground';
            app.BackgroundButton_2 = uiradiobutton(app.PlotalternativevehiclesimulationasButtonGroup);
            app.BackgroundButton_2.Text = 'Background';
            app.AlternativeButton_2 = uiradiobutton(app.PlotalternativevehiclesimulationasButtonGroup);
            app.AlternativeButton_2.Text = 'Alternative';
            app.UseRoutedataforlinesupplyCheckBox = uicheckbox(app.SettingsTab);
            app.UseRoutedataforlinesupplyCheckBox.Text = 'Use Route data for line supply';
            app.UseRoutedataforlinesupplyCheckBox.Value = true;
            app.PlotaveragevaluesFVICheckBox = uicheckbox(app.SettingsTab);
            app.PlotaveragevaluesFVICheckBox.Text = 'Plot average values (F, V & I)';
            app.ImporteddatarepresentsButtonGroup = uibuttongroup(app.SettingsTab);
            app.ImporteddatarepresentsButtonGroup.Title = 'Imported data represents:';
            app.ConsistButton = uiradiobutton(app.ImporteddatarepresentsButtonGroup);
            app.ConsistButton.Text = 'Consist';
            app.ConsistButton.Value = true;
            app.UnitButton = uiradiobutton(app.ImporteddatarepresentsButtonGroup);
            app.UnitButton.Text = 'Unit';
            app.HVSystemButton = uiradiobutton(app.ImporteddatarepresentsButtonGroup);
            app.HVSystemButton.Text = 'HV System';
            app.InverterButton = uiradiobutton(app.ImporteddatarepresentsButtonGroup);
            app.InverterButton.Text = 'Inverter';
            app.AxleButton = uiradiobutton(app.ImporteddatarepresentsButtonGroup);
            app.AxleButton.Text = 'Axle';
            app.UsedynamicthermalpowerCheckBox = uicheckbox(app.SettingsTab);
            app.UsedynamicthermalpowerCheckBox.Text = 'Use dynamic thermal power';
            % Show the figure after all components are created
            app.ShogunCockpitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShogunCockpit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShogunCockpitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShogunCockpitUIFigure)
        end
    end
end