classdef VehicleEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShogunVehicleEditorUIFigure     matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewvehicleMenu                  matlab.ui.container.Menu
        OpenvehiclefileMenu             matlab.ui.container.Menu
        SavevehiclefileMenu             matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        SetProjectDirectoryMenu         matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        UndoMenu                        matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        PasteMenu                       matlab.ui.container.Menu
        SpecialMenu                     matlab.ui.container.Menu
        ComparevehiclesMenu             matlab.ui.container.Menu
        UnlockforeditMenu               matlab.ui.container.Menu
        GenerateVehiclePerformanceReportMenu  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ArchitectureTab                 matlab.ui.container.Tab
        ShogunSimVehicleEditorLabel     matlab.ui.control.Label
        motorisationinfo                matlab.ui.control.Label
        totAxlesinfo                    matlab.ui.control.Label
        totAPUinfo                      matlab.ui.control.Label
        totTMinfo                       matlab.ui.control.Label
        totINVinfo                      matlab.ui.control.Label
        totCOVinfo                      matlab.ui.control.Label
        totHVInfo                       matlab.ui.control.Label
        LengthInfo                      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TotalNoaxlesperunitEditField    matlab.ui.control.NumericEditField
        TotalNoaxlesperunitEditFieldLabel  matlab.ui.control.Label
        NoDrivenaxlesperunitEditField   matlab.ui.control.NumericEditField
        NoDrivenaxlesperunitEditFieldLabel  matlab.ui.control.Label
        NoAPSperHVsystemEditField       matlab.ui.control.NumericEditField
        NoAPSperHVsystemEditFieldLabel  matlab.ui.control.Label
        NoTMperinverterEditField        matlab.ui.control.NumericEditField
        NoTMperinverterEditFieldLabel   matlab.ui.control.Label
        NoINVperHVsystemEditField       matlab.ui.control.NumericEditField
        NoINVperHVsystemEditFieldLabel  matlab.ui.control.Label
        NoCONVperHVsystemEditField      matlab.ui.control.NumericEditField
        NoCONVperHVsystemEditFieldLabel  matlab.ui.control.Label
        NoHVsystemsperunitEditField     matlab.ui.control.NumericEditField
        NoHVsystemsperunitEditFieldLabel  matlab.ui.control.Label
        NumberofunitsEditField          matlab.ui.control.NumericEditField
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        PurposeEditField                matlab.ui.control.EditField
        PurposeEditFieldLabel           matlab.ui.control.Label
        ClassEditField                  matlab.ui.control.EditField
        ClassEditFieldLabel             matlab.ui.control.Label
        NumberofunitsEditField_2Label   matlab.ui.control.Label
        DynamicsTab                     matlab.ui.container.Tab
        kmhLabel_5                      matlab.ui.control.Label
        LoadconditionsLabel             matlab.ui.control.Label
        MaximiseDBECheckBox             matlab.ui.control.CheckBox
        Ns2kgmLabel                     matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        NkgmLabel                       matlab.ui.control.Label
        CurveresistanceLabel            matlab.ui.control.Label
        Nkmh2Label_2                    matlab.ui.control.Label
        Nmkmh2Label                     matlab.ui.control.Label
        Nkmh2Label                      matlab.ui.control.Label
        Rediusreductioncr1EditFieldLabel  matlab.ui.control.Label
        Tightcurveresistancecr0EditFieldLabel  matlab.ui.control.Label
        C2tunnelfactorofCfactorEditFieldLabel  matlab.ui.control.Label
        DLengthdeppartofCfactorEditFieldLabel  matlab.ui.control.Label
        CFrontpartofCfactorEditFieldLabel  matlab.ui.control.Label
        NkmhLabel                       matlab.ui.control.Label
        B2SpeeddepBfactorEditFieldLabel  matlab.ui.control.Label
        NtkmhLabel                      matlab.ui.control.Label
        NtLabel                         matlab.ui.control.Label
        AConstanteffortEditFieldLabel   matlab.ui.control.Label
        NLabel_2                        matlab.ui.control.Label
        AMassdependentAfactorEditFieldLabel  matlab.ui.control.Label
        TrainresistanceDaviesformulaLabel  matlab.ui.control.Label
        tonLabel_2                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        tonRecommendedLabel             matlab.ui.control.Label
        tonLabel                        matlab.ui.control.Label
        VehiclemassesLabel              matlab.ui.control.Label
        ms3Label_2                      matlab.ui.control.Label
        JerkratebrakingEditFieldLabel   matlab.ui.control.Label
        MethodforretardationDropDownLabel  matlab.ui.control.Label
        ms2Label_5                      matlab.ui.control.Label
        ResidualretardationEditFieldLabel  matlab.ui.control.Label
        ms2Label_4                      matlab.ui.control.Label
        MinretardationEditFieldLabel    matlab.ui.control.Label
        MaxretardationEditFieldLabel    matlab.ui.control.Label
        ms2Label_3                      matlab.ui.control.Label
        BehaviourinBrakingLabel         matlab.ui.control.Label
        ms3Label                        matlab.ui.control.Label
        JerkratetractionEditFieldLabel  matlab.ui.control.Label
        MethodforaccelerationDropDownLabel  matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        BehaviourinPoweringLabel        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        SelectedloadconnditionDropDownLabel  matlab.ui.control.Label
        Axialforcerelatedcr2EditField   matlab.ui.control.NumericEditField
        Axialforcerelatedcr2EditFieldLabel  matlab.ui.control.Label
        Rediusreductioncr1EditField     matlab.ui.control.NumericEditField
        Tightcurveresistancecr0EditField  matlab.ui.control.NumericEditField
        C2tunnelfactorofCfactorEditField  matlab.ui.control.NumericEditField
        DLengthdeppartofCfactorEditField  matlab.ui.control.NumericEditField
        CFrontpartofCfactorEditField    matlab.ui.control.NumericEditField
        B2SpeeddepBfactorEditField      matlab.ui.control.NumericEditField
        B1MassdependentBfactorEditField  matlab.ui.control.NumericEditField
        B1MassdependentBfactorEditFieldLabel  matlab.ui.control.Label
        AMassdependentAfactorEditField  matlab.ui.control.NumericEditField
        AConstanteffortEditField        matlab.ui.control.NumericEditField
        RmvLoadCondBtn                  matlab.ui.control.Button
        AddLoadCondBtn                  matlab.ui.control.Button
        LoadCondTable                   matlab.ui.control.Table
        SelLoadCondDropDown             matlab.ui.control.DropDown
        EqDynMassEditField              matlab.ui.control.NumericEditField
        EquivalentdynamicmassEditFieldLabel  matlab.ui.control.Label
        UnittaremassEditField           matlab.ui.control.NumericEditField
        UnittaremassEditFieldLabel      matlab.ui.control.Label
        DelTotBrkPoint                  matlab.ui.control.Button
        AddTotBrkPoint                  matlab.ui.control.Button
        TotBrkTable                     matlab.ui.control.Table
        JerkratebrakingEditField        matlab.ui.control.NumericEditField
        MethodforretardationDropDown    matlab.ui.control.DropDown
        ResidualretardationEditField    matlab.ui.control.NumericEditField
        atspeedEditField                matlab.ui.control.NumericEditField
        atspeedEditFieldLabel           matlab.ui.control.Label
        MinretardationEditField         matlab.ui.control.NumericEditField
        MaxretardationEditField         matlab.ui.control.NumericEditField
        JerkratetractionEditField       matlab.ui.control.NumericEditField
        MethodforaccelerationDropDown   matlab.ui.control.DropDown
        MaxaccelerationEditField        matlab.ui.control.NumericEditField
        MaxaccelerationEditFieldLabel   matlab.ui.control.Label
        UnitlengthEditField             matlab.ui.control.NumericEditField
        UnitlengthEditFieldLabel        matlab.ui.control.Label
        MaxSpdUnitDropDown              matlab.ui.control.DropDown
        MaximalVehicleSpeedEditField    matlab.ui.control.NumericEditField
        MaximalVehicleSpeedEditFieldLabel  matlab.ui.control.Label
        TractionTab                     matlab.ui.container.Tab
        GraphpreviewshowsLabel          matlab.ui.control.Label
        SpdUnitFadeFinishLabel          matlab.ui.control.Label
        SpdUnitFadeStartLabel           matlab.ui.control.Label
        FadingspeedfinishEditFieldLabel  matlab.ui.control.Label
        FadingspeedstartEditFieldLabel  matlab.ui.control.Label
        CustomTEDBEfunctionEditFieldLabel  matlab.ui.control.Label
        UserdefinedDynamicBrakeEffortvsSpeedcurveLabel  matlab.ui.control.Label
        UserdefinedTractiveEffortvsSpeedcurveLabel  matlab.ui.control.Label
        WeakfieldspeedLabel             matlab.ui.control.Label
        PowerderivativeEditFieldLabel   matlab.ui.control.Label
        kWsLabel                        matlab.ui.control.Label
        MaxRailPowerEditFieldLabel      matlab.ui.control.Label
        kWLabel                         matlab.ui.control.Label
        BrakingLabel                    matlab.ui.control.Label
        TractionLabel                   matlab.ui.control.Label
        kNLabel                         matlab.ui.control.Label
        DeleteDBEPwrPoint               matlab.ui.control.Button
        AddDBEPwrPoint                  matlab.ui.control.Button
        DBE_Curve_Table                 matlab.ui.control.Table
        DeleteTEPwrPoint                matlab.ui.control.Button
        AddTEPwrPoint                   matlab.ui.control.Button
        TE_Curve_Table                  matlab.ui.control.Table
        GrafSelDropDown                 matlab.ui.control.DropDown
        CustomTEDBEfunctionEditField    matlab.ui.control.EditField
        fadeFinishEditField             matlab.ui.control.NumericEditField
        fadeStartEditField              matlab.ui.control.NumericEditField
        SpdSelectTraction               matlab.ui.control.DropDown
        PDBE_WeakfieldSpdEditField      matlab.ui.control.NumericEditField
        PTE_WeakfieldSpdEditField       matlab.ui.control.NumericEditField
        PDBE_primEditField              matlab.ui.control.NumericEditField
        PTE_primEditField               matlab.ui.control.NumericEditField
        MaxPDBEEditField                matlab.ui.control.NumericEditField
        MaxPTEEditField                 matlab.ui.control.NumericEditField
        MaxDBEEditField                 matlab.ui.control.NumericEditField
        MaxTEEditField                  matlab.ui.control.NumericEditField
        MaxTEDBELabel                   matlab.ui.control.Label
        TractionGraph                   matlab.ui.control.UIAxes
        EnergyTab                       matlab.ui.container.Tab
        LinefilterDCSystemLabel         matlab.ui.control.Label
        PowerGenerationModuleLabel      matlab.ui.control.Label
        LineSupplyLabel                 matlab.ui.control.Label
        DieselenginefuelmapEditFieldLabel  matlab.ui.control.Label
        GeneratorefficiencymapEditFieldLabel  matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        ALabel_3                        matlab.ui.control.Label
        kWLabel_8                       matlab.ui.control.Label
        kWLabel_7                       matlab.ui.control.Label
        ESOutputpowerEditFieldLabel     matlab.ui.control.Label
        MethodforILlimitinbrakingDropDownLabel  matlab.ui.control.Label
        MaxVoltageatbrakingEditFieldLabel  matlab.ui.control.Label
        kVLabel_2                       matlab.ui.control.Label
        kVLabel                         matlab.ui.control.Label
        MinVoltageattractionEditFieldLabel  matlab.ui.control.Label
        MaxLineCurrentatBrakingEditFieldLabel  matlab.ui.control.Label
        ALabel_2                        matlab.ui.control.Label
        SystemLabel                     matlab.ui.control.Label
        UnitLabel                       matlab.ui.control.Label
        TrainLabel                      matlab.ui.control.Label
        LinecurrentlimitationsLabel     matlab.ui.control.Label
        MaxLineCurrentattractionEditFieldLabel  matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        APUPowerfactorEditFieldLabel    matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        APUefficiencyEditFieldLabel     matlab.ui.control.Label
        BatterychargerpowerDCEditFieldLabel  matlab.ui.control.Label
        kWLabel_6                       matlab.ui.control.Label
        ESBoostlimitEditFieldLabel      matlab.ui.control.Label
        ESInputpowerEditFieldLabel      matlab.ui.control.Label
        EnergyStoragecapacityEditField_2Label  matlab.ui.control.Label
        kWhLabel_2                      matlab.ui.control.Label
        EnergyStorageESLabel_2          matlab.ui.control.Label
        kWLabel_5                       matlab.ui.control.Label
        APUoutputpowerACEditFieldLabel  matlab.ui.control.Label
        OperatingtemperatureEditField_2Label  matlab.ui.control.Label
        RFilterReactorEditFieldLabel    matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        AuxiliaryPowerLabel             matlab.ui.control.Label
        RcableTrafoCOVEditFieldLabel    matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        FixedTransformerlossesEditFieldLabel  matlab.ui.control.Label
        FadingtimeEditFieldLabel        matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        OperatingtemperatureEditFieldLabel  matlab.ui.control.Label
        RSecondaryWindingEditFieldLabel  matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        RPrimaryWindingEditFieldLabel   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        IronLossesEditFieldLabel        matlab.ui.control.Label
        kWLabel_4                       matlab.ui.control.Label
        HarmonicLossesEditFieldLabel    matlab.ui.control.Label
        NoLoadLossesEditFieldLabel      matlab.ui.control.Label
        kWLabel_3                       matlab.ui.control.Label
        WindingRatioEditFieldLabel      matlab.ui.control.Label
        MainTransformerACSystemLabel    matlab.ui.control.Label
        kWLabel_2                       matlab.ui.control.Label
        MaximumEnginepowerEditFieldLabel  matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        VDCLabel                        matlab.ui.control.Label
        MethodforILlimitinbrakingDropDown  matlab.ui.control.DropDown
        MaxVoltageatbrakingEditField    matlab.ui.control.NumericEditField
        MinVoltageattractionEditField   matlab.ui.control.NumericEditField
        IL_max_system_DBE_EditField     matlab.ui.control.NumericEditField
        IL_max_unit_DBE_EditField       matlab.ui.control.NumericEditField
        IL_max_train_DBE_EditField      matlab.ui.control.NumericEditField
        IL_max_system_TE_EditField      matlab.ui.control.NumericEditField
        IL_max_unit_TE_EditField        matlab.ui.control.NumericEditField
        IL_max_train_TE_EditField       matlab.ui.control.NumericEditField
        ESOperationmodeDropDownLabel    matlab.ui.control.Label
        OperatingtemperatureEditField_2  matlab.ui.control.NumericEditField
        RFilterReactorEditField         matlab.ui.control.NumericEditField
        ES_OpMode_DropDown              matlab.ui.control.DropDown
        FadingtimeEditField             matlab.ui.control.NumericEditField
        ESBoostlimitEditField           matlab.ui.control.NumericEditField
        ES_P_in_EditField               matlab.ui.control.NumericEditField
        ES_P_out_EditField              matlab.ui.control.NumericEditField
        ES_Capacity_EditField           matlab.ui.control.NumericEditField
        APUPowerfactorEditField         matlab.ui.control.NumericEditField
        APUefficiencyEditField          matlab.ui.control.NumericEditField
        APU_P_out_DC_EditField          matlab.ui.control.NumericEditField
        APU_P_out_AC_EditField          matlab.ui.control.NumericEditField
        RcableTrafoCOVEditField         matlab.ui.control.NumericEditField
        FixedTransformerlossesEditField  matlab.ui.control.NumericEditField
        OperatingtemperatureEditField   matlab.ui.control.NumericEditField
        RSecondaryWindingEditField      matlab.ui.control.NumericEditField
        RPrimaryWindingEditField        matlab.ui.control.NumericEditField
        IronLossesEditField             matlab.ui.control.NumericEditField
        HarmonicLossesEditField         matlab.ui.control.NumericEditField
        NoLoadLossesEditField           matlab.ui.control.NumericEditField
        WindingRatioEditField           matlab.ui.control.NumericEditField
        DieselenginefuelmapEditField    matlab.ui.control.EditField
        GeneratorefficiencymapEditField  matlab.ui.control.EditField
        PGM_P_Output_EditField          matlab.ui.control.NumericEditField
        LineSupplyFreqEditField         matlab.ui.control.NumericEditField
        LineSupplyFrequencyEditFieldLabel  matlab.ui.control.Label
        DClinkVoltageEditField          matlab.ui.control.NumericEditField
        DClinkVoltageEditFieldLabel     matlab.ui.control.Label
        VoltUnitDropDown                matlab.ui.control.DropDown
        NominalLineVoltageEditField     matlab.ui.control.NumericEditField
        NominalLineVoltageEditFieldLabel  matlab.ui.control.Label
        DrivesTab                       matlab.ui.container.Tab
        LaunchMotorEditorButton         matlab.ui.control.Button
        kgm3Steel7850kgm3Label          matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        RotationmasscontributionperwheelEditFieldLabel  matlab.ui.control.Label
        MotorcableresistanceEditFieldLabel  matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        FixedTractionMotorlossesEditFieldLabel  matlab.ui.control.Label
        TractionmotorLabel              matlab.ui.control.Label
        mmnominal135mmLabel             matlab.ui.control.Label
        WheelthicknessEditFieldLabel    matlab.ui.control.Label
        WheeldiameterselectionDropDownLabel  matlab.ui.control.Label
        MasscontributionperwheelLabel   matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        NewLabel                        matlab.ui.control.Label
        WornLabel                       matlab.ui.control.Label
        NominalLabel                    matlab.ui.control.Label
        WheeldiameterEditFieldLabel     matlab.ui.control.Label
        GearboxLabel                    matlab.ui.control.Label
        WheelsetLabel                   matlab.ui.control.Label
        mNmrpmLabel                     matlab.ui.control.Label
        GearlossspeeddependentEditFieldLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        GearlosstorquedependentEditFieldLabel  matlab.ui.control.Label
        NmLabel                         matlab.ui.control.Label
        MotorcableresistanceEditField   matlab.ui.control.NumericEditField
        nPolesEditField                 matlab.ui.control.NumericEditField
        NumberofpolesEditFieldLabel     matlab.ui.control.Label
        FixedTractionMotorlossesEditField  matlab.ui.control.NumericEditField
        BrowseButton                    matlab.ui.control.Button
        Motor_Map_EditField             matlab.ui.control.EditField
        TractionmotorefficiencymapEditFieldLabel  matlab.ui.control.Label
        WheeldiameterselectionDropDown  matlab.ui.control.DropDown
        MaterialdensityEditField        matlab.ui.control.NumericEditField
        MaterialdensityEditFieldLabel   matlab.ui.control.Label
        WheelthicknessEditField         matlab.ui.control.NumericEditField
        mRotNewEditField                matlab.ui.control.NumericEditField
        mRotWornEditField               matlab.ui.control.NumericEditField
        mRotNominalEditField            matlab.ui.control.NumericEditField
        mStatNewEditField               matlab.ui.control.NumericEditField
        mStatWornEditField              matlab.ui.control.NumericEditField
        mStatNominalEditField           matlab.ui.control.NumericEditField
        WD_new_EditField                matlab.ui.control.NumericEditField
        WD_worn_EditField               matlab.ui.control.NumericEditField
        WD_nominal_EditField            matlab.ui.control.NumericEditField
        GearlossspeeddependentEditField  matlab.ui.control.NumericEditField
        GearlosstorquedependentEditField  matlab.ui.control.NumericEditField
        GearlossconsttorqueEditField    matlab.ui.control.NumericEditField
        GearlossconsttorqueEditFieldLabel  matlab.ui.control.Label
        Gear_ratio_EditField            matlab.ui.control.NumericEditField
        GearreducationTMwheelEditFieldLabel  matlab.ui.control.Label
        DrivesGraph                     matlab.ui.control.UIAxes
        ConverterTab                    matlab.ui.container.Tab
        GeneratormodeLabel              matlab.ui.control.Label
        MotormodeLabel                  matlab.ui.control.Label
        RmvGenPtButton                  matlab.ui.control.Button
        RmvMotPtButton                  matlab.ui.control.Button
        AddGenPtButton                  matlab.ui.control.Button
        AddMotPtButton                  matlab.ui.control.Button
        GeneratorLabel                  matlab.ui.control.Label
        MotorLabel                      matlab.ui.control.Label
        FixedinverterlossesEditFieldLabel  matlab.ui.control.Label
        FactorforSwFreqcurrentlossEditField_2Label  matlab.ui.control.Label
        SwitchingFrequencylossEditField_2Label  matlab.ui.control.Label
        ResistivelossEditField_2Label   matlab.ui.control.Label
        InverterSwitchingfrequencyEditFieldLabel  matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        HzLabel_5                       matlab.ui.control.Label
        JLabel_2                        matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        InverterLabel                   matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        FixedconverterlossesEditFieldLabel  matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        FactorforSwFreqcurrentlossEditFieldLabel  matlab.ui.control.Label
        JLabel                          matlab.ui.control.Label
        SwitchingFrequencylossEditFieldLabel  matlab.ui.control.Label
        mLabel_8                        matlab.ui.control.Label
        ResistivelossEditFieldLabel     matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        ConverterSwitchingfrequencyEditFieldLabel  matlab.ui.control.Label
        GeneratorPulseTable             matlab.ui.control.Table
        MotorPulseTable                 matlab.ui.control.Table
        InvSwFreqGeneratorEditField     matlab.ui.control.NumericEditField
        InvSwFreqMotorEditField         matlab.ui.control.NumericEditField
        InvFixLossEditField             matlab.ui.control.NumericEditField
        InvFactorSwFreqlossEditField    matlab.ui.control.NumericEditField
        InvSwFreqLossEditField          matlab.ui.control.NumericEditField
        InvRLossEditField               matlab.ui.control.NumericEditField
        ConverterLabel                  matlab.ui.control.Label
        CovFixLossEditField             matlab.ui.control.NumericEditField
        CovFactorSwFreqlossEditField    matlab.ui.control.NumericEditField
        CovSwFreqLossEditField          matlab.ui.control.NumericEditField
        CovRLossEditField               matlab.ui.control.NumericEditField
        CovSwFreqEditField              matlab.ui.control.NumericEditField
        UPhFigure                       matlab.ui.control.UIAxes
        SwFreqFigure                    matlab.ui.control.UIAxes
        NotchingTab                     matlab.ui.container.Tab
        MaxaccelerationatstartEditField  matlab.ui.control.NumericEditField
        MaxaccelerationatstartEditFieldLabel  matlab.ui.control.Label
        ms2Label_6                      matlab.ui.control.Label
        kmhLabel_4                      matlab.ui.control.Label
        ReducationstartdeltaspeedEditField  matlab.ui.control.NumericEditField
        ReducationstartdeltaspeedEditFieldLabel  matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        kmhLabel_3                      matlab.ui.control.Label
        ApplV_Brk_EditField             matlab.ui.control.NumericEditField
        ApplV_Trc_EditField             matlab.ui.control.NumericEditField
        ApplicationspeedEditFieldLabel  matlab.ui.control.Label
        SpdSelNothingDropDown           matlab.ui.control.DropDown
        NotchOnDeltaVBrkEditField       matlab.ui.control.NumericEditField
        BrakingLabel_2                  matlab.ui.control.Label
        TractionLabel_3                 matlab.ui.control.Label
        NotchOnDeltaVTrcEditField       matlab.ui.control.NumericEditField
        NotchondeltaspeedEditFieldLabel  matlab.ui.control.Label
        MinimumnotchupdelayEditField    matlab.ui.control.NumericEditField
        MinimumnotchupdelayEditFieldLabel  matlab.ui.control.Label
        MinimumnotchingtimeEditField    matlab.ui.control.NumericEditField
        MinimumnotchingtimeEditFieldLabel  matlab.ui.control.Label
        NumberofBrakenotchesEditField   matlab.ui.control.NumericEditField
        NumberofBrakenotchesEditFieldLabel  matlab.ui.control.Label
        NothingmethodbasedonDropDown    matlab.ui.control.DropDown
        NothingmethodbasedonDropDownLabel  matlab.ui.control.Label
        NotchingmethodsLabel            matlab.ui.control.Label
        NumberofPowernotchesEditField   matlab.ui.control.NumericEditField
        NumberofPowernotchesLabel       matlab.ui.control.Label
        ThermalTab                      matlab.ui.container.Tab
        SpdSelectBRFan                  matlab.ui.control.DropDown
        CLabel_8                        matlab.ui.control.Label
        AmbienttemperatureLabel_27      matlab.ui.control.Label
        CLabel_7                        matlab.ui.control.Label
        AmbienttemperatureLabel_26      matlab.ui.control.Label
        AmbienttemperatureLabel_25      matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        CLabel_6                        matlab.ui.control.Label
        AmbienttemperatureLabel_24      matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        sLabel_12                       matlab.ui.control.Label
        sLabel_11                       matlab.ui.control.Label
        sLabel_10                       matlab.ui.control.Label
        CLabel_5                        matlab.ui.control.Label
        CLabel_4                        matlab.ui.control.Label
        AmbienttemperatureLabel_23      matlab.ui.control.Label
        BrakeResistorcoolingcontrolLabel  matlab.ui.control.Label
        sLabel_9                        matlab.ui.control.Label
        sLabel_8                        matlab.ui.control.Label
        sLabel_7                        matlab.ui.control.Label
        sLabel_6                        matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        AmbienttemperatureLabel_22      matlab.ui.control.Label
        AmbienttemperatureLabel_21      matlab.ui.control.Label
        AmbienttemperatureLabel_20      matlab.ui.control.Label
        AmbienttemperatureLabel_19      matlab.ui.control.Label
        AmbienttemperatureLabel_18      matlab.ui.control.Label
        BrakeResistordataperInverterLabel  matlab.ui.control.Label
        A_RMSLabel_10                   matlab.ui.control.Label
        A_RMSLabel_9                    matlab.ui.control.Label
        A_RMSLabel_8                    matlab.ui.control.Label
        A_RMSLabel_7                    matlab.ui.control.Label
        A_RMSLabel_6                    matlab.ui.control.Label
        A_RMSLabel_5                    matlab.ui.control.Label
        A_RMSLabel_4                    matlab.ui.control.Label
        A_RMSLabel_3                    matlab.ui.control.Label
        A_RMSLabel_2                    matlab.ui.control.Label
        TractionMotorThermalPerformanceLabel  matlab.ui.control.Label
        CLabel_3                        matlab.ui.control.Label
        ConverterThermalPerformanceLabel  matlab.ui.control.Label
        MotorInverterThermalPerformanceLabel  matlab.ui.control.Label
        DCLinefilterThermalPerformanceLabel  matlab.ui.control.Label
        TransformerThermalPerformanceLabel  matlab.ui.control.Label
        ThermalCalculationandLimitationsLabel  matlab.ui.control.Label
        A_RMSLabel                      matlab.ui.control.Label
        BRMaxTmpEditfield               matlab.ui.control.NumericEditField
        BRHighTmpEditfield              matlab.ui.control.NumericEditField
        BRFanOffVclSpdEditfield         matlab.ui.control.NumericEditField
        BRFanHiSpdHystOFFEditfield      matlab.ui.control.NumericEditField
        BRFanHiSpdHystONEditfield       matlab.ui.control.NumericEditField
        BRFanOnTempEditfield            matlab.ui.control.NumericEditField
        BRTCTempFactorEditfield         matlab.ui.control.NumericEditField
        BRTCFanHiSpEditfield            matlab.ui.control.NumericEditField
        BRTCFanLowSpEditfield           matlab.ui.control.NumericEditField
        BRTCFanOffEditfield             matlab.ui.control.NumericEditField
        BRThermCapacityEditfield        matlab.ui.control.NumericEditField
        TMcoolingtypeDropDown           matlab.ui.control.DropDown
        TMcoolingtypeDropDownLabel      matlab.ui.control.Label
        TMIphTCEditfield                matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_17      matlab.ui.control.Label
        TMIphContEditfield              matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_16      matlab.ui.control.Label
        TMIphMaxEditfield               matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_15      matlab.ui.control.Label
        CONVContIphTCEditfield          matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_14      matlab.ui.control.Label
        CONVContIphSinusEditfield       matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_13      matlab.ui.control.Label
        CONVMaxIphEditfield             matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_12      matlab.ui.control.Label
        INVContIphTCEditfield           matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_11      matlab.ui.control.Label
        INVContIphHexEditfield          matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_10      matlab.ui.control.Label
        INVContIphSinusEditfield        matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_9       matlab.ui.control.Label
        INVMaxIphEditfield              matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_8       matlab.ui.control.Label
        DCLineFiltTCCurEditfield        matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_7       matlab.ui.control.Label
        DCLineFiltContCurEditfield      matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_6       matlab.ui.control.Label
        MTrSecWindTCEditfield           matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_5       matlab.ui.control.Label
        MTrSecWindCurrentEditfield      matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_4       matlab.ui.control.Label
        MTrPrimWindTCEditfield          matlab.ui.control.NumericEditField
        AmbienttemperatureLabel_3       matlab.ui.control.Label
        AmbienttemperatureLabel_2       matlab.ui.control.Label
        MTrPrimWindCurrentEditfield     matlab.ui.control.NumericEditField
        AmbTempEditfield                matlab.ui.control.NumericEditField
        AmbienttemperatureLabel         matlab.ui.control.Label
    end

% ========================================================================
%
% ShogunSim - Vehicle editor
%
% MATLAB App to create and edit vehicle data for the ShogunSim
%
% Rev   Date        Sign    Description
% 0.1   20251127    JR      First creation
% 0.11  20251128    JR      General updates:
%
% ========================================================================

    properties (Constant)
        app_version = '0.10' % version of the app
        nDataPoints = 200    % number of data points for the internal graph
        %arcgTableContent = {''}
        arch_fields = {'unitLength', 'nUnits', 'nHVSystems', 'nCONV', 'nINV', 'nAPUs', 'nMotors', 'nAxDrive', 'nAxTrail'};
    end

    properties (Access = private)
        CurrentFile % current vehicle file (if saved)
        working_directory % The project working directory
        vehicle_fileData %
        defaultVehicle  % Description
        simulated_data % matrix containing speed vs. xx
        motorFile % path to the current motor model used
        settings_file % Description
        Dirty % Means that user made changes that has not been saved
        isReadOnly % set if the current file is in read only mode
        caller
    end

    methods (Access = public)

    end % methods (Access = public)

    methods (Static)

        function launch(caller, app, selFile)
            if exist(selFile, 'file')
                app.caller = caller;
                app.CurrentFile = selFile;
                [~, attr] = fileattrib(selFile);

                % app.working_directory = p;
                load(selFile, 'vehicle_data');
                if ~isfield(vehicle_data, 'Purpose')
                    vehicle_data.Purpose = '';
                end
                app.vehicle_fileData = vehicle_data;
                data2layout(app);
                update_infos(app);
                CleanDirty(app);
                app.isReadOnly = ~attr.UserWrite;
            end
        end
    end % methods (Static)

    methods (Access = private)
        
        function data2layout(app)
            % Architecture
            app.ClassEditField.Value = app.vehicle_fileData.class;
            app.PurposeEditField.Value = app.vehicle_fileData.Purpose;
            app.NumberofunitsEditField.Value = app.vehicle_fileData.nUnits;
            app.NoHVsystemsperunitEditField.Value = app.vehicle_fileData.nHVSystems;
            app.NoCONVperHVsystemEditField.Value = app.vehicle_fileData.nCONV;
            app.NoINVperHVsystemEditField.Value = app.vehicle_fileData.nINV;
            app.NoAPSperHVsystemEditField.Value = app.vehicle_fileData.nAPUs;
            app.NoTMperinverterEditField.Value = app.vehicle_fileData.nMotors;
            app.NoDrivenaxlesperunitEditField.Value = app.vehicle_fileData.nAxDrive;
            app.TotalNoaxlesperunitEditField.Value = app.vehicle_fileData.nAxTrail + app.vehicle_fileData.nAxDrive;
            app.NotesTextArea.Value = app.vehicle_fileData.notes;

            % Dynamics
            app.MaxSpdUnitDropDown.ValueIndex = app.vehicle_fileData.MaxSpdUnit;
            txt = app.MaxSpdUnitDropDown.Items{app.MaxSpdUnitDropDown.ValueIndex};
            app.kmhLabel_5.Text = txt;
            app.TotBrkTable.ColumnName = ['Speed [', txt, ']'; app.TotBrkTable.ColumnName(2:end)];
            app.MaximalVehicleSpeedEditField.Value = app.vehicle_fileData.vMax;
            
            app.UnitlengthEditField.Value = app.vehicle_fileData.unitLength;
            app.MaxaccelerationEditField.Value = app.vehicle_fileData.max_acc;
            app.JerkratetractionEditField.Value = app.vehicle_fileData.jerkrateT;
            app.MethodforaccelerationDropDown.ValueIndex = app.vehicle_fileData.AccMethod;

            app.MaxretardationEditField.Value = app.vehicle_fileData.retard_max;
            app.JerkratebrakingEditField.Value = app.vehicle_fileData.jerkrateB ;
            app.MinretardationEditField.Value = app.vehicle_fileData.retard_min;
            app.atspeedEditField.Value = app.vehicle_fileData.minRetard_v;

            app.ResidualretardationEditField.Value = app.vehicle_fileData.residual_ret;
            app.MethodforretardationDropDown.ValueIndex = app.vehicle_fileData.RetardMethod + 1;
            app.MaximiseDBECheckBox.Value = app.vehicle_fileData.utilEBrake;
            app.TotBrkTable.Data = app.vehicle_fileData.TotBrake;
            
            % masses
            app.UnittaremassEditField.Value = app.vehicle_fileData.TareMassUnit;
            app.EqDynMassEditField.Value = app.vehicle_fileData.RotMassUnit;
            app.LoadCondTable.Data = app.vehicle_fileData.LoadMass;
            app.SelLoadCondDropDown.Items = app.LoadCondTable.Data(:, 1)';
            app.SelLoadCondDropDown.ValueIndex = app.vehicle_fileData.LoadCond;
            
            % resistance
            app.AMassdependentAfactorEditField.Value = app.vehicle_fileData.dvs_a_dash;
            app.B1MassdependentBfactorEditField.Value = app.vehicle_fileData.dvs_b1_dash;
            app.B2SpeeddepBfactorEditField.Value = app.vehicle_fileData.dvs_b2;
            app.CFrontpartofCfactorEditField.Value = app.vehicle_fileData.dvs_c_front;
            app.DLengthdeppartofCfactorEditField.Value = app.vehicle_fileData.dvs_c_len;
            app.C2tunnelfactorofCfactorEditField.Value = app.vehicle_fileData.dvs_c_front2;
            app.Tightcurveresistancecr0EditField.Value = app.vehicle_fileData.curve_cr0;
            app.Rediusreductioncr1EditField.Value = app.vehicle_fileData.curve_cr1;
            app.Axialforcerelatedcr2EditField.Value = app.vehicle_fileData.curve_cr2;
            
            % ==== Traction
            app.MaxTEEditField.Value = app.vehicle_fileData.maxTE; % kN
            app.MaxDBEEditField.Value = app.vehicle_fileData.maxDBE; % kN
            app.MaxPTEEditField.Value = app.vehicle_fileData.maxPwrTEbase; % kW
            app.MaxPDBEEditField.Value = app.vehicle_fileData.maxPwrDBEbase; % kW
            app.PTE_primEditField.Value = app.vehicle_fileData.maxRatePwrTE;
            app.PDBE_primEditField.Value = app.vehicle_fileData.maxRatePwrDBE;
            sel = app.vehicle_fileData.SpdSelectTraction;
            app.SpdSelectTraction.ValueIndex = sel;
            txt = app.SpdSelectTraction.Items{sel};
            app.SpdUnitFadeStartLabel.Text = txt;
            app.SpdUnitFadeFinishLabel.Text = txt;
            app.TE_Curve_Table.ColumnName = ['Speed [', txt, ']'; app.TE_Curve_Table.ColumnName(2:end)];
            app.DBE_Curve_Table.ColumnName = ['Speed [', txt, ']'; app.DBE_Curve_Table.ColumnName(2:end)];

            app.PTE_WeakfieldSpdEditField.Value = app.vehicle_fileData.vBasePwr2;
            
            app.PDBE_WeakfieldSpdEditField.Value = app.vehicle_fileData.vBaseBrk2;

            app.fadeStartEditField.Value = app.vehicle_fileData.dynBrkFadeStart;
            app.fadeFinishEditField.Value = app.vehicle_fileData.dynBrkFadeEnd;
            app.TE_Curve_Table.Data = app.vehicle_fileData.PwrTE;
            app.DBE_Curve_Table.Data = app.vehicle_fileData.PwrDBE;

            app.CustomTEDBEfunctionEditField.Value = app.vehicle_fileData.customTERef;

            % ==== Energy
            app.VoltUnitDropDown.ValueIndex = app.vehicle_fileData.ULSelectionIdx;
            app.NominalLineVoltageEditField.Value = app.vehicle_fileData.U_Line_Nom;
            app.DClinkVoltageEditField.Value = app.vehicle_fileData.uDcLink;
            app.LineSupplyFreqEditField.Value = app.vehicle_fileData.uFreq;
            app.APU_P_out_DC_EditField.Value = app.vehicle_fileData.BACPower;
            app.APU_P_out_AC_EditField.Value = app.vehicle_fileData.AuxPowerAC;
            app.APUefficiencyEditField.Value = min([app.vehicle_fileData.APS_efficiency, 100]);
            app.APUPowerfactorEditField.Value = app.vehicle_fileData.AuxPF;
            
            % line supply limits
            app.IL_max_train_TE_EditField.Value = app.vehicle_fileData.ILDriveMax_train;
            app.IL_max_unit_TE_EditField.Value = app.vehicle_fileData.ILDriveMax_unit;
            app.IL_max_system_TE_EditField.Value = app.vehicle_fileData.ILDriveMax_system;
            app.IL_max_train_DBE_EditField.Value = app.vehicle_fileData.ILBrakeMax_train;
            app.IL_max_unit_DBE_EditField.Value = app.vehicle_fileData.ILBrakeMax_unit;
            app.IL_max_system_DBE_EditField.Value = app.vehicle_fileData.ILBrakeMax_unit;
            app.MinVoltageattractionEditField.Value = app.vehicle_fileData.MinUL;
            app.MaxVoltageatbrakingEditField.Value = app.vehicle_fileData.OVClevel;
            app.RcableTrafoCOVEditField.Value = app.vehicle_fileData.R_ConCable;
            app.RFilterReactorEditField.Value = app.vehicle_fileData.rLineInd;
            
            % main transformer
            app.WindingRatioEditField.Value = app.vehicle_fileData.MT_ratio;
            app.FixedTransformerlossesEditField.Value = app.vehicle_fileData.Trafo_FixLoss;
            app.NoLoadLossesEditField.Value = app.vehicle_fileData.trafoIdleLoss;
            app.HarmonicLossesEditField.Value = app.vehicle_fileData.trafoHarmonicLoss;
            app.IronLossesEditField.Value = app.vehicle_fileData.trafoIronLoss;
            app.RPrimaryWindingEditField.Value = app.vehicle_fileData.rTrafoPrim;
            app.RSecondaryWindingEditField.Value = app.vehicle_fileData.rTrafoSec;
            app.OperatingtemperatureEditField.Value = app.vehicle_fileData.TrafoTemp;
            
            % energy storage
            app.ES_Capacity_EditField.Value = app.vehicle_fileData.ES_capacity;
            app.ES_P_out_EditField.Value = app.vehicle_fileData.ES_PowerOut;
            app.ES_P_in_EditField.Value = app.vehicle_fileData.ES_PowerIn;
            app.ESBoostlimitEditField.Value = app.vehicle_fileData.ES_BoostILimit;
            app.FadingtimeEditField.Value = app.vehicle_fileData.ES_FadeTime;
            app.ES_OpMode_DropDown.ValueIndex = app.vehicle_fileData.ES_Op_Mode;


            % ==== Drives
            app.Gear_ratio_EditField.Value = app.vehicle_fileData.gearRatio;
            app.GearlossconsttorqueEditField.Value = app.vehicle_fileData.gearLoss0;
            app.GearlosstorquedependentEditField.Value = app.vehicle_fileData.gearLoss1;
            app.GearlossspeeddependentEditField.Value = app.vehicle_fileData.gearLoss2;
            
            app.WD_nominal_EditField.Value = app.vehicle_fileData.wheelsize{1} * 1000;
            app.WD_worn_EditField.Value = app.vehicle_fileData.wheelsize{2} * 1000;
            app.WD_new_EditField.Value = app.vehicle_fileData.wheelsize{3} * 1000;
            app.mStatNominalEditField.Value = app.vehicle_fileData.ws_mass{1};
            app.mStatWornEditField.Value = app.vehicle_fileData.ws_mass{2};
            app.mStatNewEditField.Value = app.vehicle_fileData.ws_mass{3};
            app.mRotNominalEditField.Value = app.vehicle_fileData.ws_rotMass{1};
            app.mRotWornEditField.Value = app.vehicle_fileData.ws_rotMass{2};
            app.mRotNewEditField.Value = app.vehicle_fileData.ws_rotMass{3};
            app.WheelthicknessEditField.Value = app.vehicle_fileData.wheelThickness ;
            app.MaterialdensityEditField.Value = app.vehicle_fileData.wheelDensity;
            app.WheeldiameterselectionDropDown.ValueIndex = app.vehicle_fileData.ws_selection;
            app.motorFile = [];
            s = '';
            if isfield(app.vehicle_fileData, 'motorFile')
                if ~isempty(app.vehicle_fileData.motorFile) && exist(app.vehicle_fileData.motorFile, 'file')
                    [~, f] = fileparts(app.vehicle_fileData.motorFile);
                    s = ['../', f];
                    app.motorFile = app.vehicle_fileData.motorFile;
                elseif ~isempty(app.vehicle_fileData.motorFile)
                    dlg = warndlg(['The vehicle refer to a motor map that cannot be found', newline app.vehicle_fileData.motorFile]);
                    uiwait(dlg);
                end
            end
            
            app.Motor_Map_EditField.Value = s;    
            app.FixedTractionMotorlossesEditField.Value = app.vehicle_fileData.TM_FixLoss * 100;
            app.nPolesEditField.Value = app.vehicle_fileData.nPoles;
            app.MotorcableresistanceEditField.Value = app.vehicle_fileData.R_MotCable * 1000;


            % Converters
            app.CovSwFreqEditField.Value = app.vehicle_fileData.swFqConv;
            app.CovRLossEditField.Value = app.vehicle_fileData.CONV_RLoss * 1000;
            app.CovSwFreqLossEditField.Value = app.vehicle_fileData.CONV_FLoss;
            app.CovFactorSwFreqlossEditField.Value = app.vehicle_fileData.CONV_FRLoss * 1e6;
            app.CovFixLossEditField.Value = app.vehicle_fileData.CONV_FixLoss * 100;
            app.InvRLossEditField.Value = app.vehicle_fileData.INV_RLoss * 1000;
            app.InvSwFreqLossEditField.Value = app.vehicle_fileData.INV_FLoss;
            app.InvFactorSwFreqlossEditField.Value = app.vehicle_fileData.INV_FRLoss * 1e6;
            app.InvFixLossEditField.Value  = app.vehicle_fileData.INV_FixLoss * 100;

            % ==== Notching - under construction!!
            app.NothingmethodbasedonDropDown.ValueIndex = app.vehicle_fileData.notchMethod + 1;
            app.NumberofPowernotchesEditField.Value = app.vehicle_fileData.nNotchT;
            app.NumberofBrakenotchesEditField.Value = app.vehicle_fileData.nNotchB;
            app.MinimumnotchingtimeEditField.Value = app.vehicle_fileData.notchMinTime;
            app.MinimumnotchupdelayEditField.Value = app.vehicle_fileData.notchrate;
            if size(app.vehicle_fileData.notchDeltaV, 2) < 2
                app.NotchOnDeltaVTrcEditField.Value = [];
                app.NotchOnDeltaVBrkEditField.Value = [];
            else
                app.NotchOnDeltaVTrcEditField.Value = app.vehicle_fileData.notchDeltaV(1);
                app.NotchOnDeltaVBrkEditField.Value = app.vehicle_fileData.notchDeltaV(2);
            end
            if size(app.vehicle_fileData.notchMinV, 2) < 2
                app.ApplV_Trc_EditField.Value = [];
                app.ApplV_Brk_EditField.Value = [];
            else
                app.ApplV_Trc_EditField.Value = app.vehicle_fileData.notchMinV(1);
                app.ApplV_Brk_EditField.Value = app.vehicle_fileData.notchMinV(2);
            end
            app.ReducationstartdeltaspeedEditField.Value = app.vehicle_fileData.notchDeltaVAcc;
            app.MaxaccelerationatstartEditField.Value = app.vehicle_fileData.notchMaxAcc;

            % Thermal
            app.AmbTempEditfield.Value = app.vehicle_fileData.AmbientTemp;
            
            % --- Main transformer
            app.MTrPrimWindCurrentEditfield.Value = app.vehicle_fileData.IPrimTrafoCont;
            app.MTrPrimWindTCEditfield.Value = app.vehicle_fileData.IPrimTrafoContTC;
            app.MTrSecWindCurrentEditfield.Value = app.vehicle_fileData.I2ndTrafoCont;
            app.MTrSecWindTCEditfield.Value = app.vehicle_fileData.I2ndTrafoContTC;
            % --- DC line filter
            app.DCLineFiltContCurEditfield.Value = app.vehicle_fileData.IDCFilterCont;
            app.DCLineFiltTCCurEditfield.Value = app.vehicle_fileData.IDCFilterContTC;
            % --- Inverter
            app.INVMaxIphEditfield.Value = app.vehicle_fileData.IphMCMMax;
            app.INVContIphSinusEditfield.Value = app.vehicle_fileData.IphMCMContSin;
            app.INVContIphHexEditfield.Value = app.vehicle_fileData.IphMCMContHex;
            app.INVContIphTCEditfield.Value = app.vehicle_fileData.IphMCMContTC;
            % --- Converter
            app.CONVMaxIphEditfield.Value = app.vehicle_fileData.IphLCMMax;
            app.CONVContIphSinusEditfield.Value = app.vehicle_fileData.IphLCMCont;
            app.CONVContIphTCEditfield.Value = app.vehicle_fileData.IphLCMContTC;
            % --- Traction Motor
            app.TMIphMaxEditfield.Value = app.vehicle_fileData.IstatTMMax;
            app.TMIphContEditfield.Value = app.vehicle_fileData.IstatTMCont;
            app.TMIphTCEditfield.Value = app.vehicle_fileData.IstatTMContTC;
            app.TMcoolingtypeDropDown.ValueIndex = app.vehicle_fileData.TMventType;
            % --- Brake resistor data
            app.BRThermCapacityEditfield.Value = app.vehicle_fileData.BRThermC;
            app.BRTCFanLowSpEditfield.Value = app.vehicle_fileData.BRThermTC_FanOnLow;
            app.BRTCFanHiSpEditfield.Value = app.vehicle_fileData.BRThermTC_FanOnHigh;
            app.BRTCFanOffEditfield.Value = app.vehicle_fileData.BRThermTC_FanOff;
            app.BRTCTempFactorEditfield.Value = app.vehicle_fileData.BRThermTC_Tempfactor * 100;
            app.BRFanOnTempEditfield.Value = app.vehicle_fileData.BRFanOnTemp;
            app.BRFanHiSpdHystONEditfield.Value = app.vehicle_fileData.BRFanHighSpeedTemp;
            app.BRFanHiSpdHystOFFEditfield.Value = app.vehicle_fileData.BRFanOffTemp;
            app.SpdSelectBRFan.ValueIndex = app.vehicle_fileData.BRFanSpeedSel;

            app.BRFanOffVclSpdEditfield.Value = app.vehicle_fileData.BRFanOffSpeed;
            app.BRHighTmpEditfield.Value = app.vehicle_fileData.BRTemp_High;
            app.BRMaxTmpEditfield.Value = app.vehicle_fileData.BRTemp_Max;

            % update info
            if isempty(app.vehicle_fileData.unitLength)
                app.LengthInfo.Text = '';
            elseif isempty(app.vehicle_fileData.unitLength) || app.vehicle_fileData.unitLength ~= app.vehicle_fileData.unitLength || app.vehicle_fileData.nUnits ~= app.vehicle_fileData.nUnits
                app.LengthInfo.Text = ['Total train length: ', num2str(app.vehicle_fileData.unitLength * app.vehicle_fileData.nUnits,'%5.2f'), ' m'];
            end
            if app.vehicle_fileData.nUnits ~= app.vehicle_fileData.nUnits || app.vehicle_fileData.nHVSystems ~= app.vehicle_fileData.nHVSystems
                app.totHVInfo.Text = [num2str(app.vehicle_fileData.nUnits * app.vehicle_fileData.nHVSystems), ' High Voltage Systems'];
            end
            app.vehicle_fileData = app.vehicle_fileData;
        end % data2layout

        function layout2data(app)
            % read the data - flag error if implausible and return 
            % Architecture tab
            theVehicle.class        = app.ClassEditField.Value;
            theVehicle.Purpose      = app.PurposeEditField.Value;
            theVehicle.nUnits       = app.NumberofunitsEditField.Value;
            theVehicle.nHVSystems   = app.NoHVsystemsperunitEditField.Value;
            theVehicle.nCONV        = app.NoCONVperHVsystemEditField.Value;
            theVehicle.nINV         = app.NoINVperHVsystemEditField.Value;
            theVehicle.nAPUs        = app.NoAPSperHVsystemEditField.Value;
            theVehicle.nMotors      = app.NoTMperinverterEditField.Value;
            theVehicle.nAxDrive     = app.NoDrivenaxlesperunitEditField.Value;
            theVehicle.nAxTrail     = app.TotalNoaxlesperunitEditField.Value - theVehicle.nAxDrive;
            theVehicle.notes        = app.NotesTextArea.Value;

            % Dynamics tab
            theVehicle.MaxSpdUnit   = app.MaxSpdUnitDropDown.ValueIndex;
            theVehicle.vMax         = app.MaximalVehicleSpeedEditField.Value;
            theVehicle.unitLength   = app.UnitlengthEditField.Value;
            theVehicle.max_acc      = app.MaxaccelerationEditField.Value;
            theVehicle.jerkrateT    = app.JerkratetractionEditField.Value;
            theVehicle.AccMethod    = app.MethodforaccelerationDropDown.ValueIndex;
            theVehicle.retard_max   = app.MaxretardationEditField.Value;
            theVehicle.jerkrateB    = app.JerkratebrakingEditField.Value;
            theVehicle.retard_min   = app.MinretardationEditField.Value;
            theVehicle.minRetard_v  = app.atspeedEditField.Value;
            theVehicle.residual_ret = app.ResidualretardationEditField.Value;
            
            theVehicle.RetardMethod = app.MethodforretardationDropDown.ValueIndex - 1;
            theVehicle.utilEBrake   = app.MaximiseDBECheckBox.Value;
            theVehicle.TotBrake     = app.TotBrkTable.Data;

            % masses
            theVehicle.TareMassUnit = app.UnittaremassEditField.Value; % ton
            theVehicle.RotMassUnit  = app.EqDynMassEditField.Value; % - theVehicle.TareMassUnit;
            theVehicle.LoadMass     = app.LoadCondTable.Data;
            theVehicle.LoadCond     = app.SelLoadCondDropDown.ValueIndex;

            % resistance
            theVehicle.dvs_a_actual = app.AConstanteffortEditField.Value;
            theVehicle.dvs_a_dash   = app.AMassdependentAfactorEditField.Value;
            theVehicle.dvs_b1_dash  = app.B1MassdependentBfactorEditField.Value;
            theVehicle.dvs_b2       = app.B2SpeeddepBfactorEditField.Value;
            theVehicle.dvs_c_front  = app.CFrontpartofCfactorEditField.Value;
            theVehicle.dvs_c_len    = app.DLengthdeppartofCfactorEditField.Value;
            theVehicle.dvs_c_front2 = app.C2tunnelfactorofCfactorEditField.Value;
            theVehicle.curve_cr0    = app.Tightcurveresistancecr0EditField.Value;
            theVehicle.curve_cr1    = app.Rediusreductioncr1EditField.Value;
            theVehicle.curve_cr2    = app.Axialforcerelatedcr2EditField.Value;

            
            % ==== Traction
            theVehicle.SpdSelectTraction = app.SpdSelectTraction.ValueIndex;
            theVehicle.maxTE = app.MaxTEEditField.Value; % kN
            theVehicle.maxDBE = app.MaxDBEEditField.Value; % kN
            theVehicle.maxPwrTEbase = app.MaxPTEEditField.Value; % kW
            theVehicle.maxPwrDBEbase = app.MaxPDBEEditField.Value; % kW
            theVehicle.maxRatePwrTE = app.PTE_primEditField.Value;
            theVehicle.maxRatePwrDBE = app.PDBE_primEditField.Value;
            theVehicle.vBasePwr = 0;
            theVehicle.vBasePwr2 = app.PTE_WeakfieldSpdEditField.Value;
            theVehicle.vBaseBrk = 0;
            theVehicle.vBaseBrk2 = app.PDBE_WeakfieldSpdEditField.Value;
            theVehicle.dynBrkFadeStart = app.fadeStartEditField.Value;
            theVehicle.dynBrkFadeEnd = app.fadeFinishEditField.Value;
            
            % user defined TE/DBE curves limit
            theVehicle.PwrTE = app.TE_Curve_Table.Data;
            theVehicle.PwrDBE = app.DBE_Curve_Table.Data;
            
            theVehicle.customTERef = app.CustomTEDBEfunctionEditField.Value;

            % ==== Energy
            theVehicle.ULSelectionIdx = app.VoltUnitDropDown.ValueIndex;
            theVehicle.U_Line_Nom = app.NominalLineVoltageEditField.Value;
            theVehicle.uDcLink = app.DClinkVoltageEditField.Value;
            theVehicle.uFreq = app.LineSupplyFreqEditField.Value;
            theVehicle.AuxPowerAC = app.APU_P_out_AC_EditField.Value;
            theVehicle.BACPower = app.APU_P_out_DC_EditField.Value;
            theVehicle.APS_efficiency = app.APUefficiencyEditField.Value;
            theVehicle.AuxPF = app.APUPowerfactorEditField.Value;
            
            % line supply limits
            theVehicle.ILDriveMax_train = app.IL_max_train_TE_EditField.Value;
            theVehicle.ILDriveMax_unit = app.IL_max_unit_TE_EditField.Value;
            theVehicle.ILDriveMax_system = app.IL_max_system_TE_EditField.Value;
            
            theVehicle.ILBrakeMax_train = app.IL_max_train_DBE_EditField.Value;
            theVehicle.ILBrakeMax_unit = app.IL_max_unit_DBE_EditField.Value;
            theVehicle.ILBrakeMax_system = app.IL_max_system_DBE_EditField.Value;

            theVehicle.MinUL = app.MinVoltageattractionEditField.Value;
            theVehicle.OVClevel = app.MaxVoltageatbrakingEditField.Value;
            theVehicle.OVCMethod = app.MethodforILlimitinbrakingDropDown.ValueIndex;
            theVehicle.R_ConCable = app.RcableTrafoCOVEditField.Value;
            theVehicle.rLineInd = app.RFilterReactorEditField.Value;

            % main transformer
            theVehicle.MT_ratio = app.WindingRatioEditField.Value;
            theVehicle.Trafo_FixLoss = app.FixedTransformerlossesEditField.Value;
            theVehicle.trafoIdleLoss = app.NoLoadLossesEditField.Value;
            theVehicle.trafoHarmonicLoss = app.HarmonicLossesEditField.Value;
            theVehicle.trafoIronLoss = app.IronLossesEditField.Value;
            theVehicle.rTrafoPrim = app.RPrimaryWindingEditField.Value;
            theVehicle.rTrafoSec = app.RSecondaryWindingEditField.Value;
            theVehicle.TrafoTemp = app.OperatingtemperatureEditField.Value;

            % energy storage
            theVehicle.ES_capacity = app.ES_Capacity_EditField.Value;
            theVehicle.ES_PowerOut = app.ES_P_out_EditField.Value;
            theVehicle.ES_PowerIn = app.ES_P_in_EditField.Value;
            theVehicle.ES_BoostILimit = app.ESBoostlimitEditField.Value;
            theVehicle.ES_FadeTime = app.FadingtimeEditField.Value;
            theVehicle.ES_Op_Mode = app.ES_OpMode_DropDown.ValueIndex;

            % ==== Drives
            theVehicle.gearRatio = app.Gear_ratio_EditField.Value;
            theVehicle.gearLoss0 = app.GearlossconsttorqueEditField.Value;
            theVehicle.gearLoss1 = app.GearlosstorquedependentEditField.Value;
            theVehicle.gearLoss2 = app.GearlossspeeddependentEditField.Value;
            theVehicle.wheelsize{1} = app.WD_nominal_EditField.Value;
            theVehicle.wheelsize{2} = app.WD_worn_EditField.Value;
            theVehicle.wheelsize{3} = app.WD_new_EditField.Value;
            theVehicle.ws_mass{1} = app.mStatNominalEditField.Value;
            theVehicle.ws_mass{2} = app.mStatWornEditField.Value;
            theVehicle.ws_mass{3} = app.mStatNewEditField.Value;
            theVehicle.ws_rotMass{1} = app.mRotNominalEditField.Value;
            theVehicle.ws_rotMass{2} = app.mRotWornEditField.Value;
            theVehicle.ws_rotMass{3} = app.mRotNewEditField.Value;
            theVehicle.wheelThickness = app.WheelthicknessEditField.Value;
            theVehicle.wheelDensity = app.MaterialdensityEditField.Value;
            theVehicle.ws_selection = app.WheeldiameterselectionDropDown.ValueIndex;
            
            if exist(app.motorFile,'file')
                theVehicle.hasMotorMap = 1;
                theVehicle.motorMethod = 2;
                theVehicle.motorFile = app.motorFile;
                load(app.motorFile, 'motor');
                theVehicle.motorMap = motor;
                theVehicle.rpmBase_Mot = motor.rpmBaseMotor;
                theVehicle.rpmBase_Gen = motor.rpmBaseGenerator;
                app.FixedTractionMotorlossesEditField.Value = [];
            else
                theVehicle.hasMotorMap = 0;
                theVehicle.motorMethod = 1;
                theVehicle.motorFile = [];
                theVehicle.motorMap = [];
            end
            theVehicle.nPoles = app.nPolesEditField.Value;
            theVehicle.TM_FixLoss = app.FixedTractionMotorlossesEditField.Value;
            
            
            theVehicle.Speed0 = [0 0];
            theVehicle.R_MotCable = app.MotorcableresistanceEditField.Value;
            
            % Converters
            theVehicle.swFqConv = app.CovSwFreqEditField.Value;
            theVehicle.CONV_RLoss = app.CovRLossEditField.Value / 1000;
            theVehicle.CONV_FLoss = app.CovSwFreqLossEditField.Value;
            theVehicle.CONV_FRLoss = app.CovFactorSwFreqlossEditField.Value / 1e6;
            theVehicle.CONV_FixLoss = app.CovFixLossEditField.Value / 100;
            theVehicle.INV_RLoss = app.InvRLossEditField.Value / 1000;
            theVehicle.INV_FLoss = app.InvSwFreqLossEditField.Value;
            theVehicle.INV_FRLoss = app.InvFactorSwFreqlossEditField.Value / 1e6;
            theVehicle.INV_FixLoss = app.InvFixLossEditField.Value / 100;
            % Async sw freq in motoring
            theVehicle.asyncSwFqInvMotor = app.InvSwFreqMotorEditField.Value;
            % Async sw freq in braking
            theVehicle.asyncSwFqInvGenerator = app.InvSwFreqGeneratorEditField.Value;
            theVehicle.MotorPulseData = app.MotorPulseTable.Data;
            theVehicle.GeneratorPulseData = app.GeneratorPulseTable.Data;

            % ==== Notching - under construction!!
            theVehicle.notchMethod = app.NothingmethodbasedonDropDown.ValueIndex - 1;
            theVehicle.nNotchT = app.NumberofPowernotchesEditField.Value;
            theVehicle.nNotchB = app.NumberofBrakenotchesEditField.Value;
            theVehicle.notchMinTime = app.MinimumnotchingtimeEditField.Value;
            theVehicle.notchrate = app.MinimumnotchupdelayEditField.Value;
            theVehicle.notchSpdSel = app.SpdSelNothingDropDown.ValueIndex;
            theVehicle.notchDeltaV = [app.NotchOnDeltaVTrcEditField.Value, app.NotchOnDeltaVBrkEditField.Value];
            theVehicle.notchMinV = [app.ApplV_Trc_EditField.Value, app.ApplV_Brk_EditField.Value];
            theVehicle.notchDeltaVAcc = app.ReducationstartdeltaspeedEditField.Value;
            theVehicle.notchMaxAcc = app.MaxaccelerationatstartEditField.Value;

            % Thermal values
            theVehicle.AmbientTemp = app.AmbTempEditfield.Value;

            % --- Main transformer
            theVehicle.IPrimTrafoCont = app.MTrPrimWindCurrentEditfield.Value;
            theVehicle.IPrimTrafoContTC = app.MTrPrimWindTCEditfield.Value;
            theVehicle.I2ndTrafoCont = app.MTrSecWindCurrentEditfield.Value;
            theVehicle.I2ndTrafoContTC = app.MTrSecWindTCEditfield.Value;
            % --- DC line filter
            theVehicle.IDCFilterCont = app.DCLineFiltContCurEditfield.Value;
            theVehicle.IDCFilterContTC = app.DCLineFiltTCCurEditfield.Value;
            % --- Inverter
            theVehicle.IphMCMMax = app.INVMaxIphEditfield.Value;
            theVehicle.IphMCMContSin = app.INVContIphSinusEditfield.Value;
            theVehicle.IphMCMContHex = app.INVContIphHexEditfield.Value;
            theVehicle.IphMCMContTC = app.INVContIphTCEditfield.Value;
            % --- Converter
            theVehicle.IphLCMMax = app.CONVMaxIphEditfield.Value;
            theVehicle.IphLCMCont = app.CONVContIphSinusEditfield.Value;
            theVehicle.IphLCMContTC = app.CONVContIphTCEditfield.Value;
            % --- Traction Motor
            theVehicle.IstatTMMax = app.TMIphMaxEditfield.Value;
            theVehicle.IstatTMCont = app.TMIphContEditfield.Value;
            theVehicle.IstatTMContTC = app.TMIphTCEditfield.Value;
            theVehicle.TMventType = app.TMcoolingtypeDropDown.ValueIndex;
            % --- Brake resistor data
            theVehicle.BRThermC = app.BRThermCapacityEditfield.Value;
            theVehicle.BRThermTC_FanOnLow = app.BRTCFanLowSpEditfield.Value;
            theVehicle.BRThermTC_FanOnHigh = app.BRTCFanHiSpEditfield.Value;
            theVehicle.BRThermTC_FanOff = app.BRTCFanOffEditfield.Value;
            theVehicle.BRThermTC_Tempfactor = app.BRTCTempFactorEditfield.Value;
            theVehicle.BRFanOnTemp = app.BRFanOnTempEditfield.Value;
            theVehicle.BRFanHighSpeedTemp = app.BRFanHiSpdHystONEditfield.Value;
            theVehicle.BRFanOffTemp = app.BRFanHiSpdHystOFFEditfield.Value;
            theVehicle.BRFanSpeedSel = app.SpdSelectBRFan.ValueIndex;
            theVehicle.BRFanOffSpeed = app.BRFanOffVclSpdEditfield.Value;
            theVehicle.BRTemp_High = app.BRHighTmpEditfield.Value;
            theVehicle.BRTemp_Max = app.BRMaxTmpEditfield.Value;
            
            % update info
            app.vehicle_fileData = theVehicle;
        end % layout2data

        function update_infos(app)
            nUnits = app.vehicle_fileData.nUnits;
            nHV = app.vehicle_fileData.nHVSystems;
            app.LengthInfo.Text = ['Total train length: ' num2str(app.vehicle_fileData.unitLength * nUnits,'%5.2f') ' m'];
            app.totHVInfo.Text = [num2str(nHV * nUnits) ' High Voltage Systems'];
            nCONV = app.vehicle_fileData.nCONV;
            app.totCOVinfo.Text = [num2str(nCONV * nHV) ' High Voltage Systems per Unit'];
            nINV = app.vehicle_fileData.nINV;
            app.totINVinfo.Text = [num2str(nCONV * nHV * nINV) ' Traction Inverters per Unit'];
            nMotors = app.vehicle_fileData.nMotors;
            app.totTMinfo.Text = [num2str(nCONV * nHV * nINV * nMotors) ' Traction Motors per Unit'];
            nAPUs = app.vehicle_fileData.nAPUs;
            app.totAPUinfo.Text = [num2str(nHV * nAPUs) ' Auxiliary Converters per Unit'];
            nAxDrive = app.vehicle_fileData.nAxDrive;
            nAxTrail = app.vehicle_fileData.nAxTrail;
            app.totAxlesinfo.Text = [num2str(nUnits * nAxDrive) ' driven axles in the consist'];
            app.motorisationinfo.Text = [num2str(round(nAxDrive/(nAxDrive + nAxTrail) * 100)) '% Motorisation'];
            calc_data(app);
        end % update_infos
        
        function lims = get_ylims(~, curve, lims)
            lims(1) = min([0, min(curve) * 1.05, lims(1)]);
            lims(2) = max([0, max(curve) * 1.05, lims(2)]);
        end % get_ylims

        function calc_data(app)
            data(app.nDataPoints+2,2:10) = 0;
            limitation.thermEffort = 1;
            limitation.thermPower = 1;
            sim_config.useSubStations = 0;
            sim_config.ThermReduction = 0;
            vehicle = ShSim_calcVehicle(app.vehicle_fileData);
            data(app.nDataPoints+2, 1) = vehicle.vMax;
            local_vehicle = ShSim_CalcMassRes(vehicle);
            loadCase = local_vehicle.LoadCondition;
            switch app.SpdSelectTraction.ValueIndex
                case 1
                    spd_scale = 3.6;
                case 2
                    spd_scale = 2.2369;
                case 3
                    spd_scale = 1;
                case 4
                    spd_scale = local_vehicle.gearRatio * local_vehicle.nPoles/(local_vehicle.wheelsize{local_vehicle.ws_selection} * pi * 2);
            end
            graphSel = app.GrafSelDropDown.ValueIndex;
            
            for i1 = 0:app.nDataPoints
                v = local_vehicle.vMax * i1 / app.nDataPoints;
                F_davies_BC = local_vehicle.davies_b * v * 3.6 + local_vehicle.davies_c1 * (v * 3.6)^2;
                TE_demand = ShSim_maxTE(inf, local_vehicle, v, limitation);
                DBE_demand = ShSim_maxDBE(-inf, local_vehicle, v, limitation);
                FTotBrk = ShSim_maxBE(local_vehicle, v, 0, local_vehicle.davies_a(loadCase) + F_davies_BC);
                [F_TE, UL_TE, UDC_TE, IL_TE, ~, Iph_TE,~,~,~,~, Loss_TE] =...
                    ShSim_Efforts(TE_demand, local_vehicle, v, [], sim_config, limitation);
                [F_DBE, UL_DBE, UDC_DBE, IL_DBE, P_Br, Iph_DBE,~,~,~,~, Loss_DBE] =...
                    ShSim_Efforts(DBE_demand, local_vehicle, v, [], sim_config, limitation);

                data(i1 + 1, 1) = v;
                data(i1 + 1, 2) = F_TE;
                data(i1 + 1, 3) = F_DBE;
                data(i1 + 1, 4) = FTotBrk;
                data(i1 + 1, 5) = local_vehicle.davies_a(loadCase) + F_davies_BC;
                data(i1 + 1, 6) = IL_TE;
                data(i1 + 1, 7) = IL_DBE;
                data(i1 + 1, 8) = UL_TE;
                data(i1 + 1, 9) = UL_DBE;
                

            end
            switch graphSel 
                case 1 % effort vs. speed
                    curve1 = data(:,2); % TE
                    curve2 = data(:,3); % DBE
                    curve3 = min(data(:,4) - data(:,3), 0); % FBr
                    curve4 = data(:,4); % TotBr
                    curve5 = data(:,5); % resistance
                case 2 % power vs. speed
                    curve1 = data(:,1) .* data(:,2);
                    curve2 = data(:,1) .* data(:,3);
                    curve3 = data(:,1) .* min(data(:,4) - data(:,3), 0);
                    curve4 = data(:,1) .* data(:,4);
                    curve5 = data(:,1) .* data(:,5);
                case 3 % Acceleration vs. speed
                    curve1 = (data(:,2) - data(:, 5)) / local_vehicle.totMass(loadCase);
                    curve2 = (data(:,3) - data(:, 5)) / local_vehicle.totMass(loadCase);
                    curve3 = (min(data(:,4) - data(:,3), 0) - data(:, 5)) / local_vehicle.totMass(loadCase);
                    curve4 = data(:,4) / local_vehicle.totMass(loadCase);
                    curve5 = - data(:, 5) / local_vehicle.totMass(loadCase);
                case 4 % Line power vs. speed
                    curve1 = data(:,6) .* data(:,8) / 1000;
                    curve2 = data(:,7) .* data(:,9) / 1000;
                    curve3 = [];
                    curve4 = [];
                    curve5 = [];
                case 5 % losses vs. speed
                    curve1 = data(:,6) .* data(:,8) / 1000 - data(:,1) .* data(:,2);
                    curve2 = data(:,7) .* data(:,9) / 1000 - data(:,1) .* data(:,3);
                    curve3 = [];
                    curve4 = [];
                    curve5 = [];
                case 6 % Efficiency vs. speed
                    p_aux = local_vehicle.AuxPower/local_vehicle.APS_efficiency;
                    p_line = data(:,6) .* data(:,8) / 1000 - p_aux;
                    p_rail = data(:,1) .* data(:,2);
                    curve1 = p_rail ./ p_line * 100;
                    p_line = data(:,7) .* data(:,9) / 1000 - p_aux;
                    p_rail = data(:,1) .* data(:,3);
                    curve2 = p_line ./ p_rail * 100;
                    curve3 = [];
                    curve4 = [];
                    curve5 = [];

            end
                
            hold(app.TractionGraph,"off");
            plot(app.TractionGraph, data(:,1) * spd_scale, curve1,'DisplayName','TE');
            ylims = get_ylims(app, curve1, [0, 0]);
            hold(app.TractionGraph,"on");
            grid(app.TractionGraph,"on");
            plot(app.TractionGraph, data(:,1) * spd_scale, curve2, 'DisplayName','DBE');
            ylims = get_ylims(app, curve2, ylims);
            if ~isempty(curve3)
                plot(app.TractionGraph, data(:,1) * spd_scale, curve3, 'DisplayName', 'FBr');
                ylims = get_ylims(app, curve3, ylims);
            end
            if ~isempty(curve4)
                plot(app.TractionGraph, data(:,1) * spd_scale, curve4, '--', 'DisplayName', 'TotBr');
                ylims = get_ylims(app, curve4, ylims);
            end
            if ~isempty(curve5)
                plot(app.TractionGraph, data(:,1) * spd_scale, curve5, '--', 'DisplayName', 'Resistance');
                ylims = get_ylims(app, curve5, ylims);
            end
            ylim(app.TractionGraph, ylims);
            legend(app.TractionGraph)


            gear = local_vehicle.gearRatio;
            wheeldiameter = local_vehicle.wheelsize{local_vehicle.ws_selection};
            nRpm = data(:,1) / (wheeldiameter * pi) * gear * 60;
            Tq_mot = data(:,2) * wheeldiameter * 1000 / (2 * gear * local_vehicle.nAxDrive); % Nm
            Tq_mot = Tq_mot + local_vehicle.gearLoss0 + abs(Tq_mot * local_vehicle.gearLoss1) + nRpm * local_vehicle.gearLoss2;
            Tq_gen = data(:,3) * wheeldiameter * 1000 / (2 * gear * local_vehicle.nAxDrive); % Nm
            Tq_gen = Tq_gen + local_vehicle.gearLoss0 + abs(Tq_gen * local_vehicle.gearLoss1) + nRpm * local_vehicle.gearLoss2;
            hold(app.DrivesGraph, "off");
            plot(app.DrivesGraph, nRpm, Tq_mot, 'DisplayName', 'Motoring');
            hold(app.DrivesGraph, "on");
            plot(app.DrivesGraph, nRpm, Tq_gen, 'DisplayName', 'Generator');
            grid(app.DrivesGraph,"on");

            app.simulated_data = data;
        end

        % function old_calc_data(app)
        %     data(app.nDataPoints+2,2:10) = 0;
        %     data(app.nDataPoints+2, 1) = app.vehicle.vMax;
        %     limitation.thermEffort = 1;
        %     limitation.thermPower = 1;
        %     sim_config.useSubStations = 0;
        %     sim_config.ThermReduction = 0
        %     local_vehicle = ShSim_CalcMassRes(app.vehicle);
        %     loadCase = local_vehicle.LoadCondition;
        %     nUnits = local_vehicle.nUnits;
        %     switch app.SpdSelectTraction.ValueIndex
        %         case 1
        %             spd_scale = 3.6;
        %         case 2
        %             spd_scale = 2.2369;
        %         case 3
        %             spd_scale = 1;
        %         case 4
        %             spd_scale = local_vehicle.gearRatio * local_vehicle.nPoles/(local_vehicle.wheelsize{local_vehicle.ws_selection} * pi * 2);
        %     end
        %     graphSel = app.GrafSelDropDown.ValueIndex;
        % 
        %     for i1 = 0:app.nDataPoints
        %         v = app.vehicle.vMax * i1 / app.nDataPoints;
        %         F_davies_BC = local_vehicle.davies_b(loadCase) * v * 3.6 + local_vehicle.davies_c1(nUnits) * (v * 3.6)^2;
        %         TE_demand = ShSim_maxTE(inf, app.vehicle, v, limitation);
        %         DBE_demand = ShSim_maxDBE(-inf, app.vehicle, v, limitation);
        %         F_Tot = ShSim_maxBE(app.vehicle, v, 0, local_vehicle.davies_a(loadCase) + F_davies_BC);
        %         [F_TE, ULine, ULink, ILine, BRPower,iMotor,IS,mSlip,fMode,ESPower,PropLoss] =...
        %             ShSim_Efforts(TE_demand, app.vehicle, v, [], sim_config, limitation);
        %         [F_DBE, ULine, ULink, ILine, BRPower,iMotor,IS,mSlip,fMode,ESPower,PropLoss] =...
        %             ShSim_Efforts(DBE_demand, app.vehicle, v, [], sim_config, limitation);
        %         % F_TE = min([app.vehicle.maxTE, app.vehicle.maxPwrTEbase / v, app.vehicle.maxPwrTEbase * app.vehicle.vBasePwr2^2 / v^3]);
        %         % F_DBE = min([app.vehicle.maxDBE, app.vehicle.maxPwrDBEbase / v, app.vehicle.maxPwrDBEbase * app.vehicle.vBaseBrk2^2 / v^3]);
        %         % Brake fading
        %         if v < app.vehicle.dynBrkFadeStart       % If the speed is less than higher fading speed
        %             d = (v - app.vehicle.dynBrkFadeEnd) / (app.vehicle.dynBrkFadeStart - app.vehicle.dynBrkFadeEnd);    % linear ramp down to lower fading speed
        %             F_DBE = max([min([F_DBE, d * app.vehicle.maxDBE]), 0]);
        %         end
        % 
        %         F_acc = app.vehicle.max_acc * local_vehicle.totMass;
        %         F_ret = min([app.vehicle.retard_max, app.vehicle.retard_min * app.vehicle.retard_min_speed / v]) * local_vehicle.totMass;
        % 
        % 
        %         switch app.vehicle.AccMethod
        %             case 1 % Davies A to be added
        %                 F_acc = F_acc + local_vehicle.davies_a(loadCase);
        %             case {2, 3}
        %                 F_acc = F_acc + local_vehicle.davies_a(loadCase) + F_davies_BC;
        %         end % switch
        %         switch app.vehicle.RetardMethod
        %             case 1 % Davies A to be added
        %                 F_ret = F_ret + local_vehicle.davies_a(loadCase);
        %             case {2, 3}
        %                 F_ret = F_ret + local_vehicle.davies_a(loadCase) + F_davies_BC;
        %         end
        %         % power curve traction
        %         if any(app.vehicle.PwrTE_f ~= inf) && size(app.vehicle.PwrTE_f,2)>1
        %             if v <= app.vehicle.PwrTE_v(1)
        %                 FTE_pwr = app.vehicle.PwrTE_f(1);
        %             elseif v < app.vehicle.PwrTE_v(2)
        %                 d = v / app.vehicle.PwrTE_v(2);
        %                 FTE_pwr = d * app.vehicle.PwrTE_f(2) + (1 - d) * app.vehicle.PwrTE_f(1);
        %             else
        %                 x = find(app.vehicle.PwrTE_v > v, 1, 'first') - 1;
        %                 if isempty(x)
        %                     x = size(app.vehicle.PwrTE_v, 2) - 1;
        %                 end
        %                 d = (v - app.vehicle.PwrTE_v(x)) / (app.vehicle.PwrTE_v(x + 1) - app.vehicle.PwrTE_v(x));
        %                 P = d * app.vehicle.PwrTE_p(x + 1) + (1 - d) * app.vehicle.PwrTE_p(x);
        %                 FTE_pwr = P / v;
        %             end
        %         else
        %             FTE_pwr = inf;
        %         end
        %         % power curve dynamic brake
        %         if any(app.vehicle.PwrDBE_f ~= inf) && size(app.vehicle.PwrDBE_f,2)>1
        %            if v <= app.vehicle.PwrDBE_v(1)         % if speed is less than the first speed point (=lower fading speed)
        %                FDBE_pwr = app.vehicle.PwrDBE_f(1);           % No Power
        %                if v > app.vehicle.dynBrkFadeStart
        %                    FDBE_pwr = app.vehicle.maxDBE;
        %                end
        %            elseif v < app.vehicle.PwrDBE_v(2)
        %                d = v / app.vehicle.PwrDBE_v(2);    %linear effort 
        %                FDBE_pwr = (1 - d) * app.vehicle.PwrDBE_f(1) + d * app.vehicle.PwrDBE_f(2);
        %            else
        %                x = find(app.vehicle.PwrDBE_v > v, 1, 'first') - 1;
        %                if isempty(x)
        %                    x = size(app.vehicle.PwrDBE_v, 2) - 1;
        %                end
        %                d = (v - app.vehicle.PwrDBE_v(x)) / (app.vehicle.PwrDBE_v(x + 1) - app.vehicle.PwrDBE_v(x));
        %                P = d * app.vehicle.PwrDBE_p(x + 1) + (1 - d) * app.vehicle.PwrDBE_p(x);
        %                FDBE_pwr = P / v;
        %            end
        %         else
        %             FDBE_pwr = inf;
        %         end
        %         % power curve total brake
        %         FTotBrk = inf;
        %         if any(app.vehicle.TotBrake_f ~= inf) && size(app.vehicle.TotBrake_f, 2)>1
        %             x = find(app.vehicle.TotBrake_v > v, 1, 'first') - 1;
        %             if isempty(x)
        %                 x = size(app.vehicle.TotBrake_f, 2) - 1;
        %             end
        %             d = (v - app.vehicle.TotBrake_v(x)) / (app.vehicle.TotBrake_v(x + 1) - app.vehicle.TotBrake_v(x));
        %             if x > 1
        %                 P = d * app.vehicle.TotBrake_p(x + 1) + (1 - d) * app.vehicle.TotBrake_p(x);
        %                 Brk_F = P / v;
        %             else
        %                 Brk_F = d * app.vehicle.TotBrake_f(x + 1) + (1 - d) * app.vehicle.TotBrake_f(x);
        %             end
        %             FTotBrk = min([Brk_F, FTotBrk]);
        %         end
        %         data(i1 + 1, 1) = v;
        %         data(i1 + 1, 2) = min([F_TE F_acc FTE_pwr]);
        %         dbe_max = min([F_DBE FDBE_pwr]);
        %         data(i1 + 1, 3) = -min([F_DBE F_ret FTotBrk FDBE_pwr]);
        %         fricbrk = max([min([F_ret FTotBrk]) - dbe_max, 0]);
        %         data(i1 + 1, 4) = -fricbrk;
        %         data(i1 + 1, 5) = local_vehicle.davies_a(loadCase) + F_davies_BC;
        %     end
        %     switch graphSel 
        %         case 1 % effort vs. speed
        %             curve1 = data(:,2);
        %             curve2 = data(:,3);
        %             curve3 = data(:,4);
        %             curve4 = data(:,3) + data(:,4);
        %             curve5 = data(:,5);
        %         case 2 % power vs. speed
        %             curve1 = data(:,1) .* data(:,2);
        %             curve2 = data(:,1) .* data(:,3);
        %             curve3 = data(:,1) .* data(:,4);
        %             curve4 = data(:,1) .* (data(:,3) + data(:,4));
        %             curve5 = data(:,1) .* data(:,5);
        %         case 3 % Acceleration vs. speed
        %             curve1 = (data(:,2) - data(:, 5)) / local_vehicle.totMass(loadCase);
        %             curve2 = (data(:,3) - data(:, 5)) / local_vehicle.totMass(loadCase);
        %             curve3 = (data(:,4) - data(:, 5)) / local_vehicle.totMass(loadCase);
        %             curve4 = (data(:,3) + data(:,4) - data(:, 5)) / local_vehicle.totMass(loadCase);
        %             curve5 = - data(:, 5) / local_vehicle.totMass(loadCase);
        %         case 4 % Line power
        %             curve1 = data(:,1) .* data(:,2);
        %             curve2 = data(:,1) .* data(:,3);
        %             curve3 = [];
        %             curve4 = [];
        %             curve5 = [];
        %     end
        % 
        %     hold(app.TractionGraph,"off");
        %     plot(app.TractionGraph, data(:,1) * spd_scale, curve1,'DisplayName','TE');
        %     hold(app.TractionGraph,"on");
        %     grid(app.TractionGraph,"on");
        %     plot(app.TractionGraph, data(:,1) * spd_scale, curve2, 'DisplayName','DBE');
        %     plot(app.TractionGraph, data(:,1) * spd_scale, curve3, 'DisplayName', 'FBr');
        %     plot(app.TractionGraph, data(:,1) * spd_scale, curve4, '--', 'DisplayName', 'TotBr');
        %     if ~isempty(curve5)
        %         plot(app.TractionGraph, data(:,1) * spd_scale, curve5, '--', 'DisplayName', 'Resistance');
        %     end
        %     legend(app.TractionGraph)
        %     app.simulated_data = data;
        % end

        function results = assignif(~,val1,val2)
            if isempty(val1)
                results = val2;
            else
                results = val1;
            end
        end % assignif
        
        
        function UpdatePulseGraphs(app)
            
            function [Freqline, Voltline] = data2SwFrline(data, asyncSwFr, maxHz, baseSpdHz, udc)
                if isempty(data)
                    data = {'Async', 0, inf};
                end
                Freqline(size(data,1) * 2, 2) = 0;
                Voltline(size(data,1) * 2, 2) = 0;
                for i1 = 1:size(data,1)
                    idx = i1 * 2 - 1;
                    Freqline(idx, 1) = data{i1, 2};
                    Freqline(idx + 1, 1) = min([data{i1, 3}, maxHz]);
                    Voltline(idx, 1) = data{i1, 2};
                    Voltline(idx + 1, 1) =min([data{i1, 3}, maxHz]);
                    if strcmp(data(i1,1),'Async')
                        Freqline(idx,2) = asyncSwFr;
                        Freqline(idx + 1, 2) = asyncSwFr;
                        Voltline(idx,2) = data{i1, 2} / baseSpdHz * sqrt(6) / pi * udc;
                        Voltline(idx + 1, 2) = data{i1, 3} / baseSpdHz * sqrt(6) / pi * udc;
                    else
                        Freqline(idx,2) = str2double(data{i1,1}) * min([data{i1,2}, maxHz]);
                        Freqline(idx + 1, 2) = str2double(data{i1,1}) * min([data{i1,3}, maxHz]);
                        Voltline(idx,2) = min([(data{i1, 2} + data{i1, 3}) / (2 * baseSpdHz) * udc, udc]) * sqrt(6) / pi;
                        Voltline(idx + 1, 2) = Voltline(idx,2);
                    end
                end
            end % data2SwFrline


            if app.vehicle_fileData.hasMotorMap
                maxRPM = app.vehicle_fileData.vMax / (app.vehicle_fileData.wheelsize{app.vehicle_fileData.ws_selection} * pi) * app.vehicle_fileData.gearRatio * 60;
                MaxHz = maxRPM * app.vehicle_fileData.nPoles / 120;
                BaseSpdHzMot = app.vehicle_fileData.rpmBase_Mot * app.vehicle_fileData.nPoles / 120;
                BaseSpdHzGen = app.vehicle_fileData.rpmBase_Gen * app.vehicle_fileData.nPoles / 120;
                [SwFrCurveMot, VoltCurveMot] = data2SwFrline(app.MotorPulseTable.Data, app.vehicle_fileData.asyncSwFqInvMotor, MaxHz, BaseSpdHzMot, app.vehicle_fileData.uDcLink);
                [SwFrCurveGen, VoltCurveGen] = data2SwFrline(app.GeneratorPulseTable.Data, app.vehicle_fileData.asyncSwFqInvMotor, MaxHz, BaseSpdHzGen, app.vehicle_fileData.uDcLink);
                hold(app.SwFreqFigure, "off")
                plot(app.SwFreqFigure, SwFrCurveMot(:,1), SwFrCurveMot(:,2),'DisplayName','Motoring')
                grid(app.SwFreqFigure, "on");
                hold(app.SwFreqFigure, "on");
                plot(app.SwFreqFigure, SwFrCurveGen(:,1), SwFrCurveGen(:,2),'DisplayName','Generator')
                yl = ylim(app.SwFreqFigure);
                yl(1) = 0;
                ylim(app.SwFreqFigure, yl);

                hold(app.UPhFigure, "off");
                plot(app.UPhFigure, VoltCurveMot(:,1), VoltCurveMot(:,2),'DisplayName','Motoring');
                grid(app.UPhFigure, "on");
                hold(app.UPhFigure, "on");
                plot(app.UPhFigure, VoltCurveGen(:,1), VoltCurveGen(:,2),'DisplayName','Generator');
            end
        end % UpdatePulseGraphs
        
        function CleanDirty(app)
            if exist(app.CurrentFile, 'file')
                [~, f] = fileparts(app.CurrentFile);
                [~, attr] = fileattrib(app.CurrentFile);
                if attr.UserWrite
                    name = ['Shogun Vehicle Editor - ', f];
                else
                    name = ['Shogun Vehicle Editor - ', f, ' [Read-only]'];
                end
            else
                name = 'Shogun Vehicle Editor';
            end
            app.ShogunVehicleEditorUIFigure.Name = name;
            app.Dirty = false;
        end % CleanDirty
    
        % function pos = MoveIntoScreen(~, pos)
        %     % Get monitor positions: each row = [left bottom width height]
        %     monitors = get(0, 'MonitorPositions');
        %     % add title bar height
        %     titlebarheight = 40;
        %     pos(3) = pos(3) + titlebarheight;
        %     % Choose the monitor where most of the figure currently lies
        %     overlapArea = zeros(size(monitors,1),1);
        %     for i = 1:size(monitors, 1)
        %         mon = monitors(i, :);
        %         % Compute overlap rectangle
        %         overlapWidth  = max(0, min(pos(1) + pos(3), mon(1) + mon(3)) - max(pos(1), mon(1)));
        %         overlapHeight = max(0, min(pos(2) + pos(4), mon(2) + mon(4)) - max(pos(2), mon(2)));
        %         overlapArea(i) = overlapWidth * overlapHeight;
        %     end
        %     [~, bestMonIdx] = max(overlapArea);
        %     mon = monitors(bestMonIdx,:);
        % 
        %     % Clamp figure coordinates to stay inside chosen monitor
        %     newLeft   = max(mon(1), min(pos(1), mon(1)+mon(3)-pos(3)));
        %     newBottom = max(mon(2), min(pos(2), mon(2)+mon(4)-pos(4)));
        % 
        %     % Apply new position
        %     pos = [newLeft newBottom pos(3) pos(4)];
        %     pos(3) = pos(3) - titlebarheight;
        % end % MoveIntoScreen

        
        function DirIfExist(app, dirs)
            for i1 = 1:size(dirs, 2)
                if exist(dirs{i1}, 'dir')
                    cd(dirs{i1});
                else
                    return
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ShogunVehicleEditorUIFigure.Visible = 'off';
            app.caller = [];
            drawnow;
            cd(getenv('APPDATA'));
            Shogun_Settings.workingdirectory = [];
            Shogun_Settings.lastFile = [];
            if ~exist('ShogunSim_Settings','dir')
                err = mkdir('ShogunSim_Settings');
                cd('ShogunSim_Settings')
            else
                cd('ShogunSim_Settings')
            end
            if ~exist('Shogun_Settings.mat','file')
                Shogun_Settings.VehicleEditorPosition = app.ShogunVehicleEditorUIFigure.Position;
                save('Shogun_Settings.mat','Shogun_Settings');
            end

            load('Shogun_Settings.mat','Shogun_Settings');
            app.settings_file = fullfile(pwd,'Shogun_Settings.mat');
            app.working_directory = Shogun_Settings.workingdirectory;
            if ~exist(app.working_directory, 'dir')
                p = uigetdir('Select a working directory');
                if p
                    Shogun_Settings.workingdirectory = p;
                    save('Shogun_Settings.mat','Shogun_Settings');
                else
                    delete(app)
                    return
                end
            end
            app.simulated_data = [];
            cd(fileparts(mfilename("fullpath")));
            addpath('code')
            app.LoadCondTable.Data = {'AW0', 0, 'Empty'};
            layout2data(app);
            update_infos(app);
            app.defaultVehicle = app.vehicle_fileData;
            % app.vehicle_fileData = ShSim_CalcMassRes(app.vehicle_fileData);
            % data2layout(app);
            app.CurrentFile = [];
            calc_data(app);
            if isfield(Shogun_Settings, 'VehicleEditorPosition')
                r = MoveIntoScreen(Shogun_Settings.VehicleEditorPosition);
                app.ShogunVehicleEditorUIFigure.Position = r;
            end
            CleanDirty(app);
            app.isReadOnly = false;
            app.motorFile = [];
            app.ShogunVehicleEditorUIFigure.Visible = 'on';
        end

        % Close request function: ShogunVehicleEditorUIFigure
        function ShogunVehicleEditorUIFigureCloseRequest(app, event)
            if app.Dirty
                answ = questdlg('Save changes before closing?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.ShogunVehicleEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            if exist(app.settings_file, 'file')
                load(app.settings_file, 'Shogun_Settings');
                Shogun_Settings.VehicleEditorPosition = app.ShogunVehicleEditorUIFigure.Position;
                save(app.settings_file, 'Shogun_Settings');
            end
            delete(app)
        end

        % Menu selected function: NewvehicleMenu
        function NewvehicleMenuSelected(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?',event.Source.Tag,'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.ShogunVehicleEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            app.CurrentFile = [];
            app.vehicle_fileData = app.defaultVehicle;
            data2layout(app);
            update_infos(app);
            CleanDirty(app);
        end

        % Menu selected function: OpenvehiclefileMenu
        function OpenvehiclefileMenuSelected(app, event)
            if app.Dirty
                answ = questdlg('Save changes before loading a new vehicle?', event.Source.Tag, 'Yes', 'No', 'Cancel', 'Yes');
                if strcmp(answ,'Cancel')
                    figure(app.ShogunVehicleEditorUIFigure);
                    return
                elseif strcmp(answ,'Yes')
                    SavevehiclefileMenuSelected(app,event)
                end
            end
            cd(app.working_directory);
            if exist('Vehicles','dir')
                cd('Vehicles')
            end
            [f,p] = uigetfile('*.mat', 'Select Vehicle file to be loaded');
            cd(fileparts(mfilename("fullpath")));
            if f
                app.CurrentFile = fullfile(p,f);
                % check if read only
                [~, attr] = fileattrib(app.CurrentFile);

                % app.working_directory = p;
                load(fullfile(p, f), 'vehicle_data');
                app.vehicle_fileData = vehicle_data;
                
                data2layout(app);
                update_infos(app);
                CleanDirty(app);

                app.isReadOnly = ~attr.UserWrite;
            end
            figure(app.ShogunVehicleEditorUIFigure);
        end

        % Menu selected function: SavevehiclefileMenu
        function SavevehiclefileMenuSelected(app, event)
            vehicle_data = app.vehicle_fileData;
            if isempty(app.CurrentFile) % not saved before
                SaveasMenuSelected(app, event)
            elseif exist(app.CurrentFile,'file')
                try
                    save(app.CurrentFile,'vehicle_data');
                    CleanDirty(app);
                catch
                    dlg = warndlg('Vehicle file could not be saved');
                    uiwait(dlg);
                    return
                end
                if ~isempty(app.caller)
                    app.caller.VehicleDataHasChanged(app.caller)
                end
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            cd(app.working_directory);
            if exist('Vehicles', 'dir')
                cd('Vehicles');
            end
            [f,p] = uiputfile('*.mat','Save vehicle as...');
            cd(fileparts(mfilename("fullpath")));
            if f
                layout2data(app);
                vehicle_data = app.vehicle_fileData;
                app.CurrentFile = fullfile(p,f);
                try
                    save(app.CurrentFile,'vehicle_data');
                    CleanDirty(app);
                catch
                    dlg = warndlg('Vehicle file could not be saved');
                    uiwait(dlg);
                    return
                end
                if ~isempty(app.caller)
                    app.caller.VehicleDataHasChanged(app.caller)
                end
            end
            figure(app.ShogunVehicleEditorUIFigure);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            close(app.ShogunVehicleEditorUIFigure);
        end

        % Menu selected function: SetProjectDirectoryMenu
        function SetProjectDirectoryMenuSelected(app, event)
            if exist(app.working_directory,'dir')
                cd(app.working_directory);
            end
            p = uigetdir('Select project directory...');
            if p
                % need to check that the necessary folders exist
                app.working_directory = p;
                % save the settings file
                load(app.settings_file,'Shogun_Settings');
                Shogun_Settings.workingdirectory = app.working_directory;
                save(app.settings_file,'Shogun_Settings');
            end
            cd(fileparts(mfilename("fullpath")));
            figure(app.ShogunVehicleEditorUIFigure);
        end

        % Menu selected function: GenerateVehiclePerformanceReportMenu
        function GenerateVehiclePerformanceReportMenuSelected(app, event)
            if app.Dirty || ~exist(app.CurrentFile,'file')
                dlg = warndlg('Please save the data before generating performance report');
                uiwait(dlg);
                return
            end
            [p,f,x] = fileparts(app.CurrentFile);
            data_main.vehicle_file.path = p;
            data_main.vehicle_file.fName = [f x];
            data_main.vehicle2_file.path = '';
            data_main.vehicle2_file.fName = '';
            sim_config.ShSimVer = app.app_version;
            sim_config.workingDirectory = app.working_directory;
            sim_config.ThermReduction = 0;
            sim_config.useSubStations = 0;
            ShSim_VehiclePerfReport(data_main,sim_config)
        end

        % Value changed function: AConstanteffortEditField, 
        % ...and 108 other components
        function GeneralValueChanged(app, event)
            if app.isReadOnly
                switch event.Source.Type
                    case {'uieditfield', 'uinumericeditfield', 'uitextarea'}
                        event.Source.Value = event.PreviousValue;
                    case 'uidropdown'
                        event.Source.ValueIndex = event.PreviousValueIndex;
                    otherwise
                        event.Source.Type
                end
            else
                layout2data(app);
                update_infos(app);
                if ~isempty(app.CurrentFile)
                    [~,f] = fileparts(app.CurrentFile);
                    app.ShogunVehicleEditorUIFigure.Name = ['Shogun Vehicle Editor - ' f '*'];
                else
                    app.ShogunVehicleEditorUIFigure.Name = 'Shogun Vehicle Editor*';
                end
                app.Dirty = 1;
            end
        end

        % Value changed function: EqDynMassEditField, SelLoadCondDropDown, 
        % ...and 1 other component
        function UnitMassChanged(app, event)
            if app.isReadOnly
                switch event.Source.Type
                    case {'uieditfield', 'uinumericeditfield', 'uitextarea'}
                        event.Source.Value = event.PreviousValue;

                    otherwise
                        event.Source.Type
                end
            else
                tvehicle = app.vehicle_fileData;
                tvehicle.TareMassUnit = app.UnittaremassEditField.Value; % ton
                tvehicle.RotationMassUnit = assignif(app, app.EqDynMassEditField.Value, 0); % - theVehicle.TareMassUnit;
                tvehicle.LoadMassUnit = num2cell([app.LoadCondTable.Data{:,2}]/1000);
                tvehicle.LoadMassName = app.LoadCondTable.Data(:,1)';
                tvehicle.LoadMassDef = app.LoadCondTable.Data(:,3)';
                tvehicle.LoadCondition = app.SelLoadCondDropDown.ValueIndex;
                tvehicle.ws_mass{1} = app.mStatNominalEditField.Value;
                tvehicle.ws_mass{2} = app.mStatWornEditField.Value;
                tvehicle.ws_mass{3} = app.mStatNewEditField.Value;
                tvehicle.ws_rotMass{1} = app.mRotNominalEditField.Value;
                tvehicle.ws_rotMass{2} = app.mRotWornEditField.Value;
                tvehicle.ws_rotMass{3} = app.mRotNewEditField.Value;
                tvehicle.ws_selection = app.WheeldiameterselectionDropDown.ValueIndex;
                tvehicle = ShSim_calcVehicle(tvehicle);
                tvehicle = ShSim_CalcMassRes(tvehicle);
                m_dyn = tvehicle.totMass(tvehicle.LoadCondition);
                for i1 = 1:size(app.TotBrkTable.Data,1)
                    app.TotBrkTable.Data{i1,4} = app.TotBrkTable.Data{i1,2} / m_dyn;
                end
                for i1 = 1:size(app.TE_Curve_Table.Data,1)
                    app.TE_Curve_Table.Data{i1,4} = app.TE_Curve_Table.Data{i1,2} / m_dyn;
                end
                for i1 = 1:size(app.DBE_Curve_Table.Data,1)
                    app.DBE_Curve_Table.Data{i1,4} = app.DBE_Curve_Table.Data{i1,2} / m_dyn;
                end

                tot = tvehicle.nAxDrive + tvehicle.nAxTrail;
                tvehicle.statmassLoad
                mdyn_recommended = tvehicle.statmassLoad * (tvehicle.nAxDrive * 0.1 + tvehicle.nAxTrail * 0.05)/(tvehicle.nAxDrive + tvehicle.nAxTrail);
                app.Label.Text = num2str(round(mdyn_recommended*100)/100);
                GeneralValueChanged(app, event);
            end
        end

        % Button pushed function: AddDBEPwrPoint, AddTEPwrPoint, 
        % ...and 1 other component
        function AddPointPushed(app, event)
            if ~app.isReadOnly
                vcl = ShSim_calcVehicle(app.vehicle_fileData);
                local_vehicle = ShSim_CalcMassRes(vcl);
                switch event.Source.Tag
                    case 'TotBrake'
                        list_table = app.TotBrkTable;
                        spd_sel = app.MaxSpdUnitDropDown.ValueIndex;
                        f_start = min([local_vehicle.retard_max, local_vehicle.retard_min, 1]) * local_vehicle.totMass;
                    case 'TE_Curve'
                        list_table = app.TE_Curve_Table;
                        spd_sel = app.SpdSelectTraction.ValueIndex;
                        f_start = local_vehicle.maxTE;
                    case 'DBE_Curve'
                        list_table = app.DBE_Curve_Table;
                        spd_sel = app.SpdSelectTraction.ValueIndex;
                        f_start = local_vehicle.maxDBE;
                    otherwise
                        return
                end
                acc = f_start / local_vehicle.totMass(local_vehicle.LoadCondition);
                switch spd_sel
                    case 1
                        spd_scale = 3.6;
                    case 2
                        spd_scale = 2.2369;
                    case 3
                        spd_scale = 1;
                    case 4
                        spd_scale = 1; % to include gear, wheel diameter and number of poles
                end
                if isempty(list_table.Data)
                    p = f_start * local_vehicle.vMax;
                    list_table.Data = {0, f_start, 0, acc; local_vehicle.vMax * spd_scale, f_start, p, acc};
                else
                    sel = list_table.Selection;
                    if isempty(sel)
                        list_table.Data(end+1, :) = {0, 0, 0, 0};
                    else
                        list_table.Data = [list_table.Data(1:sel(1), :); list_table.Data(sel(1), :); list_table.Data(sel+1:end, :)];
                    end
                end
                GeneralValueChanged(app, event);
            end
        end

        % Button pushed function: DelTotBrkPoint, DeleteDBEPwrPoint, 
        % ...and 1 other component
        function DelPointPushed(app, event)
            if ~app.isReadOnly
                switch event.Source.Tag
                    case 'TotBrake'
                        list_table = app.TotBrkTable;
                    case 'TE_Curve'
                        list_table = app.TE_Curve_Table;
                    case 'DBE_Curve'
                        list_table = app.DBE_Curve_Table;
                    otherwise
                        return
                end
                sel = list_table.Selection(1);
                newdata = [list_table.Data(1:sel-1,:);list_table.Data(sel+1:end,:)];
                if size(newdata,1) == 1
                    answ = questdlg('All data will be removed, ok?',event.Source.Tag,'OK', 'Cancel','OK');
                    if strcmp(answ,'Cancel')
                        return
                    else
                        newdata = [];
                    end
                end
                list_table.Data = newdata;
                GeneralValueChanged(app, event);
            end
        end

        % Selection changed function: TotBrkTable
        function TotBrkTableSelectionChanged(app, event)
            sel = app.TotBrkTable.Selection;
            app.DelTotBrkPoint.Enable = ~isempty(sel) && ~app.isReadOnly;
            app.AddTotBrkPoint.Enable = ~app.isReadOnly;
        end

        % Cell edit callback: DBE_Curve_Table, TE_Curve_Table, TotBrkTable
        function TotBrkTableCellEdit(app, event)
            if app.isReadOnly
                event.Source.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                indices = event.Indices;
                m_dyn = app.vehicle_fileData.TareMassUnit + app.vehicle_fileData.RotMassUnit;
                switch app.MaxSpdUnitDropDown.ValueIndex
                    case 1 % km/h
                        spd_scale = 3.6;
                    case 2 % mph
                        spd_scale = 2.2369;
                    case 3 % m/s
                        spd_scale = 1;
                end

                switch indices(2)
                    case {1, 2} % speed or effort
                        v = event.Source.Data{indices(1),1} / spd_scale;
                        f = event.Source.Data{indices(1),2};
                        p = v * f;
                        a = f/m_dyn;
                        event.Source.Data{indices(1),3} = p;
                        event.Source.Data{indices(1),4} = a;
                        row = indices(1);
                        if indices(2) == 1
                            data = [event.Source.Data(1:row-1,:);event.Source.Data(row+1:end,:)];
                            idx = 1;
                            while idx <= size(data,1)
                                if data{idx,1}>event.NewData
                                    break
                                end
                                idx = idx + 1;
                            end
                            data = [data(1:idx-1,:); event.Source.Data(row,:); data(idx:end,:)];
                            event.Source.Data = data;
                            event.Source.Selection(1) = idx;
                            % app.TotBrkTable.DisplaySelection
                        end
                    case 3 % power
                        p = event.NewData;
                        v = event.Source.Data{indices(1),1} / spd_scale;
                        f = p / v;
                        a = f/m_dyn;
                        event.Source.Data{indices(1),2} = f;
                        event.Source.Data{indices(1),4} = a;
                    case 4 % retardation
                        a = event.NewData;
                        f = a * m_dyn;
                        v = event.Source.Data{indices(1),1} / spd_scale;
                        p = v * f;
                        event.Source.Data{indices(1),2} = f;
                        event.Source.Data{indices(1),3} = p;
                end
                GeneralValueChanged(app,event)
            end
        end

        % Selection changed function: TE_Curve_Table
        function TE_Curve_TableSelectionChanged(app, event)
            selection = app.TE_Curve_Table.Selection;
            app.DeleteTEPwrPoint.Enable = ~isempty(selection) && ~app.isReadOnly;
            app.AddTEPwrPoint.Enable = ~app.isReadOnly;
        end

        % Selection changed function: DBE_Curve_Table
        function DBE_Curve_TableSelectionChanged(app, event)
            selection = app.DBE_Curve_Table.Selection;
            app.DeleteDBEPwrPoint.Enable = ~isempty(selection) && ~app.isReadOnly;
            app.AddDBEPwrPoint.Enable = ~app.isReadOnly;
        end

        % Button pushed function: BrowseButton
        function BrowseMotorBtnPushed(app, event)
            if ~app.isReadOnly
                DirIfExist(app, {app.working_directory, 'Vehicles', 'MotorMaps'});
                [f, p]=uigetfile('*.mat', 'Select motor map to be used');
                cd(fileparts(mfilename("fullpath")));
                if f
                    data = load(fullfile(p, f));
                    if isfield(data, 'motor')
                        app.motorFile = fullfile(p, f);
                        app.Motor_Map_EditField.Value = ['../', f];
                        app.FixedTractionMotorlossesEditField.Value = [];
                    end
                    GeneralValueChanged(app, event);
                end
                figure(app.ShogunVehicleEditorUIFigure);
            end
        end

        % Value changed function: MaxSpdUnitDropDown
        function MaxSpdUnitDropDownValueChanged(app, event)
            if app.isReadOnly
                event.Source.ValueIndex = event.PreviousValueIndex;
            else
                idx = app.MaxSpdUnitDropDown.ValueIndex;
                txt = app.MaxSpdUnitDropDown.Items{idx};
                app.kmhLabel_5.Text = txt;
                app.TotBrkTable.ColumnName = ['Speed [', txt, ']'; app.TotBrkTable.ColumnName(2:end)];
                GeneralValueChanged(app,event);
            end
        end

        % Value changed function: SpdSelectTraction
        function SpdSelectTractionValueChanged(app, event)
            if app.isReadOnly
                event.Source.ValueIndex = event.PreviousValueIndex;
            else
                sel = app.SpdSelectTraction.ValueIndex;
                txt = app.SpdSelectTraction.Items{sel};
                app.SpdUnitFadeStartLabel.Text = txt;
                app.SpdUnitFadeFinishLabel.Text = txt;
                % switch sel
                %     case 1 % km/h
                %         spd_scale = 3.6;
                %         xlabel(app.TractionGraph, 'Speed [km/h]')
                %     case 2 % mph
                %         spd_scale = 2.2369;
                %         xlabel(app.TractionGraph, 'Speed [mph]')
                %     case 3 % m/s
                %         spd_scale = 1;
                %         xlabel(app.TractionGraph, 'Speed [m/s]')
                %     case 4 % Hz
                %         spd_scale = app.vehicle_fileData.gearRatio * app.vehicle_fileData.nPoles/(app.vehicle_fileData.wheelsize{app.vehicle_fileData.ws_selection} * pi * 2);
                %         xlabel(app.TractionGraph, 'Speed [Hz]')
                % end
                % if app.vehicle_fileData.vBasePwr2 < inf
                %     app.PTE_WeakfieldSpdEditField.Value = app.vehicle_fileData.vBasePwr2 * spd_scale;
                % end
                % if app.vehicle_fileData.vBaseBrk2 < inf
                %     app.PDBE_WeakfieldSpdEditField.Value = app.vehicle_fileData.vBaseBrk2 * spd_scale;
                % end
                % if app.vehicle_fileData.dynBrkFadeStart
                %     app.fadeStartEditField.Value = app.vehicle_fileData.dynBrkFadeStart * spd_scale;
                % end
                % if app.vehicle_fileData.dynBrkFadeEnd
                %     app.fadeFinishEditField.Value = app.vehicle_fileData.dynBrkFadeEnd * spd_scale;
                % end
                GeneralValueChanged(app, event);
            end
        end

        % Value changed function: GrafSelDropDown
        function GrafSelDropDownValueChanged(app, event)
            value = app.GrafSelDropDown.ValueIndex;
            switch value
                case 1
                    ylabel(app.TractionGraph,'Effort [kN]')
                    title(app.TractionGraph, 'Effort vs. Speed')
                case 2
                    ylabel(app.TractionGraph,'Rail power [kW]')
                    title(app.TractionGraph, 'Rail power vs. Speed')
                case 3
                    ylabel(app.TractionGraph,'Acceleration [m/s^2]')
                    title(app.TractionGraph, 'Acceleration vs. Speed')
                case 4
                    ylabel(app.TractionGraph,'Line power [kW]')
                    title(app.TractionGraph, 'Line power vs. Speed')
                case 5 % losses
                    ylabel(app.TractionGraph,'Loss power [kW]')
                    title(app.TractionGraph, 'Loss power vs. Speed')
                case 6 % efficiency
                    ylabel(app.TractionGraph,'Efficiency [%%]')
                    title(app.TractionGraph, 'Efficiency vs. Speed')
            end
            calc_data(app);
            % wasDirty = app.dirty;
            % GeneralValueChanged(app, event);
            % app.dirty = false;
        end

        % Button pushed function: AddLoadCondBtn
        function AddLoadCondBtnButtonPushed(app, event)
            if ~app.isReadOnly
                lastName = app.LoadCondTable.Data{end,1};
                lastName(end) = lastName(end) + 1;
                app.LoadCondTable.Data = [app.LoadCondTable.Data;{lastName,0,''}];
                app.SelLoadCondDropDown.Items = app.LoadCondTable.Data(:,1)';
                GeneralValueChanged(app,event);
            end
        end

        % Button pushed function: RmvLoadCondBtn
        function RmvLoadCondBtnButtonPushed(app, event)
            if ~app.isReadOnly
                row = app.LoadCondTable.Selection(1);
                app.LoadCondTable.Data = [app.LoadCondTable.Data(1:row-1,:);app.LoadCondTable.Data(row+1:end,:)];
                app.RmvLoadCondBtn.Enable = "off";
                app.SelLoadCondDropDown.Items = app.LoadCondTable.Data(:,1)';
                GeneralValueChanged(app,event);
            end
        end

        % Cell edit callback: LoadCondTable
        function LoadCondTableCellEdit(app, event)
            if app.isReadOnly
                event.Source.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            else
                indices = event.Indices;
                row = indices(1);
                if indices(2) == 2
                    data = [event.Source.Data(1:row-1,:);event.Source.Data(row+1:end,:)];
                    idx = 1;
                    while idx <= size(data,1)
                        if data{idx,2}>event.NewData
                            break
                        end
                        idx = idx + 1;
                    end
                    data = [data(1:idx-1,:); event.Source.Data(row,:); data(idx:end,:)];
                    event.Source.Data = data;
                    event.Source.Selection(1) = idx;
                    % app.TotBrkTable.DisplaySelection
                end
                app.SelLoadCondDropDown.Items = app.LoadCondTable.Data(:,1)';
                UnitMassChanged(app, []);
            end
        end

        % Cell selection callback: LoadCondTable
        function LoadCondTableCellSelection(app, event)
            if size(app.LoadCondTable.Data,1)>1
                app.RmvLoadCondBtn.Enable = ~isempty(app.LoadCondTable.Selection) && ~app.isReadOnly;
            else
                app.RmvLoadCondBtn.Enable = 'off';
            end
        end

        % Button pushed function: AddGenPtButton, AddMotPtButton
        function AddSwPtButtonPushed(app, event)
            if ~app.isReadOnly
                switch event.Source.Tag
                    case 'Motor'
                        tab = app.MotorPulseTable;
                    case 'Generator'
                        tab = app.GeneratorPulseTable;
                    otherwise
                        return
                end
                if isempty(tab.Data)
                    tab.Data = {'Async', 0, inf};
                else
                    tab.Data{end,3} = 0;
                    tab.Data = [tab.Data; {'9', 0, inf}];
                end
            end
        end

        % Button pushed function: RmvGenPtButton, RmvMotPtButton
        function RmvSwPtButtonPushed(app, event)
            if ~app.isReadOnly
                switch event.Source.Tag
                    case 'Motor'
                        tab = app.MotorPulseTable;
                    case 'Generator'
                        tab = app.GeneratorPulseTable;
                    otherwise
                        return
                end
                if ~isempty(tab.Selection)
                    row = tab.Selection(1);
                    if row > 1
                        tab.Data{row-1, 3} = tab.Data{row, 3};
                        tab.Data = [tab.Data(1:row-1,:);tab.Data(row+1:end,:)];
                    end
                end
            end
        end

        % Cell edit callback: GeneratorPulseTable, MotorPulseTable
        function MotorPulseTableCellEdit(app, event)
            if ~app.isReadOnly
                indices = event.Indices;
                row = indices(1);
                tab = event.Source;
                if indices(2) == 2 % no pulses
                    data = tab.Data;
                    rowdata = data(row,:);
                    data = [data(1:row - 1, :);data(row + 1:end, :)];
                    row = 1;
                    while row <= size(data, 1) && rowdata{2} > data{row, 2}
                        row = row + 1;
                    end
                    data = [data(1:row - 1,:); rowdata; data(row:end,:)];
                    for row = 1:size(data, 1)-1
                        data{row, 3} = data{row + 1, 2};
                    end
                    data{end, 3} = inf;
                    tab.Data = data;
                end
                UpdatePulseGraphs(app);
            end
        end

        % Button pushed function: LaunchMotorEditorButton
        function LaunchMotorEditorButtonPushed(app, event)
            editApp = MotorEdit; % launch the motor edit app?
            editApp.launch(app, editApp, app.motorFile);
        end

        % Callback function
        function SelLoadCondDropDownValueChanged(app, event)
            if app.isReadOnly
                event.Source.ValueIndex = event.PreviousValueIndex;
            else
                GeneralValueChanged(app,event);
            end
        end

        % Callback function
        function MinRetardSpdUnitDownValueChanged(app, event)
            if app.isReadOnly
                event.Source.ValueIndex = event.PreviousValueIndex;
            else
                app.MinRetardSpdUnitDown.Value;
            end        
        end

        % Value changed function: SpdSelNothingDropDown
        function SpdSelNothingDropDownValueChanged(app, event)
            txt = app.SpdSelNothingDropDown.Items{app.SpdSelNothingDropDown.ValueIndex};
            app.kmhLabel_3.Text = txt;
            app.kmhLabel_4.Text = txt;
        end

        % Callback function
        function ShogunVehicleEditorUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(event.Modifier, 'control')
                switch key
                    case 'pagedown'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab), size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);

                        fprintf(['Figure: <', key, '>', newline])
                        active = app.ShogunVehicleEditorUIFigure.CurrentObject;
                        if isempty(active)
                            disp("No focus after tab switch")
                        else
                            disp("Focus is on: " + class(active))
                        end
                    case 'pageup'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab) - 2, size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);

                        fprintf(['Figure: <', key, '>', newline])
                        active = app.ShogunVehicleEditorUIFigure.CurrentObject;
                        if isempty(active)
                            disp("No focus after tab switch")
                        else
                            disp("Focus is on: " + class(active))
                        end
                end
            end
            fprintf(['Figure: <', key, '>', newline])
        end

        % Window key press function: ShogunVehicleEditorUIFigure
        function ShogunVehicleEditorUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(event.Modifier, 'control')
                needFocus = 0;
                switch key
                    case 'pagedown'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab), size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);

                        fprintf(['Window: <', key, '>', newline])
                        active = app.ShogunVehicleEditorUIFigure.CurrentObject;
                        if isempty(active)
                            disp("No focus after tab switch")
                        else
                            disp("Focus is on: " + class(active))
                        end
                        needFocus = 1;
                    case 'pageup'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab) - 2, size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);

                        fprintf(['Window: <', key, '>', newline])
                        active = app.ShogunVehicleEditorUIFigure.CurrentObject;
                        if isempty(active)
                            disp("No focus after tab switch")
                        else
                            disp("Focus is on: " + class(active))
                        end
                        needFocus = 1;
                end
                if needFocus
                    for i1 = size(tabs(idx).Children,1):-1:1
                        switch tabs(idx).Children(i1).Type
                            case 'uinumericeditfield'
                                focus(tabs(idx).Children(i1))
                                break
                            case 'uieditfield'
                                focus(tabs(idx).Children(i1))
                                break
                        end
                    end
                end
                
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ShogunVehicleEditorUIFigure = uifigure('Visible', 'off');
            app.ShogunVehicleEditorUIFigure.Name = 'Shogun Vehicle Editor';
            app.ShogunVehicleEditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShogunVehicleEditorUIFigureCloseRequest, true);
            app.ShogunVehicleEditorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ShogunVehicleEditorUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.ShogunVehicleEditorUIFigure);
            app.FileMenu.Text = 'File';
            app.NewvehicleMenu = uimenu(app.FileMenu);
            app.NewvehicleMenu.MenuSelectedFcn = createCallbackFcn(app, @NewvehicleMenuSelected, true);
            app.NewvehicleMenu.Accelerator = 'N';
            app.NewvehicleMenu.Text = 'New vehicle';
            app.OpenvehiclefileMenu = uimenu(app.FileMenu);
            app.OpenvehiclefileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenvehiclefileMenuSelected, true);
            app.OpenvehiclefileMenu.Accelerator = 'O';
            app.OpenvehiclefileMenu.Text = 'Open vehicle file...';
            app.SavevehiclefileMenu = uimenu(app.FileMenu);
            app.SavevehiclefileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavevehiclefileMenuSelected, true);
            app.SavevehiclefileMenu.Accelerator = 'S';
            app.SavevehiclefileMenu.Text = 'Save vehicle file';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.SetProjectDirectoryMenu = uimenu(app.FileMenu);
            app.SetProjectDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SetProjectDirectoryMenuSelected, true);
            app.SetProjectDirectoryMenu.Separator = 'on';
            app.SetProjectDirectoryMenu.Text = 'Set Project Directory...';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Accelerator = 'Q';
            app.QuitMenu.Text = 'Quit';
            app.EditMenu = uimenu(app.ShogunVehicleEditorUIFigure);
            app.EditMenu.Text = 'Edit';
            app.UndoMenu = uimenu(app.EditMenu);
            app.UndoMenu.Accelerator = 'Z';
            app.UndoMenu.Text = 'Undo';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.Accelerator = 'C';
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.Text = 'Paste';
            app.SpecialMenu = uimenu(app.ShogunVehicleEditorUIFigure);
            app.SpecialMenu.Text = 'Special';
            app.ComparevehiclesMenu = uimenu(app.SpecialMenu);
            app.ComparevehiclesMenu.Text = 'Compare vehicles';
            app.UnlockforeditMenu = uimenu(app.SpecialMenu);
            app.UnlockforeditMenu.Text = 'Unlock for edit';
            app.GenerateVehiclePerformanceReportMenu = uimenu(app.SpecialMenu);
            app.GenerateVehiclePerformanceReportMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateVehiclePerformanceReportMenuSelected, true);
            app.GenerateVehiclePerformanceReportMenu.Separator = 'on';
            app.GenerateVehiclePerformanceReportMenu.Text = 'Generate Vehicle Performance Report';
            app.TabGroup = uitabgroup(app.ShogunVehicleEditorUIFigure);
            app.ArchitectureTab = uitab(app.TabGroup);
            app.ArchitectureTab.Title = 'Architecture';
            app.NumberofunitsEditField_2Label = uilabel(app.ArchitectureTab);
            app.NumberofunitsEditField_2Label.Text = 'Number of units:';
            app.ClassEditFieldLabel = uilabel(app.ArchitectureTab);
            app.ClassEditFieldLabel.Text = 'Class:';
            app.ClassEditField = uieditfield(app.ArchitectureTab, 'text');
            app.ClassEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.ClassEditField.Tooltip = {'class'};
            app.PurposeEditFieldLabel = uilabel(app.ArchitectureTab);
            app.PurposeEditFieldLabel.Text = 'Purpose:';
            app.PurposeEditField = uieditfield(app.ArchitectureTab, 'text');
            app.PurposeEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.PurposeEditField.Tooltip = {'class'};
            app.NotesTextAreaLabel = uilabel(app.ArchitectureTab);
            app.NotesTextAreaLabel.Text = 'Notes:';
            app.NotesTextArea = uitextarea(app.ArchitectureTab);
            app.NotesTextArea.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NotesTextArea.Tooltip = {'notes'};
            app.NumberofunitsEditField = uieditfield(app.ArchitectureTab, 'numeric');
            app.NumberofunitsEditField.Limits = [1 8];
            app.NumberofunitsEditField.RoundFractionalValues = 'on';
            app.NumberofunitsEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NumberofunitsEditField.Tooltip = {'nUnits'};
            app.NumberofunitsEditField.Value = 1;
            app.NoHVsystemsperunitEditFieldLabel = uilabel(app.ArchitectureTab);
            app.NoHVsystemsperunitEditFieldLabel.Text = 'No HV systems per unit:';
            app.NoHVsystemsperunitEditField = uieditfield(app.ArchitectureTab, 'numeric');
            app.NoHVsystemsperunitEditField.Limits = [1 Inf];
            app.NoHVsystemsperunitEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NoHVsystemsperunitEditField.Tooltip = {'nHVSystems'};
            app.NoHVsystemsperunitEditField.Value = 2;
            app.NoCONVperHVsystemEditFieldLabel = uilabel(app.ArchitectureTab);
            app.NoCONVperHVsystemEditFieldLabel.Text = 'No CONV per HV system:';
            app.NoCONVperHVsystemEditField = uieditfield(app.ArchitectureTab, 'numeric');
            app.NoCONVperHVsystemEditField.Limits = [1 Inf];
            app.NoCONVperHVsystemEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NoCONVperHVsystemEditField.Tooltip = {'nCONV'};
            app.NoCONVperHVsystemEditField.Value = 2;
            app.NoINVperHVsystemEditFieldLabel = uilabel(app.ArchitectureTab);
            app.NoINVperHVsystemEditFieldLabel.Text = 'No INV per HV system:';
            app.NoINVperHVsystemEditField = uieditfield(app.ArchitectureTab, 'numeric');
            app.NoINVperHVsystemEditField.Limits = [1 Inf];
            app.NoINVperHVsystemEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NoINVperHVsystemEditField.Tooltip = {'nINV'};
            app.NoINVperHVsystemEditField.Value = 2;
            app.NoTMperinverterEditFieldLabel = uilabel(app.ArchitectureTab);
            app.NoTMperinverterEditFieldLabel.Text = 'No TM per inverter:';
            app.NoTMperinverterEditField = uieditfield(app.ArchitectureTab, 'numeric');
            app.NoTMperinverterEditField.Limits = [1 Inf];
            app.NoTMperinverterEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NoTMperinverterEditField.Tooltip = {'nMotors'};
            app.NoTMperinverterEditField.Value = 1;
            app.NoAPSperHVsystemEditFieldLabel = uilabel(app.ArchitectureTab);
            app.NoAPSperHVsystemEditFieldLabel.Text = 'No APS per HV system:';
            app.NoAPSperHVsystemEditField = uieditfield(app.ArchitectureTab, 'numeric');
            app.NoAPSperHVsystemEditField.Limits = [0 Inf];
            app.NoAPSperHVsystemEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NoAPSperHVsystemEditField.Tooltip = {'nAPUs'};
            app.NoAPSperHVsystemEditField.Value = 1;
            app.NoDrivenaxlesperunitEditFieldLabel = uilabel(app.ArchitectureTab);
            app.NoDrivenaxlesperunitEditFieldLabel.Text = 'No Driven axles per unit:';
            app.NoDrivenaxlesperunitEditField = uieditfield(app.ArchitectureTab, 'numeric');
            app.NoDrivenaxlesperunitEditField.Limits = [1 Inf];
            app.NoDrivenaxlesperunitEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NoDrivenaxlesperunitEditField.Tooltip = {'nAxDrive'};
            app.NoDrivenaxlesperunitEditField.Value = 8;
            app.TotalNoaxlesperunitEditFieldLabel = uilabel(app.ArchitectureTab);
            app.TotalNoaxlesperunitEditFieldLabel.Text = 'Total No axles per unit:';
            app.TotalNoaxlesperunitEditField = uieditfield(app.ArchitectureTab, 'numeric');
            app.TotalNoaxlesperunitEditField.Limits = [0 Inf];
            app.TotalNoaxlesperunitEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.TotalNoaxlesperunitEditField.Tooltip = {'sum of nAxDrive & nAxTrail'};
            app.TotalNoaxlesperunitEditField.Value = 16;
            app.Image = uiimage(app.ArchitectureTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'res', 'System overview.png');
            app.LengthInfo = uilabel(app.ArchitectureTab);
            app.LengthInfo.Text = 'Length';
            app.totHVInfo = uilabel(app.ArchitectureTab);
            app.totCOVinfo = uilabel(app.ArchitectureTab);
            app.totINVinfo = uilabel(app.ArchitectureTab);
            app.totTMinfo = uilabel(app.ArchitectureTab);
            app.totAPUinfo = uilabel(app.ArchitectureTab);
            app.totAxlesinfo = uilabel(app.ArchitectureTab);
            app.motorisationinfo = uilabel(app.ArchitectureTab);
            app.ShogunSimVehicleEditorLabel = uilabel(app.ArchitectureTab);
            app.ShogunSimVehicleEditorLabel.Text = 'ShogunSim - Vehicle Editor';
            app.DynamicsTab = uitab(app.TabGroup);
            app.DynamicsTab.Title = 'Dynamics';
            app.MaximalVehicleSpeedEditFieldLabel = uilabel(app.DynamicsTab);
            app.MaximalVehicleSpeedEditFieldLabel.Text = 'Maximal Vehicle Speed:';
            app.MaximalVehicleSpeedEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.MaximalVehicleSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaximalVehicleSpeedEditField.Value = 100;
            app.MaxSpdUnitDropDown = uidropdown(app.DynamicsTab);
            app.MaxSpdUnitDropDown.Items = {'km/h', 'mph', 'm/s'};
            app.MaxSpdUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @MaxSpdUnitDropDownValueChanged, true);
            app.MaxSpdUnitDropDown.Value = 'km/h';
            app.UnitlengthEditFieldLabel = uilabel(app.DynamicsTab);
            app.UnitlengthEditFieldLabel.Text = 'Unit length:';
            app.UnitlengthEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.UnitlengthEditField.AllowEmpty = 'on';
            app.UnitlengthEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.UnitlengthEditField.Value = [];
            app.MaxaccelerationEditFieldLabel = uilabel(app.DynamicsTab);
            app.MaxaccelerationEditFieldLabel.Text = 'Max acceleration:';
            app.MaxaccelerationEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.MaxaccelerationEditField.AllowEmpty = 'on';
            app.MaxaccelerationEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaxaccelerationEditField.Value = [];
            app.MethodforaccelerationDropDown = uidropdown(app.DynamicsTab);
            app.MethodforaccelerationDropDown.Items = {'F = m[tot] * a', 'F = m[tot] * a + Davies[A]', 'F = m[tot] * a + Davies[ABC]', 'F = m[tot] * a + Davies[ABC] + F[slope]'};
            app.MethodforaccelerationDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MethodforaccelerationDropDown.Value = 'F = m[tot] * a';
            app.JerkratetractionEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.JerkratetractionEditField.AllowEmpty = 'on';
            app.JerkratetractionEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.JerkratetractionEditField.Value = [];
            app.MaxretardationEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.MaxretardationEditField.AllowEmpty = 'on';
            app.MaxretardationEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaxretardationEditField.Value = [];
            app.MinretardationEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.MinretardationEditField.AllowEmpty = 'on';
            app.MinretardationEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MinretardationEditField.Value = [];
            app.atspeedEditFieldLabel = uilabel(app.DynamicsTab);
            app.atspeedEditFieldLabel.Text = 'at speed:';
            app.atspeedEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.atspeedEditField.AllowEmpty = 'on';
            app.atspeedEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.atspeedEditField.Value = [];
            app.ResidualretardationEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.ResidualretardationEditField.AllowEmpty = 'on';
            app.ResidualretardationEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.ResidualretardationEditField.Value = [];
            app.MethodforretardationDropDown = uidropdown(app.DynamicsTab);
            app.MethodforretardationDropDown.Items = {'F = m[tot] * a', 'F = m[tot] * a - Davies[A]', 'F = m[tot] * a - Davies[ABC]', 'F = m[tot] * a - Davies[ABC] - F[slope]'};
            app.MethodforretardationDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MethodforretardationDropDown.Value = 'F = m[tot] * a';
            app.JerkratebrakingEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.JerkratebrakingEditField.AllowEmpty = 'on';
            app.JerkratebrakingEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.JerkratebrakingEditField.Value = [];
            app.TotBrkTable = uitable(app.DynamicsTab);
            app.TotBrkTable.ColumnName = {'Speed [km/h]'; 'Brake Effort [kN]'; 'Rail Power [kW]'; 'Retardation [m/s2]'};
            app.TotBrkTable.RowName = {};
            app.TotBrkTable.ColumnEditable = true;
            app.TotBrkTable.CellEditCallback = createCallbackFcn(app, @TotBrkTableCellEdit, true);
            app.TotBrkTable.SelectionChangedFcn = createCallbackFcn(app, @TotBrkTableSelectionChanged, true);
            app.TotBrkTable.Multiselect = 'off';
            app.AddTotBrkPoint = uibutton(app.DynamicsTab, 'push');
            app.AddTotBrkPoint.ButtonPushedFcn = createCallbackFcn(app, @AddPointPushed, true);
            app.AddTotBrkPoint.Tag = 'TotBrake';
            app.AddTotBrkPoint.Text = 'Add row';
            app.DelTotBrkPoint = uibutton(app.DynamicsTab, 'push');
            app.DelTotBrkPoint.ButtonPushedFcn = createCallbackFcn(app, @DelPointPushed, true);
            app.DelTotBrkPoint.Tag = 'TotBrake';
            app.DelTotBrkPoint.Text = 'Delete row';
            app.UnittaremassEditFieldLabel = uilabel(app.DynamicsTab);
            app.UnittaremassEditFieldLabel.Text = 'Unit tare mass:';
            app.UnittaremassEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.UnittaremassEditField.ValueChangedFcn = createCallbackFcn(app, @UnitMassChanged, true);
            app.UnittaremassEditField.Value = 100;
            app.EquivalentdynamicmassEditFieldLabel = uilabel(app.DynamicsTab);
            app.EquivalentdynamicmassEditFieldLabel.Text = 'Equivalent dynamic mass:';
            app.EqDynMassEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.EqDynMassEditField.AllowEmpty = 'on';
            app.EqDynMassEditField.ValueChangedFcn = createCallbackFcn(app, @UnitMassChanged, true);
            app.EqDynMassEditField.Value = [];
            app.SelLoadCondDropDown = uidropdown(app.DynamicsTab);
            app.SelLoadCondDropDown.Items = {'AW0'};
            app.SelLoadCondDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitMassChanged, true);
            app.SelLoadCondDropDown.Value = 'AW0';
            app.LoadCondTable = uitable(app.DynamicsTab);
            app.LoadCondTable.ColumnName = {'Cond.'; 'Mass (kg)'; 'Definition'};
            app.LoadCondTable.RowName = {};
            app.LoadCondTable.ColumnEditable = true;
            app.LoadCondTable.CellEditCallback = createCallbackFcn(app, @LoadCondTableCellEdit, true);
            app.LoadCondTable.CellSelectionCallback = createCallbackFcn(app, @LoadCondTableCellSelection, true);
            app.AddLoadCondBtn = uibutton(app.DynamicsTab, 'push');
            app.AddLoadCondBtn.ButtonPushedFcn = createCallbackFcn(app, @AddLoadCondBtnButtonPushed, true);
            app.AddLoadCondBtn.Text = '+';
            app.RmvLoadCondBtn = uibutton(app.DynamicsTab, 'push');
            app.RmvLoadCondBtn.ButtonPushedFcn = createCallbackFcn(app, @RmvLoadCondBtnButtonPushed, true);
            app.RmvLoadCondBtn.Text = '-';
            app.AConstanteffortEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.AConstanteffortEditField.Limits = [0 Inf];
            app.AConstanteffortEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.AMassdependentAfactorEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.AMassdependentAfactorEditField.Limits = [0 Inf];
            app.AMassdependentAfactorEditField.AllowEmpty = 'on';
            app.AMassdependentAfactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.AMassdependentAfactorEditField.Value = [];
            app.B1MassdependentBfactorEditFieldLabel = uilabel(app.DynamicsTab);
            app.B1MassdependentBfactorEditFieldLabel.Text = 'B1'' - Mass dependent B-factor:';
            app.B1MassdependentBfactorEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.B1MassdependentBfactorEditField.Limits = [0 Inf];
            app.B1MassdependentBfactorEditField.AllowEmpty = 'on';
            app.B1MassdependentBfactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.B1MassdependentBfactorEditField.Value = [];
            app.B2SpeeddepBfactorEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.B2SpeeddepBfactorEditField.Limits = [0 Inf];
            app.B2SpeeddepBfactorEditField.AllowEmpty = 'on';
            app.B2SpeeddepBfactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.B2SpeeddepBfactorEditField.Value = [];
            app.CFrontpartofCfactorEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.CFrontpartofCfactorEditField.Limits = [0 Inf];
            app.CFrontpartofCfactorEditField.AllowEmpty = 'on';
            app.CFrontpartofCfactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.CFrontpartofCfactorEditField.Value = [];
            app.DLengthdeppartofCfactorEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.DLengthdeppartofCfactorEditField.AllowEmpty = 'on';
            app.DLengthdeppartofCfactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.DLengthdeppartofCfactorEditField.Value = [];
            app.C2tunnelfactorofCfactorEditField = uieditfield(app.DynamicsTab, 'numeric');
            app.C2tunnelfactorofCfactorEditField.AllowEmpty = 'on';
            app.C2tunnelfactorofCfactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.C2tunnelfactorofCfactorEditField.Value = [];
            app.Tightcurveresistancecr0EditField = uieditfield(app.DynamicsTab, 'numeric');
            app.Tightcurveresistancecr0EditField.AllowEmpty = 'on';
            app.Tightcurveresistancecr0EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.Tightcurveresistancecr0EditField.Value = [];
            app.Rediusreductioncr1EditField = uieditfield(app.DynamicsTab, 'numeric');
            app.Rediusreductioncr1EditField.AllowEmpty = 'on';
            app.Rediusreductioncr1EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.Rediusreductioncr1EditField.Value = [];
            app.Axialforcerelatedcr2EditFieldLabel = uilabel(app.DynamicsTab);
            app.Axialforcerelatedcr2EditFieldLabel.Text = 'Axial force related, cr2:';
            app.Axialforcerelatedcr2EditField = uieditfield(app.DynamicsTab, 'numeric');
            app.Axialforcerelatedcr2EditField.AllowEmpty = 'on';
            app.Axialforcerelatedcr2EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.Axialforcerelatedcr2EditField.Value = [];
            app.SelectedloadconnditionDropDownLabel = uilabel(app.DynamicsTab);
            app.SelectedloadconnditionDropDownLabel.Text = 'Selected load conndition:';
            app.mLabel = uilabel(app.DynamicsTab);
            app.mLabel.Text = 'm';
            app.BehaviourinPoweringLabel = uilabel(app.DynamicsTab);
            app.BehaviourinPoweringLabel.Text = 'Behaviour in Powering:';
            app.ms2Label = uilabel(app.DynamicsTab);
            app.ms2Label.Interpreter = 'tex';
            app.ms2Label.Text = 'm/s^2';
            app.MethodforaccelerationDropDownLabel = uilabel(app.DynamicsTab);
            app.MethodforaccelerationDropDownLabel.Text = 'Method for acceleration:';
            app.JerkratetractionEditFieldLabel = uilabel(app.DynamicsTab);
            app.JerkratetractionEditFieldLabel.Text = 'Jerk rate (traction):';
            app.ms3Label = uilabel(app.DynamicsTab);
            app.ms3Label.Interpreter = 'tex';
            app.ms3Label.Text = 'm/s^3';
            app.BehaviourinBrakingLabel = uilabel(app.DynamicsTab);
            app.BehaviourinBrakingLabel.Text = 'Behaviour in Braking:';
            app.ms2Label_3 = uilabel(app.DynamicsTab);
            app.ms2Label_3.Interpreter = 'tex';
            app.ms2Label_3.Text = 'm/s^2';
            app.MaxretardationEditFieldLabel = uilabel(app.DynamicsTab);
            app.MaxretardationEditFieldLabel.Text = 'Max retardation:';
            app.MinretardationEditFieldLabel = uilabel(app.DynamicsTab);
            app.MinretardationEditFieldLabel.Text = 'Min retardation:';
            app.ms2Label_4 = uilabel(app.DynamicsTab);
            app.ms2Label_4.Interpreter = 'tex';
            app.ms2Label_4.Text = 'm/s^2';
            app.ResidualretardationEditFieldLabel = uilabel(app.DynamicsTab);
            app.ResidualretardationEditFieldLabel.Text = 'Residual retardation:';
            app.ms2Label_5 = uilabel(app.DynamicsTab);
            app.ms2Label_5.Interpreter = 'tex';
            app.ms2Label_5.Text = 'm/s^2';
            app.MethodforretardationDropDownLabel = uilabel(app.DynamicsTab);
            app.MethodforretardationDropDownLabel.Text = 'Method for retardation:';
            app.JerkratebrakingEditFieldLabel = uilabel(app.DynamicsTab);
            app.JerkratebrakingEditFieldLabel.Text = 'Jerk rate (braking):';
            app.ms3Label_2 = uilabel(app.DynamicsTab);
            app.ms3Label_2.Interpreter = 'tex';
            app.ms3Label_2.Text = 'm/s^3';
            app.VehiclemassesLabel = uilabel(app.DynamicsTab);
            app.VehiclemassesLabel.Text = 'Vehicle masses:';
            app.tonLabel = uilabel(app.DynamicsTab);
            app.tonLabel.Text = 'ton';
            app.tonRecommendedLabel = uilabel(app.DynamicsTab);
            app.tonRecommendedLabel.Text = 'ton  -  Recommended:';
            app.Label = uilabel(app.DynamicsTab);
            app.Label.Tooltip = {'According to JIS standard'};
            app.Label.Text = '-';
            app.tonLabel_2 = uilabel(app.DynamicsTab);
            app.tonLabel_2.Text = 'ton';
            app.TrainresistanceDaviesformulaLabel = uilabel(app.DynamicsTab);
            app.TrainresistanceDaviesformulaLabel.Text = 'Train resistance [Davies formula]:';
            app.AMassdependentAfactorEditFieldLabel = uilabel(app.DynamicsTab);
            app.AMassdependentAfactorEditFieldLabel.Text = 'A'' - Mass dependent A-factor:';
            app.NLabel_2 = uilabel(app.DynamicsTab);
            app.NLabel_2.Interpreter = 'tex';
            app.NLabel_2.Text = 'N';
            app.AConstanteffortEditFieldLabel = uilabel(app.DynamicsTab);
            app.AConstanteffortEditFieldLabel.Text = 'A - Constant effort:';
            app.NtLabel = uilabel(app.DynamicsTab);
            app.NtLabel.Interpreter = 'tex';
            app.NtLabel.Text = 'N/t';
            app.NtkmhLabel = uilabel(app.DynamicsTab);
            app.NtkmhLabel.Interpreter = 'tex';
            app.NtkmhLabel.Text = 'N/t/(km/h)';
            app.B2SpeeddepBfactorEditFieldLabel = uilabel(app.DynamicsTab);
            app.B2SpeeddepBfactorEditFieldLabel.Text = 'B2 - Speed dep. B-factor:';
            app.NkmhLabel = uilabel(app.DynamicsTab);
            app.NkmhLabel.Interpreter = 'tex';
            app.NkmhLabel.Text = 'N/(km/h)';
            app.CFrontpartofCfactorEditFieldLabel = uilabel(app.DynamicsTab);
            app.CFrontpartofCfactorEditFieldLabel.Text = 'C'' - Front part of C-factor:';
            app.DLengthdeppartofCfactorEditFieldLabel = uilabel(app.DynamicsTab);
            app.DLengthdeppartofCfactorEditFieldLabel.Text = 'D'' - Length dep part of C-factor:';
            app.C2tunnelfactorofCfactorEditFieldLabel = uilabel(app.DynamicsTab);
            app.C2tunnelfactorofCfactorEditFieldLabel.Text = 'C2 - tunnel factor of C-factor:';
            app.Tightcurveresistancecr0EditFieldLabel = uilabel(app.DynamicsTab);
            app.Tightcurveresistancecr0EditFieldLabel.Text = 'Tight curve resistance, cr0:';
            app.Rediusreductioncr1EditFieldLabel = uilabel(app.DynamicsTab);
            app.Rediusreductioncr1EditFieldLabel.Text = 'Redius reduction, cr1:';
            app.Nkmh2Label = uilabel(app.DynamicsTab);
            app.Nkmh2Label.Interpreter = 'tex';
            app.Nkmh2Label.Text = 'N/(km/h)^2';
            app.Nmkmh2Label = uilabel(app.DynamicsTab);
            app.Nmkmh2Label.Interpreter = 'tex';
            app.Nmkmh2Label.Text = 'N/m/(km/h)^2';
            app.Nkmh2Label_2 = uilabel(app.DynamicsTab);
            app.Nkmh2Label_2.Interpreter = 'tex';
            app.Nkmh2Label_2.Text = 'N/(km/h)^2';
            app.CurveresistanceLabel = uilabel(app.DynamicsTab);
            app.CurveresistanceLabel.Text = 'Curve resistance:';
            app.NkgmLabel = uilabel(app.DynamicsTab);
            app.NkgmLabel.Interpreter = 'tex';
            app.NkgmLabel.Text = 'N/kgm';
            app.mLabel_2 = uilabel(app.DynamicsTab);
            app.mLabel_2.Text = 'm';
            app.Ns2kgmLabel = uilabel(app.DynamicsTab);
            app.Ns2kgmLabel.Interpreter = 'tex';
            app.Ns2kgmLabel.Text = 'Ns^{2}/kgm';
            app.MaximiseDBECheckBox = uicheckbox(app.DynamicsTab);
            app.MaximiseDBECheckBox.Text = 'Maximise DBE';
            app.LoadconditionsLabel = uilabel(app.DynamicsTab);
            app.LoadconditionsLabel.Text = 'Load conditions:';
            app.kmhLabel_5 = uilabel(app.DynamicsTab);
            app.kmhLabel_5.Interpreter = 'tex';
            app.kmhLabel_5.Text = 'km/h';
            app.TractionTab = uitab(app.TabGroup);
            app.TractionTab.Title = 'Traction';
            app.TractionGraph = uiaxes(app.TractionTab);
            title(app.TractionGraph, 'Effort Curve')
            xlabel(app.TractionGraph, 'Speed [km/h]')
            ylabel(app.TractionGraph, 'Effort [kN]')
            app.MaxTEDBELabel = uilabel(app.TractionTab);
            app.MaxTEDBELabel.Text = 'Max TE/DBE:';
            app.MaxTEEditField = uieditfield(app.TractionTab, 'numeric');
            app.MaxTEEditField.Limits = [0 Inf];
            app.MaxTEEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaxTEEditField.Tooltip = {'maxTE'};
            app.MaxTEEditField.Value = 100;
            app.MaxDBEEditField = uieditfield(app.TractionTab, 'numeric');
            app.MaxDBEEditField.Limits = [0 Inf];
            app.MaxDBEEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaxDBEEditField.Value = 100;
            app.MaxPTEEditField = uieditfield(app.TractionTab, 'numeric');
            app.MaxPTEEditField.Limits = [0 Inf];
            app.MaxPTEEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaxPTEEditField.Tooltip = {'maxPwrTEbase'};
            app.MaxPTEEditField.Value = 1000;
            app.MaxPDBEEditField = uieditfield(app.TractionTab, 'numeric');
            app.MaxPDBEEditField.Limits = [0 Inf];
            app.MaxPDBEEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaxPDBEEditField.Value = 1000;
            app.PTE_primEditField = uieditfield(app.TractionTab, 'numeric');
            app.PTE_primEditField.Limits = [0 Inf];
            app.PTE_primEditField.AllowEmpty = 'on';
            app.PTE_primEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.PTE_primEditField.Tooltip = {'maxRatePwrTE'};
            app.PTE_primEditField.Value = [];
            app.PDBE_primEditField = uieditfield(app.TractionTab, 'numeric');
            app.PDBE_primEditField.Limits = [0 Inf];
            app.PDBE_primEditField.AllowEmpty = 'on';
            app.PDBE_primEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.PDBE_primEditField.Value = [];
            app.PTE_WeakfieldSpdEditField = uieditfield(app.TractionTab, 'numeric');
            app.PTE_WeakfieldSpdEditField.Limits = [0 Inf];
            app.PTE_WeakfieldSpdEditField.AllowEmpty = 'on';
            app.PTE_WeakfieldSpdEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.PTE_WeakfieldSpdEditField.Tooltip = {'vBasePwr2'};
            app.PTE_WeakfieldSpdEditField.Value = [];
            app.PDBE_WeakfieldSpdEditField = uieditfield(app.TractionTab, 'numeric');
            app.PDBE_WeakfieldSpdEditField.Limits = [0 Inf];
            app.PDBE_WeakfieldSpdEditField.AllowEmpty = 'on';
            app.PDBE_WeakfieldSpdEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.PDBE_WeakfieldSpdEditField.Value = [];
            app.SpdSelectTraction = uidropdown(app.TractionTab);
            app.SpdSelectTraction.Items = {'km/h', 'mph', 'm/s', 'Hz'};
            app.SpdSelectTraction.ValueChangedFcn = createCallbackFcn(app, @SpdSelectTractionValueChanged, true);
            app.SpdSelectTraction.Value = 'km/h';
            app.fadeStartEditField = uieditfield(app.TractionTab, 'numeric');
            app.fadeStartEditField.AllowEmpty = 'on';
            app.fadeStartEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.fadeStartEditField.Tooltip = {'vBasePwr2'};
            app.fadeStartEditField.Value = [];
            app.fadeFinishEditField = uieditfield(app.TractionTab, 'numeric');
            app.fadeFinishEditField.AllowEmpty = 'on';
            app.fadeFinishEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.fadeFinishEditField.Tooltip = {'vBasePwr2'};
            app.fadeFinishEditField.Value = [];
            app.CustomTEDBEfunctionEditField = uieditfield(app.TractionTab, 'text');
            app.CustomTEDBEfunctionEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.GrafSelDropDown = uidropdown(app.TractionTab);
            app.GrafSelDropDown.Items = {'Effort vs. Speed', 'Power vs. Speed', 'Acceleration vs. Speed', 'Line Power vs. Speed', 'Losses vs. Speed', 'Efficiency vs. Speed'};
            app.GrafSelDropDown.ValueChangedFcn = createCallbackFcn(app, @GrafSelDropDownValueChanged, true);
            app.GrafSelDropDown.Value = 'Effort vs. Speed';
            app.TE_Curve_Table = uitable(app.TractionTab);
            app.TE_Curve_Table.ColumnName = {'Speed [km/h]'; 'Effort [kN]'; 'Rail Power [kW]'; 'Acceleration [m/s3]'};
            app.TE_Curve_Table.RowName = {};
            app.TE_Curve_Table.ColumnEditable = true;
            app.TE_Curve_Table.CellEditCallback = createCallbackFcn(app, @TotBrkTableCellEdit, true);
            app.TE_Curve_Table.SelectionChangedFcn = createCallbackFcn(app, @TE_Curve_TableSelectionChanged, true);
            app.TE_Curve_Table.Multiselect = 'off';
            app.AddTEPwrPoint = uibutton(app.TractionTab, 'push');
            app.AddTEPwrPoint.ButtonPushedFcn = createCallbackFcn(app, @AddPointPushed, true);
            app.AddTEPwrPoint.Tag = 'TE_Curve';
            app.AddTEPwrPoint.Text = 'Add row';
            app.DeleteTEPwrPoint = uibutton(app.TractionTab, 'push');
            app.DeleteTEPwrPoint.ButtonPushedFcn = createCallbackFcn(app, @DelPointPushed, true);
            app.DeleteTEPwrPoint.Tag = 'TE_Curve';
            app.DeleteTEPwrPoint.Text = 'Delete row';
            app.DBE_Curve_Table = uitable(app.TractionTab);
            app.DBE_Curve_Table.ColumnName = {'Speed [km/h]'; 'Effort [kN]'; 'Rail Power [kW]'; 'Retardation [m/s3]'};
            app.DBE_Curve_Table.RowName = {};
            app.DBE_Curve_Table.ColumnEditable = true;
            app.DBE_Curve_Table.CellEditCallback = createCallbackFcn(app, @TotBrkTableCellEdit, true);
            app.DBE_Curve_Table.SelectionChangedFcn = createCallbackFcn(app, @DBE_Curve_TableSelectionChanged, true);
            app.DBE_Curve_Table.Multiselect = 'off';
            app.AddDBEPwrPoint = uibutton(app.TractionTab, 'push');
            app.AddDBEPwrPoint.ButtonPushedFcn = createCallbackFcn(app, @AddPointPushed, true);
            app.AddDBEPwrPoint.Tag = 'DBE_Curve';
            app.AddDBEPwrPoint.Text = 'Add row';
            app.DeleteDBEPwrPoint = uibutton(app.TractionTab, 'push');
            app.DeleteDBEPwrPoint.ButtonPushedFcn = createCallbackFcn(app, @DelPointPushed, true);
            app.DeleteDBEPwrPoint.Tag = 'DBE_Curve';
            app.DeleteDBEPwrPoint.Text = 'Delete row';
            app.kNLabel = uilabel(app.TractionTab);
            app.kNLabel.Text = 'kN';
            app.TractionLabel = uilabel(app.TractionTab);
            app.TractionLabel.Text = 'Traction';
            app.BrakingLabel = uilabel(app.TractionTab);
            app.BrakingLabel.Text = 'Braking';
            app.kWLabel = uilabel(app.TractionTab);
            app.kWLabel.Text = 'kW';
            app.MaxRailPowerEditFieldLabel = uilabel(app.TractionTab);
            app.MaxRailPowerEditFieldLabel.Text = 'Max Rail Power:';
            app.kWsLabel = uilabel(app.TractionTab);
            app.kWsLabel.Text = 'kW/s';
            app.PowerderivativeEditFieldLabel = uilabel(app.TractionTab);
            app.PowerderivativeEditFieldLabel.Text = 'Power derivative:';
            app.WeakfieldspeedLabel = uilabel(app.TractionTab);
            app.WeakfieldspeedLabel.Text = 'Weak field speed:';
            app.UserdefinedTractiveEffortvsSpeedcurveLabel = uilabel(app.TractionTab);
            app.UserdefinedTractiveEffortvsSpeedcurveLabel.Text = 'User defined Tractive Effort vs. Speed curve:';
            app.UserdefinedDynamicBrakeEffortvsSpeedcurveLabel = uilabel(app.TractionTab);
            app.UserdefinedDynamicBrakeEffortvsSpeedcurveLabel.Text = 'User defined Dynamic Brake Effort vs. Speed curve:';
            app.CustomTEDBEfunctionEditFieldLabel = uilabel(app.TractionTab);
            app.CustomTEDBEfunctionEditFieldLabel.Text = 'Custom TE/DBE function:';
            app.FadingspeedstartEditFieldLabel = uilabel(app.TractionTab);
            app.FadingspeedstartEditFieldLabel.Text = 'Fading speed, start:';
            app.FadingspeedfinishEditFieldLabel = uilabel(app.TractionTab);
            app.FadingspeedfinishEditFieldLabel.Text = 'Fading speed, finish:';
            app.SpdUnitFadeStartLabel = uilabel(app.TractionTab);
            app.SpdUnitFadeStartLabel.Text = 'km/h';
            app.SpdUnitFadeFinishLabel = uilabel(app.TractionTab);
            app.SpdUnitFadeFinishLabel.Text = 'km/h';
            app.GraphpreviewshowsLabel = uilabel(app.TractionTab);
            app.GraphpreviewshowsLabel.Text = 'Graph preview shows:';
            app.EnergyTab = uitab(app.TabGroup);
            app.EnergyTab.Title = 'Energy';
            app.NominalLineVoltageEditFieldLabel = uilabel(app.EnergyTab);
            app.NominalLineVoltageEditFieldLabel.Text = 'Nominal Line Voltage:';
            app.NominalLineVoltageEditField = uieditfield(app.EnergyTab, 'numeric');
            app.NominalLineVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NominalLineVoltageEditField.Value = 1500;
            app.VoltUnitDropDown = uidropdown(app.EnergyTab);
            app.VoltUnitDropDown.Items = {'VDC', 'VAC', 'kVDC', 'kVAC'};
            app.VoltUnitDropDown.Value = 'VDC';
            app.DClinkVoltageEditFieldLabel = uilabel(app.EnergyTab);
            app.DClinkVoltageEditFieldLabel.Text = 'DC-link Voltage:';
            app.DClinkVoltageEditField = uieditfield(app.EnergyTab, 'numeric');
            app.DClinkVoltageEditField.AllowEmpty = 'on';
            app.DClinkVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.DClinkVoltageEditField.Value = [];
            app.LineSupplyFrequencyEditFieldLabel = uilabel(app.EnergyTab);
            app.LineSupplyFrequencyEditFieldLabel.Text = 'Line Supply Frequency:';
            app.LineSupplyFreqEditField = uieditfield(app.EnergyTab, 'numeric');
            app.LineSupplyFreqEditField.AllowEmpty = 'on';
            app.LineSupplyFreqEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.LineSupplyFreqEditField.Value = [];
            app.PGM_P_Output_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.PGM_P_Output_EditField.AllowEmpty = 'on';
            app.PGM_P_Output_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.PGM_P_Output_EditField.Value = [];
            app.GeneratorefficiencymapEditField = uieditfield(app.EnergyTab, 'text');
            app.GeneratorefficiencymapEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.DieselenginefuelmapEditField = uieditfield(app.EnergyTab, 'text');
            app.DieselenginefuelmapEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.WindingRatioEditField = uieditfield(app.EnergyTab, 'numeric');
            app.WindingRatioEditField.AllowEmpty = 'on';
            app.WindingRatioEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.WindingRatioEditField.Value = [];
            app.NoLoadLossesEditField = uieditfield(app.EnergyTab, 'numeric');
            app.NoLoadLossesEditField.AllowEmpty = 'on';
            app.NoLoadLossesEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.NoLoadLossesEditField.Value = [];
            app.HarmonicLossesEditField = uieditfield(app.EnergyTab, 'numeric');
            app.HarmonicLossesEditField.AllowEmpty = 'on';
            app.HarmonicLossesEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.HarmonicLossesEditField.Value = [];
            app.IronLossesEditField = uieditfield(app.EnergyTab, 'numeric');
            app.IronLossesEditField.AllowEmpty = 'on';
            app.IronLossesEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.IronLossesEditField.Value = [];
            app.RPrimaryWindingEditField = uieditfield(app.EnergyTab, 'numeric');
            app.RPrimaryWindingEditField.AllowEmpty = 'on';
            app.RPrimaryWindingEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.RPrimaryWindingEditField.Value = [];
            app.RSecondaryWindingEditField = uieditfield(app.EnergyTab, 'numeric');
            app.RSecondaryWindingEditField.AllowEmpty = 'on';
            app.RSecondaryWindingEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.RSecondaryWindingEditField.Value = [];
            app.OperatingtemperatureEditField = uieditfield(app.EnergyTab, 'numeric');
            app.OperatingtemperatureEditField.AllowEmpty = 'on';
            app.OperatingtemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.OperatingtemperatureEditField.Value = [];
            app.FixedTransformerlossesEditField = uieditfield(app.EnergyTab, 'numeric');
            app.FixedTransformerlossesEditField.AllowEmpty = 'on';
            app.FixedTransformerlossesEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.FixedTransformerlossesEditField.Value = [];
            app.RcableTrafoCOVEditField = uieditfield(app.EnergyTab, 'numeric');
            app.RcableTrafoCOVEditField.AllowEmpty = 'on';
            app.RcableTrafoCOVEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.RcableTrafoCOVEditField.Value = [];
            app.APU_P_out_AC_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.APU_P_out_AC_EditField.Limits = [0 Inf];
            app.APU_P_out_AC_EditField.AllowEmpty = 'on';
            app.APU_P_out_AC_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.APU_P_out_AC_EditField.Value = [];
            app.APU_P_out_DC_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.APU_P_out_DC_EditField.Limits = [0 Inf];
            app.APU_P_out_DC_EditField.AllowEmpty = 'on';
            app.APU_P_out_DC_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.APU_P_out_DC_EditField.Value = [];
            app.APUefficiencyEditField = uieditfield(app.EnergyTab, 'numeric');
            app.APUefficiencyEditField.Limits = [0 100];
            app.APUefficiencyEditField.AllowEmpty = 'on';
            app.APUefficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.APUefficiencyEditField.Value = [];
            app.APUPowerfactorEditField = uieditfield(app.EnergyTab, 'numeric');
            app.APUPowerfactorEditField.Limits = [0 1];
            app.APUPowerfactorEditField.AllowEmpty = 'on';
            app.APUPowerfactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.APUPowerfactorEditField.Value = [];
            app.ES_Capacity_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.ES_Capacity_EditField.AllowEmpty = 'on';
            app.ES_Capacity_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.ES_Capacity_EditField.Value = [];
            app.ES_P_out_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.ES_P_out_EditField.AllowEmpty = 'on';
            app.ES_P_out_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.ES_P_out_EditField.Value = [];
            app.ES_P_in_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.ES_P_in_EditField.AllowEmpty = 'on';
            app.ES_P_in_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.ES_P_in_EditField.Value = [];
            app.ESBoostlimitEditField = uieditfield(app.EnergyTab, 'numeric');
            app.ESBoostlimitEditField.AllowEmpty = 'on';
            app.ESBoostlimitEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.ESBoostlimitEditField.Value = [];
            app.FadingtimeEditField = uieditfield(app.EnergyTab, 'numeric');
            app.FadingtimeEditField.AllowEmpty = 'on';
            app.FadingtimeEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.FadingtimeEditField.Value = [];
            app.ES_OpMode_DropDown = uidropdown(app.EnergyTab);
            app.ES_OpMode_DropDown.Items = {'Inactive', 'Charging with braking power', 'Charging with braking & stationary', 'Charging when not in traction', 'Always charging'};
            app.ES_OpMode_DropDown.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.ES_OpMode_DropDown.Value = 'Inactive';
            app.RFilterReactorEditField = uieditfield(app.EnergyTab, 'numeric');
            app.RFilterReactorEditField.AllowEmpty = 'on';
            app.RFilterReactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.RFilterReactorEditField.Value = [];
            app.OperatingtemperatureEditField_2 = uieditfield(app.EnergyTab, 'numeric');
            app.OperatingtemperatureEditField_2.AllowEmpty = 'on';
            app.OperatingtemperatureEditField_2.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.OperatingtemperatureEditField_2.Value = [];
            app.ESOperationmodeDropDownLabel = uilabel(app.EnergyTab);
            app.ESOperationmodeDropDownLabel.Text = 'ES Operation mode:';
            app.IL_max_train_TE_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.IL_max_train_TE_EditField.AllowEmpty = 'on';
            app.IL_max_train_TE_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.IL_max_train_TE_EditField.Value = [];
            app.IL_max_unit_TE_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.IL_max_unit_TE_EditField.AllowEmpty = 'on';
            app.IL_max_unit_TE_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.IL_max_unit_TE_EditField.Value = [];
            app.IL_max_system_TE_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.IL_max_system_TE_EditField.AllowEmpty = 'on';
            app.IL_max_system_TE_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.IL_max_system_TE_EditField.Value = [];
            app.IL_max_train_DBE_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.IL_max_train_DBE_EditField.AllowEmpty = 'on';
            app.IL_max_train_DBE_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.IL_max_train_DBE_EditField.Value = [];
            app.IL_max_unit_DBE_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.IL_max_unit_DBE_EditField.AllowEmpty = 'on';
            app.IL_max_unit_DBE_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.IL_max_unit_DBE_EditField.Value = [];
            app.IL_max_system_DBE_EditField = uieditfield(app.EnergyTab, 'numeric');
            app.IL_max_system_DBE_EditField.AllowEmpty = 'on';
            app.IL_max_system_DBE_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.IL_max_system_DBE_EditField.Value = [];
            app.MinVoltageattractionEditField = uieditfield(app.EnergyTab, 'numeric');
            app.MinVoltageattractionEditField.AllowEmpty = 'on';
            app.MinVoltageattractionEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MinVoltageattractionEditField.Value = [];
            app.MaxVoltageatbrakingEditField = uieditfield(app.EnergyTab, 'numeric');
            app.MaxVoltageatbrakingEditField.AllowEmpty = 'on';
            app.MaxVoltageatbrakingEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaxVoltageatbrakingEditField.Value = [];
            app.MethodforILlimitinbrakingDropDown = uidropdown(app.EnergyTab);
            app.MethodforILlimitinbrakingDropDown.Items = {'Rail power reduction', 'Fire brake resistor'};
            app.MethodforILlimitinbrakingDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MethodforILlimitinbrakingDropDown.Value = 'Rail power reduction';
            app.VDCLabel = uilabel(app.EnergyTab);
            app.VDCLabel.Text = 'VDC';
            app.HzLabel = uilabel(app.EnergyTab);
            app.HzLabel.Text = 'Hz';
            app.MaximumEnginepowerEditFieldLabel = uilabel(app.EnergyTab);
            app.MaximumEnginepowerEditFieldLabel.Text = 'Maximum Engine power:';
            app.kWLabel_2 = uilabel(app.EnergyTab);
            app.kWLabel_2.Text = 'kW';
            app.MainTransformerACSystemLabel = uilabel(app.EnergyTab);
            app.MainTransformerACSystemLabel.Text = 'Main Transformer (AC System):';
            app.WindingRatioEditFieldLabel = uilabel(app.EnergyTab);
            app.WindingRatioEditFieldLabel.Text = 'Winding Ratio:';
            app.kWLabel_3 = uilabel(app.EnergyTab);
            app.kWLabel_3.Text = 'kW';
            app.NoLoadLossesEditFieldLabel = uilabel(app.EnergyTab);
            app.NoLoadLossesEditFieldLabel.Text = 'No Load Losses:';
            app.HarmonicLossesEditFieldLabel = uilabel(app.EnergyTab);
            app.HarmonicLossesEditFieldLabel.Text = 'Harmonic Losses:';
            app.kWLabel_4 = uilabel(app.EnergyTab);
            app.kWLabel_4.Text = 'kW';
            app.IronLossesEditFieldLabel = uilabel(app.EnergyTab);
            app.IronLossesEditFieldLabel.Text = 'Iron Losses:';
            app.Label_2 = uilabel(app.EnergyTab);
            app.Label_2.Text = '%';
            app.RPrimaryWindingEditFieldLabel = uilabel(app.EnergyTab);
            app.RPrimaryWindingEditFieldLabel.Text = 'R Primary Winding:';
            app.mLabel_3 = uilabel(app.EnergyTab);
            app.mLabel_3.Text = 'mΩ';
            app.mLabel_4 = uilabel(app.EnergyTab);
            app.mLabel_4.Text = 'mΩ';
            app.RSecondaryWindingEditFieldLabel = uilabel(app.EnergyTab);
            app.RSecondaryWindingEditFieldLabel.Text = 'R Secondary Winding:';
            app.OperatingtemperatureEditFieldLabel = uilabel(app.EnergyTab);
            app.OperatingtemperatureEditFieldLabel.Text = 'Operating temperature:';
            app.CLabel = uilabel(app.EnergyTab);
            app.CLabel.Text = '°C';
            app.FadingtimeEditFieldLabel = uilabel(app.EnergyTab);
            app.FadingtimeEditFieldLabel.Text = 'Fading time:';
            app.FixedTransformerlossesEditFieldLabel = uilabel(app.EnergyTab);
            app.FixedTransformerlossesEditFieldLabel.Text = 'Fixed Transformer losses:';
            app.Label_3 = uilabel(app.EnergyTab);
            app.Label_3.Text = '%';
            app.mLabel_6 = uilabel(app.EnergyTab);
            app.mLabel_6.Text = 'mΩ';
            app.RcableTrafoCOVEditFieldLabel = uilabel(app.EnergyTab);
            app.RcableTrafoCOVEditFieldLabel.Text = 'R cable Trafo - COV:';
            app.AuxiliaryPowerLabel = uilabel(app.EnergyTab);
            app.AuxiliaryPowerLabel.Text = 'Auxiliary Power:';
            app.mLabel_7 = uilabel(app.EnergyTab);
            app.mLabel_7.Text = 'mΩ';
            app.RFilterReactorEditFieldLabel = uilabel(app.EnergyTab);
            app.RFilterReactorEditFieldLabel.Text = 'R Filter Reactor';
            app.OperatingtemperatureEditField_2Label = uilabel(app.EnergyTab);
            app.OperatingtemperatureEditField_2Label.Text = 'Operating temperature:';
            app.APUoutputpowerACEditFieldLabel = uilabel(app.EnergyTab);
            app.APUoutputpowerACEditFieldLabel.Text = 'APU output power (AC):';
            app.kWLabel_5 = uilabel(app.EnergyTab);
            app.kWLabel_5.Text = 'kW';
            app.EnergyStorageESLabel_2 = uilabel(app.EnergyTab);
            app.EnergyStorageESLabel_2.Text = 'Energy Storage (ES):';
            app.kWhLabel_2 = uilabel(app.EnergyTab);
            app.kWhLabel_2.Text = 'kWh';
            app.EnergyStoragecapacityEditField_2Label = uilabel(app.EnergyTab);
            app.EnergyStoragecapacityEditField_2Label.Text = 'Energy Storage capacity:';
            app.ESInputpowerEditFieldLabel = uilabel(app.EnergyTab);
            app.ESInputpowerEditFieldLabel.Text = 'ES Input power:';
            app.ESBoostlimitEditFieldLabel = uilabel(app.EnergyTab);
            app.ESBoostlimitEditFieldLabel.Text = 'ES Boost limit:';
            app.kWLabel_6 = uilabel(app.EnergyTab);
            app.kWLabel_6.Text = 'kW';
            app.BatterychargerpowerDCEditFieldLabel = uilabel(app.EnergyTab);
            app.BatterychargerpowerDCEditFieldLabel.Text = 'Battery charger power (DC):';
            app.APUefficiencyEditFieldLabel = uilabel(app.EnergyTab);
            app.APUefficiencyEditFieldLabel.Text = 'APU efficiency:';
            app.Label_6 = uilabel(app.EnergyTab);
            app.Label_6.Text = '%';
            app.CLabel_2 = uilabel(app.EnergyTab);
            app.CLabel_2.Text = '°C';
            app.APUPowerfactorEditFieldLabel = uilabel(app.EnergyTab);
            app.APUPowerfactorEditFieldLabel.Text = 'APU Power factor:';
            app.ALabel = uilabel(app.EnergyTab);
            app.ALabel.Text = 'A';
            app.MaxLineCurrentattractionEditFieldLabel = uilabel(app.EnergyTab);
            app.MaxLineCurrentattractionEditFieldLabel.Text = 'Max Line Current at traction:';
            app.LinecurrentlimitationsLabel = uilabel(app.EnergyTab);
            app.LinecurrentlimitationsLabel.Text = 'Line current limitations:';
            app.TrainLabel = uilabel(app.EnergyTab);
            app.TrainLabel.Text = 'Train';
            app.UnitLabel = uilabel(app.EnergyTab);
            app.UnitLabel.Text = 'Unit';
            app.SystemLabel = uilabel(app.EnergyTab);
            app.SystemLabel.Text = 'System';
            app.ALabel_2 = uilabel(app.EnergyTab);
            app.ALabel_2.Text = 'A';
            app.MaxLineCurrentatBrakingEditFieldLabel = uilabel(app.EnergyTab);
            app.MaxLineCurrentatBrakingEditFieldLabel.Text = 'Max Line Current at Braking:';
            app.MinVoltageattractionEditFieldLabel = uilabel(app.EnergyTab);
            app.MinVoltageattractionEditFieldLabel.Text = 'Min Voltage at traction:';
            app.kVLabel = uilabel(app.EnergyTab);
            app.kVLabel.Text = 'kV';
            app.kVLabel_2 = uilabel(app.EnergyTab);
            app.kVLabel_2.Text = 'kV';
            app.MaxVoltageatbrakingEditFieldLabel = uilabel(app.EnergyTab);
            app.MaxVoltageatbrakingEditFieldLabel.Text = 'Max Voltage at braking:';
            app.MethodforILlimitinbrakingDropDownLabel = uilabel(app.EnergyTab);
            app.MethodforILlimitinbrakingDropDownLabel.Text = 'Method for IL limit in braking:';
            app.ESOutputpowerEditFieldLabel = uilabel(app.EnergyTab);
            app.ESOutputpowerEditFieldLabel.Text = 'ES Output power:';
            app.kWLabel_7 = uilabel(app.EnergyTab);
            app.kWLabel_7.Text = 'kW';
            app.kWLabel_8 = uilabel(app.EnergyTab);
            app.kWLabel_8.Text = 'kW';
            app.ALabel_3 = uilabel(app.EnergyTab);
            app.ALabel_3.Text = 'A';
            app.sLabel_3 = uilabel(app.EnergyTab);
            app.sLabel_3.Text = 's';
            app.GeneratorefficiencymapEditFieldLabel = uilabel(app.EnergyTab);
            app.GeneratorefficiencymapEditFieldLabel.Text = 'Generator efficiency map:';
            app.DieselenginefuelmapEditFieldLabel = uilabel(app.EnergyTab);
            app.DieselenginefuelmapEditFieldLabel.Text = 'Diesel engine fuel map:';
            app.LineSupplyLabel = uilabel(app.EnergyTab);
            app.LineSupplyLabel.Text = 'Line Supply:';
            app.PowerGenerationModuleLabel = uilabel(app.EnergyTab);
            app.PowerGenerationModuleLabel.Text = 'Power Generation Module:';
            app.LinefilterDCSystemLabel = uilabel(app.EnergyTab);
            app.LinefilterDCSystemLabel.Text = 'Line filter (DC System):';
            app.DrivesTab = uitab(app.TabGroup);
            app.DrivesTab.Title = 'Drives';
            app.DrivesGraph = uiaxes(app.DrivesTab);
            title(app.DrivesGraph, 'Motor Torque vs. Speed')
            xlabel(app.DrivesGraph, 'Motor speed [RPM]')
            ylabel(app.DrivesGraph, 'Torque [Nm]')
            zlabel(app.DrivesGraph, 'Z')
            app.GearreducationTMwheelEditFieldLabel = uilabel(app.DrivesTab);
            app.GearreducationTMwheelEditFieldLabel.Text = 'Gear reducation TM-wheel:';
            app.Gear_ratio_EditField = uieditfield(app.DrivesTab, 'numeric');
            app.Gear_ratio_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.Gear_ratio_EditField.Value = 4.087;
            app.GearlossconsttorqueEditFieldLabel = uilabel(app.DrivesTab);
            app.GearlossconsttorqueEditFieldLabel.Text = 'Gear loss, const torque:';
            app.GearlossconsttorqueEditField = uieditfield(app.DrivesTab, 'numeric');
            app.GearlossconsttorqueEditField.AllowEmpty = 'on';
            app.GearlossconsttorqueEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.GearlossconsttorqueEditField.Value = [];
            app.GearlosstorquedependentEditField = uieditfield(app.DrivesTab, 'numeric');
            app.GearlosstorquedependentEditField.AllowEmpty = 'on';
            app.GearlosstorquedependentEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.GearlosstorquedependentEditField.Value = [];
            app.GearlossspeeddependentEditField = uieditfield(app.DrivesTab, 'numeric');
            app.GearlossspeeddependentEditField.AllowEmpty = 'on';
            app.GearlossspeeddependentEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.GearlossspeeddependentEditField.Value = [];
            app.WD_nominal_EditField = uieditfield(app.DrivesTab, 'numeric');
            app.WD_nominal_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.WD_nominal_EditField.Value = 840;
            app.WD_worn_EditField = uieditfield(app.DrivesTab, 'numeric');
            app.WD_worn_EditField.AllowEmpty = 'on';
            app.WD_worn_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.WD_worn_EditField.Value = [];
            app.WD_new_EditField = uieditfield(app.DrivesTab, 'numeric');
            app.WD_new_EditField.AllowEmpty = 'on';
            app.WD_new_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.WD_new_EditField.Value = [];
            app.mStatNominalEditField = uieditfield(app.DrivesTab, 'numeric');
            app.mStatNominalEditField.AllowEmpty = 'on';
            app.mStatNominalEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.mStatNominalEditField.Tooltip = {'The mass contribution affects both static and dynamic mass equally'};
            app.mStatNominalEditField.Value = [];
            app.mStatWornEditField = uieditfield(app.DrivesTab, 'numeric');
            app.mStatWornEditField.AllowEmpty = 'on';
            app.mStatWornEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.mStatWornEditField.Value = [];
            app.mStatNewEditField = uieditfield(app.DrivesTab, 'numeric');
            app.mStatNewEditField.AllowEmpty = 'on';
            app.mStatNewEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.mStatNewEditField.Value = [];
            app.mRotNominalEditField = uieditfield(app.DrivesTab, 'numeric');
            app.mRotNominalEditField.AllowEmpty = 'on';
            app.mRotNominalEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.mRotNominalEditField.Tooltip = {'The mass contribution affects both static and dynamic mass equally'};
            app.mRotNominalEditField.Value = [];
            app.mRotWornEditField = uieditfield(app.DrivesTab, 'numeric');
            app.mRotWornEditField.AllowEmpty = 'on';
            app.mRotWornEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.mRotWornEditField.Value = [];
            app.mRotNewEditField = uieditfield(app.DrivesTab, 'numeric');
            app.mRotNewEditField.AllowEmpty = 'on';
            app.mRotNewEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.mRotNewEditField.Value = [];
            app.WheelthicknessEditField = uieditfield(app.DrivesTab, 'numeric');
            app.WheelthicknessEditField.AllowEmpty = 'on';
            app.WheelthicknessEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.WheelthicknessEditField.Value = [];
            app.MaterialdensityEditFieldLabel = uilabel(app.DrivesTab);
            app.MaterialdensityEditFieldLabel.Text = 'Material density:';
            app.MaterialdensityEditField = uieditfield(app.DrivesTab, 'numeric');
            app.MaterialdensityEditField.AllowEmpty = 'on';
            app.MaterialdensityEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MaterialdensityEditField.Value = [];
            app.WheeldiameterselectionDropDown = uidropdown(app.DrivesTab);
            app.WheeldiameterselectionDropDown.Items = {'Nominal wheels', 'Worn wheels', 'New wheels'};
            app.WheeldiameterselectionDropDown.Value = 'Nominal wheels';
            app.TractionmotorefficiencymapEditFieldLabel = uilabel(app.DrivesTab);
            app.TractionmotorefficiencymapEditFieldLabel.Text = 'Traction motor efficiency map:';
            app.Motor_Map_EditField = uieditfield(app.DrivesTab, 'text');
            app.Motor_Map_EditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.BrowseButton = uibutton(app.DrivesTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMotorBtnPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FixedTractionMotorlossesEditField = uieditfield(app.DrivesTab, 'numeric');
            app.FixedTractionMotorlossesEditField.AllowEmpty = 'on';
            app.FixedTractionMotorlossesEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.FixedTractionMotorlossesEditField.Value = 7;
            app.NumberofpolesEditFieldLabel = uilabel(app.DrivesTab);
            app.NumberofpolesEditFieldLabel.Text = 'Number of poles:';
            app.nPolesEditField = uieditfield(app.DrivesTab, 'numeric');
            app.nPolesEditField.Limits = [2 Inf];
            app.nPolesEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.nPolesEditField.Value = 4;
            app.MotorcableresistanceEditField = uieditfield(app.DrivesTab, 'numeric');
            app.MotorcableresistanceEditField.AllowEmpty = 'on';
            app.MotorcableresistanceEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.MotorcableresistanceEditField.Value = 7;
            app.NmLabel = uilabel(app.DrivesTab);
            app.NmLabel.Text = 'Nm';
            app.GearlosstorquedependentEditFieldLabel = uilabel(app.DrivesTab);
            app.GearlosstorquedependentEditFieldLabel.Text = 'Gear loss, torque dependent:';
            app.Label_4 = uilabel(app.DrivesTab);
            app.Label_4.Text = '%';
            app.GearlossspeeddependentEditFieldLabel = uilabel(app.DrivesTab);
            app.GearlossspeeddependentEditFieldLabel.Text = 'Gear loss, speed dependent:';
            app.mNmrpmLabel = uilabel(app.DrivesTab);
            app.mNmrpmLabel.Text = 'mNm/rpm';
            app.WheelsetLabel = uilabel(app.DrivesTab);
            app.WheelsetLabel.Text = 'Wheel set:';
            app.GearboxLabel = uilabel(app.DrivesTab);
            app.GearboxLabel.Text = 'Gear box:';
            app.WheeldiameterEditFieldLabel = uilabel(app.DrivesTab);
            app.WheeldiameterEditFieldLabel.Text = 'Wheel diameter:';
            app.NominalLabel = uilabel(app.DrivesTab);
            app.NominalLabel.Text = 'Nominal';
            app.WornLabel = uilabel(app.DrivesTab);
            app.WornLabel.Text = 'Worn';
            app.NewLabel = uilabel(app.DrivesTab);
            app.NewLabel.Text = 'New';
            app.mmLabel = uilabel(app.DrivesTab);
            app.mmLabel.Text = 'mm';
            app.kgLabel = uilabel(app.DrivesTab);
            app.kgLabel.Text = 'kg';
            app.MasscontributionperwheelLabel = uilabel(app.DrivesTab);
            app.MasscontributionperwheelLabel.Text = 'Static mass contribution per wheel:';
            app.WheeldiameterselectionDropDownLabel = uilabel(app.DrivesTab);
            app.WheeldiameterselectionDropDownLabel.Text = 'Wheel diameter selection:';
            app.WheelthicknessEditFieldLabel = uilabel(app.DrivesTab);
            app.WheelthicknessEditFieldLabel.Text = 'Wheel thickness:';
            app.mmnominal135mmLabel = uilabel(app.DrivesTab);
            app.mmnominal135mmLabel.Text = 'mm (nominal 135 mm)';
            app.TractionmotorLabel = uilabel(app.DrivesTab);
            app.TractionmotorLabel.Text = 'Traction motor:';
            app.FixedTractionMotorlossesEditFieldLabel = uilabel(app.DrivesTab);
            app.FixedTractionMotorlossesEditFieldLabel.Text = 'Fixed Traction Motor losses:';
            app.Label_5 = uilabel(app.DrivesTab);
            app.Label_5.Text = '%';
            app.mLabel_5 = uilabel(app.DrivesTab);
            app.mLabel_5.Text = 'mΩ';
            app.MotorcableresistanceEditFieldLabel = uilabel(app.DrivesTab);
            app.MotorcableresistanceEditFieldLabel.Text = 'Motor cable resistance:';
            app.RotationmasscontributionperwheelEditFieldLabel = uilabel(app.DrivesTab);
            app.RotationmasscontributionperwheelEditFieldLabel.Text = 'Rotation mass contribution per wheel:';
            app.kgLabel_2 = uilabel(app.DrivesTab);
            app.kgLabel_2.Text = 'kg';
            app.kgm3Steel7850kgm3Label = uilabel(app.DrivesTab);
            app.kgm3Steel7850kgm3Label.Interpreter = 'tex';
            app.kgm3Steel7850kgm3Label.Text = 'kg/m^{3} (Steel 7850 kg/m^{3}';
            app.LaunchMotorEditorButton = uibutton(app.DrivesTab, 'push');
            app.LaunchMotorEditorButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMotorEditorButtonPushed, true);
            app.LaunchMotorEditorButton.Text = 'Launch MotorEditor';
            app.ConverterTab = uitab(app.TabGroup);
            app.ConverterTab.Title = 'Converter';
            app.SwFreqFigure = uiaxes(app.ConverterTab);
            title(app.SwFreqFigure, 'Switching freq')
            xlabel(app.SwFreqFigure, 'Stator frequency [Hz]')
            ylabel(app.SwFreqFigure, 'F_{switch} [Hz]')
            app.UPhFigure = uiaxes(app.ConverterTab);
            title(app.UPhFigure, 'Phase voltage')
            xlabel(app.UPhFigure, 'Stator frequency [Hz]')
            ylabel(app.UPhFigure, 'U_{Phase} [V]')
            app.CovSwFreqEditField = uieditfield(app.ConverterTab, 'numeric');
            app.CovSwFreqEditField.Limits = [0 Inf];
            app.CovSwFreqEditField.AllowEmpty = 'on';
            app.CovSwFreqEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.CovSwFreqEditField.Tooltip = {'maxRatePwrTE'};
            app.CovSwFreqEditField.Value = [];
            app.CovRLossEditField = uieditfield(app.ConverterTab, 'numeric');
            app.CovRLossEditField.AllowEmpty = 'on';
            app.CovRLossEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.CovRLossEditField.Tooltip = {'maxRatePwrTE'};
            app.CovRLossEditField.Value = [];
            app.CovSwFreqLossEditField = uieditfield(app.ConverterTab, 'numeric');
            app.CovSwFreqLossEditField.AllowEmpty = 'on';
            app.CovSwFreqLossEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.CovSwFreqLossEditField.Tooltip = {'maxRatePwrTE'};
            app.CovSwFreqLossEditField.Value = [];
            app.CovFactorSwFreqlossEditField = uieditfield(app.ConverterTab, 'numeric');
            app.CovFactorSwFreqlossEditField.AllowEmpty = 'on';
            app.CovFactorSwFreqlossEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.CovFactorSwFreqlossEditField.Tooltip = {'maxRatePwrTE'};
            app.CovFactorSwFreqlossEditField.Value = [];
            app.CovFixLossEditField = uieditfield(app.ConverterTab, 'numeric');
            app.CovFixLossEditField.AllowEmpty = 'on';
            app.CovFixLossEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.CovFixLossEditField.Tooltip = {'maxRatePwrTE'};
            app.CovFixLossEditField.Value = [];
            app.ConverterLabel = uilabel(app.ConverterTab);
            app.ConverterLabel.Text = 'Converter:';
            app.InvRLossEditField = uieditfield(app.ConverterTab, 'numeric');
            app.InvRLossEditField.AllowEmpty = 'on';
            app.InvRLossEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.InvRLossEditField.Tooltip = {'maxRatePwrTE'};
            app.InvRLossEditField.Value = [];
            app.InvSwFreqLossEditField = uieditfield(app.ConverterTab, 'numeric');
            app.InvSwFreqLossEditField.AllowEmpty = 'on';
            app.InvSwFreqLossEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.InvSwFreqLossEditField.Tooltip = {'maxRatePwrTE'};
            app.InvSwFreqLossEditField.Value = [];
            app.InvFactorSwFreqlossEditField = uieditfield(app.ConverterTab, 'numeric');
            app.InvFactorSwFreqlossEditField.AllowEmpty = 'on';
            app.InvFactorSwFreqlossEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.InvFactorSwFreqlossEditField.Tooltip = {'maxRatePwrTE'};
            app.InvFactorSwFreqlossEditField.Value = [];
            app.InvFixLossEditField = uieditfield(app.ConverterTab, 'numeric');
            app.InvFixLossEditField.AllowEmpty = 'on';
            app.InvFixLossEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.InvFixLossEditField.Tooltip = {'maxRatePwrTE'};
            app.InvFixLossEditField.Value = [];
            app.InvSwFreqMotorEditField = uieditfield(app.ConverterTab, 'numeric');
            app.InvSwFreqMotorEditField.AllowEmpty = 'on';
            app.InvSwFreqMotorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.InvSwFreqMotorEditField.Tooltip = {'maxRatePwrTE'};
            app.InvSwFreqMotorEditField.Value = [];
            app.InvSwFreqGeneratorEditField = uieditfield(app.ConverterTab, 'numeric');
            app.InvSwFreqGeneratorEditField.AllowEmpty = 'on';
            app.InvSwFreqGeneratorEditField.ValueChangedFcn = createCallbackFcn(app, @GeneralValueChanged, true);
            app.InvSwFreqGeneratorEditField.Tooltip = {'maxRatePwrTE'};
            app.InvSwFreqGeneratorEditField.Value = [];
            app.MotorPulseTable = uitable(app.ConverterTab);
            app.MotorPulseTable.ColumnName = {'nPulse'; 'From'; 'To'};
            app.MotorPulseTable.RowName = {};
            app.MotorPulseTable.ColumnEditable = [true true false];
            app.MotorPulseTable.CellEditCallback = createCallbackFcn(app, @MotorPulseTableCellEdit, true);
            app.GeneratorPulseTable = uitable(app.ConverterTab);
            app.GeneratorPulseTable.ColumnName = {'nPulse'; 'From'; 'To'};
            app.GeneratorPulseTable.RowName = {};
            app.GeneratorPulseTable.ColumnEditable = [true true false];
            app.GeneratorPulseTable.CellEditCallback = createCallbackFcn(app, @MotorPulseTableCellEdit, true);
            app.ConverterSwitchingfrequencyEditFieldLabel = uilabel(app.ConverterTab);
            app.ConverterSwitchingfrequencyEditFieldLabel.Text = 'Converter Switching frequency:';
            app.HzLabel_2 = uilabel(app.ConverterTab);
            app.HzLabel_2.Text = 'Hz';
            app.ResistivelossEditFieldLabel = uilabel(app.ConverterTab);
            app.ResistivelossEditFieldLabel.Text = 'Resistive loss:';
            app.mLabel_8 = uilabel(app.ConverterTab);
            app.mLabel_8.Text = 'mΩ';
            app.SwitchingFrequencylossEditFieldLabel = uilabel(app.ConverterTab);
            app.SwitchingFrequencylossEditFieldLabel.Text = 'Switching Frequency loss:';
            app.JLabel = uilabel(app.ConverterTab);
            app.JLabel.Text = 'J';
            app.FactorforSwFreqcurrentlossEditFieldLabel = uilabel(app.ConverterTab);
            app.FactorforSwFreqcurrentlossEditFieldLabel.Text = 'Factor for SwFreq & current loss:';
            app.HzLabel_3 = uilabel(app.ConverterTab);
            app.HzLabel_3.Text = 'μΩ/Hz';
            app.FixedconverterlossesEditFieldLabel = uilabel(app.ConverterTab);
            app.FixedconverterlossesEditFieldLabel.Text = 'Fixed converter losses:';
            app.Label_7 = uilabel(app.ConverterTab);
            app.Label_7.Text = '%';
            app.InverterLabel = uilabel(app.ConverterTab);
            app.InverterLabel.Text = 'Inverter:';
            app.HzLabel_4 = uilabel(app.ConverterTab);
            app.HzLabel_4.Text = 'Hz';
            app.mLabel_9 = uilabel(app.ConverterTab);
            app.mLabel_9.Text = 'mΩ';
            app.JLabel_2 = uilabel(app.ConverterTab);
            app.JLabel_2.Text = 'J';
            app.HzLabel_5 = uilabel(app.ConverterTab);
            app.HzLabel_5.Text = 'μΩ/Hz';
            app.Label_8 = uilabel(app.ConverterTab);
            app.Label_8.Text = '%';
            app.InverterSwitchingfrequencyEditFieldLabel = uilabel(app.ConverterTab);
            app.InverterSwitchingfrequencyEditFieldLabel.Text = 'Inverter Switching frequency:';
            app.ResistivelossEditField_2Label = uilabel(app.ConverterTab);
            app.ResistivelossEditField_2Label.Text = 'Resistive loss:';
            app.SwitchingFrequencylossEditField_2Label = uilabel(app.ConverterTab);
            app.SwitchingFrequencylossEditField_2Label.Text = 'Switching Frequency loss:';

            app.FactorforSwFreqcurrentlossEditField_2Label = uilabel(app.ConverterTab);
            app.FactorforSwFreqcurrentlossEditField_2Label.Text = 'Factor for SwFreq & current loss:';
            app.FixedinverterlossesEditFieldLabel = uilabel(app.ConverterTab);
            app.FixedinverterlossesEditFieldLabel.Text = 'Fixed inverter losses:';
            app.MotorLabel = uilabel(app.ConverterTab);
            app.MotorLabel.Text = 'Motor';
            app.GeneratorLabel = uilabel(app.ConverterTab);
            app.GeneratorLabel.Text = 'Generator';
            app.AddMotPtButton = uibutton(app.ConverterTab, 'push');
            app.AddMotPtButton.ButtonPushedFcn = createCallbackFcn(app, @AddSwPtButtonPushed, true);
            app.AddMotPtButton.Tag = 'Motor';
            app.AddMotPtButton.Text = '+';
            app.AddGenPtButton = uibutton(app.ConverterTab, 'push');
            app.AddGenPtButton.ButtonPushedFcn = createCallbackFcn(app, @AddSwPtButtonPushed, true);
            app.AddGenPtButton.Tag = 'Generator';
            app.AddGenPtButton.Text = '+';
            app.RmvMotPtButton = uibutton(app.ConverterTab, 'push');
            app.RmvMotPtButton.ButtonPushedFcn = createCallbackFcn(app, @RmvSwPtButtonPushed, true);
            app.RmvMotPtButton.Tag = 'Motor';
            app.RmvMotPtButton.Text = '-';
            app.RmvGenPtButton = uibutton(app.ConverterTab, 'push');
            app.RmvGenPtButton.ButtonPushedFcn = createCallbackFcn(app, @RmvSwPtButtonPushed, true);
            app.RmvGenPtButton.Tag = 'Generator';
            app.RmvGenPtButton.Text = '-';
            app.MotormodeLabel = uilabel(app.ConverterTab);
            app.MotormodeLabel.Text = 'Motor mode';
            app.GeneratormodeLabel = uilabel(app.ConverterTab);
            app.GeneratormodeLabel.Text = 'Generator mode';
            app.NotchingTab = uitab(app.TabGroup);
            app.NotchingTab.Title = 'Notching';
            app.NumberofPowernotchesLabel = uilabel(app.NotchingTab);
            app.NumberofPowernotchesLabel.Text = 'Number of Power notches:';
            app.NumberofPowernotchesEditField = uieditfield(app.NotchingTab, 'numeric');
            app.NumberofPowernotchesEditField.AllowEmpty = 'on';
            app.NumberofPowernotchesEditField.Tooltip = {'maxRatePwrTE'};
            app.NumberofPowernotchesEditField.Value = [];
            app.NotchingmethodsLabel = uilabel(app.NotchingTab);
            app.NotchingmethodsLabel.Text = 'Notching methods:';
            app.NothingmethodbasedonDropDownLabel = uilabel(app.NotchingTab);
            app.NothingmethodbasedonDropDownLabel.Text = 'Nothing method, based on:';
            app.NothingmethodbasedonDropDown = uidropdown(app.NotchingTab);
            app.NothingmethodbasedonDropDown.Items = {'None', 'Timing', 'Speed', 'Controller'};
            app.NothingmethodbasedonDropDown.Value = 'None';
            app.NumberofBrakenotchesEditFieldLabel = uilabel(app.NotchingTab);
            app.NumberofBrakenotchesEditFieldLabel.Text = 'Number of Brake notches:';
            app.NumberofBrakenotchesEditField = uieditfield(app.NotchingTab, 'numeric');
            app.NumberofBrakenotchesEditField.AllowEmpty = 'on';
            app.NumberofBrakenotchesEditField.Tooltip = {'maxRatePwrTE'};
            app.NumberofBrakenotchesEditField.Value = [];
            app.MinimumnotchingtimeEditFieldLabel = uilabel(app.NotchingTab);
            app.MinimumnotchingtimeEditFieldLabel.Text = 'Minimum notching time:';
            app.MinimumnotchingtimeEditField = uieditfield(app.NotchingTab, 'numeric');
            app.MinimumnotchingtimeEditField.AllowEmpty = 'on';
            app.MinimumnotchingtimeEditField.Tooltip = {'maxRatePwrTE'};
            app.MinimumnotchingtimeEditField.Value = [];
            app.MinimumnotchupdelayEditFieldLabel = uilabel(app.NotchingTab);
            app.MinimumnotchupdelayEditFieldLabel.Text = 'Minimum notch up delay:';
            app.MinimumnotchupdelayEditField = uieditfield(app.NotchingTab, 'numeric');
            app.MinimumnotchupdelayEditField.AllowEmpty = 'on';
            app.MinimumnotchupdelayEditField.Tooltip = {'maxRatePwrTE'};
            app.MinimumnotchupdelayEditField.Value = [];
            app.NotchondeltaspeedEditFieldLabel = uilabel(app.NotchingTab);
            app.NotchondeltaspeedEditFieldLabel.Text = 'Notch-on delta speed:';
            app.NotchOnDeltaVTrcEditField = uieditfield(app.NotchingTab, 'numeric');
            app.NotchOnDeltaVTrcEditField.AllowEmpty = 'on';
            app.NotchOnDeltaVTrcEditField.Tooltip = {'maxRatePwrTE'};
            app.NotchOnDeltaVTrcEditField.Value = [];
            app.TractionLabel_3 = uilabel(app.NotchingTab);
            app.TractionLabel_3.Text = 'Traction';
            app.BrakingLabel_2 = uilabel(app.NotchingTab);
            app.BrakingLabel_2.Text = 'Braking';
            app.NotchOnDeltaVBrkEditField = uieditfield(app.NotchingTab, 'numeric');
            app.NotchOnDeltaVBrkEditField.AllowEmpty = 'on';
            app.NotchOnDeltaVBrkEditField.Tooltip = {'maxRatePwrTE'};
            app.NotchOnDeltaVBrkEditField.Value = [];
            app.SpdSelNothingDropDown = uidropdown(app.NotchingTab);
            app.SpdSelNothingDropDown.Items = {'km/h', 'mph', 'm/s', 'Hz'};
            app.SpdSelNothingDropDown.ValueChangedFcn = createCallbackFcn(app, @SpdSelNothingDropDownValueChanged, true);
            app.SpdSelNothingDropDown.Value = 'km/h';
            app.ApplicationspeedEditFieldLabel = uilabel(app.NotchingTab);
            app.ApplicationspeedEditFieldLabel.Text = 'Application speed:';
            app.ApplV_Trc_EditField = uieditfield(app.NotchingTab, 'numeric');
            app.ApplV_Trc_EditField.AllowEmpty = 'on';
            app.ApplV_Trc_EditField.Tooltip = {'maxRatePwrTE'};
            app.ApplV_Trc_EditField.Value = [];
            app.ApplV_Brk_EditField = uieditfield(app.NotchingTab, 'numeric');
            app.ApplV_Brk_EditField.AllowEmpty = 'on';
            app.ApplV_Brk_EditField.Tooltip = {'maxRatePwrTE'};
            app.ApplV_Brk_EditField.Value = [];
            app.kmhLabel_3 = uilabel(app.NotchingTab);
            app.kmhLabel_3.Text = 'km/h';
            app.sLabel = uilabel(app.NotchingTab);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.NotchingTab);
            app.sLabel_2.Text = 's';
            app.ReducationstartdeltaspeedEditFieldLabel = uilabel(app.NotchingTab);
            app.ReducationstartdeltaspeedEditFieldLabel.Text = 'Reducation start delta speed:';
            app.ReducationstartdeltaspeedEditField = uieditfield(app.NotchingTab, 'numeric');
            app.ReducationstartdeltaspeedEditField.AllowEmpty = 'on';
            app.ReducationstartdeltaspeedEditField.Tooltip = {'maxRatePwrTE'};
            app.ReducationstartdeltaspeedEditField.Value = [];
            app.kmhLabel_4 = uilabel(app.NotchingTab);
            app.kmhLabel_4.Text = 'km/h';
            app.ms2Label_6 = uilabel(app.NotchingTab);
            app.ms2Label_6.Interpreter = 'tex';
            app.ms2Label_6.Text = 'm/s^2';
            app.MaxaccelerationatstartEditFieldLabel = uilabel(app.NotchingTab);
            app.MaxaccelerationatstartEditFieldLabel.Text = 'Max acceleration at start:';
            app.MaxaccelerationatstartEditField = uieditfield(app.NotchingTab, 'numeric');
            app.MaxaccelerationatstartEditField.AllowEmpty = 'on';
            app.MaxaccelerationatstartEditField.Tooltip = {'maxRatePwrTE'};
            app.MaxaccelerationatstartEditField.Value = [];
            app.ThermalTab = uitab(app.TabGroup);
            app.ThermalTab.Title = 'Thermal';
            app.AmbienttemperatureLabel = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel.Text = 'Ambient temperature:';
            app.AmbTempEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.AmbTempEditfield.Limits = [-297 200];
            app.AmbTempEditfield.AllowEmpty = 'on';
            app.AmbTempEditfield.Value = 35;
            app.MTrPrimWindCurrentEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.MTrPrimWindCurrentEditfield.Limits = [-297 200];
            app.MTrPrimWindCurrentEditfield.AllowEmpty = 'on';
            app.MTrPrimWindCurrentEditfield.Value = [];
            app.AmbienttemperatureLabel_2 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_2.Text = 'Cont Prim wind current:';
            app.AmbienttemperatureLabel_3 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_3.Text = 'Time constant:';
            app.MTrPrimWindTCEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.MTrPrimWindTCEditfield.Limits = [-297 200];
            app.MTrPrimWindTCEditfield.AllowEmpty = 'on';
            app.MTrPrimWindTCEditfield.Value = [];
            app.AmbienttemperatureLabel_4 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_4.Text = 'Cont 2nd winding current:';
            app.MTrSecWindCurrentEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.MTrSecWindCurrentEditfield.Limits = [-297 200];
            app.MTrSecWindCurrentEditfield.AllowEmpty = 'on';
            app.MTrSecWindCurrentEditfield.Value = [];
            app.AmbienttemperatureLabel_5 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_5.Text = 'Time constant:';
            app.MTrSecWindTCEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.MTrSecWindTCEditfield.Limits = [-297 200];
            app.MTrSecWindTCEditfield.AllowEmpty = 'on';
            app.MTrSecWindTCEditfield.Value = [];
            app.AmbienttemperatureLabel_6 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_6.Text = 'Continuous Line current:';
            app.DCLineFiltContCurEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.DCLineFiltContCurEditfield.Limits = [-297 200];
            app.DCLineFiltContCurEditfield.AllowEmpty = 'on';
            app.DCLineFiltContCurEditfield.Value = [];
            app.AmbienttemperatureLabel_7 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_7.Text = 'Time constant:';
            app.DCLineFiltTCCurEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.DCLineFiltTCCurEditfield.Limits = [-297 200];
            app.DCLineFiltTCCurEditfield.AllowEmpty = 'on';
            app.DCLineFiltTCCurEditfield.Value = [];
            app.AmbienttemperatureLabel_8 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_8.Text = 'Max IPH:';
            app.INVMaxIphEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.INVMaxIphEditfield.Limits = [-297 200];
            app.INVMaxIphEditfield.AllowEmpty = 'on';
            app.INVMaxIphEditfield.Value = [];
            app.AmbienttemperatureLabel_9 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_9.Text = 'Continuous IPH, Sinus:';
            app.INVContIphSinusEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.INVContIphSinusEditfield.Limits = [-297 200];
            app.INVContIphSinusEditfield.AllowEmpty = 'on';
            app.INVContIphSinusEditfield.Value = [];
            app.AmbienttemperatureLabel_10 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_10.Text = 'Continuous IPH, Hex:';
            app.INVContIphHexEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.INVContIphHexEditfield.Limits = [-297 200];
            app.INVContIphHexEditfield.AllowEmpty = 'on';
            app.INVContIphHexEditfield.Value = [];
            app.AmbienttemperatureLabel_11 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_11.Text = 'Time constant:';
            app.INVContIphTCEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.INVContIphTCEditfield.Limits = [-297 200];
            app.INVContIphTCEditfield.AllowEmpty = 'on';
            app.INVContIphTCEditfield.Value = [];
            app.AmbienttemperatureLabel_12 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_12.Text = 'Max IPH:';
            app.CONVMaxIphEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.CONVMaxIphEditfield.Limits = [-297 200];
            app.CONVMaxIphEditfield.AllowEmpty = 'on';
            app.CONVMaxIphEditfield.Value = [];
            app.AmbienttemperatureLabel_13 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_13.Text = 'Continuous IPH, Sinus:';
            app.CONVContIphSinusEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.CONVContIphSinusEditfield.Limits = [-297 200];
            app.CONVContIphSinusEditfield.AllowEmpty = 'on';
            app.CONVContIphSinusEditfield.Value = [];
            app.AmbienttemperatureLabel_14 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_14.Text = 'Time constant:';
            app.CONVContIphTCEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.CONVContIphTCEditfield.Limits = [-297 200];
            app.CONVContIphTCEditfield.AllowEmpty = 'on';
            app.CONVContIphTCEditfield.Value = [];
            app.AmbienttemperatureLabel_15 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_15.Text = 'Max stator current:';
            app.TMIphMaxEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.TMIphMaxEditfield.Limits = [-297 200];
            app.TMIphMaxEditfield.AllowEmpty = 'on';
            app.TMIphMaxEditfield.Value = [];
            app.AmbienttemperatureLabel_16 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_16.Text = 'Continuous stator current:';
            app.TMIphContEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.TMIphContEditfield.Limits = [-297 200];
            app.TMIphContEditfield.AllowEmpty = 'on';
            app.TMIphContEditfield.Value = [];
            app.AmbienttemperatureLabel_17 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_17.Text = 'Time constant:';
            app.TMIphTCEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.TMIphTCEditfield.Limits = [-297 200];
            app.TMIphTCEditfield.AllowEmpty = 'on';
            app.TMIphTCEditfield.Value = [];
            app.TMcoolingtypeDropDownLabel = uilabel(app.ThermalTab);
            app.TMcoolingtypeDropDownLabel.Text = 'TM cooling type:';
            app.TMcoolingtypeDropDown = uidropdown(app.ThermalTab);
            app.TMcoolingtypeDropDown.Items = {'Forced cooling', 'Self ventilated'};
            app.TMcoolingtypeDropDown.Value = 'Forced cooling';
            app.BRThermCapacityEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRThermCapacityEditfield.Limits = [-297 200];
            app.BRThermCapacityEditfield.AllowEmpty = 'on';
            app.BRThermCapacityEditfield.Value = [];
            app.BRTCFanOffEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRTCFanOffEditfield.Limits = [-297 200];
            app.BRTCFanOffEditfield.AllowEmpty = 'on';
            app.BRTCFanOffEditfield.Value = [];
            app.BRTCFanLowSpEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRTCFanLowSpEditfield.Limits = [-297 200];
            app.BRTCFanLowSpEditfield.AllowEmpty = 'on';
            app.BRTCFanLowSpEditfield.Value = [];
            app.BRTCFanHiSpEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRTCFanHiSpEditfield.Limits = [-297 200];
            app.BRTCFanHiSpEditfield.AllowEmpty = 'on';
            app.BRTCFanHiSpEditfield.Value = [];
            app.BRTCTempFactorEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRTCTempFactorEditfield.Limits = [-297 200];
            app.BRTCTempFactorEditfield.AllowEmpty = 'on';
            app.BRTCTempFactorEditfield.Value = [];
            app.BRFanOnTempEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRFanOnTempEditfield.Limits = [-297 200];
            app.BRFanOnTempEditfield.AllowEmpty = 'on';
            app.BRFanOnTempEditfield.Value = [];
            app.BRFanHiSpdHystONEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRFanHiSpdHystONEditfield.Limits = [-297 200];
            app.BRFanHiSpdHystONEditfield.AllowEmpty = 'on';
            app.BRFanHiSpdHystONEditfield.Value = [];
            app.BRFanHiSpdHystOFFEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRFanHiSpdHystOFFEditfield.Limits = [-297 200];
            app.BRFanHiSpdHystOFFEditfield.AllowEmpty = 'on';
            app.BRFanHiSpdHystOFFEditfield.Value = [];
            app.BRFanOffVclSpdEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRFanOffVclSpdEditfield.Limits = [-297 200];
            app.BRFanOffVclSpdEditfield.AllowEmpty = 'on';
            app.BRFanOffVclSpdEditfield.Value = [];
            app.BRHighTmpEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRHighTmpEditfield.Limits = [-297 200];
            app.BRHighTmpEditfield.AllowEmpty = 'on';
            app.BRHighTmpEditfield.Value = [];
            app.BRMaxTmpEditfield = uieditfield(app.ThermalTab, 'numeric');
            app.BRMaxTmpEditfield.Limits = [-297 200];
            app.BRMaxTmpEditfield.AllowEmpty = 'on';
            app.BRMaxTmpEditfield.Value = [];
            app.A_RMSLabel = uilabel(app.ThermalTab);
            app.A_RMSLabel.Interpreter = 'tex';
            app.A_RMSLabel.Text = 'A_{RMS}';
            app.ThermalCalculationandLimitationsLabel = uilabel(app.ThermalTab);
            app.ThermalCalculationandLimitationsLabel.Text = 'Thermal Calculation and Limitations';
            app.TransformerThermalPerformanceLabel = uilabel(app.ThermalTab);
            app.TransformerThermalPerformanceLabel.Text = 'Transformer Thermal Performance:';
            app.DCLinefilterThermalPerformanceLabel = uilabel(app.ThermalTab);
            app.DCLinefilterThermalPerformanceLabel.Text = 'DC Line filter Thermal Performance:';
            app.MotorInverterThermalPerformanceLabel = uilabel(app.ThermalTab);
            app.MotorInverterThermalPerformanceLabel.Text = 'Motor Inverter Thermal Performance:';
            app.ConverterThermalPerformanceLabel = uilabel(app.ThermalTab);
            app.ConverterThermalPerformanceLabel.Text = 'Converter Thermal Performance:';
            app.CLabel_3 = uilabel(app.ThermalTab);
            app.CLabel_3.Text = '°C';
            app.TractionMotorThermalPerformanceLabel = uilabel(app.ThermalTab);
            app.TractionMotorThermalPerformanceLabel.Text = 'Traction Motor Thermal Performance:';
            app.A_RMSLabel_2 = uilabel(app.ThermalTab);
            app.A_RMSLabel_2.Interpreter = 'tex';
            app.A_RMSLabel_2.Text = 'A_{RMS}';
            app.A_RMSLabel_3 = uilabel(app.ThermalTab);
            app.A_RMSLabel_3.Interpreter = 'tex';
            app.A_RMSLabel_3.Text = 'A_{RMS}';
            app.A_RMSLabel_4 = uilabel(app.ThermalTab);
            app.A_RMSLabel_4.Interpreter = 'tex';
            app.A_RMSLabel_4.Text = 'A_{RMS}';
            app.A_RMSLabel_5 = uilabel(app.ThermalTab);
            app.A_RMSLabel_5.Interpreter = 'tex';
            app.A_RMSLabel_5.Text = 'A_{RMS}';
            app.A_RMSLabel_6 = uilabel(app.ThermalTab);
            app.A_RMSLabel_6.Interpreter = 'tex';
            app.A_RMSLabel_6.Text = 'A_{RMS}';
            app.A_RMSLabel_7 = uilabel(app.ThermalTab);
            app.A_RMSLabel_7.Interpreter = 'tex';
            app.A_RMSLabel_7.Text = 'A_{RMS}';
            app.A_RMSLabel_8 = uilabel(app.ThermalTab);
            app.A_RMSLabel_8.Interpreter = 'tex';
            app.A_RMSLabel_8.Text = 'A_{RMS}';
            app.A_RMSLabel_9 = uilabel(app.ThermalTab);
            app.A_RMSLabel_9.Interpreter = 'tex';
            app.A_RMSLabel_9.Text = 'A_{RMS}';
            app.A_RMSLabel_10 = uilabel(app.ThermalTab);
            app.A_RMSLabel_10.Interpreter = 'tex';
            app.A_RMSLabel_10.Text = 'A_{RMS}';
            app.BrakeResistordataperInverterLabel = uilabel(app.ThermalTab);
            app.BrakeResistordataperInverterLabel.Text = 'Brake Resistor data (per Inverter):';
            app.AmbienttemperatureLabel_18 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_18.Text = 'Thermal Capacitance:';
            app.AmbienttemperatureLabel_19 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_19.Text = 'Thermal TC fan at low speed:';
            app.AmbienttemperatureLabel_20 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_20.Text = 'Thermal TC fan at high speed:';
            app.AmbienttemperatureLabel_21 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_21.Text = 'Thermal TC fan off:';
            app.AmbienttemperatureLabel_22 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_22.Text = 'TC temperature factor:';
            app.sLabel_4 = uilabel(app.ThermalTab);
            app.sLabel_4.Text = 's';
            app.sLabel_5 = uilabel(app.ThermalTab);
            app.sLabel_5.Text = 's';
            app.sLabel_6 = uilabel(app.ThermalTab);
            app.sLabel_6.Text = 's';
            app.sLabel_7 = uilabel(app.ThermalTab);
            app.sLabel_7.Text = 's';
            app.sLabel_8 = uilabel(app.ThermalTab);
            app.sLabel_8.Text = 's';
            app.sLabel_9 = uilabel(app.ThermalTab);
            app.sLabel_9.Text = 's';
            app.BrakeResistorcoolingcontrolLabel = uilabel(app.ThermalTab);
            app.BrakeResistorcoolingcontrolLabel.Text = 'Brake Resistor, cooling control:';
            app.AmbienttemperatureLabel_23 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_23.Text = 'Fan on temperature:';
            app.CLabel_4 = uilabel(app.ThermalTab);
            app.CLabel_4.Text = '°C';
            app.CLabel_5 = uilabel(app.ThermalTab);
            app.CLabel_5.Text = '°C';
            app.sLabel_10 = uilabel(app.ThermalTab);
            app.sLabel_10.Text = 's';
            app.sLabel_11 = uilabel(app.ThermalTab);
            app.sLabel_11.Text = 's';
            app.sLabel_12 = uilabel(app.ThermalTab);
            app.sLabel_12.Text = 's';
            app.Label_9 = uilabel(app.ThermalTab);
            app.Label_9.Text = '%';
            app.AmbienttemperatureLabel_24 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_24.Text = 'Fan high speed hystereesis, on/off:';
            app.CLabel_6 = uilabel(app.ThermalTab);
            app.CLabel_6.Text = '°C';
            app.Label_10 = uilabel(app.ThermalTab);
            app.Label_10.Text = '/';
            app.AmbienttemperatureLabel_25 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_25.Text = 'Fan off, vehicle speed:';
            app.AmbienttemperatureLabel_26 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_26.Text = 'Brake resistor, high temperature:';
            app.CLabel_7 = uilabel(app.ThermalTab);
            app.CLabel_7.Text = '°C';
            app.AmbienttemperatureLabel_27 = uilabel(app.ThermalTab);
            app.AmbienttemperatureLabel_27.Text = 'Brake resistor, max temperature:';
            app.CLabel_8 = uilabel(app.ThermalTab);
            app.CLabel_8.Text = '°C';
            app.SpdSelectBRFan = uidropdown(app.ThermalTab);
            app.SpdSelectBRFan.Items = {'km/h', 'mph', 'm/s', 'Hz'};
            app.SpdSelectBRFan.Value = 'km/h';
            % Show the figure after all components are created
            app.ShogunVehicleEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VehicleEditor

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ShogunVehicleEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ShogunVehicleEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShogunVehicleEditorUIFigure)
        end
    end
end