classdef new_motor_dlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogueFig                   matlab.ui.Figure
        MaxPowerinmotoringkWLabel_10  matlab.ui.control.Label
        MaxPowerinmotoringkWLabel_8   matlab.ui.control.Label
        MaxPowerinmotoringkWLabel_7   matlab.ui.control.Label
        NoPolesEdit                   matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_12  matlab.ui.control.Label
        NoSpdPointsPwGenEdit          matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_11  matlab.ui.control.Label
        NoPwLevPointsGenEdit          matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_10  matlab.ui.control.Label
        NoSpdPointsTqGenEdit          matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_9  matlab.ui.control.Label
        NoTqLevPointsGenEdit          matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_8  matlab.ui.control.Label
        NoSpdPointsPwMotEdit          matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_7  matlab.ui.control.Label
        NoPwLevPointsMotEdit          matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_6  matlab.ui.control.Label
        NoSpdPointsTqMotEdit          matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_5  matlab.ui.control.Label
        NoTqLevPointsMotEdit          matlab.ui.control.NumericEditField
        NoTorquelevelsinmotoringLabel_4  matlab.ui.control.Label
        UphSpeedEdit                  matlab.ui.control.NumericEditField
        UphTqEdit                     matlab.ui.control.NumericEditField
        UdcGenEdit                    matlab.ui.control.NumericEditField
        MaxPowerinmotoringkWLabel_6   matlab.ui.control.Label
        UdcMotEdit                    matlab.ui.control.NumericEditField
        MaxPowerinmotoringkWLabel_5   matlab.ui.control.Label
        MxSpdEdit                     matlab.ui.control.NumericEditField
        MaxPowerinmotoringkWLabel_4   matlab.ui.control.Label
        BaseLossGenEdit               matlab.ui.control.NumericEditField
        BaseLossMotEdit               matlab.ui.control.NumericEditField
        MaxPowerinmotoringkWLabel_9   matlab.ui.control.Label
        MxPwGenEdit                   matlab.ui.control.NumericEditField
        MaxPowerinmotoringkWLabel_3   matlab.ui.control.Label
        MxTqGenEdit                   matlab.ui.control.NumericEditField
        MaxPowerinmotoringkWLabel_2   matlab.ui.control.Label
        MxPwMotEdit                   matlab.ui.control.NumericEditField
        MaxPowerinmotoringkWLabel     matlab.ui.control.Label
        MxTqMotEdit                   matlab.ui.control.NumericEditField
        MaxTorqueinmotoringNmEditFieldLabel_2  matlab.ui.control.Label
        NopointsinGeneratorLabel      matlab.ui.control.Label
        NopointsinMotoringLabel       matlab.ui.control.Label
        vBaseMotLabel_4               matlab.ui.control.Label
        BasespeedGeneratorLabel       matlab.ui.control.Label
        vBaseMotLabel_2               matlab.ui.control.Label
        BasespeedMotoringLabel        matlab.ui.control.Label
        NoPointsGenLabel              matlab.ui.control.Label
        NoPointsMotLabel              matlab.ui.control.Label
        vBaseGenLabel                 matlab.ui.control.Label
        vBaseMotLabel                 matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        dlg % to make sure we can close it from the caller
        motor % Description
        DialogResult % Description
    end
    
    methods (Access = public)
        
    end

    methods (Static)

        function [dlg, values] = ask(centrePoint)
            % Create the app
            app = new_motor_dlg;

            % Center on parent if provided

            dlgPos = app.DialogueFig.Position;
            newpos = centrePoint-dlgPos(3:4)/2;
            app.DialogueFig.Position(1:2) = newpos;

            uiwait(app.DialogueFig);
            dlg = app;
            if isvalid(app) && strcmp(app.DialogResult,"OK")
                values = app.motor;
            else
                values = [];                    % Cancel or window closed
            end
        end

    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            FieldChanged(app,'')
        end

        % Value changed function: MxPwGenEdit, MxPwMotEdit, MxSpdEdit, 
        % ...and 13 other components
        function FieldChanged(app, event)
            TqMot = app.MxTqMotEdit.Value;
            PwMot = app.MxPwMotEdit.Value * 1000; % W
            TqGen = app.MxTqGenEdit.Value;
            PwGen = app.MxPwGenEdit.Value * 1000; % W
            mot.nPoles = app.NoPolesEdit.Value;
            mot.rpmBaseMotor = PwMot/TqMot/(2 * pi)*60;
            mot.rpmBaseGenerator = PwGen/TqGen/(2 * pi)*60;
            mot.VMax = app.MxSpdEdit.Value;
            URateTq = app.UphTqEdit.Value; % mV/Nm
            URateSpeed = app.UphSpeedEdit.Value;
            app.vBaseMotLabel.Text = num2str(mot.rpmBaseMotor, '%5.2f');
            app.vBaseGenLabel.Text = num2str(mot.rpmBaseGenerator, '%5.2f');
            n_Mot = app.NoTqLevPointsMotEdit.Value * app.NoSpdPointsTqMotEdit.Value + app.NoPwLevPointsMotEdit.Value * app.NoSpdPointsPwMotEdit.Value;
            n_Gen = app.NoTqLevPointsGenEdit.Value * app.NoSpdPointsTqGenEdit.Value + app.NoPwLevPointsGenEdit.Value * app.NoSpdPointsPwGenEdit.Value;
            app.NoPointsMotLabel.Text = num2str(n_Mot);
            app.NoPointsGenLabel.Text = num2str(n_Gen);
            % udc
            mot.UDC_motor = app.UdcMotEdit.Value;
            mot.UDC_generator = app.UdcGenEdit.Value;

            % MT map
            uphMaxMot = mot.UDC_motor * sqrt(6)/pi;
            % Reference voltage
            uphBaseMot = min([URateSpeed * mot.rpmBaseMotor * mot.nPoles/120 + URateTq * TqMot / 1000, uphMaxMot]);
            baseLossMot = app.BaseLossMotEdit.Value * 1000;
            % Reference current
            iphBaseMot = (PwMot + baseLossMot) / (uphBaseMot * sqrt(3));
            nSpdPts = app.NoSpdPointsTqMotEdit.Value;
            nTqLev = app.NoTqLevPointsMotEdit.Value;
            mot.SpeedMT = (0:(nSpdPts - 1))' .* ones(1, nTqLev) / (nSpdPts - 1) * mot.rpmBaseMotor;
            mot.TorqueMT = (0:(nTqLev  - 1)) .* ones(nSpdPts, 1) / (nTqLev  - 1) * app.MxTqMotEdit.Value;
            pow = mot.TorqueMT .* mot.SpeedMT * 2 * pi / 60;
            mot.TotLossMT = zeros(nSpdPts, nTqLev);
            mot.IPhaseMT = ones(nSpdPts,nTqLev );
            uph = mot.TorqueMT * URateTq/1000 + mot.SpeedMT/60 * mot.nPoles/2 * URateSpeed;
            mot.UPhaseMT = min(uph, uphMaxMot);
            mot.IPhaseMT = (0:(nTqLev - 1)) .* ones(nSpdPts,1) / (nTqLev - 1) * iphBaseMot; %  * uphBaseMot ./ mot.UPhaseMT

            % mot.UPhaseMT = (0:(nSpdPts - 1))' .* ones(1,nTqLev) / (nSpdPts - 1) * uphBaseMot;
            mot.SlipMT = zeros(nSpdPts, nTqLev);
            mot.PFactMT = ones(nSpdPts, nTqLev);
            % iph = (pow + loss) / pf / uph / sqrt(3);
            % pow + loss = iph * pf * uph * sqrt(3)
            % pow = tq * spd * 2 * pi / 60;
            
            % loss = iph * pf * uph * sqrt(3) - pow
            loss = mot.IPhaseMT .* mot.PFactMT .* mot.UPhaseMT * sqrt(3) - pow;
            mot.TotLossMT = loss;

            % GT map
            uphMaxGen = mot.UDC_generator * sqrt(6)/pi;
            uphBaseGen = min([URateSpeed * mot.rpmBaseGenerator * mot.nPoles/120 + URateTq * TqGen / 1000, uphMaxGen]);
            baseLossGen = app.BaseLossGenEdit.Value * 1000;
            iphBaseGen  = (PwGen + baseLossGen) / (uphBaseGen * sqrt(3));
            nSpdPts = app.NoSpdPointsTqGenEdit.Value;
            nTqLev = app.NoTqLevPointsGenEdit.Value;
            mot.SpeedGT = (0:(nSpdPts - 1))' .* ones(1, nTqLev) / (nSpdPts - 1) * mot.rpmBaseGenerator;
            mot.TorqueGT = (0:(nTqLev - 1)) .* ones(nSpdPts, 1) / (nTqLev - 1) * -app.MxTqGenEdit.Value;
            pow = mot.TorqueGT .* mot.SpeedGT * 2 * pi / 60;
            mot.TotLossGT = zeros(nSpdPts, nTqLev);
            mot.IPhaseGT = (0:(nTqLev - 1)) .* ones(nSpdPts,1) / (nTqLev - 1) * iphBaseGen;
            mot.UPhaseGT = min(mot.TorqueGT * -URateTq/1000 + mot.SpeedGT/60 * mot.nPoles/2 * URateSpeed, uphMaxGen);
            % mot.UPhaseGT = (0:(nSpdPts - 1))' .* ones(1,nTqLev) / (nSpdPts - 1) * uphBaseGen;
            mot.SlipGT = zeros(nSpdPts, nTqLev);
            mot.PFactGT = -ones(nSpdPts, nTqLev);
            loss = pow - mot.IPhaseGT .* mot.PFactGT .* mot.UPhaseGT * sqrt(3);
            mot.TotLossGT = loss;

            % MP map
            nSpdPts = app.NoSpdPointsPwMotEdit.Value;
            nPwLev = app.NoPwLevPointsMotEdit.Value;
            mot.SpeedMP = (0:(nSpdPts - 1))' .* ones(1,nPwLev) / (nSpdPts - 1) * (mot.VMax - mot.rpmBaseMotor) + mot.rpmBaseMotor;
            mot.PowerMP = (0:(nPwLev - 1)) .* ones(nSpdPts, 1) / (nPwLev - 1) * PwMot;
            mot.TotLossMP = zeros(nSpdPts, nPwLev);
            % mot.IPhaseMP = mot.PowerMP / (uphMaxMot * sqrt(3));
            mot.IPhaseMP = ones(nSpdPts,nPwLev);
            
            uph = mot.PowerMP./(mot.SpeedMP*2*pi/60) * URateTq/1000 + mot.SpeedMP/60 * mot.nPoles/2 * URateSpeed;
            % mot.UPhaseMT
            mot.UPhaseMP = min(uph, uphMaxMot);
            % mot.IPhaseMT = (0:(nTqLev - 1)) .* ones(nSpdPts,1) / (nTqLev - 1) * iphBaseMot;
            mot.IPhaseMP = (0:(nPwLev - 1)) .* ones(nSpdPts,1) / (nPwLev - 1) * iphBaseMot * uphBaseMot ./ mot.UPhaseMP(:,end);
            % mot.UPhaseMP ./ uph
            mot.SlipMP = zeros(nSpdPts, nPwLev);
            mot.PFactMP = ones(nSpdPts, nPwLev);
            loss = mot.IPhaseMP .* mot.PFactMP .* mot.UPhaseMP * sqrt(3) - mot.PowerMP;
            mot.TotLossMP = loss;
            
            % GP map
            nSpdPts = app.NoSpdPointsPwGenEdit.Value;
            nPwLev = app.NoPwLevPointsGenEdit.Value;
            mot.SpeedGP = (0:(nSpdPts - 1))' .* ones(1, nPwLev) / (nSpdPts - 1) * (mot.VMax - mot.rpmBaseGenerator) + mot.rpmBaseGenerator;
            mot.PowerGP = (0:(nPwLev - 1)) .* ones(nSpdPts, 1) / (nPwLev - 1) * -PwGen;
            mot.TotLossGP = zeros(nSpdPts, nPwLev);
            mot.IPhaseGP = ones(nSpdPts,nPwLev);
            uph = mot.PowerGP./(mot.SpeedGP*2*pi/60) * -URateTq/1000 + mot.SpeedGP/60 * mot.nPoles/2 * URateSpeed;
            mot.UPhaseGP = min(uph, uphMaxGen);
            % mot.UPhaseGP = min(mot.PowerGP./(mot.SpeedGP*2*pi/60) * URateTq/1000 + mot.SpeedGP/60 * mot.nPoles/2 * URateSpeed, uphBaseGen);
            % mot.UPhaseGP = ones(nSpdPts, nPwLev) * uphBaseGen;
            mot.IPhaseGP = (0:(nPwLev - 1)) .* ones(nSpdPts,1) / (nPwLev - 1) * iphBaseGen * uphBaseGen ./ mot.UPhaseGP(:,end);
            mot.SlipGP = zeros(nSpdPts, nPwLev);
            mot.PFactGP = -ones(nSpdPts, nPwLev);
            loss = mot.PowerGP - mot.IPhaseGP .* mot.PFactGP .* mot.UPhaseGP * sqrt(3);
            mot.TotLossGP = loss;

            app.motor = mot;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % app.Output.motor = app.motor;
            app.DialogResult = "OK";
            uiresume(app.DialogueFig);
            % delete(app.DialogueFig);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.DialogResult = "Cancel";
            uiresume(app.DialogueFig);
            % delete(app.DialogueFig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogueFig = uifigure('Visible', 'off');
            app.DialogueFig.Name = 'New motor dialog';
            app.OKButton = uibutton(app.DialogueFig, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.DialogueFig, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.vBaseMotLabel = uilabel(app.DialogueFig);
            app.vBaseMotLabel.Text = '-';
            app.vBaseGenLabel = uilabel(app.DialogueFig);
            app.vBaseGenLabel.Text = '-';
            app.NoPointsMotLabel = uilabel(app.DialogueFig);
            app.NoPointsMotLabel.Text = '-';
            app.NoPointsGenLabel = uilabel(app.DialogueFig);
            app.NoPointsGenLabel.Text = '-';
            app.BasespeedMotoringLabel = uilabel(app.DialogueFig);
            app.BasespeedMotoringLabel.Text = 'Base speed Motoring:';
            app.vBaseMotLabel_2 = uilabel(app.DialogueFig);
            app.vBaseMotLabel_2.Text = 'rpm';
            app.BasespeedGeneratorLabel = uilabel(app.DialogueFig);
            app.BasespeedGeneratorLabel.Text = 'Base speed Generator:';
            app.vBaseMotLabel_4 = uilabel(app.DialogueFig);
            app.vBaseMotLabel_4.Text = 'rpm';
            app.NopointsinMotoringLabel = uilabel(app.DialogueFig);
            app.NopointsinMotoringLabel.Text = 'No points in Motoring:';
            app.NopointsinGeneratorLabel = uilabel(app.DialogueFig);
            app.NopointsinGeneratorLabel.Text = 'No points in Generator:';
            app.MaxTorqueinmotoringNmEditFieldLabel_2 = uilabel(app.DialogueFig);
            app.MaxTorqueinmotoringNmEditFieldLabel_2.Text = 'Max Torque in motoring [Nm]:';
            app.MxTqMotEdit = uieditfield(app.DialogueFig, 'numeric');
            app.MxTqMotEdit.Limits = [1 Inf];
            app.MxTqMotEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.MxTqMotEdit.Value = 1000;
            app.MaxPowerinmotoringkWLabel = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel.Text = 'Max Power in motoring [kW]:';
            app.MxPwMotEdit = uieditfield(app.DialogueFig, 'numeric');
            app.MxPwMotEdit.Limits = [1 Inf];
            app.MxPwMotEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.MxPwMotEdit.Value = 200;
            app.MaxPowerinmotoringkWLabel_2 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_2.Text = 'Max Torque in generator [Nm]:';
            app.MxTqGenEdit = uieditfield(app.DialogueFig, 'numeric');
            app.MxTqGenEdit.Limits = [1 Inf];
            app.MxTqGenEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.MxTqGenEdit.Value = 1000;
            app.MaxPowerinmotoringkWLabel_3 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_3.Text = 'Max Power in generator [kW]:';
            app.MxPwGenEdit = uieditfield(app.DialogueFig, 'numeric');
            app.MxPwGenEdit.Limits = [1 Inf];
            app.MxPwGenEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.MxPwGenEdit.Value = 200;
            app.MaxPowerinmotoringkWLabel_9 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_9.Text = 'Base loss in motoring [kW]:';
            app.BaseLossMotEdit = uieditfield(app.DialogueFig, 'numeric');
            app.BaseLossMotEdit.Limits = [1 Inf];
            app.BaseLossMotEdit.Value = 20;
            app.BaseLossGenEdit = uieditfield(app.DialogueFig, 'numeric');
            app.BaseLossGenEdit.Limits = [1 Inf];
            app.BaseLossGenEdit.Value = 20;
            app.MaxPowerinmotoringkWLabel_4 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_4.Text = 'Max Speed [rpm]:';
            app.MxSpdEdit = uieditfield(app.DialogueFig, 'numeric');
            app.MxSpdEdit.Limits = [1 Inf];
            app.MxSpdEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.MxSpdEdit.Value = 5000;
            app.MaxPowerinmotoringkWLabel_5 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_5.Text = 'UDC in motoring [V]:';
            app.UdcMotEdit = uieditfield(app.DialogueFig, 'numeric');
            app.UdcMotEdit.Limits = [0 Inf];
            app.UdcMotEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.UdcMotEdit.Value = 1000;
            app.MaxPowerinmotoringkWLabel_6 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_6.Text = 'UDC in generator [V]:';
            app.UdcGenEdit = uieditfield(app.DialogueFig, 'numeric');
            app.UdcGenEdit.Limits = [0 Inf];
            app.UdcGenEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.UdcGenEdit.Value = 1000;
            app.UphTqEdit = uieditfield(app.DialogueFig, 'numeric');
            app.UphTqEdit.Limits = [0 Inf];
            app.UphTqEdit.Value = 20;
            app.UphSpeedEdit = uieditfield(app.DialogueFig, 'numeric');
            app.UphSpeedEdit.Limits = [0 Inf];
            app.UphSpeedEdit.Value = 10;
            app.NoTorquelevelsinmotoringLabel_4 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_4.Text = 'No. Torque levels in motoring:';
            app.NoTqLevPointsMotEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoTqLevPointsMotEdit.Limits = [2 Inf];
            app.NoTqLevPointsMotEdit.RoundFractionalValues = 'on';
            app.NoTqLevPointsMotEdit.ValueDisplayFormat = '%4.0f';
            app.NoTqLevPointsMotEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoTqLevPointsMotEdit.Value = 11;
            app.NoTorquelevelsinmotoringLabel_5 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_5.Text = 'No. Speed points in constant Torque motoring:';
            app.NoSpdPointsTqMotEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoSpdPointsTqMotEdit.Limits = [2 Inf];
            app.NoSpdPointsTqMotEdit.ValueDisplayFormat = '%4.0f';
            app.NoSpdPointsTqMotEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoSpdPointsTqMotEdit.Value = 11;
            app.NoTorquelevelsinmotoringLabel_6 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_6.Text = 'No. Power levels in motoring:';
            app.NoPwLevPointsMotEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoPwLevPointsMotEdit.Limits = [2 Inf];
            app.NoPwLevPointsMotEdit.ValueDisplayFormat = '%4.0f';
            app.NoPwLevPointsMotEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoPwLevPointsMotEdit.Value = 11;
            app.NoTorquelevelsinmotoringLabel_7 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_7.Text = 'No. Speed points in constant Power motoring:';
            app.NoSpdPointsPwMotEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoSpdPointsPwMotEdit.Limits = [2 Inf];
            app.NoSpdPointsPwMotEdit.ValueDisplayFormat = '%4.0f';
            app.NoSpdPointsPwMotEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoSpdPointsPwMotEdit.Value = 11;
            app.NoTorquelevelsinmotoringLabel_8 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_8.Text = 'No. Torque levels in generator:';
            app.NoTqLevPointsGenEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoTqLevPointsGenEdit.Limits = [2 Inf];
            app.NoTqLevPointsGenEdit.ValueDisplayFormat = '%4.0f';
            app.NoTqLevPointsGenEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoTqLevPointsGenEdit.Value = 11;
            app.NoTorquelevelsinmotoringLabel_9 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_9.Text = 'No. Speed points in constant Torque generator:';
            app.NoSpdPointsTqGenEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoSpdPointsTqGenEdit.Limits = [2 Inf];
            app.NoSpdPointsTqGenEdit.ValueDisplayFormat = '%4.0f';
            app.NoSpdPointsTqGenEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoSpdPointsTqGenEdit.Value = 11;
            app.NoTorquelevelsinmotoringLabel_10 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_10.Text = 'No. Power levels in generator:';
            app.NoPwLevPointsGenEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoPwLevPointsGenEdit.Limits = [2 Inf];
            app.NoPwLevPointsGenEdit.ValueDisplayFormat = '%4.0f';
            app.NoPwLevPointsGenEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoPwLevPointsGenEdit.Value = 11;
            app.NoTorquelevelsinmotoringLabel_11 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_11.Text = 'No. Speed points in constant Power generator:';
            app.NoSpdPointsPwGenEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoSpdPointsPwGenEdit.Limits = [2 Inf];
            app.NoSpdPointsPwGenEdit.ValueDisplayFormat = '%4.0f';
            app.NoSpdPointsPwGenEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoSpdPointsPwGenEdit.Value = 11;
            app.NoTorquelevelsinmotoringLabel_12 = uilabel(app.DialogueFig);
            app.NoTorquelevelsinmotoringLabel_12.Text = 'No. poles in the motor:';
            app.NoPolesEdit = uieditfield(app.DialogueFig, 'numeric');
            app.NoPolesEdit.Limits = [2 Inf];
            app.NoPolesEdit.RoundFractionalValues = 'on';
            app.NoPolesEdit.ValueDisplayFormat = '%4.0f';
            app.NoPolesEdit.ValueChangedFcn = createCallbackFcn(app, @FieldChanged, true);
            app.NoPolesEdit.Value = 4;
            app.MaxPowerinmotoringkWLabel_7 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_7.Text = 'Torque related UPh [mV/Nm]:';
            app.MaxPowerinmotoringkWLabel_8 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_8.Text = 'U/F related UPh [V/Hz]:';
            app.MaxPowerinmotoringkWLabel_10 = uilabel(app.DialogueFig);
            app.MaxPowerinmotoringkWLabel_10.Text = 'Base loss in generator [kW]:';
            % Show the figure after all components are created
            app.DialogueFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_motor_dlg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogueFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogueFig)
        end
    end
end